classdef GUI_Syre < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        CloseallButton                  matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        MainDataTab                     matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        PreliminaryDesignPanel          matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        MechstressButton                matlab.ui.control.StateButton
        DemagUGOButton                  matlab.ui.control.StateButton
        syrmDesignThermalInputDropDown  matlab.ui.control.DropDown
        CarrierDesignDropDown           matlab.ui.control.DropDown
        BarrierDesignDropDow            matlab.ui.control.DropDown
        FEAfixPopUp                     matlab.ui.control.DropDown
        ofFEAfixsimulationsDropDownLabel  matlab.ui.control.Label
        DemagHWCButton                  matlab.ui.control.StateButton
        MTPApointButton                 matlab.ui.control.StateButton
        FEAfixcorrectionsandcomputationsLabel  matlab.ui.control.Label
        DemagratedButton                matlab.ui.control.StateButton
        HWCSCcurrentButton              matlab.ui.control.StateButton
        CharcurrentButton               matlab.ui.control.StateButton
        PMfillingfactorpuEditField      matlab.ui.control.EditField
        PMfactorpuLabel                 matlab.ui.control.Label
        CurrentDensityArmsmm2EditField  matlab.ui.control.EditField
        CurrentDensityArmsmm2EditFieldLabel  matlab.ui.control.Label
        ThermalLoadingsyrmDesignEditField  matlab.ui.control.EditField
        ThermalLoadingkjWm2EditFieldLabel  matlab.ui.control.Label
        RotoryokefactorpuEditField      matlab.ui.control.EditField
        RotoryokefactorpuEditFieldLabel  matlab.ui.control.Label
        StatoryokefactorpuEditField     matlab.ui.control.EditField
        StatoryokefactorpuEditFieldLabel  matlab.ui.control.Label
        ktEdit                          matlab.ui.control.EditField
        ToothsizefactorpuLabel          matlab.ui.control.Label
        BfeEdit                         matlab.ui.control.EditField
        IronLoadingTEditFieldLabel      matlab.ui.control.Label
        bRangeEdit                      matlab.ui.control.EditField
        RangeofbairgapironsplitEditFieldLabel  matlab.ui.control.Label
        xRangeEdit                      matlab.ui.control.EditField
        RangeofxrotorstatorsplitEditFieldLabel  matlab.ui.control.Label
        FEAfixPushButt                  matlab.ui.control.Button
        syrmDesignPushButt              matlab.ui.control.Button
        CustomPanel                     matlab.ui.container.Panel
        GridLayout14                    matlab.ui.container.GridLayout
        CustomClear                     matlab.ui.control.Button
        CustomLoad                      matlab.ui.control.Button
        StateLamp                       matlab.ui.control.Lamp
        MainMotorParametersPanel        matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        TypeOfRotorList                 matlab.ui.control.DropDown
        TypeofrotorDropDownLabel        matlab.ui.control.Label
        NumberofstatorslotsEditField    matlab.ui.control.EditField
        NumberofstatorslotsEditFieldLabel  matlab.ui.control.Label
        Numberof3phasesetsEditField     matlab.ui.control.EditField
        Numberof3phasesetsEditFieldLabel_2  matlab.ui.control.Label
        StackLenghtEdit                 matlab.ui.control.EditField
        StacklengthmmEditFieldLabel     matlab.ui.control.Label
        ShaftRadEdit                    matlab.ui.control.EditField
        ShaftradiusmmEditFieldLabel     matlab.ui.control.Label
        AirGapRadiusEdit                matlab.ui.control.EditField
        AirgapradiusmmEditFieldLabel    matlab.ui.control.Label
        StatorOuterRadEdit              matlab.ui.control.EditField
        StatorouterradiusmmEditFieldLabel  matlab.ui.control.Label
        ScaleCheck                      matlab.ui.control.StateButton
        GapThiEdit                      matlab.ui.control.EditField
        AirgapthicknessmmEditFieldLabel  matlab.ui.control.Label
        NumOfSlotsEdit                  matlab.ui.control.EditField
        NumberofslotspolephaseLabel     matlab.ui.control.Label
        PolePairsEdit                   matlab.ui.control.EditField
        NumberofpolepairsEditFieldLabel  matlab.ui.control.Label
        StatorTab_2                     matlab.ui.container.Tab
        GridLayout5_3                   matlab.ui.container.GridLayout
        SlotModelPanel                  matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        ConductorTypeEdit               matlab.ui.control.DropDown
        ConductortypeDropDownLabel      matlab.ui.control.Label
        EvalSlotModelPush               matlab.ui.control.Button
        DrawSlotModelPush               matlab.ui.control.Button
        SlotModelTemperatureEdit        matlab.ui.control.EditField
        TemperaturevectorCLabel         matlab.ui.control.Label
        SlotModelFrequencyEdit          matlab.ui.control.EditField
        FrequencyvectorHzEditFieldLabel  matlab.ui.control.Label
        ConductorSlotGapEdit            matlab.ui.control.EditField
        ConductorslotgapmmLabel         matlab.ui.control.Label
        ConductorRadiusEdit             matlab.ui.control.EditField
        ConductorradiusmmEditFieldLabel  matlab.ui.control.Label
        ConductorHeightEdit             matlab.ui.control.EditField
        ConductorheightmmEditFieldLabel  matlab.ui.control.Label
        ConductorWidthEdit              matlab.ui.control.EditField
        ConductorwidthmmEditFieldLabel  matlab.ui.control.Label
        ConductorInsulationEdit         matlab.ui.control.EditField
        InsulationthicknessmmEditFieldLabel  matlab.ui.control.Label
        ParallelNumberEdit              matlab.ui.control.EditField
        NumberofparallelsLabel          matlab.ui.control.Label
        ConductorNumberEdit             matlab.ui.control.EditField
        NumberofconductorsEditFieldLabel  matlab.ui.control.Label
        MainWindingDataPanel_5          matlab.ui.container.Panel
        GridLayout15_5                  matlab.ui.container.GridLayout
        SetDefaultWinding               matlab.ui.control.Button
        SaveConfPush                    matlab.ui.control.Button
        WinTable                        matlab.ui.control.Table
        SlotlayerposDropDown            matlab.ui.control.DropDown
        SlotlayerpositionDropDownLabel  matlab.ui.control.Label
        SlotSimulEdit                   matlab.ui.control.EditField
        NumberofsimulatedslotsEditFieldLabel  matlab.ui.control.Label
        SlotlinerthicknessmmEditField   matlab.ui.control.EditField
        SlotlinerthicknessmmEditFieldLabel  matlab.ui.control.Label
        PitchWindEdit                   matlab.ui.control.EditField
        PitchshorteningfactorEditFieldLabel  matlab.ui.control.Label
        TurnsSeriesEdit                 matlab.ui.control.EditField
        TurnsinseriesperphaseEditFieldLabel  matlab.ui.control.Label
        SlotFillFacEdit                 matlab.ui.control.EditField
        SlotfillingfactorEditFieldLabel  matlab.ui.control.Label
        StatorParametersPanel_3         matlab.ui.container.Panel
        GridLayout15_4                  matlab.ui.container.GridLayout
        YokeLengthEditField             matlab.ui.control.EditField
        YokelengthmmEditFieldLabel      matlab.ui.control.Label
        FillCorSlotEdit                 matlab.ui.control.EditField
        FilletatslotbottommmEditFieldLabel  matlab.ui.control.Label
        ToothTangAngleEdit              matlab.ui.control.EditField
        ToothtangangleEditFieldLabel    matlab.ui.control.Label
        ToothTanDepEdit                 matlab.ui.control.EditField
        ToothtangdepthmmEditFieldLabel  matlab.ui.control.Label
        StatorSlotOpeEdit               matlab.ui.control.EditField
        StatorslotopeningpuEditFieldLabel  matlab.ui.control.Label
        SlotshapeDropDown               matlab.ui.control.DropDown
        SlotshapeDropDownLabel          matlab.ui.control.Label
        SlotWidthEdit                   matlab.ui.control.EditField
        SlotwidthmmLabel                matlab.ui.control.Label
        ToothWidthEdit                  matlab.ui.control.EditField
        ToothwidthmmEditFieldLabel      matlab.ui.control.Label
        ToothLengEdit                   matlab.ui.control.EditField
        ToothlengthmmEditFieldLabel     matlab.ui.control.Label
        RotorTab_2                      matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        IMRotorParametersPanel          matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        FilletatrotorslotbottommmEditField  matlab.ui.control.EditField
        FilletatrotorslotbottommmEditFieldLabel  matlab.ui.control.Label
        FilletatrotorslottopmmEditField  matlab.ui.control.EditField
        FilletatrotorslottopmmEditFieldLabel  matlab.ui.control.Label
        RotortoothtangmmEditField       matlab.ui.control.EditField
        RotortoothtangmmEditFieldLabel  matlab.ui.control.Label
        RotorslotopenEditField          matlab.ui.control.EditField
        RotorslotopenEditFieldLabel     matlab.ui.control.Label
        RotortoothwidthmmEditField      matlab.ui.control.EditField
        RotortoothwidthmmEditFieldLabel  matlab.ui.control.Label
        RotortoothlengthmmEditField     matlab.ui.control.EditField
        RotortoothlengthmmEditFieldLabel  matlab.ui.control.Label
        NumberofrotorbarsEditField      matlab.ui.control.EditField
        NumberofrotorbarsEditFieldLabel  matlab.ui.control.Label
        EESMRotorParametersPanel        matlab.ui.container.Panel
        GridLayout22_2                  matlab.ui.container.GridLayout
        PoleheadshapeDropDown           matlab.ui.control.DropDown
        PoleheadshapeDropDownLabel      matlab.ui.control.Label
        CoilfillingfactorpuEditField    matlab.ui.control.EditField
        CoilfillingfactorpuEditFieldLabel  matlab.ui.control.Label
        NumberofturnsperpoleEditField   matlab.ui.control.EditField
        NumberofturnsperpoleEditFieldLabel  matlab.ui.control.Label
        PoleHeadAngledegEditField       matlab.ui.control.EditField
        PoleHeadAngledegEditFieldLabel  matlab.ui.control.Label
        PoleHeadFilletmmEditField       matlab.ui.control.EditField
        PoleHeadFilletmmEditFieldLabel  matlab.ui.control.Label
        CoilheightmmEditField           matlab.ui.control.EditField
        CoilheightmmEditFieldLabel      matlab.ui.control.Label
        CoilwidthmmEditField            matlab.ui.control.EditField
        CoilwidthmmEditFieldLabel       matlab.ui.control.Label
        PolewidthmmEditField            matlab.ui.control.EditField
        PolewidthmmEditFieldLabel       matlab.ui.control.Label
        PoleAnglepuEditField            matlab.ui.control.EditField
        PoleAnglepuEditFieldLabel       matlab.ui.control.Label
        PoleheadheightmmEditField       matlab.ui.control.EditField
        PoleheadheightmmEditFieldLabel  matlab.ui.control.Label
        YokewidthmmEditField            matlab.ui.control.EditField
        YokewidthmmEditFieldLabel       matlab.ui.control.Label
        PolebodyheightmmEditField       matlab.ui.control.EditField
        PolebodyheightmmEditFieldLabel  matlab.ui.control.Label
        PMSMSyRParametersPanel          matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        ThetaFBSEdit                    matlab.ui.control.EditField
        ThetaFBSmechEditFieldLabel      matlab.ui.control.Label
        BetaEdit                        matlab.ui.control.EditField
        PMshapefactorbetapuEditFieldLabel  matlab.ui.control.Label
        NarrowFactorEdit                matlab.ui.control.EditField
        OuterbranchnarrowingfactorpuLabel  matlab.ui.control.Label
        RadShiftInnerEdit               matlab.ui.control.EditField
        InnerbranchradialshiftmmLabel   matlab.ui.control.Label
        CentralBarriersShrinkEdit       matlab.ui.control.EditField
        CentralbarriersshrinkpuLabel    matlab.ui.control.Label
        DxEdit                          matlab.ui.control.EditField
        BarriersoffsetpuLabel           matlab.ui.control.Label
        hcmmEdit                        matlab.ui.control.EditField
        BarrierswidthmmLabel            matlab.ui.control.Label
        hcpuEdit                        matlab.ui.control.EditField
        BarrierswidthpuLabel            matlab.ui.control.Label
        AlphadegreeEdit                 matlab.ui.control.EditField
        BarriersanglesalphaEditFieldLabel  matlab.ui.control.Label
        AlphapuEdit                     matlab.ui.control.EditField
        BarriersanglesalphapuEditFieldLabel  matlab.ui.control.Label
        NumberOfLayersEdit              matlab.ui.control.EditField
        NumberofrotorbarriersEditFieldLabel  matlab.ui.control.Label
        OptionsTab                      matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        StructuralParametersPanel       matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        PlotStatsButton                 matlab.ui.control.Button
        SleeveDesignerButton            matlab.ui.control.Button
        RotorsleevetemperatureCEditField  matlab.ui.control.EditField
        RotorsleevetemperatureCEditFieldLabel  matlab.ui.control.Label
        RotorsleeveinterferencemmEditField  matlab.ui.control.EditField
        RotorsleeveinterferencemmEditFieldLabel  matlab.ui.control.Label
        RotorsleevethicknessmmEditField  matlab.ui.control.EditField
        RotorsleevethicknessmmEditFieldLabel  matlab.ui.control.Label
        MecTolerEdit                    matlab.ui.control.EditField
        MinimummechtolerancemmLabel     matlab.ui.control.Label
        OverSpeedEdit                   matlab.ui.control.EditField
        OverspeedrpmLabel               matlab.ui.control.Label
        RibsDesignPanel                 matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        RadRibCheck                     matlab.ui.control.StateButton
        TanRibCheck                     matlab.ui.control.StateButton
        RadialRibsAngleEditField        matlab.ui.control.EditField
        TangentialribswidthmmEditFieldLabel_2  matlab.ui.control.Label
        SplitRibsEditField              matlab.ui.control.EditField
        RotorfilletmmEditFieldLabel_    matlab.ui.control.Label
        RotorfilletmmEditFieldLabel_5   matlab.ui.control.Label
        RotorFilletTan2EditField        matlab.ui.control.EditField
        RotorFilletRadOutEditField      matlab.ui.control.EditField
        RotorfilletmmEditFieldLabel_4   matlab.ui.control.Label
        RotorFilletRadInEditField       matlab.ui.control.EditField
        RotorfilletmmEditFieldLabel_2   matlab.ui.control.Label
        RadRibEdit                      matlab.ui.control.EditField
        RadialribswidthmmEditFieldLabel  matlab.ui.control.Label
        RotorFilletTan1EditField        matlab.ui.control.EditField
        RotorfilletmmEditFieldLabel     matlab.ui.control.Label
        TanRibEdit                      matlab.ui.control.EditField
        TangentialribswidthmmEditFieldLabel  matlab.ui.control.Label
        ThermalParametersPanel          matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        FieldresistancetargettempOhmEditField  matlab.ui.control.EditField
        FieldresistancetargettempOhmEditFieldLabel  matlab.ui.control.Label
        RatedfieldcurrentAEditField     matlab.ui.control.EditField
        RatedfieldcurrentAEditFieldLabel  matlab.ui.control.Label
        FieldcurrentdensityArmsmm2EditField  matlab.ui.control.EditField
        FieldcurrentdensityArmsmm2EditFieldLabel  matlab.ui.control.Label
        Rsedit                          matlab.ui.control.EditField
        PhresistancetargettemperatureOhmLabel  matlab.ui.control.Label
        CurrentdensityEdit              matlab.ui.control.EditField
        RMScurrentdensityAmm2Label      matlab.ui.control.Label
        EstimatedCoppTemp               matlab.ui.control.EditField
        EstimatedcoppertemperatureCLabel  matlab.ui.control.Label
        JouleLossesEdit                 matlab.ui.control.EditField
        AdmittedcopperlossWLabel        matlab.ui.control.Label
        CalculatedRatedCurrent          matlab.ui.control.EditField
        CalculatedratedcurrentALabel    matlab.ui.control.Label
        HousingTempEdit                 matlab.ui.control.EditField
        HousingtemperatureCEditFieldLabel  matlab.ui.control.Label
        CopperTempEdit                  matlab.ui.control.EditField
        TargetcoppertemperatureCLabel   matlab.ui.control.Label
        ThermalLoadKj                   matlab.ui.control.EditField
        ThermalloadingkjWm2Label        matlab.ui.control.Label
        MaterialsTab                    matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        LaminationPropertiesPanel       matlab.ui.container.Panel
        GridLayout41                    matlab.ui.container.GridLayout
        StackingfactorEditField         matlab.ui.control.EditField
        StackingfactorEditFieldLabel    matlab.ui.control.Label
        MagnetPanel                     matlab.ui.container.Panel
        GridLayout26                    matlab.ui.container.GridLayout
        PMNaSegments                    matlab.ui.control.EditField
        PMaxialsegmentsLabel            matlab.ui.control.Label
        PMNcSegmentsTable               matlab.ui.control.Table
        PMDesignPush                    matlab.ui.control.Button
        CarCurEdit                      matlab.ui.control.EditField
        CharacteristiccurrentpuLabel    matlab.ui.control.Label
        PMtemperatureEdit               matlab.ui.control.EditField
        PMtemperatureCEditFieldLabel_3  matlab.ui.control.Label
        BrPMEdit                        matlab.ui.control.EditField
        PMremanenceTEditFieldLabel_3    matlab.ui.control.Label
        PMclearanceTable                matlab.ui.control.Table
        PMdimTable                      matlab.ui.control.Table
        MaterialLibraryPanel            matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        ViewPropPush                    matlab.ui.control.Button
        RmvSleevePush                   matlab.ui.control.Button
        AddSleevePush                   matlab.ui.control.Button
        SleeveLibraryPush               matlab.ui.control.Button
        RmvMagnetPush                   matlab.ui.control.Button
        AddMagnetPush                   matlab.ui.control.Button
        BarrierLibraryPush              matlab.ui.control.Button
        RmvConductorPush                matlab.ui.control.Button
        AddConductorPush                matlab.ui.control.Button
        RmvIronPush                     matlab.ui.control.Button
        AddIronPush                     matlab.ui.control.Button
        ConductorLibraryPush            matlab.ui.control.Button
        IronLibraryPush                 matlab.ui.control.Button
        MaterialDataPanel               matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        StatorcorematerialDropDown      matlab.ui.control.DropDown
        kgLabel_6                       matlab.ui.control.Label
        MassTotalEditField              matlab.ui.control.EditField
        TotalmotormassLabel             matlab.ui.control.Label
        RotorInertiaLabel               matlab.ui.control.Label
        kgm2Label                       matlab.ui.control.Label
        RotorInertiaEditField           matlab.ui.control.EditField
        SleevematerialDropDown          matlab.ui.control.DropDown
        SleevematerialDropDownLabel     matlab.ui.control.Label
        ShaftmaterialDropDown           matlab.ui.control.DropDown
        ShaftmaterialDropDownLabel      matlab.ui.control.Label
        kgLabel_7                       matlab.ui.control.Label
        MassBarEditField                matlab.ui.control.EditField
        RotorslotmaterialDropDown       matlab.ui.control.DropDown
        StatorslotmaterialDropDownLabel_2  matlab.ui.control.Label
        kgLabel_4                       matlab.ui.control.Label
        MassFluxBarrierEditField        matlab.ui.control.EditField
        FluxbarriermaterialDropDown     matlab.ui.control.DropDown
        FluxbarriermaterialDropDownLabel  matlab.ui.control.Label
        kgLabel_3                       matlab.ui.control.Label
        MassRotorIronEditField          matlab.ui.control.EditField
        RotorcorematerialDropDown       matlab.ui.control.DropDown
        RotorcorematerialDropDownLabel  matlab.ui.control.Label
        kgLabel                         matlab.ui.control.Label
        MassWindingEditField            matlab.ui.control.EditField
        StatorslotmaterialDropDown      matlab.ui.control.DropDown
        StatorslotmaterialDropDownLabel  matlab.ui.control.Label
        kgLabel_2                       matlab.ui.control.Label
        MassStatorIronEditField         matlab.ui.control.EditField
        StatorcorematerialDropDownLabel  matlab.ui.control.Label
        OptimizationTab                 matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        EvaluationoptionsTab            matlab.ui.container.Tab
        GridLayout42                    matlab.ui.container.GridLayout
        ObjectivesandPenalizationLimitsPanel  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        MaxExpMechStress                matlab.ui.control.EditField
        VonMisesstressMPaButton         matlab.ui.control.StateButton
        RotorJoulelossWButton           matlab.ui.control.StateButton
        MaxExpPjf                       matlab.ui.control.EditField
        MaxExpPjs                       matlab.ui.control.EditField
        StatorJoulelossWButton          matlab.ui.control.StateButton
        NoLoadFluxOptCheck              matlab.ui.control.StateButton
        MaxExpNoLoadFluxEdit            matlab.ui.control.EditField
        PowerFactorOptCheck             matlab.ui.control.StateButton
        MinExpPowerFactorEdit           matlab.ui.control.EditField
        MassPMEdit                      matlab.ui.control.EditField
        MassPMOptCheck                  matlab.ui.control.StateButton
        MassCuEdit                      matlab.ui.control.EditField
        MassCuOptCheck                  matlab.ui.control.StateButton
        MaxExpeRippleTorEdit            matlab.ui.control.EditField
        TorRipOptCheck                  matlab.ui.control.StateButton
        TorqueOptCheck                  matlab.ui.control.StateButton
        MinExpTorEdit                   matlab.ui.control.EditField
        TimesteppingfineParetoreevaluationPanel  matlab.ui.container.Panel
        GridLayout30                    matlab.ui.container.GridLayout
        SimPosFinerEdit                 matlab.ui.control.EditField
        ofrotorpositionsEditField_2Label  matlab.ui.control.Label
        RotorPosFinerEdit               matlab.ui.control.EditField
        RotorangularexcursionEditField_2Label  matlab.ui.control.Label
        TimesteppingrawevolutionPanel   matlab.ui.container.Panel
        GridLayout29                    matlab.ui.container.GridLayout
        SimPosMOEdit                    matlab.ui.control.EditField
        ofrotorpositionsEditFieldLabel  matlab.ui.control.Label
        RotorPosiMOEdit                 matlab.ui.control.EditField
        RotorangularexcursionEditFieldLabel  matlab.ui.control.Label
        OptimizationoptionsPanel        matlab.ui.container.Panel
        GridLayout28                    matlab.ui.container.GridLayout
        OptimizePush                    matlab.ui.control.Button
        FieldbystatorcurrentdensityEditField  matlab.ui.control.EditField
        FieldbystatorcurrentdensityEditFieldLabel  matlab.ui.control.Label
        StatorcurrentsettingLabel       matlab.ui.control.Label
        flag_OptCurrConst               matlab.ui.control.DropDown
        XPopEdit                        matlab.ui.control.EditField
        PopulationsizeEditFieldLabel    matlab.ui.control.Label
        MaxGenEdit                      matlab.ui.control.EditField
        ofgenerationsEditFieldLabel     matlab.ui.control.Label
        OptimizationtypeDropDown        matlab.ui.control.DropDown
        CurrentOverLoadEdit             matlab.ui.control.EditField
        CurrentoverloadpuEditFieldLabel_2  matlab.ui.control.Label
        VariablesandBoundariesTab       matlab.ui.container.Tab
        GridLayout43                    matlab.ui.container.GridLayout
        PoleRotHeadAngleBouCheck        matlab.ui.control.StateButton
        PoleRotHeadAngleBou             matlab.ui.control.EditField
        PoleRotHeadFilletBouCheck       matlab.ui.control.StateButton
        PoleRotHeadFilletBou            matlab.ui.control.EditField
        CoilHeightBouCheck              matlab.ui.control.StateButton
        CoilHeightBou                   matlab.ui.control.EditField
        CoilWidthBouCheck               matlab.ui.control.StateButton
        CoilWidthBou                    matlab.ui.control.EditField
        PoleWidthBouCheck               matlab.ui.control.StateButton
        PoleWidthBou                    matlab.ui.control.EditField
        PoleAnglepuBouCheck             matlab.ui.control.StateButton
        PoleAnglepuBou                  matlab.ui.control.EditField
        PoleBodyHeightBouCheck          matlab.ui.control.StateButton
        PoleBodyHeightBou               matlab.ui.control.EditField
        YokeWidthBouCheck               matlab.ui.control.StateButton
        YokeWidthBou                    matlab.ui.control.EditField
        BetaPMshapeBouEdit              matlab.ui.control.EditField
        BetaPMshapeBouCheck             matlab.ui.control.StateButton
        FilletTanribsoutBou             matlab.ui.control.EditField
        FilletTanribsoutBouCheck        matlab.ui.control.StateButton
        FilletTanribsinBou              matlab.ui.control.EditField
        FilletTanribsinBouCheck         matlab.ui.control.StateButton
        FilletRadribsoutBou             matlab.ui.control.EditField
        FilletRadribsoutBouCheck        matlab.ui.control.StateButton
        FilletRadribsinBou              matlab.ui.control.EditField
        FilletRadribsinBouCheck         matlab.ui.control.StateButton
        TanRibBouEdit                   matlab.ui.control.EditField
        TanRibBouCheck                  matlab.ui.control.StateButton
        RadRibBouEdit                   matlab.ui.control.EditField
        RadRibBouCheck                  matlab.ui.control.StateButton
        BrBouEdit                       matlab.ui.control.EditField
        BrBouCheck                      matlab.ui.control.StateButton
        PMdimBouEdit                    matlab.ui.control.EditField
        PMdimBouCheck                   matlab.ui.control.StateButton
        RadShiftInnerBouEdit            matlab.ui.control.EditField
        RadShiftInnerBouCheck           matlab.ui.control.StateButton
        CentralShrinkBouEdit            matlab.ui.control.EditField
        CentralShrinkBouCheck           matlab.ui.control.StateButton
        DfeBouEdit                      matlab.ui.control.EditField
        DxBouCheck                      matlab.ui.control.StateButton
        hcBouEdit                       matlab.ui.control.EditField
        hcBouCheck                      matlab.ui.control.StateButton
        DeltaAlphaBouEdit               matlab.ui.control.EditField
        DalphaBouCheck                  matlab.ui.control.StateButton
        Alpha1BouEdit                   matlab.ui.control.EditField
        Dalpha1BouCheck                 matlab.ui.control.StateButton
        PhaseAngleCurrBouEdit           matlab.ui.control.EditField
        GammaBouCheck                   matlab.ui.control.StateButton
        ThetaFBSBouEdit                 matlab.ui.control.EditField
        ThetaFBSBouCheck                matlab.ui.control.StateButton
        GapBouEdit                      matlab.ui.control.EditField
        GapBouCheck                     matlab.ui.control.StateButton
        ToothTangDepthBouEdit           matlab.ui.control.EditField
        ToothTangDepthBouCheck          matlab.ui.control.StateButton
        StatorSlotOpenBouEdit           matlab.ui.control.EditField
        StatorSlotOpenBouCheck          matlab.ui.control.StateButton
        ToothLenBouEdit                 matlab.ui.control.EditField
        ToothLengthBouCheck             matlab.ui.control.StateButton
        ToothWidthBouEdit               matlab.ui.control.EditField
        ToothWidthBouCheck              matlab.ui.control.StateButton
        AirgapRadiusBouEdit             matlab.ui.control.EditField
        AirgapRadiusBouCheck            matlab.ui.control.StateButton
        SimulationTab                   matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        COMSOLButton_2                  matlab.ui.control.Button
        JMAGButton                      matlab.ui.control.Button
        MeshControlPanel                matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        StructuralPDEDropDown           matlab.ui.control.DropDown
        StructuralPDEDropDownLabel      matlab.ui.control.Label
        mesh_kpmEdit                    matlab.ui.control.EditField
        Mesh_MOOALabel_2                matlab.ui.control.Label
        MeshMOOAEdit                    matlab.ui.control.EditField
        Mesh_MOOALabel                  matlab.ui.control.Label
        MeshEdit                        matlab.ui.control.EditField
        MeshLabel                       matlab.ui.control.Label
        StartPProAnsysPush              matlab.ui.control.Button
        StartPProMagnetPush             matlab.ui.control.Button
        StartPProPush                   matlab.ui.control.Button
        SimulationSetupPanel            matlab.ui.container.Panel
        GridLayout31                    matlab.ui.container.GridLayout
        FieldcurrentloadpuEditField     matlab.ui.control.EditField
        FieldcurrentloadpuEditFieldLabel  matlab.ui.control.Label
        OffsetcurrentAEditField         matlab.ui.control.EditField
        OffsetcurrentAEditFieldLabel    matlab.ui.control.Label
        FieldcurrentAEditField          matlab.ui.control.EditField
        FieldcurrentAEditFieldLabel     matlab.ui.control.Label
        EvalTypePopUp                   matlab.ui.control.DropDown
        EvaluationtypeDropDownLabel     matlab.ui.control.Label
        AxistypeDropDown                matlab.ui.control.DropDown
        AxistypeDropDownLabel           matlab.ui.control.Label
        MapQuadrantsPopUp               matlab.ui.control.DropDown
        NumberofmapquadrantsLabel       matlab.ui.control.Label
        Active3phasesetsEditField       matlab.ui.control.EditField
        Active3phasesetsEditFieldLabel  matlab.ui.control.Label
        EvaluatedSpeedEdit              matlab.ui.control.EditField
        RotorspeedrpmEditFieldLabel     matlab.ui.control.Label
        NGridPPEdit                     matlab.ui.control.EditField
        Numberofpointsin0ImaxLabel      matlab.ui.control.Label
        TempPPEdit                      matlab.ui.control.EditField
        PMtemperatureCEditFieldLabel_2  matlab.ui.control.Label
        BrPPEdit                        matlab.ui.control.EditField
        PMremanenceTEditFieldLabel_2    matlab.ui.control.Label
        CurrentPP                       matlab.ui.control.EditField
        PhasecurrentAEditFieldLabel     matlab.ui.control.Label
        CurrLoPPEdit                    matlab.ui.control.EditField
        CurrentloadpuEditFieldLabel     matlab.ui.control.Label
        GammaPPEdit                     matlab.ui.control.EditField
        CurrentphaseangleeltEditFieldLabel  matlab.ui.control.Label
        NumOfRotorPosiPPEdit            matlab.ui.control.EditField
        NumberofrotorpositionsEditFieldLabel  matlab.ui.control.Label
        SpanEltPPEdit                   matlab.ui.control.EditField
        RotorangularexcursioneltEditFieldLabel  matlab.ui.control.Label
        CustomCurrentPanel              matlab.ui.container.Panel
        GridLayout32                    matlab.ui.container.GridLayout
        EvalACLossCustomCurrent         matlab.ui.control.Button
        CreateCustomCurrent             matlab.ui.control.Button
        CustomCurrentFile               matlab.ui.control.EditField
        text120_2                       matlab.ui.control.Label
        LoadCustomCurrent               matlab.ui.control.Button
        CustomCurrentSwitch             matlab.ui.control.Switch
        MotorCADTab                     matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        ExportMCadPush                  matlab.ui.control.Button
        BuildThermalModelPanel          matlab.ui.container.Panel
        GridLayout34                    matlab.ui.container.GridLayout
        FluidDropDown                   matlab.ui.control.DropDown
        FluidDropDownLabel              matlab.ui.control.Label
        HousingTypeDropDown             matlab.ui.control.DropDown
        HousingTypeDropDownLabel        matlab.ui.control.Label
        ExportThermalParameters_MCADPush  matlab.ui.control.Button
        FluidFlowRateEditField          matlab.ui.control.EditField
        FluidflowratelminLabel          matlab.ui.control.Label
        InlettemperatureEditField       matlab.ui.control.EditField
        InlettemperatureCEditFieldLabel  matlab.ui.control.Label
        ElectromagneticModulePanel      matlab.ui.container.Panel
        GridLayout36                    matlab.ui.container.GridLayout
        StartMCadSimPush                matlab.ui.control.Button
        ExportFluxMapsPush              matlab.ui.control.Button
        ThermalSimulationPanel          matlab.ui.container.Panel
        GridLayout33                    matlab.ui.container.GridLayout
        ThermalevaluationtypeDropDown   matlab.ui.control.DropDown
        ThermalevaluationtypeDropDownLabel  matlab.ui.control.Label
        RotorspeedrpmEditFieldLabel_3   matlab.ui.control.Label
        CustomLossMCADCheck             matlab.ui.control.CheckBox
        ThermalSimulation_MCAD          matlab.ui.control.Button
        EvaluatedSpeedMirrorEdit        matlab.ui.control.EditField
        RotorspeedrpmEditFieldLabel_2   matlab.ui.control.Label
        AmbientTemperatureEditField     matlab.ui.control.EditField
        AmbienttemperatureCLabel        matlab.ui.control.Label
        InitialTemperatureEditField     matlab.ui.control.EditField
        InitialtemperatureCLabel_2      matlab.ui.control.Label
        TimestepEditField               matlab.ui.control.EditField
        NumberofpointsLabel             matlab.ui.control.Label
        TransientperiodEditField        matlab.ui.control.EditField
        TransientperiodsEditFieldLabel  matlab.ui.control.Label
        ThermalLimitsatZeroSpeedPanel   matlab.ui.container.Panel
        GridLayout35                    matlab.ui.container.GridLayout
        SimulatedTransientCurrent       matlab.ui.control.EditField
        OutputALabel                    matlab.ui.control.Label
        CalculateTrans_MCAD             matlab.ui.control.Button
        SimulatedRatedCurrent           matlab.ui.control.EditField
        OutputALabel_2                  matlab.ui.control.Label
        CalculateCont_MCAD              matlab.ui.control.Button
        TransientTimeEditField          matlab.ui.control.EditField
        TransienttimesLabel             matlab.ui.control.Label
        TempCuLimitEdit                 matlab.ui.control.EditField
        CopperlimitCLabel               matlab.ui.control.Label
        AxisLogoMCAD                    matlab.ui.control.UIAxes
        UtilitiesTab                    matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        FEMMXFEMMPanel                  matlab.ui.container.Panel
        GridLayout45                    matlab.ui.container.GridLayout
        XFEMMsimulationButton           matlab.ui.control.StateButton
        ModelsimulationLabel            matlab.ui.control.Label
        ModelcreationLabel              matlab.ui.control.Label
        XFEMMcreationButton             matlab.ui.control.StateButton
        ZenodoopendatabasePanel         matlab.ui.container.Panel
        GridLayout44                    matlab.ui.container.GridLayout
        UpdatedmodelsandmoreonPEICPoliTOcommunityLabel  matlab.ui.control.Label
        ZenodoOpenButton                matlab.ui.control.Button
        ZenodoPEICHyperlink             matlab.ui.control.Hyperlink
        ZenodoSaveButton                matlab.ui.control.Button
        ZenodoReadMeButton              matlab.ui.control.Button
        ZenodoTextArea                  matlab.ui.control.TextArea
        ZenodoDropDown                  matlab.ui.control.DropDown
        toWorkSpaceButton               matlab.ui.control.Button
        LaunchxbDesignPlaneExplorerButton  matlab.ui.control.Button
        LaunchMMMButton                 matlab.ui.control.Button
        DocumentationPanel              matlab.ui.container.Panel
        GridLayout40                    matlab.ui.container.GridLayout
        CheckReleaseButton              matlab.ui.control.Button
        ReferencesButton                matlab.ui.control.Button
        UsermanualButton                matlab.ui.control.Button
        ParallelcomputingPanel          matlab.ui.container.Panel
        GridLayout39                    matlab.ui.container.GridLayout
        DisableButton                   matlab.ui.control.Button
        EnableButton                    matlab.ui.control.Button
        CheckButton                     matlab.ui.control.Button
        ExportPanel                     matlab.ui.container.Panel
        GridLayout37                    matlab.ui.container.GridLayout
        COMSOLButton                    matlab.ui.control.Button
        JMAGButton_2                    matlab.ui.control.Button
        AnsysMotorCADButton             matlab.ui.control.Button
        SaveMachineAnsysPush            matlab.ui.control.Button
        SaveMachineMagnetPush           matlab.ui.control.Button
        dxfButton                       matlab.ui.control.Button
        LoadMachinePush                 matlab.ui.control.Button
        text120                         matlab.ui.control.Label
        currentMotFileName              matlab.ui.control.EditField
        ClearCachePush                  matlab.ui.control.Button
        text95                          matlab.ui.control.Label
        SaveMachinePush                 matlab.ui.control.Button
        AxisLogo                        matlab.ui.control.UIAxes
        AxisGeometry                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % definition of the properties of app. Used to share data among the
        % application (as dataSet)
        dataSet                             % main machine data
    end
    
    methods (Access = public)
        
        function GUI_update(app)
            [app.dataSet,~,~,~] = back_compatibility(app.dataSet,[],[],0);
            GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_Syre_OpeningFcn(app, varargin)
            
            reset(groot); % reset graphics settings to default values
            
            setupPath(0);
            
            % Set figure at the center of the screen
            app.figure1.Units = 'pixels';
            screenPos = get(groot,'ScreenSize');
            figPos = app.figure1.Position;
            figPos(1:2)=screenPos(3:4)/2-figPos(3:4)/2;
            app.figure1.Position = figPos;
            
            % set the first tab
            app.TabGroup.SelectedTab = app.TabGroup.Children(1);
            
            % Axes update
            
            im = imread('syre.png');
            image(app.AxisLogo,im);
            set(app.AxisLogo,'dataAspectRatio',[1 1 1],'Visible','off')
            
            im = imread('mcad.jpg');
            image(app.AxisLogoMCAD,im);
            set(app.AxisLogoMCAD,'dataAspectRatio',[1 1 1],'Visible','off')
            
            set(app.AxisGeometry,...
                'XTickLabel',[],...
                'YTickLabel',[],...
                'Box','on')
            %
            tmp = ver('matlab');
            vMatlab = eval(tmp.Version);
            vMatlabDate = datetime(tmp.Date);
            if vMatlabDate>=datetime('18-Jul-2019')
                app.AxisGeometry.Interactions = [dataTipInteraction];
                app.AxisLogo.Interactions = [];
                app.AxisLogoMCAD.Interactions = [];
                set(app.AxisGeometry.Toolbar,'Visible','on')
                set(app.AxisLogo.Toolbar,'Visible','off')
                set(app.AxisLogoMCAD.Toolbar,'Visible','off')
            end

            if vMatlabDate>=datetime('14-May-2021')
               app.figure1.Icon = 'icon.png';
            end


            % load model
            if length(varargin)==1
                dataSet = varargin{1};
                [dataSet,geo,per,mat] = back_compatibility(dataSet,[],[],0);
            else
                if length(varargin)==0
                    defaultMotor = 'syreDefaultMotor.mat';
                    defaultPath  = checkPathSyntax([cd '\motorExamples\']);
                elseif length(varargin)==2
                    defaultMotor = varargin{2};
                    defaultPath  = varargin{1};
                end
                load([defaultPath defaultMotor])
                update_material_library(defaultPath,defaultMotor)
                [dataSet,geo,per,mat] = back_compatibility(dataSet,geo,per,0);
                dataSet.currentpathname = defaultPath;
                dataSet.currentfilename = defaultMotor;
            end


            app.dataSet = dataSet;
            
            % update materials dropdowns
            mat = material_properties_conductor('0');
            app.StatorslotmaterialDropDown.Items = mat.MatList;
            app.RotorslotmaterialDropDown.Items  = mat.MatList;
            
            mat = material_properties_layer('0');
            app.FluxbarriermaterialDropDown.Items = mat.MatList;
            
            mat = material_properties_iron('0');
            app.StatorcorematerialDropDown.Items = mat.MatList;
            app.RotorcorematerialDropDown.Items  = mat.MatList;
            app.ShaftmaterialDropDown.Items      = ['Air' mat.MatList];

            mat = material_properties_sleeve('0');
            app.SleevematerialDropDown.Items = mat.MatList;

            ZenodoNames = ZenodoLibrary_list();
            app.ZenodoDropDown.Items = ZenodoNames;
            app.ZenodoDropDown.Value = ZenodoNames{1};
            description = ZenodoLibrary_description(ZenodoNames{1});
            app.ZenodoTextArea.Value = description;
            
            app = GUI_APP_DrawMachine(app);
            
            % update GUI fields according to the current motor
            GUI_APP_SetParameters(app);
            
            app.dataSet = dataSet;
        end

        % Button pushed function: AddConductorPush
        function AddConductorPush_Callback(app, event)
            
            answer = inputdlg('New Material Name','Conductor Material',1,{'New Conductor'});
            MatName = char(answer);
            mat=material_properties_conductor('');
            MatList=mat.MatList;
            flag=1;
            for ii=1:length(MatList)
                if strcmp(MatName,MatList{ii})
                    flag=0;
                end
            end
            if flag
                add_material_conductor(MatName);
            else
                disp('Material already present in the library')
            end
            
            % update materials dropdowns
            mat = material_properties_conductor('0');
            app.StatorslotmaterialDropDown.Items = mat.MatList;
        end

        % Button pushed function: AddIronPush
        function AddIronPush_Callback(app, event)
            % hObject    handle to AddIronPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            answer = inputdlg('New Material Name','Iron Material',1,{'New Iron'});
            MatName = char(answer);
            mat=material_properties_iron('');
            MatList=mat.MatList;
            flag=1;
            for ii=1:length(MatList)
                if strcmp(MatName,MatList{ii})
                    flag=0;
                end
            end
            if flag
                add_material_iron(MatName);
            else
                disp('Material already present in the library')
            end
            % update materials dropdowns
            mat = material_properties_iron('0');
            app.StatorcorematerialDropDown.Items = mat.MatList;
            app.RotorcorematerialDropDown.Items  = mat.MatList;
            app.ShaftmaterialDropDown.Items      = ['Air' mat.MatList];
            
        end

        % Button pushed function: AddMagnetPush
        function AddMagnetPush_Callback(app, event)
            % hObject    handle to AddMagnetPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            answer = inputdlg('New Material Name','Magnet Material',1,{'New Magnet'});
            MatName = char(answer);
            mat=material_properties_layer('');
            MatList=mat.MatList;
            flag=1;
            for ii=1:length(MatList)
                if strcmp(MatName,MatList{ii})
                    flag=0;
                end
            end
            if flag
                add_material_layer(MatName);
            else
                disp('Material already present in the library')
            end
            
            % update materials dropdowns
            mat = material_properties_layer('0');
            app.FluxbarriermaterialDropDown.Items = mat.MatList;
        end

        % Value changed function: AirGapRadiusEdit
        function AirGapRadiusEdit_Callback(app, event)
            % Airgap Radius [mm](geo.r)
            
            % hObject    handle to AirGapRadiusEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of AirGapRadiusEdit as text
            %        str2double(get(hObject,'String')) returns contents of AirGapRadiusEdit as a double
            dataSet = app.dataSet;
            dataSet.AirGapRadius = eval(app.AirGapRadiusEdit.Value);
            %             dataSet.ToothLength = dataSet.StatorOuterRadius * (1-dataSet.AirGapRadius/dataSet.StatorOuterRadius*(1+dataSet.MagLoadingYoke/dataSet.NumOfPolePairs));
            %             dataSet.ToothLength = round(dataSet.ToothLength*10000)/10000;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: AirgapRadiusBouCheck
        function AirgapRadiusBouCheck_Callback(app, event)
            % Airgap radius [mm](bounds_xr)check box
            % hObject    handle to AirgapRadiusBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of AirgapRadiusBouCheck
            dataSet = app.dataSet;
            dataSet.AirgapRadiusBouCheck = app.AirgapRadiusBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            %dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: AirgapRadiusBouEdit
        function AirgapRadiusBouEdit_Callback(app, event)
            % Airgap radius [mm](bounds_xr)
            % hObject    handle to AirgapRadiusBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of AirgapRadiusBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of AirgapRadiusBouEdit as a double
            dataSet = app.dataSet;
            dataSet.GapRadiusBou = eval(app.AirgapRadiusBouEdit.Value);
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: Alpha1BouEdit
        function Alpha1BouEdit_Callback(app, event)
            % first barrier airgap angle or dalpha 1 (bounds_dalpha_1)
            % hObject    handle to Alpha1BouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of Alpha1BouEdit as text
            %        str2double(get(hObject,'String')) returns contents of Alpha1BouEdit as a double
            dataSet = app.dataSet;
            dataSet.Alpha1Bou = eval(app.Alpha1BouEdit.Value);
            [m,n] = size(dataSet.Alpha1Bou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: AlphadegreeEdit
        function AlphadegreeEdit_Callback(app, event)
            % alpha [degrees]
            % hObject    handle to AlphadegreeEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of AlphadegreeEdit as text
            %        str2double(get(hObject,'String')) returns contents of AlphadegreeEdit as a double
            dataSet = app.dataSet;
%             dataSet.AngleSpanOfPM = eval(app.AlphadegreeEdit.Value);
%             if dataSet.AngleSpanOfPM>179.5
%                 dataSet.AngleSpanOfPM=179.5;
%                 disp('PM span limited to 179.5 elt deg')
%             end
            dataSet.ALPHAdeg= eval(app.AlphadegreeEdit.Value);
            dataSet.ALPHApu = dataSet.ALPHAdeg/180*dataSet.NumOfPolePairs;
            app.dataSet = dataSet;
            
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: AlphapuEdit
        function AlphapuEdit_Callback(app, event)
            % alpha [p.u.]
            % hObject    handle to AlphapuEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hO bject,'String') returns contents of AlphapuEdit as text
            %        str2double(get(hObject,'String')) returns contents of AlphapuEdit as a double
            dataSet = app.dataSet;
            ALPHApu = eval(app.AlphapuEdit.Value);
            % the sum of pu angles is rescaled to one
            % alpha1 is not rescaled: only the angles from the second barrier onwards
            if ~strcmp(dataSet.TypeOfRotor,'SPM-Halbach')
                if sum(ALPHApu)>(1-0.05) % 0.05 is the angular space guaranteed for the spider
                    if ALPHApu(1)>(1-0.05*(dataSet.NumOfLayers)) % max ALPHApu(1)=1-0.05-0.05*(nlay-1)
                        disp('Wrong value of first alpha_pu')
                        ALPHApu(1)=1-0.05*(dataSet.NumOfLayers);
                    end
                    ALPHApu(2:end)=ALPHApu(2:end)/sum(ALPHApu(2:end))*(1-0.05-ALPHApu(1));
                    disp('Correct value of alpha_pu')
                end
            end
            
            %Limitazione escursione minima di ALPHApu caso Vtype - rev.Gallo
            [~,~,geo,~,~] = data0(dataSet);
            if strcmp(geo.RotType,'Vtype')
                if ALPHApu < 0.25 %escursione minima ammessa caso multibarriera
                    disp ('Value not allowed of alpha_pu')
                    ALPHApu=0.25;
                    disp('Minimum value of alpha_pu is set')
                end
            end
            
            dataSet.ALPHApu=round(ALPHApu,3);
            app.AlphapuEdit.Value = mat2str(dataSet.ALPHApu);
            %             set(hObject,'String',mat2str(dataSet.ALPHApu));
            dataSet.Dalpha1BouCheck = 0;
            dataSet.DalphaBouCheck = 0;
            
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Button pushed function: BarrierLibraryPush
        function BarrierLibraryPush_Callback(app, event)
            % hObject    handle to BarrierLibraryPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            mat = material_properties_layer(0);
            
            material=reshape(mat.MatList,numel(mat.MatList),1);
            
            disp('Permanent magnets material library:')
            for ii=1:length(material)
                disp(['- ' material{ii}])
            end
        end

        % Value changed function: BetaEdit
        function BetaEdit_Callback(app, event)
            % hObject    handle to BetaEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of BetaEdit as text
            %        str2double(get(hObject,'String')) returns contents of BetaEdit as a double
            dataSet = app.dataSet;
            % dataSet.betaPMshape = str2double(get(hObject,'String'));
            beta = eval(app.BetaEdit.Value);
            
            beta(beta>1) = 1;
            beta(beta<0) = 0;
            dataSet.betaPMshape = beta;
            
            %             set(hObject,'String',mat2str(dataSet.betaPMshape));
            app.BetaEdit.Value = mat2str(dataSet.betaPMshape);
            %set(app.FluxBarMatEdit,'String',dataSet.FluxBarrierMaterial); %rev.Gallo
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: BetaPMshapeBouCheck
        function BetaPMshapeBouCheck_Callback(app, event)
            % hObject    handle to BetaPMshapeBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of BetaPMshapeBouCheck
            dataSet = app.dataSet;
            dataSet.BetaPMshapeBouCheck = app.BetaPMshapeBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: BetaPMshapeBouEdit
        function BetaPMshapeBouEdit_Callback(app, event)
            % hObject    handle to BetaPMshapeBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of BetaPMshapeBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of BetaPMshapeBouEdit as a double
            dataSet = app.dataSet;
            dataSet.BetaPMshapeBou = eval(app.BetaPMshapeBouEdit.Value);
            [m,n] = size(dataSet.BetaPMshapeBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            
            if ((max(dataSet.BetaPMshapeBou)>1)||(min(dataSet.BetaPMshapeBou)<0))
                dataSet.BetaPMshapeBou = [0.5 1];
            end
            
            % dataSet.BetaPMshapeBou(dataSet.BetaPMshapeBou>1) = 1;
            % dataSet.BetaPMshapeBou(dataSet.BetaPMshapeBou<0) = 0;
            
            [bounds, objs, geo, per, ~] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: BfeEdit
        function BfeEdit_Callback(app, event)
            % hObject    handle to BfeEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of BfeEdit as text
            %        str2double(get(hObject,'String')) returns contents of BfeEdit as a double
            dataSet = app.dataSet;
            dataSet.Bfe = eval(app.BfeEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: BrBouCheck
        function BrBouCheck_Callback(app, event)
            % Br [T] (bounds_Br)
            % hObject    handle to BrBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hint: get(hObject,'Value') returns toggle state of BrBouCheck
            dataSet = app.dataSet;
            dataSet.BrBouCheck = app.BrBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: BrBouEdit
        function BrBouEdit_Callback(app, event)
            % Br [T](bounds_Br)
            % hObject    handle to BrBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of BrBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of BrBouEdit as a double
            dataSet = app.dataSet;
            dataSet.BrBou = eval(app.BrBouEdit.Value);
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: BrPMEdit
        function BrPMEdit_Callback(app, event)
            % Br for PMs
            
            % hObject    handle to BrPMEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of BrPMEdit as text
            %        str2double(get(hObject,'String')) returns contents of BrPMEdit as a double
            dataSet = app.dataSet;
            dataSet.Br = eval(app.BrPMEdit.Value);
            dataSet.BrPP = dataSet.Br;  % update Br post proc when Br is changed
            dataSet.BrBouCheck = 0;
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: BrPPEdit
        function BrPPEdit_Callback(app, event)
            % Br [T]
            
            % hObject    handle to BrPPEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of BrPPEdit as text
            %        str2double(get(hObject,'String')) returns contents of BrPPEdit as a double
            
            dataSet = app.dataSet;
            dataSet.BrPP = eval(app.BrPPEdit.Value);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: CarCurEdit
        function CarCurEdit_Callback(app, event)
            % hObject    handle to CarCurEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of CarCurEdit as text
            %        str2double(get(hObject,'String')) returns contents of CarCurEdit as a double
            dataSet = app.dataSet;
            dataSet.CurrPM = eval(app.CarCurEdit.Value);
            
            app.dataSet = dataSet;
            %app = GUI_APP_DrawMachine(app);
            % % guidata(hObject,handles)
        end

        % Button pushed function: ClearCachePush
        function ClearCachePush_Callback(app, event)
            % Empty \tmp folder pushbutton
            % hObject    handle to ClearCachePush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            clearTmpFolder(1);
            % % guidata(hObject,handles)
        end

        % Value changed function: ConductorHeightEdit
        function ConductorHeightEdit_Callback(app, event)
            % hObject    handle to ConductorHeightEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ConductorHeightEdit as text
            %        str2double(get(hObject,'String')) returns contents of ConductorHeightEdit as a double
            dataSet = app.dataSet;
            dataSet.SlotConductorHeight = eval(app.ConductorHeightEdit.Value);
            if dataSet.SlotConductorHeight<0
                dataSet.SlotConductorHeight=-dataSet.SlotConductorHeight;
            end
            app.dataSet = dataSet;
            % app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ConductorInsulationEdit
        function ConductorInsulationEdit_Callback(app, event)
            % hObject    handle to ConductorInsulationEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ConductorInsulationEdit as text
            %        str2double(get(hObject,'String')) returns contents of ConductorInsulationEdit as a double
            dataSet = app.dataSet;
            dataSet.SlotConductorInsulation = eval(app.ConductorInsulationEdit.Value);
            if dataSet.SlotConductorInsulation<0
                dataSet.SlotConductorInsulation=0.1;
            end
            app.dataSet = dataSet;
            % app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Button pushed function: ConductorLibraryPush
        function ConductorLibraryPush_Callback(app, event)
            % hObject    handle to ConductorLibraryPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            mat = material_properties_conductor(0);
            
            material=reshape(mat.MatList,numel(mat.MatList),1);
            
            disp('Conductor material library:')
            for ii=1:length(material)
                disp(['- ' material{ii}])
            end
        end

        % Value changed function: ConductorNumberEdit
        function ConductorNumberEdit_Callback(app, event)
            % hObject    handle to ConductorNumberEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ConductorNumberEdit as text
            %        str2double(get(hObject,'String')) returns contents of ConductorNumberEdit as a double
            dataSet = app.dataSet;
            dataSet.SlotConductorNumber = eval(app.ConductorNumberEdit.Value);
            if dataSet.SlotConductorNumber<0
                dataSet.SlotConductorNumber=dataSet.TurnsInSeries/dataSet.NumOfPolePairs/(dataSet.NumOfSlots);
            end
            app.dataSet = dataSet;
            % app = GUI_APP_DrawMachine(app);
            GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ConductorRadiusEdit
        function ConductorRadiusEdit_Callback(app, event)
            % hObject    handle to ConductorRadiusEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ConductorRadiusEdit as text
            %        str2double(get(hObject,'String')) returns contents of ConductorRadiusEdit as a double
            dataSet = app.dataSet;
            dataSet.SlotConductorRadius = eval(app.ConductorRadiusEdit.Value);
            if dataSet.SlotConductorRadius<0
                dataSet.SlotConductorRadius=-dataSet.SlotConductorRadius;
            end
            app.dataSet = dataSet;
            % app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ConductorTypeEdit
        function ConductorTypeEdit_Callback(app, event)
            % hObject    handle to ConductorTypeEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns ConductorTypeEdit contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from ConductorTypeEdit
            dataSet = app.dataSet;
            %             ind = get(hObject,'Value');
            %             str = get(hObject,'String');
            dataSet.SlotConductorType = app.ConductorTypeEdit.Value;
            app.dataSet = dataSet;
            % % guidata(hObject,handles)
        end

        % Value changed function: ConductorWidthEdit
        function ConductorWidthEdit_Callback(app, event)
            % hObject    handle to ConductorWidthEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ConductorWidthEdit as text
            %        str2double(get(hObject,'String')) returns contents of ConductorWidthEdit as a double
            dataSet = app.dataSet;
            dataSet.SlotConductorWidth = eval(app.ConductorWidthEdit.Value);
            if dataSet.SlotConductorWidth<0
                dataSet.SlotConductorWidth=-dataSet.SlotConductorWidth;
            end
            app.dataSet = dataSet;
            % app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: CopperTempEdit
        function CopperTempEdit_Callback(app, event)
            % Target Copper Temperature
            
            % hObject    handle to CopperTempEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of CopperTempEdit as text
            %        str2double(get(hObject,'String')) returns contents of CopperTempEdit as a double
            dataSet = app.dataSet;
            dataSet.TargetCopperTemp = eval(app.CopperTempEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: CurrLoPPEdit
        function CurrLoPPEdit_Callback(app, event)
            % Current load [p.u.]
            % hObject    handle to CurrLoPPEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of CurrLoPPEdit as text
            %        str2double(get(hObject,'String')) returns contents of CurrLoPPEdit as a double
            
            dataSet = app.dataSet;
            dataSet.CurrLoPP = eval(app.CurrLoPPEdit.Value);
            dataSet.SimulatedCurrent = dataSet.CurrLoPP*dataSet.RatedCurrent;
            
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app)
            % % guidata(hObject,handles)
        end

        % Value changed function: CurrentOverLoadEdit
        function CurrentOverLoadEdit_Callback(app, event)
            % current overload factor used for optimization (per overload)
            
            % hObject    handle to CurrentOverLoadEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of CurrentOverLoadEdit as text
            %        str2double(get(hObject,'String')) returns contents of CurrentOverLoadEdit as a double
            dataSet = app.dataSet;
            dataSet.CurrOverLoad = eval(app.CurrentOverLoadEdit.Value);
            app.dataSet = dataSet;
            % % guidata(hObject,handles)
        end

        % Value changed function: Dalpha1BouCheck
        function Dalpha1BouCheck_Callback(app, event)
            % 1st barrier airgap angle [p.u.](bounds_dalpha_1)check box
            
            % hObject    handle to Dalpha1BouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of Dalpha1BouCheck
            dataSet = app.dataSet;
            dataSet.Dalpha1BouCheck = app.Dalpha1BouCheck.Value;
            if dataSet.Dalpha1BouCheck ==0
                dataSet.DalphaBouCheck = 0;
            else
                dataSet.DalphaBouCheck = 1;
            end
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: DalphaBouCheck
        function DalphaBouCheck_Callback(app, event)
            % Other barriers angles [p.u.](bounds_dalpha)
            % hObject    handle to DalphaBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of DalphaBouCheck
            dataSet = app.dataSet;
            dataSet.DalphaBouCheck = app.DalphaBouCheck.Value;
            if dataSet.DalphaBouCheck ==0
                dataSet.Dalpha1BouCheck = 0;
            else
                dataSet.Dalpha1BouCheck = 1;
            end
            % set(app.Dalpha1BouCheck,'Value',dataSet.Dalpha1BouCheck);
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: DeltaAlphaBouEdit
        function DeltaAlphaBouEdit_Callback(app, event)
            % Other barriers angles [p.u.](bounds_dalpha)
            % hObject    handle to DeltaAlphaBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of DeltaAlphaBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of DeltaAlphaBouEdit as a double
            dataSet = app.dataSet;
            dataSet.DeltaAlphaBou = eval(app.DeltaAlphaBouEdit.Value);
            [m,n] = size(dataSet.DeltaAlphaBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: DfeBouEdit
        function DfeBouEdit_Callback(app, event)
            % dx [p.u.](bounds_Dx)
            
            % hObject    handle to DfeBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of DfeBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of DfeBouEdit as a double
            dataSet = app.dataSet;
            dataSet.DfeBou = eval(app.DfeBouEdit.Value);
            [m,n] = size(dataSet.DfeBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Button pushed function: DrawSlotModelPush
        function DrawSlotModelPush_Callback(app, event)
            % hObject    handle to DrawSlotModelPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dataSet = app.dataSet;
            dataSet = skinEffect_draw(dataSet);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: DxBouCheck
        function DxBouCheck_Callback(app, event)
            % dx [p.u.](bounds_Dx)
            % hObject    handle to DxBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of DxBouCheck
            dataSet = app.dataSet;
            dataSet.DxBouCheck = app.DxBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: DxEdit
        function DxEdit_Callback(app, event)
            % hObject    handle to DxEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of DxEdit as text
            %        str2double(get(hObject,'String')) returns contents of DxEdit as a double
            dataSet = app.dataSet;
            dataSet.DepthOfBarrier = eval(app.DxEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Button pushed function: EvalSlotModelPush
        function EvalSlotModelPush_Callback(app, event)
            % hObject    handle to EvalSlotModelPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dataSet = app.dataSet;
            skinEffect_eval(dataSet);
            % guidata(hObject,handles)
        end

        % Value changed function: EvalTypePopUp
        function EvalTypePopUp_Callback(app, event)
            % hObject    handle to EvalTypePopUp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns EvalTypePopUp contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from EvalTypePopUp
            dataSet = app.dataSet;
            tmp     = app.EvalTypePopUp.Value;
            switch tmp
                case 'Single Point'
                    dataSet.EvalType = 'singt';
                    if length(dataSet.BrPP)>1
                        mat=material_properties_layer(dataSet.FluxBarrierMaterial);
                        if ~isfield(mat,'temp')
                            dataSet.tempPP=20;
                            dataSet.BrPP=mat.Br;
                        else
                            dataSet.BrPP   = mat.Br;
                            dataSet.tempPP=interp1(mat.temp.Br,mat.temp.temp,dataSet.BrPP);
                        end
                    end
                    dataSet.AngularSpanPP = 60;
                    %dataSet.NumOfRotPosPP = 15;
                case 'Flux Map'
                    dataSet.EvalType = 'singm';
                    if length(dataSet.BrPP)>1
                        mat=material_properties_layer(dataSet.FluxBarrierMaterial);
                        if ~isfield(mat,'temp')
                            dataSet.tempPP=20;
                            dataSet.BrPP=mat.Br;
                        else
                            dataSet.BrPP   = mat.Br;
                            dataSet.tempPP=interp1(mat.temp.Br,mat.temp.temp,dataSet.BrPP);
                        end
                    end 
                    dataSet.CurrLoPP = dataSet.CurrLoPP(1);
                    dataSet.SimulatedCurrent = dataSet.RatedCurrent*dataSet.CurrLoPP;
                    % dataSet.MapQuadrants = 1;
                    %dataSet.AngularSpanPP = 60;
                    %dataSet.NumOfRotPosPP = 15;
                    dataSet.NumGrid       = 15;
                case 'Demagnetization Analysis'
                    dataSet.EvalType = 'demagArea';
                    mat = material_properties_layer(dataSet.FluxBarrierMaterial);
                    if ~isfield(mat,'temp')
                        warning('The selected PM has no demag properties. Change or update material!!!')
                    else
                        dataSet.tempPP = mat.temp.temp(1);
                        dataSet.BrPP   = mat.temp.Br(1);
                    end
                case 'Demagnetization Curve'
                    dataSet.EvalType = 'idemag';
                    mat = material_properties_layer(dataSet.FluxBarrierMaterial);
                    if ~isfield(mat,'temp')
                        warning('The selected PM has no demag properties. Change or update material!!!')
                    else
                        dataSet.tempPP = mat.temp.temp;
                        dataSet.BrPP   = mat.temp.Br;
                    end
                case 'Demagnetization Curve DQ'
                    dataSet.EvalType = 'idemagDQ';
                    mat = material_properties_layer(dataSet.FluxBarrierMaterial);
                    if ~isfield(mat,'temp')
                        warning('The selected PM has no demag properties. Change or update material!!!')
                    else
                        if length(dataSet.tempPP)>1
                            dataSet.tempPP = mat.temp.temp(1);
                            dataSet.BrPP   = mat.temp.Br(1);
                        end
                    end
                case 'Characteristic Current'
                    dataSet.EvalType = 'ichval';
                    mat = material_properties_layer(dataSet.FluxBarrierMaterial);
                    if ~isfield(mat,'temp')
                        warning('The selected PM has no demag properties. Change or update material!!!')
                    else
                        dataSet.tempPP = mat.temp.temp;
                        dataSet.BrPP   = mat.temp.Br;
                    end
                case 'Flux Density Analysis'
                    dataSet.EvalType = 'flxdn';
                    if length(dataSet.BrPP)>1
                        mat=material_properties_layer(dataSet.FluxBarrierMaterial);
                        if ~isfield(mat,'temp')
                            dataSet.tempPP=20;
                            dataSet.BrPP=mat.Br;
                        else
                            dataSet.BrPP   = mat.Br;
                            dataSet.tempPP=interp1(mat.temp.Br,mat.temp.temp,dataSet.BrPP);
                        end
                    end
                case 'Current Offset'
                    dataSet.EvalType = 'izero';
                    if length(dataSet.BrPP)>1
                        mat=material_properties_layer(dataSet.FluxBarrierMaterial);
                        if ~isfield(mat,'temp')
                            dataSet.tempPP=20;
                            dataSet.BrPP=mat.Br;
                        else
                            dataSet.BrPP   = mat.Br;
                            dataSet.tempPP=interp1(mat.temp.Br,mat.temp.temp,dataSet.BrPP);
                        end
                    end
                case 'Airgap Force'
                    dataSet.EvalType = 'force';
                    if length(dataSet.BrPP)>1
                        mat=material_properties_layer(dataSet.FluxBarrierMaterial);
                        if ~isfield(mat,'temp')
                            dataSet.tempPP=20;
                            dataSet.BrPP=mat.Br;
                        else
                            dataSet.BrPP   = mat.Br;
                            dataSet.tempPP=interp1(mat.temp.Br,mat.temp.temp,dataSet.BrPP);
                        end
                    end
                    dataSet.AngularSpanPP = 180;
                case 'Iron Loss - Single Point'
                    dataSet.EvalType = 'singtIron';
                    if length(dataSet.BrPP)>1
                        mat=material_properties_layer(dataSet.FluxBarrierMaterial);
                        if ~isfield(mat,'temp')
                            dataSet.tempPP=20;
                            dataSet.BrPP=mat.Br;
                        else
                            dataSet.BrPP   = mat.Br;
                            dataSet.tempPP=interp1(mat.temp.Br,mat.temp.temp,dataSet.BrPP);
                        end
                    end
                    if dataSet.EvalSpeed==0
                        dataSet.EvalSpeed = 3000/dataSet.NumOfPolePairs;
                        disp('EvalSpeed must be >0!!!')
                    elseif dataSet.EvalSpeed<0
                        dataSet.EvalSpeed = -dataSet.EvalSpeed;
                    end
                    dataSet.AngularSpanPP = 180;
                    dataSet.NumOfRotPosPP = 90;
                case 'Iron Loss - Flux Map'
                    dataSet.EvalType = 'singmIron';
                    if length(dataSet.BrPP)>1
                        mat=material_properties_layer(dataSet.FluxBarrierMaterial);
                        if ~isfield(mat,'temp')
                            dataSet.tempPP=20;
                            dataSet.BrPP=mat.Br;
                        else
                            dataSet.BrPP   = mat.Br;
                            dataSet.tempPP=interp1(mat.temp.Br,mat.temp.temp,dataSet.BrPP);
                        end
                    end
                    if dataSet.EvalSpeed==0
                        dataSet.EvalSpeed = 3000/dataSet.NumOfPolePairs;
                        disp('EvalSpeed must be >0!!!')
                    elseif dataSet.EvalSpeed<0
                        dataSet.EvalSpeed = -dataSet.EvalSpeed;
                    end
                    dataSet.AngularSpanPP = 180;
                    dataSet.NumOfRotPosPP = 90;
                    %dataSet.MapQuadrants  = 1;
                    %dataSet.NumGrid       = 9;
                    
                    dataSet.CurrLoPP = dataSet.CurrLoPP(1);
                    
                    if ~strcmp(dataSet.FluxBarrierMaterial,'Air')
                        dataSet.MapQuadrants = 1;
                    end
                    dataSet.SimulatedCurrent = dataSet.RatedCurrent*dataSet.CurrLoPP;                    
                case 'Structural Analysis'
                    dataSet.EvalType = 'structural';
                    if dataSet.EvalSpeed==0
                        dataSet.EvalSpeed = 3000/dataSet.NumOfPolePairs;
                        disp('EvalSpeed must be >0!!!')
                    elseif dataSet.EvalSpeed<0
                        dataSet.EvalSpeed = -dataSet.EvalSpeed;
                    end
                case 'HWC Short-Circuit Current'
                    dataSet.EvalType = 'shortCircuit';
                    if length(dataSet.BrPP)>1
                        mat=material_properties_layer(dataSet.FluxBarrierMaterial);
                        if ~isfield(mat,'temp')
                            dataSet.tempPP=20;
                            dataSet.BrPP=mat.Br;
                        else
                            dataSet.BrPP   = mat.Br;
                            dataSet.tempPP=interp1(mat.temp.Br,mat.temp.temp,dataSet.BrPP);
                        end
                    end
                case 'Steady-State Short Circuit'
                    dataSet.EvalType = 'steadyStateShortCircuit';
                    if length(dataSet.BrPP)>1
                        mat=material_properties_layer(dataSet.FluxBarrierMaterial);
                        if ~isfield(mat,'temp')
                            dataSet.tempPP=20;
                            dataSet.BrPP=mat.Br;
                        else
                            dataSet.BrPP   = mat.Br;
                            dataSet.tempPP=interp1(mat.temp.Br,mat.temp.temp,dataSet.BrPP);
                        end
                    end
            end
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app)
            % guidata(hObject,handles)
        end

        % Value changed function: EvaluatedSpeedEdit
        function EvaluatedSpeedEdit_Callback(app, event)
            % hObject    handle to EvaluatedSpeedEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of EvaluatedSpeedEdit as text
            %        str2double(get(hObject,'String')) returns contents of EvaluatedSpeedEdit as a double
            dataSet = app.dataSet;
            dataSet.EvalSpeed = eval(app.EvaluatedSpeedEdit.Value);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: FEAfixPopUp
        function FEAfixPopUp_Callback(app, event)
            % hObject    handle to FEAfixPopUp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns FEAfixPopUp contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from FEAfixPopUp
            dataSet = app.dataSet;
            %             ind = get(hObject,'Value');
            %             str = get(hObject,'String');
            %             dataSet.FEAfixN = eval(str{ind});
            dataSet.FEAfixN = eval(app.FEAfixPopUp.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Button pushed function: FEAfixPushButt
        function FEAfixPushButt_Callback(app, event)
            % hObject    handle to FEAfixPushButt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dataSet = app.dataSet;
            [dataSet,flagS] = eval_xbDesignPlane(dataSet);
            app.dataSet = dataSet;
            if flagS
                dataSet = DrawAndSaveMachine(dataSet,dataSet.currentfilename,dataSet.currentpathname);
            end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: FillCorSlotEdit
        function FillCorSlotEdit_Callback(app, event)
            % Fillet at the back iron corner of the slot[mm](geo.SFR)
            
            % hObject    handle to FillCorSlotEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of FillCorSlotEdit as text
            %        str2double(get(hObject,'String')) returns contents of FillCorSlotEdit as a double
            dataSet = app.dataSet;
            dataSet.FilletCorner = eval(app.FillCorSlotEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: GammaBouCheck
        function GammaBouCheck_Callback(app, event)
            % Current phase angle[elect. deg.](bounds_gamma)
            % hObject    handle to GammaBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of GammaBouCheck
            dataSet = app.dataSet;
            dataSet.GammaBouCheck = app.GammaBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: GammaPPEdit
        function GammaPPEdit_Callback(app, event)
            % Current phase angle[elect. deg.]
            
            % hObject    handle to GammaPPEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of GammaPPEdit as text
            %        str2double(get(hObject,'String')) returns contents of GammaPPEdit as a double
            
            dataSet = app.dataSet;
            dataSet.GammaPP = eval(app.GammaPPEdit.Value);
%             if strcmp(dataSet.AxisType,'PM')
%                 dataSet.GammaPP = dataSet.GammaPP + 90;
%             end
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            %GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: GapBouCheck
        function GapBouCheck_Callback(app, event)
            % Airgap thickness[mm](bounds_g)
            % hObject    handle to GapBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hint: get(hObject,'Value') returns toggle state of GapBouCheck
            dataSet = app.dataSet;
            dataSet.GapBouCheck = app.GapBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: GapBouEdit
        function GapBouEdit_Callback(app, event)
            % Airgap thickness[mm](bounds_g)
            
            % hObject    handle to GapBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of GapBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of GapBouEdit as a double
            dataSet = app.dataSet;
            dataSet.GapBou = eval(app.GapBouEdit.Value);
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: GapThiEdit
        function GapThiEdit_Callback(app, event)
            % Airgap Thickness[mm](geo.g)
            % hObject    handle to GapThiEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of GapThiEdit as text
            %        str2double(get(hObject,'String')) returns contents of GapThiEdit as a double
            dataSet = app.dataSet;
            dataSet.AirGapThickness = eval(app.GapThiEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: HousingTempEdit
        function HousingTempEdit_Callback(app, event)
            % hObject    handle to HousingTempEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of HousingTempEdit as text
            %        str2double(get(hObject,'String')) returns contents of HousingTempEdit as a double
            dataSet = app.dataSet;
            dataSet.HousingTemp = eval(app.HousingTempEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Button pushed function: IronLibraryPush
        function IronLibraryPush_Callback(app, event)
            % hObject    handle to IronLibraryPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            mat = material_properties_iron(0);
            
            material=reshape(mat.MatList,numel(mat.MatList),1);
            disp('Lamination material library:')
            for ii=1:length(material)
                disp(['- ' material{ii}])
            end
            % app.MaterialListBox.Value = material;
            %             set(app.MaterialText,'Style','Edit');
            %             set(app.MaterialText,'String',material);
        end

        % Value changed function: MassCuEdit
        function MassCuEdit_Callback(app, event)
            % hObject    handle to MassCuEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of MassCuEdit as text
            %        str2double(get(hObject,'String')) returns contents of MassCuEdit as a double
            
            dataSet = app.dataSet;
            dataSet.MaxCuMass = eval(app.MassCuEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: MassCuOptCheck
        function MassCuOptCheck_Callback(app, event)
            % hObject    handle to MassCuOptCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of MassCuOptCheck
            
            dataSet = app.dataSet;
            dataSet.MassCuOptCheck = app.MassCuOptCheck.Value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: MassPMEdit
        function MassPMEdit_Callback(app, event)
            % hObject    handle to MassPMEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of MassPMEdit as text
            %        str2double(get(hObject,'String')) returns contents of MassPMEdit as a double
            dataSet = app.dataSet;
            dataSet.MaxPMMass = eval(app.MassPMEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: MassPMOptCheck
        function MassPMOptCheck_Callback(app, event)
            % hObject    handle to MassPMOptCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of MassPMOptCheck
            dataSet = app.dataSet;
            dataSet.MassPMOptCheck = app.MassPMOptCheck.Value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: MaxExpeRippleTorEdit
        function MaxExpeRippleTorEdit_Callback(app, event)
            % Maximum expected torque ripple during optimization [p.u.](per.max_exp_ripple)
            % hObject    handle to MaxExpeRippleTorEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of MaxExpeRippleTorEdit as text
            %        str2double(get(hObject,'String')) returns contents of MaxExpeRippleTorEdit as a double
            dataSet = app.dataSet;
            dataSet.MaxRippleTorque = eval(app.MaxExpeRippleTorEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: MaxGenEdit
        function MaxGenEdit_Callback(app, event)
            % Maximum number of generations
            % hObject    handle to MaxGenEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of MaxGenEdit as text
            %        str2double(get(hObject,'String')) returns contents of MaxGenEdit as a double
            dataSet = app.dataSet;
            dataSet.MaxGen = eval(app.MaxGenEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: MecTolerEdit
        function MecTolerEdit_Callback(app, event)
            % minimum mechanical tolerance (geo.pont0)
            
            % hObject    handle to MecTolerEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of MecTolerEdit as text
            %        str2double(get(hObject,'String')) returns contents of MecTolerEdit as a double
            dataSet = app.dataSet;
            dataSet.MinMechTol = str2double(app.MecTolerEdit.Value);
            if dataSet.MinMechTol==0
                dataSet.MinMechTol=0.1;
                disp('Minimum mechanical tolerance set to 0.1 mm')
            end
            dataSet.TanRibEdit(dataSet.TanRibEdit<dataSet.MinMechTol)=dataSet.MinMechTol;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: MeshEdit
        function MeshEdit_Callback(app, event)
            % mesh (geo.K_mesh)
            
            % hObject    handle to MeshEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of MeshEdit as text
            %        str2double(get(hObject,'String')) returns contents of MeshEdit as a double
            dataSet = app.dataSet;
            dataSet.Mesh = eval(app.MeshEdit.Value);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: MeshMOOAEdit
        function MeshMOOAEdit_Callback(app, event)
            % Mesh MOOA (geo.mesh_K_MOOA)
            
            % hObject    handle to MeshMOOAEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of MeshMOOAEdit as text
            %        str2double(get(hObject,'String')) returns contents of MeshMOOAEdit as a double
            dataSet = app.dataSet;
            dataSet.Mesh_MOOA = eval(app.MeshMOOAEdit.Value);
            
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: MinExpTorEdit
        function MinExpTorEdit_Callback(app, event)
            % Minimum expected torque (per.min_exp_torque)
            % hObject    handle to MinExpTorEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of MinExpTorEdit as text
            %        str2double(get(hObject,'String')) returns contents of MinExpTorEdit as a double
            dataSet = app.dataSet;
            dataSet.MinExpTorque = eval(app.MinExpTorEdit.Value);
            if dataSet.MinExpTorque>0
                disp('Torque limit penalization must be negative for optimization settings!!!')
                beep
                dataSet.MinExpTorque = -dataSet.MinExpTorque;
            end
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: NGridPPEdit
        function NGridPPEdit_Callback(app, event)
            % Number of points in [0 Imax] (n_grid)
            % hObject    handle to NGridPPEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of NGridPPEdit as text
            %        str2double(get(hObject,'String')) returns contents of NGridPPEdit as a double
            dataSet = app.dataSet;
            dataSet.NumGrid = eval(app.NGridPPEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: NumOfRotorPosiPPEdit
        function NumOfRotorPosiPPEdit_Callback(app, event)
            % Number of rotor positions
            % hObject    handle to NumOfRotorPosiPPEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of NumOfRotorPosiPPEdit as text
            %        str2double(get(hObject,'String')) returns contents of NumOfRotorPosiPPEdit as a double
            dataSet = app.dataSet;
            dataSet.NumOfRotPosPP = eval(app.NumOfRotorPosiPPEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: NumOfSlotsEdit
        function NumOfSlotsEdit_Callback(app, event)
            % Number of slots/(poles*phases) (geo.q)
            
            % hObject    handle to NumOfSlotsEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of NumOfSlotsEdit as text
            %        str2double(get(hObject,'String')) returns contents of NumOfSlotsEdit as a double
            dataSet = app.dataSet;
            dataSet.NumOfSlots = eval(app.NumOfSlotsEdit.Value);
            p = dataSet.NumOfPolePairs;
            n3ph = dataSet.Num3PhaseCircuit;
            Q = round(dataSet.NumOfSlots*6*p*n3ph);
            dataSet.NumOfStatorSlots = Q;
            %             yq = dataSet.PitchShortFac*dataSet.NumOfSlots*3*n3ph;
            %             path = pwd;
            %             cd(fullfile (path,'koil'));
            %             system(['koil_syre.exe',' ',num2str(Q),' ',num2str(p),' ',num2str(yq)]);
            %             cd(path);
            %             Windings = MatrixWin();
            Windings = WindingDefinition(dataSet);
            t2 = gcd(Q,2*p);
            Qs = Q/t2;
            dataSet.Qs = Qs;
            dataSet.WinMatr = Windings(:,1:floor(Qs)); % winding matrix, only Qs columns
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: NumberOfLayersEdit
        function NumberOfLayersEdit_Callback(app, event)
            % Number of rotor barriers(geo.nlay)
            
            % hObject    handle to NumberOfLayersEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of NumberOfLayersEdit as text
            %        str2double(get(hObject,'String')) returns contents of NumberOfLayersEdit as a double
            dataSet = app.dataSet;
            dataSet.NumOfLayers = eval(app.NumberOfLayersEdit.Value);

            dataSet.ALPHApu = ones(1,dataSet.NumOfLayers)*round(1/(dataSet.NumOfLayers+0.5)*100)/100;
            if exist('calc_nr.m','file')
                out = calc_nr(6*dataSet.Num3PhaseCircuit*dataSet.NumOfSlots);
                index = 1:1:length(out.nlay);
                ii = index(out.nlay==dataSet.NumOfLayers);
                if ~isempty(ii)
                    dataSet.ALPHApu = round(out.dalpha_pu{ii},4);
                    disp([int2str(out.ns) ' / ' int2str(out.nr(ii))])
                end
            end
            

            dataSet.HCpu               = ones(1,dataSet.NumOfLayers)*0.5;
            dataSet.DepthOfBarrier     = zeros(1,dataSet.NumOfLayers);
            dataSet.betaPMshape        = zeros(1,dataSet.NumOfLayers);
            dataSet.TanRibEdit         = dataSet.MinMechTol*ones(1,dataSet.NumOfLayers);
            dataSet.RadRibCheck        = 0;
            dataSet.RadRibEdit         = zeros(1,dataSet.NumOfLayers);
            dataSet.pontRangEdit       = zeros(1,dataSet.NumOfLayers);
            dataSet.pontRoffsetEdit    = zeros(1,dataSet.NumOfLayers);
            dataSet.RotorFilletRadEdit = dataSet.MinMechTol*ones(1,dataSet.NumOfLayers);
            dataSet.CentralShrink      = zeros(1,dataSet.NumOfLayers);
            dataSet.RadShiftInner      = zeros(1,dataSet.NumOfLayers);
            dataSet.NarrowFactor       = ones(1,dataSet.NumOfLayers);
            dataSet.RadRibSplit        = zeros(1,dataSet.NumOfLayers);
            dataSet.RotorFilletIn      = dataSet.MinMechTol*ones(1,dataSet.NumOfLayers);
            dataSet.RotorFilletOut     = dataSet.MinMechTol*ones(1,dataSet.NumOfLayers);
            
            if isfinite(dataSet.RotorFilletTan1)
                dataSet.RotorFilletTan1 = ones(1,dataSet.NumOfLayers);
                dataSet.RotorFilletTan2 = ones(1,dataSet.NumOfLayers);
            else
                dataSet.RotorFilletTan1 = nan(1,dataSet.NumOfLayers);
                dataSet.RotorFilletTan2 = nan(1,dataSet.NumOfLayers);
            end
            
            if ((strcmp(dataSet.TypeOfRotor,'Seg')||strcmp(dataSet.TypeOfRotor,'ISeg'))&&~strcmp(dataSet.FluxBarrierMaterial,'Air'))
                dataSet.PMdim=Inf(2,dataSet.NumOfLayers);
                dataSet.PMdim(2,1)=0;
            elseif ((strcmp(dataSet.TypeOfRotor,'Circular')||strcmp(dataSet.TypeOfRotor,'Vtype'))&&~strcmp(dataSet.FluxBarrierMaterial,'Air'))
                dataSet.PMdim=Inf(2,dataSet.NumOfLayers);
                dataSet.PMdim(2,:)=0;
            else
                dataSet.PMdim=zeros(2,dataSet.NumOfLayers);
            end
            
            dataSet.PMclear = zeros(2,dataSet.NumOfLayers);
            dataSet.PMNc    = ones(2,dataSet.NumOfLayers);
            
            if strcmp(dataSet.TypeOfRotor,'Vtype')
                dataSet.HCpu = 5*ones(1,dataSet.NumOfLayers);
                dataSet.betaPMshape = 0.5*ones(1,dataSet.NumOfLayers);
            end
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Button pushed function: OptimizePush
        function OptimizePush_Callback(app, event)
            % Optimize pushbutton
            
            % hObject    handle to OptimizePush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            dataSet = app.dataSet;
            eval_OptimizatioProblem(dataSet);
        end

        % Value changed function: OverSpeedEdit
        function OverSpeedEdit_Callback(app, event)
            % overspeed (geo.nmax)
            
            % hObject    handle to OverSpeedEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of OverSpeedEdit as text
            %        str2double(get(hObject,'String')) returns contents of OverSpeedEdit as a double
            dataSet = app.dataSet;
            dataSet.OverSpeed = eval(app.OverSpeedEdit.Value);
            dataSet.RadRibCheck = 0;
            dataSet.RadRibEdit = zeros(1,dataSet.NumOfLayers);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Button pushed function: PMDesignPush
        function PMDesignPush_Callback(app, event)
            % hObject    handle to PMDesignPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dataSet = app.dataSet;
            [dataSet] = PMdesign(dataSet);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
            buttonS = questdlg('Save the designed machine?','SELECT','Yes','No','Yes');
            if isequal(buttonS,'Yes')
                % save new machine
                BrName = ['Br' num2str(round(dataSet.PMdesign.BrPM,2))];
                BrName(BrName=='.') = 'T';
                if isempty(BrName=='T')
                    BrName = [BrName 'T0'];
                end
                IqName = ['Ich' num2str(round(dataSet.PMdesign.iq0,2))];
                IqName(IqName=='.') = 'A';
                if isempty(IqName=='T')
                    IqName = [IqName 'A0'];
                end
                dataSet.currentfilename = [dataSet.currentfilename(1:end-4) '_' BrName '_' IqName '.mat'];
                dataSet = DrawAndSaveMachine(dataSet,dataSet.currentfilename,dataSet.currentpathname);
            end
            
            % guidata(hObject,handles)
        end

        % Value changed function: PMdimBouCheck
        function PMdimBouCheck_Callback(app, event)
            % hObject    handle to PMdimBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of PMdimBouCheck
            
            dataSet = app.dataSet;
            dataSet.PMdimBouCheck = app.PMdimBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            % guidata(hObject,handles)
        end

        % Value changed function: PMdimBouEdit
        function PMdimBouEdit_Callback(app, event)
            % hObject    handle to PMdimBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of PMdimBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of PMdimBouEdit as a double
            
            dataSet = app.dataSet;
            dataSet.PMdimBou = eval(app.PMdimBouEdit.Value);
            [m,n] = size(dataSet.PMdimBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            
            if ((max(dataSet.PMdimBou)>1)||(min(dataSet.PMdimBou)<0))
                dataSet.PMdimBou = [0 1];
            end
            
            [bounds, objs, geo, per, ~] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            % guidata(hObject,handles)
        end

        % Cell edit callback: PMdimTable
        function PMdimTable_CellEditCallback(app, event)
            % hObject    handle to PMdimTable (see GCBO)
            % eventdata  structure with the following fields (see MATLAB.UI.CONTROL.TABLE)
            %	Indices: row and column indices of the cell(s) edited
            %	PreviousData: previous data for the cell(s) edited
            %	EditData: string(s) entered by the user
            %	NewData: EditData or its converted form set on the Data property. Empty if Data was not changed
            %	Error: error string when failed to convert EditData to appropriate value for Data
            % handles    structure with handles and user data (see GUIDATA)
            dataSet = app.dataSet;
            %tmp = get(hObject,'data');
            tmp = app.PMdimTable.Data;
            
            if (strcmp(dataSet.TypeOfRotor,'Circular')||strcmp(dataSet.TypeOfRotor,'Vtype')||strcmp(dataSet.TypeOfRotor,'Spoke-type'))
                tmp(2,:)=0;
            elseif strcmp(dataSet.TypeOfRotor,'ISeg')
                tmp(2,1)=0;
            end
            
            dataSet.PMdim = tmp;
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            % guidata(hObject,handles)
        end

        % Value changed function: PMtemperatureEdit
        function PMtemperatureEdit_Callback(app, event)
            % hObject    handle to PMtemperatureEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of PMtemperatureEdit as text
            %        str2double(get(hObject,'String')) returns contents of PMtemperatureEdit as a double
            dataSet = app.dataSet;
            tmp = eval(app.PMtemperatureEdit.Value);
            mat=material_properties_layer(dataSet.FluxBarrierMaterial);
            if isfield(mat,'temp')
                if tmp>max(mat.temp.temp)
                    tmp=max(mat.temp.temp);
                    warning('PM temperature selected higher than maximum PM temperature!!!')
                elseif tmp<min(mat.temp.temp)
                    tmp=min(mat.temp.temp);
                    warning('PM temperature selected lower than minimum PM temperature!!!')
                end
                Br = interp1(mat.temp.temp,mat.temp.Br,tmp);
            else
                warning('The selected PM material has no temperature information.')
                tmp=20;
                Br=mat.Br;
            end
            
            dataSet.PMtemp = tmp;
            dataSet.Br = Br;
            
            dataSet.tempPP = tmp;
            dataSet.BrPP = Br;
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: PhaseAngleCurrBouEdit
        function PhaseAngleCurrBouEdit_Callback(app, event)
            % Current phase angle  [elect. deg.](bounds_gamma)
            
            % hObject    handle to PhaseAngleCurrBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of PhaseAngleCurrBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of PhaseAngleCurrBouEdit as a double
            dataSet = app.dataSet;
            dataSet.PhaseAngleCurrBou = eval(app.PhaseAngleCurrBouEdit.Value);
            [m,n] = size(dataSet.PhaseAngleCurrBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: PitchWindEdit
        function PitchWindEdit_Callback(app, event)
            % Pitch shortening factor(geo.Kracc)
            
            % hObject    handle to PitchWindEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of PitchWindEdit as text
            %        str2double(get(hObject,'String')) returns contents of PitchWindEdit as a double
            dataSet = app.dataSet;
            dataSet.PitchShortFac = eval(app.PitchWindEdit.Value);
            if dataSet.Num3PhaseCircuit>1
                dataSet.PitchShortFac=1;
                disp('Shortening Factor not available with multi-3phase windings.')
            end
            
            p = dataSet.NumOfPolePairs;
            Q = round(dataSet.NumOfSlots*6*p*dataSet.Num3PhaseCircuit);
            %             yq = dataSet.PitchShortFac*dataSet.NumOfSlots*3;
            %             path = pwd;
            %             cd(fullfile (path,'koil'));
            %             system(['koil_syre.exe',' ',num2str(Q),' ',num2str(p),' ',num2str(yq)]);
            %             cd(path);
            %             Windings = MatrixWin();
            %             t2 = gcd(round(dataSet.NumOfSlots*6*dataSet.NumOfPolePairs),2*dataSet.NumOfPolePairs);
            %             Qs = Q/t2;
            %             dataSet.Qs = Qs;
            %             dataSet.WinMatr = Windings(:,1:floor(Qs)); % winding matrix, only Qs columns
            t2 = gcd(Q,2*p);
            Qs = Q/t2;
            Windings = WindingDefinition(dataSet);
            dataSet.WinMatr = Windings(:,1:Qs);
            dataSet.Qs      = Qs;
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: PolePairsEdit
        function PolePairsEdit_Callback(app, event)
            % Number of pole pairs(geo.p)
            
            % hObject    handle to PolePairsEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of PolePairsEdit as text
            %        str2double(get(hObject,'String')) returns contents of PolePairsEdit as a double
            dataSet = app.dataSet;
            dataSet.NumOfPolePairs = eval(app.PolePairsEdit.Value);
            %             %             p = dataSet.NumOfPolePairs;
            %             Q = round(dataSet.NumOfSlots*6*p);
            %             yq = dataSet.PitchShortFac*dataSet.NumOfSlots*3;
            %             t2 = gcd(round(dataSet.NumOfSlots*6*dataSet.NumOfPolePairs),2*dataSet.NumOfPolePairs);
            %             dataSet.Qs = Q/t2;
            %             dataSet.ToothLength = dataSet.StatorOuterRadius * (1-dataSet.AirGapRadius/dataSet.StatorOuterRadius*(1+dataSet.MagLoadingYoke/dataSet.NumOfPolePairs));
            %             dataSet.ToothLength = round(dataSet.ToothLength*10000)/10000;
            %             dataSet = WindingDefinition(dataSet);
            dataSet.NumOfStatorSlots = 6*dataSet.NumOfPolePairs*dataSet.NumOfSlots*dataSet.Num3PhaseCircuit;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: RadRibCheck
        function RadRibCheck_Callback(app, event)
            % hObject    handle to RadRibCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of RadRibCheck
            
            dataSet = app.dataSet;
            dataSet.RadRibCheck = app.RadRibCheck.Value;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: RadRibEdit
        function RadRibEdit_Callback(app, event)
            % hObject    handle to RadRibEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of RadRibEdit as text
            %        str2double(get(hObject,'String')) returns contents of RadRibEdit as a double
            
            dataSet = app.dataSet;
            dataSet.RadRibEdit = eval(app.RadRibEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Button pushed function: RmvConductorPush
        function RmvConductorPush_Callback(app, event)
            % hObject    handle to RmvConductorPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            prompt={'Material Name'};
            answer={'MatName'};
            answer = inputdlg(prompt,'Remove material',1,answer);
            MatName = answer{1};
            remove_material(MatName,'Conductor');
            
            % update materials dropdowns
            mat = material_properties_conductor('0');
            app.StatorslotmaterialDropDown.Items = mat.MatList;
        end

        % Button pushed function: RmvIronPush
        function RmvIronPush_Callback(app, event)
            % hObject    handle to RmvIronPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            prompt={'Material Name'};
            answer={'MatName'};
            answer = inputdlg(prompt,'Remove material',1,answer);
            MatName = answer{1};
            remove_material(MatName,'Iron');
            
            % update materials dropdowns
            mat = material_properties_iron('0');
            app.StatorcorematerialDropDown.Items = mat.MatList;
            app.RotorcorematerialDropDown.Items  = mat.MatList;
            app.ShaftmaterialDropDown.Items      = ['Air' mat.MatList];
        end

        % Button pushed function: RmvMagnetPush
        function RmvMagnetPush_Callback(app, event)
            % hObject    handle to RmvMagnetPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            prompt={'Material Name'};
            answer={'MatName'};
            answer = inputdlg(prompt,'Remove material',1,answer);
            MatName = answer{1};
            remove_material(MatName,'Layer');
            
            % update materials dropdowns
            mat = material_properties_layer('0');
            app.FluxbarriermaterialDropDown.Items = mat.MatList;
        end

        % Value changed function: RotorPosFinerEdit
        function RotorPosFinerEdit_Callback(app, event)
            % Rotor angular excursion for Pareto
            
            % hObject    handle to RotorPosFinerEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of RotorPosFinerEdit as text
            %        str2double(get(hObject,'String')) returns contents of RotorPosFinerEdit as a double
            dataSet = app.dataSet;
            dataSet.RotPoFine = eval(app.RotorPosFinerEdit.Value);
            dataSet.AngularSpanPP = eval(app.RotorPosFinerEdit.Value);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: RotorPosiMOEdit
        function RotorPosiMOEdit_Callback(app, event)
            % Rotor angular excursion for MODE (geo.delta_sim_MOOA)
            
            % hObject    handle to RotorPosiMOEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of RotorPosiMOEdit as text
            %        str2double(get(hObject,'String')) returns contents of RotorPosiMOEdit as a double
            dataSet = app.dataSet;
            dataSet.RotPoMOOA = eval(app.RotorPosiMOEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Button pushed function: SaveConfPush
        function SaveConfPush_Callback(app, event)
            % Save Configuration pushbutton
            % hObject    handle to SaveConfPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            new_avv = app.WinTable.Data;
            dataSet = app.dataSet;
            [~, ~, geo, ~, ~] = data0(dataSet);
            avv=geo.win.avv;  %AS
            n3phase=geo.win.n3phase; %AS
            [m,n] = size(new_avv);
            
            % Create avv_flag (size = # of phases) for fault case
            for i = 1 : m
                for k = 1 : n %create avv_flag (size = # of phases)
                    if ~isequal(new_avv(i,k),avv(i,k)) && new_avv(i,k)==0
                        avv_flag(abs(avv(i,k)))=0;  % if 0 the coil is deactivated
                    else
                        avv_flag(abs(new_avv(i,k)))=1;
                    end
                end
            end
            
            % Correct the winding matix
            for ih=1:(3*n3phase)
                for i = 1 : m %rows
                    for k = 1 : n %columns
                        if avv_flag(ih)==0 && abs(new_avv(i,k))==ih
                            new_avv(i,k)=0;
                        end
                    end
                end
            end
            dataSet.WinMatr = new_avv; %save winding in dataSet
            dataSet.WinFlag=avv_flag;
            msgbox('Matrix of windings saved');
            app.dataSet = dataSet;
            set(app.WinTable,'data',dataSet.WinMatr(:,1:floor(geo.Qs)));
            app.WinTable.Data = dataSet.WinMatr(:,1:floor(geo.Qs));
            % guidata(hObject,handles)
        end

        % Button pushed function: SaveMachineMagnetPush
        function SaveMachineMagnetPush_Callback(app, event)
            % hObject    handle to SaveMachineMagnetPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dataSet = app.dataSet;
            dataSet = DrawAndSaveMachine_MN(dataSet);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Button pushed function: SaveMachinePush
        function SaveMachinePush_Callback(app, event)
            
            % Save machine pushbutton
            
            % hObject    handle to SaveMachinePush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.dataSet.ScaleCheck =0;
            dataSet = app.dataSet;
            set(app.ScaleCheck,'Value',dataSet.ScaleCheck);
            GUI_APP_SetParameters(app);
            dataSet = DrawAndSaveMachine(dataSet);
            app.currentMotFileName.Value = dataSet.currentfilename;
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Button pushed function: SetDefaultWinding
        function SetDefaultWinding_Callback(app, event)
            % hObject    handle to SetDefaultWinding (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dataSet = app.dataSet;
            [~, ~, geo, ~, ~] = data0(dataSet);
            Windings = MatrixWin(dataSet.NumOfStatorSlots,dataSet.NumOfPolePairs,dataSet.NumOfSlots);
            app.WinTable.Data = Windings(:,1:floor(geo.Qs));
            %             set(app.WinTable,'data',Windings(:,1:floor(geo.Qs)));
        end

        % Value changed function: ShaftRadEdit
        function ShaftRadEdit_Callback(app, event)
            % Shaft radius [mm] (geo.Ar)
            
            % hObject    handle to ShaftRadEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of ShaftRadEdit as text
            %        str2double(get(hObject,'String')) returns contents of ShaftRadEdit as a double
            dataSet = app.dataSet;
            dataSet.ShaftRadius = eval(app.ShaftRadEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: SimPosFinerEdit
        function SimPosFinerEdit_Callback(app, event)
            % Rotor position for Pareto(geo.nsim_singt)
            
            % hObject    handle to SimPosFinerEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of SimPosFinerEdit as text
            %        str2double(get(hObject,'String')) returns contents of SimPosFinerEdit as a double
            dataSet = app.dataSet;
            dataSet.SimPoFine = eval(app.SimPosFinerEdit.Value);
            dataSet.NumOfRotPosPP = eval(app.SimPosFinerEdit.Value);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: SimPosMOEdit
        function SimPosMOEdit_Callback(app, event)
            % Simulated position for MODE(geo.nsim_MOOA)
            
            % hObject    handle to SimPosMOEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of SimPosMOEdit as text
            %        str2double(get(hObject,'String')) returns contents of SimPosMOEdit as a double
            dataSet = app.dataSet;
            dataSet.SimPoMOOA = eval(app.SimPosMOEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: SlotFillFacEdit
        function SlotFillFacEdit_Callback(app, event)
            % Slot filling factor(geo.Kcu)
            
            % hObject    handle to SlotFillFacEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of SlotFillFacEdit as text
            %        str2double(get(hObject,'String')) returns contents of SlotFillFacEdit as a double
            dataSet = app.dataSet;
            dataSet.SlotFillFactor = eval(app.SlotFillFacEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: SlotModelFrequencyEdit
        function SlotModelFrequencyEdit_Callback(app, event)
            % hObject    handle to SlotModelFrequencyEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of SlotModelFrequencyEdit as text
            %        str2double(get(hObject,'String')) returns contents of SlotModelFrequencyEdit as a double
            dataSet = app.dataSet;
            dataSet.SlotConductorFrequency = eval(app.SlotModelFrequencyEdit.Value);
            dataSet.SlotConductorFrequency(dataSet.SlotConductorFrequency<=0)=0.1;
            dataSet.SlotConductorFrequency = unique(dataSet.SlotConductorFrequency);
            dataSet.SlotConductorFrequency = sort(dataSet.SlotConductorFrequency);
            app.dataSet = dataSet;
            % app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: SlotSimulEdit
        function SlotSimulEdit_Callback(app, event)
            % hObject    handle to SlotSimulEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of SlotSimulEdit as text
            %        str2double(get(hObject,'String')) returns contents of SlotSimulEdit as a double
            dataSet = app.dataSet;
            Qs = eval(app.SlotSimulEdit.Value);
            p = dataSet.NumOfPolePairs;
            n3ph = dataSet.Num3PhaseCircuit;
            Q = round(dataSet.NumOfSlots*6*p*n3ph);
            %             yq = dataSet.PitchShortFac*dataSet.NumOfSlots*3;
            %             path = pwd;
            %             cd(fullfile (path,'koil'));
            %             system(['koil_syre.exe',' ',num2str(Q),' ',num2str(p),' ',num2str(yq)]);
            %             cd(path);
            %             Windings = MatrixWin();
            % check if Qs and ps is feasible (if ps is integer)
            t2 = gcd(Q,2*p);
            QsCalc = Q/t2;
            psCalc = 2*p/t2;
            ps = psCalc/QsCalc*Qs;
            Windings = WindingDefinition(dataSet);
            
            if not((ps-floor(ps))==0)
                disp('Error in the setting of Qs. Qs is reset to calculated standard value')
                ps=psCalc;
                Qs=QsCalc;
            end
            
            if dataSet.thetaFBS~=0
                if rem(ps,2)~=0
                    ps=2;
                    Qs=Q*ps/(2*p);
                    disp('FBS enabled, the number of poles must be even!')
                end
            end
            dataSet.Qs = Qs;
            dataSet.WinMatr = Windings(:,1:Qs); % winding matrix, only Qs columns
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: SpanEltPPEdit
        function SpanEltPPEdit_Callback(app, event)
            % Rotor angular excursion[elect. deg.]
            
            % hObject    handle to SpanEltPPEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of SpanEltPPEdit as text
            %        str2double(get(hObject,'String')) returns contents of SpanEltPPEdit as a double
            dataSet = app.dataSet;
            dataSet.AngularSpanPP = eval(app.SpanEltPPEdit.Value);
            if (strcmp(dataSet.EvalType,'singtIron')||strcmp(dataSet.EvalType,'singmIron'))
                if ((dataSet.AngularSpanPP~=360) && (dataSet.AngularSpanPP~=180))
                    dataSet.AngularSpanPP=180;
                    warning('Angular span set to 180 deg!!!')
                end
            end
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: StackLenghtEdit
        function StackLenghtEdit_Callback(app, event)
            % Stack length [mm] (geo.l)
            
            % hObject    handle to StackLenghtEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of StackLenghtEdit as text
            %        str2double(get(hObject,'String')) returns contents of StackLenghtEdit as a double
            dataSet = app.dataSet;
            dataSet.StackLength = eval(app.StackLenghtEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Button pushed function: StartPProMagnetPush
        function StartPProMagnetPush_Callback(app, event)
            % hObject    handle to StartPProMagnetPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dataSet = app.dataSet;
            % check if speed is greater than zero
            if dataSet.EvalSpeed==0
                msg = 'Speed MUST be greater than one in MagNet simulations! - see PostProcessing TAB';
                title = 'WARNING';
                f = warndlg(msg,title,'modal');
                error(msg);
            end
            
            % dataSet.SimulatedCurrent = dataSet.CurrLoPP*dataSet.RatedCurrent;
            
            switch dataSet.EvalType
                case {'singt','singtIron'}
                    eval_operatingPointMN(dataSet);
                case {'singm','singmIron'}
                    eval_fluxMapMN(dataSet);
                otherwise
                    error('Simulation type not supported in MagNet!!!')
            end
            
            % guidata(hObject,handles)
        end

        % Button pushed function: StartPProPush
        function StartPProPush_Callback(app, event)
            % Start pushbutton of processing
            % hObject    handle to StartPProPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            dataSet = app.dataSet;

            % check if motor is available (OneDrive error for online files)
            if ~dataSet.XFEMMsimulation
                openfemm(1)
                opendocument([dataSet.currentpathname dataSet.currentfilename(1:end-4) '.fem']);
                closefemm()
            else
                %FemmProblem = loadfemmfile(checkPathSyntax([dataSet.currentpathname dataSet.currentfilename]));
            end
      
            switch dataSet.EvalType
                case {'singt','flxdn','izero','force','singtIron'}
                    if dataSet.NumOfRotPosPP>0
                        eval_operatingPoint(dataSet);
                    else
                        autoSyRe_calcCurrentWaveform(dataSet);
                    end
                case {'singm','singmIron'}
                    eval_fluxMap(dataSet);
                case 'demagArea'
                    eval_demagArea(dataSet);
                case 'idemag'
                    eval_idemag_vol(dataSet);
                case 'idemagDQ'
                    eval_idemag_gg(dataSet);
                case 'ichval'
                    eval_ich(dataSet);
                case 'structural'
                    eval_vonMisesStress(dataSet);
                case 'shortCircuit'
                    eval_peakShortCircuitCurrent(dataSet);
                case 'steadyStateShortCircuit'
                    eval_steadyStateShortCircuitCondition(dataSet);
            end
            
            % guidata(hObject,handles)
        end

        % Value changed function: StatorOuterRadEdit
        function StatorOuterRadEdit_Callback(app, event)
            % Stator outer radius[mm](geo.R)
            
            % hObject    handle to StatorOuterRadEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of StatorOuterRadEdit as text
            %        str2double(get(hObject,'String')) returns contents of StatorOuterRadEdit as a double
            preValue = app.dataSet.StatorOuterRadius;
            dataSet = app.dataSet;
            dataSet.StatorOuterRadius = eval(app.StatorOuterRadEdit.Value);
            %dataSet.ToothLength = dataSet.StatorOuterRadius * (1-dataSet.AirGapRadius/dataSet.StatorOuterRadius*(1+dataSet.MagLoadingYoke/dataSet.NumOfPolePairs));
            %dataSet.ToothLength = round(dataSet.ToothLength*10000)/10000;
            if dataSet.ScaleCheck
                [dataSet] = scaleMachine(dataSet,preValue);
            end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
            
        end

        % Value changed function: StatorSlotOpeEdit
        function StatorSlotOpeEdit_Callback(app, event)
            % Stator slot opening [p.u.] (geo.acs)
            
            % hObject    handle to StatorSlotOpeEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of StatorSlotOpeEdit as text
            %        str2double(get(hObject,'String')) returns contents of StatorSlotOpeEdit as a double
            dataSet = app.dataSet;
            dataSet.StatorSlotOpen = eval(app.StatorSlotOpeEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: StatorSlotOpenBouCheck
        function StatorSlotOpenBouCheck_Callback(app, event)
            % stator slot open [p.u.] (bounds_acs)
            % hObject    handle to StatorSlotOpenBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of StatorSlotOpenBouCheck
            dataSet = app.dataSet;
            dataSet.StatorSlotOpenBouCheck = app.StatorSlotOpenBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: StatorSlotOpenBouEdit
        function StatorSlotOpenBouEdit_Callback(app, event)
            % stator slot open [p.u.] (bounds_acs)
            % hObject    handle to StatorSlotOpenBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of StatorSlotOpenBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of StatorSlotOpenBouEdit as a double
            dataSet = app.dataSet;
            dataSet.StatorSlotOpenBou = eval(app.StatorSlotOpenBouEdit.Value);
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: TanRibEdit
        function TanRibEdit_Callback(app, event)
            % hObject    handle to TanRibEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of TanRibEdit as text
            %        str2double(get(hObject,'String')) returns contents of TanRibEdit as a double
            dataSet = app.dataSet;
            dataSet.TanRibEdit = eval(app.TanRibEdit.Value);
            dataSet.TanRibEdit(dataSet.TanRibEdit<0)=0;
            dataSet.TanRibEdit(dataSet.TanRibEdit<dataSet.MinMechTol & dataSet.TanRibEdit>0) = dataSet.MinMechTol;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            %             % guidata(hObject,handles)
        end

        % Value changed function: TempPPEdit
        function TempPPEdit_Callback(app, event)
            % hObject    handle to TempPPEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of TempPPEdit as text
            %        str2double(get(hObject,'String')) returns contents of TempPPEdit as a double
            dataSet = app.dataSet;
            dataSet.tempPP = eval(app.TempPPEdit.Value);
            tempPP=dataSet.tempPP;
            BrPP=dataSet.BrPP;
            mat=material_properties_layer(dataSet.FluxBarrierMaterial);
            warning('off','backtrace')
            if isfield(mat,'temp')
                if tempPP>max(mat.temp.temp)
                    tempPP=max(mat.temp.temp);
                    warning(['Minimum PMs temperature = ' num2str(tempPP) '°C'])
                elseif tempPP<min(mat.temp.temp)
                    tempPP=min(mat.temp.temp);
                    warning(['Maximum PMs temperature = ' num2str(tempPP) '°C'])
                end
                BrPP=interp1(mat.temp.temp,mat.temp.Br,tempPP);
            else
                warning('This PM material do not have temperature data!!!')
            end
            warning('on','backtrace')
            
            dataSet.tempPP=tempPP;
            dataSet.BrPP=BrPP;
            % set(app.BrPPEdit,'Value',mat2str(dataSet.BrPP));
            % set(app.TempPPEdit,'Value',mat2str(dataSet.tempPP));
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ThermalLoadKj
        function ThermalLoadKj_Callback(app, event)
            % hObject    handle to ThermalLoadKj (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ThermalLoadKj as text
            %        str2double(get(hObject,'String')) returns contents of ThermalLoadKj as a double
            dataSet = app.dataSet;
            
            dataSet.ThermalLoadKj = eval(app.ThermalLoadKj.Value);
            dataSet.AdmiJouleLosses = NaN;
            dataSet.CurrentDensity = NaN;
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ThetaFBSBouCheck
        function ThetaFBSBouCheck_Callback(app, event)
            % hObject    handle to ThetaFBSBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ThetaFBSBouCheck
            dataSet = app.dataSet;
            dataSet.ThetaFBSBouCheck = app.ThetaFBSBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            p=dataSet.NumOfPolePairs;
            Q=round(6*p*dataSet.NumOfSlots*dataSet.Num3PhaseCircuit);
            ps=2;
            Qs=Q*ps/(2*p);
            % check if Qs and ps is feasible (if ps is integer)
            t2 = gcd(Q,2*p);
            QsCalc = Q/t2;
            psCalc = 2*p/t2;
            
            if rem(Qs,2)>0
                disp('Error in the geometry reconstrunction. FBS not applied')
                Qs=QsCalc;
                dataSet.thetsFBS=0;
            else
                dataSet.Qs=Qs;
                %                 yq = dataSet.PitchShortFac*dataSet.NumOfSlots*3;
                %                 path = pwd;
                %                 cd(fullfile (path,'koil'));
                %                 system(['koil_syre.exe',' ',num2str(Q),' ',num2str(p),' ',num2str(yq)]);
                %                 cd(path);
                %                 Windings = MatrixWin();
                
                Windings = WindingDefinition(dataSet);
                dataSet.WinMatr = Windings(:,1:floor(Qs)); % winding matrix, only Qs columns
            end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ThetaFBSBouEdit
        function ThetaFBSBouEdit_Callback(app, event)
            % hObject    handle to ThetaFBSBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ThetaFBSBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of ThetaFBSBouEdit as a double
            dataSet = app.dataSet;
            dataSet.ThetaFBSBou = eval(app.ThetaFBSBouEdit.Value);
            [m,n] = size(dataSet.DfeBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ThetaFBSEdit
        function ThetaFBSEdit_Callback(app, event)
            % hObject    handle to ThetaFBSEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ThetaFBSEdit as text
            %        str2double(get(hObject,'String')) returns contents of ThetaFBSEdit as a double
            dataSet = app.dataSet;
            dataSet.thetaFBS = eval(app.ThetaFBSEdit.Value);
            p=dataSet.NumOfPolePairs;
            Q=round(6*p*dataSet.NumOfSlots*dataSet.Num3PhaseCircuit);
            ps=2;
            Qs=Q*ps/(2*p);
            % check if Qs and ps is feasible (if ps is integer)
            t2 = gcd(Q,2*p);
            QsCalc = Q/t2;
            psCalc = 2*p/t2;
            
            if rem(Qs,2)>0
                disp('Error in the geometry reconstrunction. FBS not applied')
                Qs=QsCalc;
                dataSet.thetsFBS=0;
                app.ThetaFBSEdit.Value = '0';
            else
                dataSet.Qs=Qs;
                %                 yq = dataSet.PitchShortFac*dataSet.NumOfSlots*3;
                %                 path = pwd;
                %                 cd(fullfile (path,'koil'));
                %                 system(['koil_syre.exe',' ',num2str(Q),' ',num2str(p),' ',num2str(yq)]);
                %                 cd(path);
                %                 Windings = MatrixWin();
                %                 dataSet.WinMatr = Windings(:,1:floor(Qs)); % winding matrix, only Qs columns
                Windings = WindingDefinition(dataSet);
                dataSet.Qs = Qs;
                dataSet.WinMatr = Windings(:,1:Qs);
                
            end
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothLenBouEdit
        function ToothLenBouEdit_Callback(app, event)
            % Tooth length [mm](bounds_lt)
            
            % hObject    handle to ToothLenBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ToothLenBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of ToothLenBouEdit as a double
            dataSet = app.dataSet;
            dataSet.ToothLeBou = eval(app.ToothLenBouEdit.Value);
            if dataSet.ToothLeBou(2)>(dataSet.StatorOuterRadius - dataSet.AirGapRadius-dataSet.AirGapThickness)
                dataSet.ToothLeBou(2) = dataSet.StatorOuterRadius - dataSet.AirGapRadius-dataSet.AirGapThickness;
            end
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothLengEdit
        function ToothLengEdit_Callback(app, event)
            % Tooth length [mm](geo.lt)
            
            % hObject    handle to ToothLengEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of ToothLengEdit as text
            %        str2double(get(hObject,'String')) returns contents of ToothLengEdit as a double
            dataSet = app.dataSet;
            dataSet.ToothLength = eval(app.ToothLengEdit.Value);
            if dataSet.ToothLength>(dataSet.StatorOuterRadius - dataSet.AirGapRadius-dataSet.AirGapThickness)
                dataSet.ToothLength = dataSet.StatorOuterRadius - dataSet.AirGapRadius-dataSet.AirGapThickness;
            end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothLengthBouCheck
        function ToothLengthBouCheck_Callback(app, event)
            % Tooth length [mm] (bounds_lt) check box
            
            % hObject    handle to ToothLengthBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ToothLengthBouCheck
            dataSet = app.dataSet;
            dataSet.ToothLengthBouCheck = app.ToothLengthBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothTanDepEdit
        function ToothTanDepEdit_Callback(app, event)
            % Tooth tang. depth [mm](geo.ttd)
            
            % hObject    handle to ToothTanDepEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of ToothTanDepEdit as text
            %        str2double(get(hObject,'String')) returns contents of ToothTanDepEdit as a double
            dataSet = app.dataSet;
            dataSet.ToothTangDepth = eval(app.ToothTanDepEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothTangAngleEdit
        function ToothTangAngleEdit_Callback(app, event)
            % Tooth tang. angle [mech. deg.](geo.tta)
            
            % hObject    handle to ToothTangAngleEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of ToothTangAngleEdit as text
            %        str2double(get(hObject,'String')) returns contents of ToothTangAngleEdit as a double
            dataSet = app.dataSet;
            dataSet.ToothTangAngle = eval(app.ToothTangAngleEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothTangDepthBouCheck
        function ToothTangDepthBouCheck_Callback(app, event)
            % tooth tang depth[mm]  (bounds_ttd)
            % hObject    handle to ToothTangDepthBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ToothTangDepthBouCheck
            dataSet = app.dataSet;
            dataSet.ToothTangDepthBouCheck = app.ToothTangDepthBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothTangDepthBouEdit
        function ToothTangDepthBouEdit_Callback(app, event)
            % tooth tang depth[mm]  (bounds_ttd)
            % hObject    handle to ToothTangDepthBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ToothTangDepthBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of ToothTangDepthBouEdit as a double
            dataSet = app.dataSet;
            dataSet.ToothTangDepthBou = eval(app.ToothTangDepthBouEdit.Value);
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothWidthBouCheck
        function ToothWidthBouCheck_Callback(app, event)
            % Tooth width [mm](bounds_wt)check box
            
            % hObject    handle to ToothWidthBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ToothWidthBouCheck
            dataSet = app.dataSet;
            dataSet.ToothWidthBouCheck = app.ToothWidthBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothWidthBouEdit
        function ToothWidthBouEdit_Callback(app, event)
            % Tooth width [mm](bounds_wt)
            % hObject    handle to ToothWidthBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ToothWidthBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of ToothWidthBouEdit as a double
            dataSet = app.dataSet;
            dataSet.ToothWiBou = eval(app.ToothWidthBouEdit.Value);
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ToothWidthEdit
        function ToothWidthEdit_Callback(app, event)
            % Tooth width [mm](geo.wt)
            
            % hObject    handle to ToothWidthEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of ToothWidthEdit as text
            %        str2double(get(hObject,'String')) returns contents of ToothWidthEdit as a double
            dataSet = app.dataSet;
            dataSet.ToothWidth = eval(app.ToothWidthEdit.Value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: TorRipOptCheck
        function TorRipOptCheck_Callback(app, event)
            % hObject    handle to TorRipOptCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TorRipOptCheck
            dataSet = app.dataSet;
            dataSet.TorRipOptCheck = app.TorRipOptCheck.Value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: TorqueOptCheck
        function TorqueOptCheck_Callback(app, event)
            % hObject    handle to TorqueOptCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of TorqueOptCheck
            dataSet = app.dataSet;
            dataSet.TorqueOptCheck = app.TorqueOptCheck.Value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: TurnsSeriesEdit
        function TurnsSeriesEdit_Callback(app, event)
            % Turns in series per phase(geo.Ns)
            
            % hObject    handle to TurnsSeriesEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of TurnsSeriesEdit as text
            %        str2double(get(hObject,'String')) returns contents of TurnsSeriesEdit as a double
            dataSet = app.dataSet;
            dataSet.TurnsInSeries = eval(app.TurnsSeriesEdit.Value);
            dataSet.SlotConductorNumber=dataSet.TurnsInSeries/dataSet.NumOfPolePairs/(dataSet.NumOfSlots);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: TypeOfRotorList
        function TypeOfRotorList_Callback(app, event)
            % Type of rotor (geo.RotType)
            
            % hObject    handle to TypeOfRotorList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: contents = cellstr(get(hObject,'String')) returns TypeOfRotorList contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from TypeOfRotorList
            
            dataSet = app.dataSet;
            dataSet.TypeOfRotor = app.TypeOfRotorList.Value;
            
            % set(app.SlopeBarrier,'Enable','off'); %rev.Gallo
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if strcmp(dataSet.TypeOfRotor,'SPM')
                dataSet.NumOfLayers = 1;
            end


            if (strcmp(dataSet.TypeOfRotor,'SPM')||strcmp(dataSet.TypeOfRotor,'SPM-Halbach')||strcmp(dataSet.TypeOfRotor,'Vtype')||strcmp(dataSet.TypeOfRotor,'Spoke-type'))
                dataSet.betaPMshape = 0.5*ones(1,dataSet.NumOfLayers);
                dataSet.MapQuadrants = 2;
                dataSet.axisType = 'PM';
            else
                dataSet.betaPMshape = zeros(1,dataSet.NumOfLayers);
                dataSet.MapQuadrants = 1;
                dataSet.axisType = 'SR';
            end
            
            if strcmp(dataSet.TypeOfRotor,'IM')
                dataSet.FluxBarrierMaterial = 'Air';
                dataSet.axisType = 'SR';
                dataSet.BarMaterial = 'Aluminium';
            end

            if strcmp(dataSet.TypeOfRotor,'EESM')
                dataSet.FluxBarrierMaterial = 'Air';
                dataSet.axisType = 'PM';
                dataSet.BarMaterial = 'Copper';
                dataSet.ALPHApu = 0.7;
                dataSet.YokeWidth = 15;
                dataSet.PoleBodyHeight = 15;
                dataSet.PoleWidth = 20;
                dataSet.CoilWidth = 15;
                dataSet.CoilHeight = 15;
                dataSet.PoleRotHeadAngle = 0;
                dataSet.PoleRotHeadFillet = 0;
                dataSet.axisType = 'SR';
                dataSet.NumOfLayers = 1;
            end
         

            if ~(strcmp(dataSet.TypeOfRotor,'Circular')||strcmp(dataSet.TypeOfRotor,'Seg'))
                dataSet.thetaFBS=0;
                dataSet.ThetaFBSBouCheck=0;
            end
            
            %Set initial value Vtype parameters - rev.Gallo
            if strcmp(dataSet.TypeOfRotor,'Vtype')
                %                 if dataSet.NumOfLayers~=1
                %                     dataSet.NumOfLayers = 1;
                %                     dataSet.ALPHApu = [0.5];
                %                     dataSet.HCpu = [5];
                %                     dataSet.DepthOfBarrier = 0;
                %                     dataSet.betaPMshape = [0.5];
                %                     dataSet.RadRibEdit = 0;
                %                     dataSet.RadRibCheck = 0;
                %                     dataSet.RadRibSplit = 0;
                %                     dataSet.TanRibEdit  = dataSet.MinMechTol;
                %                 end
                dataSet.HCpu = dataSet.HCpu*6;
                dataSet.BetaPMshapeBou=[0.4 1];
                dataSet.BetaPMshapeBouCheck=0;
                warning('V-type geometry will be deleted in next releases. Use Seg geometry with central barrier shrink=0.')
            end
            
            dataSet.DxBouCheck = 0;
            dataSet.PMdimBouCheck = 0;
            
            if (strcmp(dataSet.TypeOfRotor,'SPM')||strcmp(dataSet.TypeOfRotor,'SPM-Halbach'))
                dataSet.NumOfLayers = 1;
                dataSet.DepthOfBarrier = 1;
                dataSet.Dalpha1BouCheck =0;
                dataSet.DalphaBouCheck = 0;
                dataSet.DxBouCheck = 0;
                dataSet.betaPMshape = 1;
                dataSet.HCpu = 7;
                dataSet.HCmm = dataSet.HCpu*dataSet.AirGapThickness;
                dataSet.ALPHApu = 0.9;
                dataSet.FluxBarrierMaterial = 'BMN-38EH';
                [~,~,~,~,mat] = data0(dataSet);
                dataSet.Br = mat.LayerMag.Br;
                dataSet.BrPP = dataSet.Br;
                if strcmp(dataSet.TypeOfRotor,'SPM-Halbach')
                    dataSet.ALPHApu = 1;
                    dataSet.DepthOfBarrier = 2;
                end
            else
                if strcmp(dataSet.TypeOfRotor,'Vtype') %rev.Gallo
                    dataSet.FluxBarrierMaterial = 'BMN-38EH'; %imposto materiale magnete commerciale caso Vtype
                    dataSet.PMdim = inf(2,dataSet.NumOfLayers);
                    
                else
                    dataSet.FluxBarrierMaterial = 'Air';
                    dataSet.PMdim = zeros(2,dataSet.NumOfLayers);
                    dataSet.Br = 0;
                end
                
                if (strcmp(dataSet.TypeOfRotor,'Fluid') || strcmp(dataSet.TypeOfRotor,'Seg') || strcmp(dataSet.TypeOfRotor,'Circular'))
                    if not(length(dataSet.DepthOfBarrier) == dataSet.NumOfLayers)
                        dataSet.DepthOfBarrier = zeros(1,dataSet.NumOfLayers);
                    end
                end
                
                [bounds, ~, geo, ~, mat] = data0(dataSet);
%                 data = buildDefaultRQ(bounds);
                %dataSet.RQ = data;
                dataSet.Br = mat.LayerMag.Br;
                dataSet.BrPP = dataSet.Br;
                if max(dataSet.bRange)>1
                    dataSet.bRange = [0.4 0.6];
                end
                [truefalse, index] = ismember('dx', geo.RQnames);
                if truefalse
                    dataSet.DfeBou = bounds(index,:);            % barrier offset [p.u.]
                end
            end
            
            dataSet.PMclear = zeros(2,dataSet.NumOfLayers);
            dataSet.TanRibEdit = dataSet.MinMechTol*ones(1,dataSet.NumOfLayers);
            dataSet.RadRibEdit = zeros(1,dataSet.NumOfLayers);

            if strcmp(dataSet.TypeOfRotor,'Spoke-type')
                dataSet.NumOfLayers = 1;
                dataSet.DepthOfBarrier = 0;     %dx
                dataSet.Dalpha1BouCheck =0;
                dataSet.DalphaBouCheck = 0;
                dataSet.DxBouCheck = 0;
                dataSet.betaPMshape = 0;
                dataSet.HCpu = 0;
                dataSet.HCmm = 10;
                dataSet.PMdim(1,1) = 30;
                dataSet.FluxBarrierMaterial = 'BMN-38EH';
                [~,~,~,~,mat] = data0(dataSet);
                dataSet.Br = mat.LayerMag.Br;
                dataSet.BrPP = dataSet.Br;
                dataSet.MinMechTol = 0.4;
                dataSet.TanRibEdit = 3;
            end


            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles);
        end

        % Button pushed function: ViewPropPush
        function ViewPropPush_Callback(app, event)
            % hObject    handle to ViewPropPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            answer = inputdlg('Material Name','Properties viewer',1);
            MatName = char(answer);
            mat = material_properties_iron(MatName);
            if isfield(mat,'kgm3')
                plot_iron_prop(mat);
            else
                clear mat
                mat = material_properties_conductor(MatName);
                if isfield(mat,'kgm3')
                    plot_conductor_prop(mat);
                else
                    clear mat
                    mat = material_properties_layer(MatName);
                    if isfield(mat,'kgm3')
                        plot_layer_prop(mat);
                    else
                        clear mat
                        mat = material_properties_sleeve(MatName);
                        if isfield(mat,'kgm3')
                            plot_sleeve_prop(mat);
                        else
                            msgbox('Select a correct material name to see the properties','Properties viewer');
                        end
                    end
                end
            end
        end

        % Value changed function: XPopEdit
        function XPopEdit_Callback(app, event)
            % Population Size
            % hObject    handle to XPopEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of XPopEdit as text
            %        str2double(get(hObject,'String')) returns contents of XPopEdit as a double
            dataSet = app.dataSet;
            dataSet.XPop = eval(app.XPopEdit.Value);
            app.dataSet = dataSet;
            % % guidata(hObject,handles)
        end

        % Value changed function: bRangeEdit
        function bRangeEdit_Callback(app, event)
            % hObject    handle to bRangeEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of bRangeEdit as text
            %        str2double(get(hObject,'String')) returns contents of bRangeEdit as a double
            dataSet = app.dataSet;
            dataSet.bRange = eval(app.bRangeEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: hcBouCheck
        function hcBouCheck_Callback(app, event)
            % hc [p.u.](bounds_hc) check box
            % hObject    handle to hcBouCheck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of hcBouCheck
            dataSet = app.dataSet;
            dataSet.hcBouCheck = app.hcBouCheck.Value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: hcBouEdit
        function hcBouEdit_Callback(app, event)
            % hc[p.u.](bounds_hc)
            
            % hObject    handle to hcBouEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of hcBouEdit as text
            %        str2double(get(hObject,'String')) returns contents of hcBouEdit as a double
            dataSet = app.dataSet;
            dataSet.hcBou = eval(app.hcBouEdit.Value);
            [m,n] = size(dataSet.hcBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: hcmmEdit
        function hcmmEdit_Callback(app, event)
            % hc [mm]
            % hObject    handle to hcmmEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of hcmmEdit as text
            %        str2double(get(hObject,'String')) returns contents of hcmmEdit as a double
            dataSet = app.dataSet;
%             if strcmp(dataSet.TypeOfRotor,'SPM')
%                 dataSet.ThicknessOfPM = eval(app.hcmmEdit.Value);
%             else
                dataSet.HCmm = eval(app.hcmmEdit.Value);
                [~,dataSet] = hc2hcpu(dataSet);
%             end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: hcpuEdit
        function hcpuEdit_Callback(app, event)
            % hc [p.u.]
            % hObject    handle to hcpuEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of hcpuEdit as text
            %        str2double(get(hObject,'String')) returns contents of hcpuEdit as a double
            dataSet = app.dataSet;
            dataSet.HCpu = eval(app.hcpuEdit.Value);
            dataSet.hcBouCheck = 0;
            
%             %Aggiornamento valori di angle su GUI quando modifico hcpu - rev.Gallo
%             [~,~,geo,~,mat] = data0(dataSet);
%             [bounds,~,~,~,~] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Value changed function: ktEdit
        function ktEdit_Callback(app, event)
            % hObject    handle to ktEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of ktEdit as text
            %        str2double(get(hObject,'String')) returns contents of ktEdit as a double
            dataSet = app.dataSet;
            dataSet.kt = eval(app.ktEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Button pushed function: syrmDesignPushButt
        function syrmDesignPushButt_Callback(app, event)
            % hObject    handle to syrmDesignPushButt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            dataSet = app.dataSet;
            FEAfixTmp = dataSet.FEAfixN;
            dataSet.FEAfixN = 0;
            [dataSet,flagS] = eval_xbDesignPlane(dataSet);
            dataSet.FEAfixN = FEAfixTmp;
            app.dataSet = dataSet;
            if flagS
                dataSet = DrawAndSaveMachine(dataSet,dataSet.currentfilename,dataSet.currentpathname);
            end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Value changed function: xRangeEdit
        function xRangeEdit_Callback(app, event)
            % hObject    handle to xRangeEdit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of xRangeEdit as text
            %        str2double(get(hObject,'String')) returns contents of xRangeEdit as a double
            dataSet = app.dataSet;
            dataSet.xRange = eval(app.xRangeEdit.Value);
            app.dataSet = dataSet;
            % guidata(hObject,handles)
        end

        % Button pushed function: LoadMachinePush
        function LoadMachinePushButtonPushed(app, event)
            % Load new motor model. It replace the toolbox command from the
            % old GUIDE-style GUI
            [FileName,PathName] = uigetfile(checkPathSyntax([app.dataSet.currentpathname '*.mat']),'Select a motor file');
            if FileName
                if strcmp(FileName(end-3:end),'.fig')
                    hfig = openfig([PathName FileName],'invisible');
                    map = get(hfig,'UserData');
                    dataSet = map.dataSet;
                    [dataSet,~,~,~] = back_compatibility(map.dataSet,map.geo,[],1);
                    [~,~,geo,per,~] = data0(dataSet);
                    close(hfig);
                else
                    load([PathName FileName]);
                    update_material_library(PathName,FileName);
                end
                
                if ~exist('geo')
                    if exist('geo0','var')
                        geo = geo0;
                    else
                        [~,~,geo,per,~] = data0(dataSet);
                    end
                end
                
                if exist('dataSet')~=1
                    dataSet=build_dataSet(geo,per);
                    disp('dataSet reconstructed. Please check the data')
                end


                [dataSet,geo,per] = back_compatibility(dataSet,geo,per);
                dataSet.RQ = round(dataSet.RQ*10000)/10000;
                
                % Set tab to main tab
                app.TabGroup.SelectedTab = app.TabGroup.Children(1);
                
                
                dataSet.currentpathname = PathName;
                dataSet.currentfilename = [FileName(1:end-4) '.mat'];
                
                app.dataSet = dataSet;
                app = GUI_APP_DrawMachine(app);
                GUI_APP_SetParameters(app);
                
                if app.dataSet.custom
                    app.StateLamp.Color = [0 1 0];
                else
                    app.StateLamp.Color = [1 0 0];
                end
                
            end
            
            figure(app.figure1)
        end

        % Value changed function: SlotshapeDropDown
        function SlotshapeDropDownValueChanged(app, event)
            value = app.SlotshapeDropDown.Value;
            dataSet = app.dataSet;
            tmp = app.SlotshapeDropDown.Value;
            if strcmp(tmp,'Trapezoidal')
                dataSet.ParallelSlotCheck = 0;
            else
                dataSet.ParallelSlotCheck = 1;
            end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: SlotlayerposDropDown
        function SlotlayerposDropDownValueChanged(app, event)
            dataSet = app.dataSet;
            tmp = app.SlotlayerposDropDown.Value;
            if strcmp(tmp,'Stacked')
                dataSet.SlotLayerPosCheck = 0;
            else
                dataSet.SlotLayerPosCheck = 1;
            end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: StatorslotmaterialDropDown
        function StatorslotmaterialDropDownValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.SlotMaterial = app.StatorslotmaterialDropDown.Value;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: StatorcorematerialDropDown
        function StatorcorematerialDropDownValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.StatorMaterial = app.StatorcorematerialDropDown.Value;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: RotorcorematerialDropDown
        function RotorcorematerialDropDownValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.RotorMaterial = app.RotorcorematerialDropDown.Value;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: FluxbarriermaterialDropDown
        function FluxbarriermaterialDropDownValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.FluxBarrierMaterial = app.FluxbarriermaterialDropDown.Value;
            mat = material_properties_layer(dataSet.FluxBarrierMaterial);
            dataSet.Br = mat.Br;
            dataSet.BrPP = dataSet.Br;
            if isfield(mat,'temp')
                dataSet.Br = interp1(mat.temp.temp,mat.temp.Br,dataSet.PMtemp);
                dataSet.BrPP = interp1(mat.temp.temp,mat.temp.Br,dataSet.tempPP);
            end
            if ~strcmp(dataSet.FluxBarrierMaterial,'Air')
                if (strcmp(dataSet.TypeOfRotor,'Seg')||strcmp(dataSet.TypeOfRotor,'ISeg'))
                    dataSet.PMdim = Inf(2,dataSet.NumOfLayers);
                elseif (strcmp(dataSet.TypeOfRotor,'Circular')||strcmp(dataSet.TypeOfRotor,'Vtype'))
                    dataSet.PMdim = [Inf(1,dataSet.NumOfLayers);zeros(1,dataSet.NumOfLayers)];
                elseif strcmp(dataSet.TypeOfRotor,'ISeg')
                    dataSet.PMdim(2,1)=0;
                end
            else
                dataSet.PMdim = zeros(2,dataSet.NumOfLayers);
            end
            dataSet.PMclear = dataSet.MinMechTol/2*ones(2,dataSet.NumOfLayers);
            
            if strcmp(dataSet.FluxBarrierMaterial,'Air')
                dataSet.MapQuadrants = 1;
                dataSet.PMclear = zeros(2,dataSet.NumOfLayers);
            else
                dataSet.MapQuadrants = 2;
            end
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: ShaftmaterialDropDown
        function ShaftmaterialDropDownValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.ShaftMaterial = app.ShaftmaterialDropDown.Value;
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Button pushed function: ExportMCadPush
        function ExportMCadPushButtonPushed(app, event)
            dataSet = app.dataSet;
            dataSet = DrawAndSaveMachine_MCAD(dataSet);
            app.dataSet = dataSet;
        end

        % Button pushed function: StartMCadSimPush
        function StartMCadSimPushButtonPushed(app, event)
            dataSet = app.dataSet;
            switch dataSet.EvalType
                case {'singt'}
                    eval_operatingPointMCAD(dataSet);
                otherwise
                    error('Simulation type not supported in Motor-CAD!!!')
            end
        end

        % Button pushed function: ExportFluxMapsPush
        function ExportFluxMapsPushButtonPushed(app, event)
            dataSet = app.dataSet;
            dataSet = Export_FdFq_tables_in_MCAD(dataSet);
            app.dataSet = dataSet;
        end

        % Value changed function: InlettemperatureEditField
        function InlettemperatureEditFieldValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.InletTemperature = eval(app.InlettemperatureEditField.Value);
            app.dataSet = dataSet;
            
        end

        % Value changed function: TransientperiodEditField
        function TransientperiodEditFieldValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.TransientPeriod = eval(app.TransientperiodEditField.Value);
            app.dataSet = dataSet;
        end

        % Value changed function: HousingTypeDropDown
        function HousingTypeDropDownValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.HousingType = app.HousingTypeDropDown.Value;
            app.dataSet = dataSet;
            %app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
        end

        % Button pushed function: ExportThermalParameters_MCADPush
        function ExportThermalParameters_MCADPushButtonPushed(app, event)
            dataSet = app.dataSet;
            dataSet = ExportThermalParameters_MCAD(dataSet);
            app.dataSet = dataSet;
        end

        % Button pushed function: ThermalSimulation_MCAD
        function ThermalSimulation_MCADButtonPushed(app, event)
            dataSet = app.dataSet;
            dataSet = ThermalSimulation_MCAD(dataSet);
            app.dataSet = dataSet;
        end

        % Value changed function: TimestepEditField
        function TimestepEditFieldValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.TransientTimeStep = eval(app.TimestepEditField.Value);
            app.dataSet = dataSet;
            
        end

        % Value changed function: RotorFilletTan1EditField
        function RotorFilletTan1EditFieldValueChanged(app, event)
            % Rotor fillet [mm]
            
            dataSet = app.dataSet;
            value = eval(app.RotorFilletTan1EditField.Value);
            value(value==0) = dataSet.MinMechTol;
            dataSet.RotorFilletTan1 = value;
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
        end

        % Cell edit callback: PMclearanceTable
        function PMclearanceTableCellEdit(app, event)
            %             indices = event.Indices;
            %             newData = event.NewData;
            dataSet = app.dataSet;
            %tmp = get(hObject,'data');
            tmp = app.PMclearanceTable.Data;
            
            if (strcmp(dataSet.TypeOfRotor,'Circular')||strcmp(dataSet.TypeOfRotor,'Vtype'))
                tmp(2,:)=0;
            elseif strcmp(dataSet.TypeOfRotor,'ISeg')
                tmp(2,1)=0;
            end
            
            dataSet.PMclear = tmp;
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
        end

        % Value changed function: SlotModelTemperatureEdit
        function SlotModelTemperatureEditValueChanged(app, event)
            value = app.SlotModelTemperatureEdit.Value;
            value = eval(value);
            value = sort(value);
            
            dataSet = app.dataSet;
            dataSet.SlotConductorTemperature = value;
            
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: RadialRibsAngleEditField
        function RadialRibsAngleEditFieldValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.pontRangEdit = eval(app.RadialRibsAngleEditField.Value);
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: RotorFilletRadInEditField
        function RotorFilletRadInEditFieldValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.RotorFilletIn = eval(app.RotorFilletRadInEditField.Value);
            
            if strcmp(dataSet.TypeOfRotor,'Circular')
                dataSet.RotorFilletOut = dataSet.RotorFilletIn;
            end

            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: RotorFilletRadOutEditField
        function RotorFilletRadOutEditFieldValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.RotorFilletOut = eval(app.RotorFilletRadOutEditField.Value);

            if strcmp(dataSet.TypeOfRotor,'Circular')
                dataSet.RotorFilletIn = dataSet.RotorFilletOut;
            end

            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: RadShiftInnerEdit
        function RadShiftInnerEditValueChanged(app, event)
            value = app.RadShiftInnerEdit.Value;
            dataSet = app.dataSet;
            dataSet.RadShiftInner = eval(value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: NarrowFactorEdit
        function NarrowFactorEditValueChanged(app, event)
            value = app.NarrowFactorEdit.Value;
            dataSet = app.dataSet;
            dataSet.NarrowFactor = eval(value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: SplitRibsEditField
        function SplitRibsEditFieldValueChanged(app, event)
            value = app.SplitRibsEditField.Value;
            dataSet = app.dataSet;
            dataSet.RadRibSplit = eval(value);
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CentralBarriersShrinkEdit
        function CentralBarriersShrinkEditValueChanged(app, event)
            value = app.CentralBarriersShrinkEdit.Value;
            dataSet = app.dataSet;
            dataSet.CentralShrink = eval(value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: FluidDropDown
        function FluidDropDownValueChanged(app, event)
            value = app.FluidDropDown.Value;
            
            dataSet = app.dataSet;
            dataSet.Fluid = value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: FluidFlowRateEditField
        function FluidFlowRateEditFieldValueChanged(app, event)
            value = app.FluidFlowRateEditField.Value;
            dataSet = app.dataSet;
            dataSet.FlowRate = eval(value);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: ThermalevaluationtypeDropDown
        function ThermalevaluationtypeDropDownValueChanged(app, event)
            value = app.ThermalevaluationtypeDropDown.Value;
            dataSet = app.dataSet;
            dataSet.th_eval_type = value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: InitialTemperatureEditField
        function InitialTemperatureEditFieldValueChanged2(app, event)
            value = app.InitialTemperatureEditField.Value;
            
%             dataSet = app.dataSet;
%             dataSet.MachineTemperature = eval(value);
%             app.dataSet = dataSet;
%             GUI_APP_SetParameters(app);
            dataSet = app.dataSet;
            dataSet.TempPMLimit = eval(value);
            app.dataSet = dataSet;
            %GUI_APP_SetParameters(app);
        end

        % Value changed function: EvaluatedSpeedMirrorEdit
        function EvaluatedSpeedMirrorEditValueChanged(app, event)
            value = app.EvaluatedSpeedMirrorEdit.Value;
            dataSet = app.dataSet;
            dataSet.EvalSpeed = eval(value);
            app.dataSet = dataSet;
        end

        % Value changed function: RadRibBouEdit
        function RadRibBouEditValueChanged(app, event)
            value = app.RadRibBouEdit.Value;
            dataSet = app.dataSet;
            dataSet.RadRibBou = eval(value);
            [m,n] = size(dataSet.RadRibBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: RadRibBouCheck
        function RadRibBouCheckValueChanged(app, event)
            value = app.RadRibBouCheck.Value;
            
            dataSet = app.dataSet;
            dataSet.RadRibBouCheck = value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
        end

        % Value changed function: CentralShrinkBouEdit
        function CentralShrinkBouEditValueChanged(app, event)
            value = app.CentralShrinkBouEdit.Value;
            dataSet = app.dataSet;
            dataSet.CentralShrinkBou = eval(value);
            [m,n] = size(dataSet.CentralShrinkBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CentralShrinkBouCheck
        function CentralShrinkBouCheckValueChanged(app, event)
            value = app.CentralShrinkBouCheck.Value;
            dataSet = app.dataSet;
            dataSet.CentralShrinkBouCheck = value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            % guidata(hObject,handles)
        end

        % Value changed function: RadShiftInnerBouCheck
        function RadShiftInnerBouCheckValueChanged(app, event)
            value = app.RadShiftInnerBouCheck.Value;
            dataSet = app.dataSet;
            dataSet.RadShiftInnerBouCheck = value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            % guidata(hObject,handles)
        end

        % Value changed function: RadShiftInnerBouEdit
        function RadShiftInnerBouEditValueChanged(app, event)
            value = app.RadShiftInnerBouEdit.Value;
            dataSet = app.dataSet;
            dataSet.RadShiftInnerBou = eval(value);
            [m,n] = size(dataSet.RadShiftInnerBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: PowerFactorOptCheck
        function PowerFactorOptCheckValueChanged(app, event)
            value = app.PowerFactorOptCheck.Value;
            dataSet = app.dataSet;
            dataSet.PowerFactorOptCheck = value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: MinExpPowerFactorEdit
        function MinExpPowerFactorEditValueChanged(app, event)
            value = app.MinExpPowerFactorEdit.Value;
            dataSet = app.dataSet;
            dataSet.MinExpPowerFactor = eval(value);
            if dataSet.MinExpPowerFactor>0
                disp('Power factor limit penalization must be negative for optimization settings!!!')
                beep
                dataSet.MinExpPowerFactor = -dataSet.MinExpPowerFactor;
            end
            app.dataSet = dataSet;
        end

        % Value changed function: MaxExpNoLoadFluxEdit
        function MaxExpNoLoadFluxEditValueChanged(app, event)
            value = app.MaxExpNoLoadFluxEdit.Value;
            dataSet = app.dataSet;
            dataSet.MaxExpNoLoadFlux = eval(value);
            app.dataSet = dataSet;
        end

        % Value changed function: NoLoadFluxOptCheck
        function NoLoadFluxOptCheckValueChanged(app, event)
            value = app.NoLoadFluxOptCheck.Value;
            dataSet = app.dataSet;
            dataSet.NoLoadFluxOptCheck = value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            
        end

        % Callback function
        function MechStressOptCheckValueChanged(app, event)
            value = app.MechStressOptCheck.Value;
            dataSet = app.dataSet;
            dataSet.MechStressOptCheck = value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: TanRibBouEdit
        function TanRibBouEditValueChanged(app, event)
            value = app.TanRibBouEdit.Value;
            dataSet = app.dataSet;
            dataSet.TanRibBou = eval(value);
            [m,n] = size(dataSet.TanRibBou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: TanRibBouCheck
        function TanRibBouCheckValueChanged(app, event)
            value = app.TanRibBouCheck.Value;
            dataSet = app.dataSet;
            dataSet.TanRibBouCheck = value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            
        end

        % Button pushed function: CustomLoad
        function CustomLoadButtonPushed(app, event)
            app.StateLamp.Color = [0 1 0];
            app.dataSet.custom = 1;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            
        end

        % Button pushed function: CustomClear
        function CustomClearButtonPushed(app, event)
            
            app.dataSet.pShape.rotor  = [];
            app.dataSet.pShape.stator = [];
            app.dataSet.pShape.magnet = [];
            app.dataSet.pShape.slot   = [];
            app.dataSet.custom        = 0;
            app.dataSet.pShape.flag   = 0;
            
            app.StateLamp.Color = [1 0 0];
            
            pathname  = app.dataSet.currentpathname;
            fileans   = strrep(app.dataSet.currentfilename,'.mat','.ans');
            if isfile([pathname fileans])
                delete ([pathname fileans])
            end
            
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
            
        end

        % Value changed function: ConductorSlotGapEdit
        function ConductorSlotGapEditValueChanged(app, event)
            value = app.ConductorSlotGapEdit.Value;
            
            dataSet = app.dataSet;
            dataSet.SlotConductorBottomGap = eval(value);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: RotorFilletTan2EditField
        function RotorFilletTan2EditFieldValueChanged(app, event)
            
            dataSet = app.dataSet;
            value = eval(app.RotorFilletTan2EditField.Value);
            value(value==0) = dataSet.MinMechTol;
            dataSet.RotorFilletTan2 = value;
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % % guidata(hObject,handles)
            
        end

        % Value changed function: TanRibCheck
        function TanRibCheckValueChanged(app, event)
            value = app.TanRibCheck.Value;
            dataSet = app.dataSet;
            dataSet.TanRibCheck = value;
            
            if ((~strcmp(dataSet.TypeOfRotor,'Seg') && ~strcmp(dataSet.TypeOfRotor,'Circular'))  && dataSet.TanRibCheck ==1)
                dataSet.TanRibCheck = 0;
            end
            
            if dataSet.TanRibCheck ==1
                dataSet.RotorFilletTan1  = 2*dataSet.MinMechTol*ones(1,dataSet.NumOfLayers);
                dataSet.RotorFilletTan2  = 2*dataSet.MinMechTol*ones(1,dataSet.NumOfLayers);
            else
                dataSet.RotorFilletTan1  = nan(1,dataSet.NumOfLayers);
                dataSet.RotorFilletTan2  = nan(1,dataSet.NumOfLayers);
            end
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            % guidata(hObject,handles)
        end

        % Value changed function: ScaleCheck
        function ScaleCheckValueChanged(app, event)
            value = app.ScaleCheck.Value;
            app.dataSet.ScaleCheck = value;
            GUI_APP_SetParameters(app)
            dataSet = app.dataSet;
        end

        % Value changed function: SlotWidthEdit
        function SlotWidthEditValueChanged(app, event)
            value = app.SlotWidthEdit.Value;
            dataSet = app.dataSet;
            dataSet.SlotWidth = eval(value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: MapQuadrantsPopUp
        function MapQuadrantsPopUpValueChanged(app, event)
            value = app.MapQuadrantsPopUp.Value;
            app.dataSet.MapQuadrants = eval(value(1));
            GUI_APP_SetParameters(app);
        end

        % Value changed function: TempCuLimitEdit
        function TempCuLimitEditValueChanged(app, event)
            value = app.TempCuLimitEdit.Value;
            dataSet = app.dataSet;
            dataSet.TempCuLimit = eval(value);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: TransientTimeEditField
        function TransientTimeEditFieldValueChanged(app, event)
            value = app.TransientTimeEditField.Value;
            dataSet = app.dataSet;
            dataSet.TransTime = eval(value);
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
        end

        % Button pushed function: CalculateCont_MCAD
        function CalculateCont_MCADButtonPushed(app, event)
            dataSet = app.dataSet;
            dataSet = Eval_ThermalLimit0rpm(dataSet,'Steady');
            app.dataSet = dataSet;
            
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Button pushed function: CalculateTrans_MCAD
        function CalculateTrans_MCADButtonPushed(app, event)
            dataSet = app.dataSet;
            dataSet = Eval_ThermalLimit0rpm(dataSet,'Transient');
            app.dataSet = dataSet;
            
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: AmbientTemperatureEditField
        function AmbientTemperatureEditFieldValueChanged(app, event)
            value = app.AmbientTemperatureEditField.Value;
            
            dataSet = app.dataSet;
            dataSet.AmbTemp = eval(value);
            app.dataSet = dataSet;
            %GUI_APP_SetParameters(app);
        end

        % Button pushed function: SaveMachineAnsysPush
        function SaveMachineAnsysPushButtonPushed(app, event)
            dataSet = app.dataSet;
            dataSet = DrawAndSaveMachine_AM(dataSet);
            app.dataSet = dataSet;
        end

        % Button pushed function: StartPProAnsysPush
        function StartPProAnsysPushButtonPushed(app, event)

            dataSet = app.dataSet;
            switch dataSet.EvalType
                case {'singt','singtIron'}
                    eval_operatingPointAM(dataSet);
                    if app.dataSet.CustomCurrentEnable
                        app.dataSet.CustomCurrentAnsysCounter = app.dataSet.CustomCurrentAnsysCounter + 1;
                    end
                otherwise
                    error('Simulation type not supported in Ansys Maxwell!')
            end
            GUI_APP_SetParameters(app);
        end

        % Value changed function: ParallelNumberEdit
        function ParallelNumberEditValueChanged(app, event)
            value = app.ParallelNumberEdit.Value;
            
            dataSet = app.dataSet;
            dataSet.SlotConductorParallel = eval(value);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: JouleLossesEdit
        function JouleLossesEditValueChanged(app, event)
            value = app.JouleLossesEdit.Value;
            
            dataSet = app.dataSet;
            dataSet.AdmiJouleLosses = eval(value);
            dataSet.ThermalLoadKj = NaN;
            dataSet.CurrentDensity = NaN;
            
            app.dataSet = dataSet;
            
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CurrentdensityEdit
        function CurrentdensityEditValueChanged(app, event)
            value = app.CurrentdensityEdit.Value;
            
            dataSet = app.dataSet;
            dataSet.CurrentDensity = eval(value);
            dataSet.ThermalLoadKj = NaN;
            dataSet.AdmiJouleLosses = NaN;
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: BarrierDesignDropDow
        function BarrierDesignDropDowValueChanged(app, event)
            value = app.BarrierDesignDropDow.Value;
            f = app.dataSet.syrmDesignFlag;
            switch value
                case 'Constant flux barrier thickness'
                    f.hc = 0;
                case 'Constant flux barrier permeance'
                    f.hc = 1;
                case 'Lfq minimization'
                    f.hc = 2;
            end
            app.dataSet.syrmDesignFlag = f;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CarrierDesignDropDown
        function CarrierDesignDropDownValueChanged(app, event)
            value = app.CarrierDesignDropDown.Value;
            f = app.dataSet.syrmDesignFlag;
            switch value
                case 'No flux carrier design'
                    f.dx = 0;
                case 'Constant flux carrier thickness'
                    f.dx = 1;
                case 'Flux carrier thickness proportional to d-flux'
                    f.dx = 2;
            end
            app.dataSet.syrmDesignFlag = f;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: syrmDesignThermalInputDropDown
        function syrmDesignThermalInputDropDownValueChanged(app, event)
            value = app.syrmDesignThermalInputDropDown.Value;
            f = app.dataSet.syrmDesignFlag;
            switch value
                case 'Constant thermal loading kj'
                    f.i0 = 0;
                case 'Constant current density J'
                    f.i0 = 1;
                case 'Constant turns-current NI'
                    f.i0 = 2;
            end
            app.dataSet.syrmDesignFlag = f;
            GUI_APP_SetParameters(app);
        end

        % Button pushed function: dxfButton
        function dxfButtonPushed(app, event)
            syreToDxf([],[],app.dataSet.currentpathname,app.dataSet.currentfilename);
            disp(['File saved: ' app.dataSet.currentpathname app.dataSet.currentfilename(1:end-4) '.dxf']);
        end

        % Button pushed function: AnsysMotorCADButton
        function AnsysMotorCADButtonPushed(app, event)
            dataSet = app.dataSet;
            dataSet = DrawAndSaveMachine_MCAD(dataSet);
            app.dataSet = dataSet;
        end

        % Button pushed function: UsermanualButton
        function UsermanualButtonPushed(app, event)
            syreDirectory = fileparts(which('GUI_Syre.mlapp'));
            open(checkPathSyntax([syreDirectory '\ReadMe\syreManual.pdf']));
        end

        % Button pushed function: ReferencesButton
        function ReferencesButtonPushed(app, event)
            syreDirectory = fileparts(which('GUI_Syre.mlapp'));
            open(checkPathSyntax([syreDirectory '\ReadMe\syreReferences.pdf']));
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            ppState = parallelComputingCheck(1);
        end

        % Button pushed function: EnableButton
        function EnableButtonPushed(app, event)
            ppState = parallelComputingCheck(0);
            if ppState<0
                disp('Parallel Computing Toolbox not available!')
                beep;
            elseif ppState==0
                numCores = feature('numcores');
                parpool(numCores);
            else
                disp(['Parallel computing already enabled on ' int2str(ppState) ' workers'])
            end
        end

        % Button pushed function: DisableButton
        function DisableButtonPushed(app, event)
            ppState = parallelComputingCheck(0);
            if ppState<0
                disp('Parallel Computing Toolbox not available!')
                beep;
            elseif ppState==0
                disp('Parallel ccomputing already disabled')
            else
                delete(gcp)
                disp('Parallel computing disabled')
            end
        end

        % Button pushed function: CheckReleaseButton
        function CheckReleaseButtonPushed(app, event)
            checkReleases();
        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            close all
            clc
        end

        % Button pushed function: LaunchMMMButton
        function LaunchMMMButtonPushed(app, event)
            GUI_Syre_MMM(app.dataSet.currentpathname,app.dataSet.currentfilename)
        end

        % Button pushed function: LaunchxbDesignPlaneExplorerButton
        function LaunchxbDesignPlaneExplorerButtonPushed(app, event)
            if exist('xbDesignPlaneExplorer.mlapp','file')
                xbDesignPlaneExplorer();
            else
                warning('(x,b) Design Plane Explorer not included in this release!');
            end
        end

        % Value changed function: RotoryokefactorpuEditField
        function RotoryokefactorpuEditFieldValueChanged(app, event)
            value = app.RotoryokefactorpuEditField.Value;
            app.dataSet.RotorYokeFactor = eval(value);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CustomCurrentSwitch
        function CustomCurrentSwitchValueChanged(app, event)
            value = app.CustomCurrentSwitch.Value;

            dataSet = app.dataSet;
            n3ph = dataSet.Num3PhaseCircuit;

            if strcmp(value,'On')
                app.dataSet.CustomCurrentEnable = 1;

                if(isnan(n3ph))

                else
                    %%%FFT to select the number of steps
                    Ia   = app.dataSet.CustomCurrentA;
                    time = app.dataSet.CustomCurrentTime;
    
                    Fs = (time(2)-time(1))^-1;
                    L = length(Ia);
                    fP = Fs*(0:(L/2))/L;
                    P2 = abs(fft(Ia)/L);
                    P1 = P2(1:floor(L/2+1))*2;
                                
                    [~,ind] = max(P1(5:end));
                    fmain = fP(ind);
    
                    Tfund = time(end);
                    Tstep = 1/fmain/10;
    
                    app.dataSet.NumOfRotPosPP = round(Tfund/Tstep/2); %180gradi
                    app.dataSet.AngularSpanPP = 180;
                    app.dataSet.EvalSpeed = round(60/app.dataSet.NumOfPolePairs/Tfund);
    
                    [~, ~, ~, ~, mat] = data0(app.dataSet);
                    
                    delta_skinEffect = sqrt(2/(mat.LayerMag.sigmaPM*2*pi*fmain*10*mat.LayerMag.mu*4*pi*10^-7))*1000;
    %                 app.dataSet.Mesh = 3;
                    stdMesh =  1/app.dataSet.NumOfPolePairs * app.dataSet.Mesh;
                    app.dataSet.mesh_kpm = round(stdMesh/delta_skinEffect,2);
                    app.dataSet.mesh_kpm(app.dataSet.mesh_kpm<1) = 1;
                end
            else
                app.dataSet.CustomCurrentEnable = 0;
%                 app.dataSet.Mesh = 5;
                app.dataSet.mesh_kpm = 1;
%                 app.dataSet.mesh_kfe = 1;
            end


            [bounds, objs, geo, per, ~] = data0(app.dataSet);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);

        end

        % Value changed function: CustomCurrentFile
        function CustomCurrentFileValueChanged(app, event)
            value = app.CustomCurrentFile.Value;
            
        end

        % Button pushed function: LoadCustomCurrent
        function LoadCustomCurrentButtonPushed(app, event)
            n3ph = app.dataSet.Num3PhaseCircuit;

            [FileNameCurr,PathNameCurr] = uigetfile([app.dataSet.currentpathname '*.mat']);
           
            load([PathNameCurr FileNameCurr])
            
            if(isnan(n3ph))
                app.dataSet.CustomCurrentA = Ia;
                app.dataSet.CustomCurrentB = Ib;
                app.dataSet.CustomCurrentC = Ic;
                app.dataSet.CustomCurrentD = Id;
                app.dataSet.CustomCurrentE = Ie;
                app.dataSet.CustomCurrentRotorPos = rotorPos;
            else
                app.dataSet.CustomCurrentA = Ia;
                app.dataSet.CustomCurrentB = Ib;
                app.dataSet.CustomCurrentC = Ic;
                app.dataSet.CustomCurrentTime = time;
            end

            app.dataSet.CustomCurrentFilename = FileNameCurr;
            
            GUI_APP_SetParameters(app);
        end

        % Value changed function: AxistypeDropDown
        function AxistypeDropDownValueChanged(app, event)
            value = app.AxistypeDropDown.Value;  
            app.dataSet.axisType = value;
            
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: StatoryokefactorpuEditField
        function StatoryokefactorpuEditFieldValueChanged(app, event)
            value = app.StatoryokefactorpuEditField.Value;
            app.dataSet.StatorYokeFactor = eval(value);

            GUI_APP_SetParameters(app);
        end

        % Value changed function: CurrentPP
        function CurrentPPValueChanged(app, event)
            value = app.CurrentPP.Value;
            app.dataSet.CurrLoPP = eval(value)/app.dataSet.RatedCurrent;
            %app.dataSet.SimulatedCurrent = eval(value);

            GUI_APP_SetParameters(app);
        end

        % Value changed function: FilletRadribsinBou
        function FilletRadribsinBouValueChanged(app, event)
            value = app.FilletRadribsinBou.Value;
            dataSet = app.dataSet;
            dataSet.FilletRad1Bou = eval(value);
            if strcmp(dataSet.TypeOfRotor,'Circular')
                dataSet.FilletRad2Bou = eval(value);
            end
            [m,n] = size(dataSet.FilletRad1Bou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: FilletRadribsoutBou
        function FilletRadribsoutBouValueChanged(app, event)
            value = app.FilletRadribsoutBou.Value;
            dataSet = app.dataSet;
            dataSet.FilletRad2Bou = eval(value);
            if strcmp(dataSet.TypeOfRotor,'Circular')
                dataSet.FilletRad1Bou = eval(value);
            end
            [m,n] = size(dataSet.FilletRad2Bou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: FilletTanribsinBou
        function FilletTanribsinBouValueChanged(app, event)
            value = app.FilletTanribsinBou.Value;
            dataSet = app.dataSet;
            dataSet.FilletTan1Bou = eval(value);
            [m,n] = size(dataSet.FilletTan1Bou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: FilletTanribsoutBou
        function FilletTanribsoutBouValueChanged(app, event)
            value = app.FilletTanribsoutBou.Value;
            dataSet = app.dataSet;
            dataSet.FilletTan2Bou = eval(value);
            [m,n] = size(dataSet.FilletTan2Bou);
            if m*n > 2 || m*n == 1
                disp('Must be vector 2x1')
                return
            end
            [bounds, objs, geo, per, ~] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: FilletRadribsinBouCheck
        function FilletRadribsinBouCheckValueChanged(app, event)
            value = app.FilletRadribsinBouCheck.Value;
            dataSet = app.dataSet;
            dataSet.FilletRad1BouCheck = value;
            if strcmp(dataSet.TypeOfRotor,'Circular')
                dataSet.FilletRad2BouCheck = value;
            end
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
        end

        % Value changed function: FilletRadribsoutBouCheck
        function FilletRadribsoutBouCheckValueChanged(app, event)
            value = app.FilletRadribsoutBouCheck.Value;
            dataSet = app.dataSet;
            dataSet.FilletRad2BouCheck = value;
            if strcmp(dataSet.TypeOfRotor,'Circular')
                dataSet.FilletRad1BouCheck = value;
            end
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
        end

        % Value changed function: FilletTanribsinBouCheck
        function FilletTanribsinBouCheckValueChanged(app, event)
            value = app.FilletTanribsinBouCheck.Value;
            dataSet = app.dataSet;
            dataSet.FilletTan1BouCheck = value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
%             dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
        end

        % Value changed function: FilletTanribsoutBouCheck
        function FilletTanribsoutBouCheckValueChanged(app, event)
            value = app.FilletTanribsoutBouCheck.Value;
            dataSet = app.dataSet;
            dataSet.FilletTan2BouCheck = value;
            [bounds, objs, geo, per, mat] = data0(dataSet);
            %dataSet.RQ = buildDefaultRQ(bounds);
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
        end

        % Value changed function: OptimizationtypeDropDown
        function OptimizationtypeDropDownValueChanged(app, event)
            value = app.OptimizationtypeDropDown.Value;
            app.dataSet.optType = value;
            switch app.dataSet.optType
                case 'MODE Design'
                    app.dataSet.XPop = 60;
                    app.dataSet.MaxGen = 60;
                case 'MODE Refine'
                    app.dataSet.XPop = 60;
                    app.dataSet.MaxGen = 60;
                case 'Surrogate model dataset (LHS)'
                    app.dataSet.XPop = 5000;
                    app.dataSet.MaxGen = 1;
                case 'Surrogate model dataset (Sobol)'
                    app.dataSet.XPop = 5000;
                    app.dataSet.MaxGen = 0;
            end

            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
        end

        % Value changed function: Active3phasesetsEditField
        function Active3phasesetsEditFieldValueChanged(app, event)
            value = app.Active3phasesetsEditField.Value;
            tmp = eval(value);
            %tmp(tmp~=0)=1;
            if length(tmp)==1
                tmp = 1;
            end
            app.dataSet.Active3PhaseSets = tmp;

            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: NumberofrotorbarsEditField
        function NumberofrotorbarsEditFieldValueChanged(app, event)
            value = app.NumberofrotorbarsEditField.Value;
            app.dataSet.NumOfRotorBars = eval(value);

            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: RotortoothlengthmmEditField
        function RotortoothlengthmmEditFieldValueChanged(app, event)
            value = app.RotortoothlengthmmEditField.Value;
            app.dataSet.RotorToothLength = eval(value);

            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: RotortoothwidthmmEditField
        function RotortoothwidthmmEditFieldValueChanged(app, event)
            value = app.RotortoothwidthmmEditField.Value;
            app.dataSet.RotorToothWidth = eval(value);

            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: RotorslotopenEditField
        function RotorslotopenEditFieldValueChanged(app, event)
            value = app.RotorslotopenEditField.Value;
            app.dataSet.RotorSlotOpen = eval(value);

            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: RotortoothtangmmEditField
        function RotortoothtangmmEditFieldValueChanged(app, event)
            value = app.RotortoothtangmmEditField.Value;
            app.dataSet.RotorToothTangDepth = eval(value);

            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: FilletatrotorslottopmmEditField
        function FilletatrotorslottopmmEditFieldValueChanged(app, event)
            value = app.FilletatrotorslottopmmEditField.Value;
            app.dataSet.RotorSlotFilletTop = eval(value);

            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: FilletatrotorslotbottommmEditField
        function FilletatrotorslotbottommmEditFieldValueChanged(app, event)
            value = app.FilletatrotorslotbottommmEditField.Value;
            app.dataSet.RotorSlotFilletBottom = eval(value);

            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: RotorslotmaterialDropDown
        function RotorslotmaterialDropDownValueChanged(app, event)
            value = app.RotorslotmaterialDropDown.Value;
            app.dataSet.BarMaterial = value;

            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: RotorsleevethicknessmmEditField
        function RotorsleevethicknessmmEditFieldValueChanged(app, event)
            value = eval(app.RotorsleevethicknessmmEditField.Value);
            dataSet = app.dataSet;
%             if value+dataSet.MinMechTol>dataSet.AirGapThickness
%                 value = dataSet.AirGapThickness-dataSet.MinMechTol;
%             end
            dataSet.SleeveThickness = value;
            app.dataSet = dataSet;
            
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Button pushed function: SleeveLibraryPush
        function SleeveLibraryPushButtonPushed(app, event)
            mat = material_properties_sleeve(0);
            material = reshape(mat.MatList,numel(mat.MatList),1);
            disp('Sleeve material library:')
            for ii=1:length(material)
                disp(['- ' material{ii}])
            end
        end

        % Button pushed function: AddSleevePush
        function AddSleevePushButtonPushed(app, event)
            answer = inputdlg('New Material Name','Sleeve Material',1,{'New Sleeve'});
            MatName = char(answer);
            mat=material_properties_sleeve('');
            MatList=mat.MatList;
            flag=1;
            for ii=1:length(MatList)
                if strcmp(MatName,MatList{ii})
                    flag=0;
                end
            end
            if flag
                add_material_sleeve(MatName);
            else
                disp('Material already present in the library')
            end
            
            % update materials dropdowns
            mat = material_properties_sleeve('0');
            app.SleevematerialDropDown.Items = mat.MatList;
        end

        % Button pushed function: RmvSleevePush
        function RmvSleevePushButtonPushed(app, event)
            prompt={'Material Name'};
            answer={'MatName'};
            answer = inputdlg(prompt,'Remove material',1,answer);
            MatName = answer{1};
            remove_material(MatName,'Sleeve');
            
            % update materials dropdowns
            mat = material_properties_sleeve('0');
            app.SleevematerialDropDown.Items = mat.MatList;
        end

        % Value changed function: ThermalLoadingsyrmDesignEditField
        function ThermalLoadingsyrmDesignEditFieldValueChanged(app, event)
            value = app.ThermalLoadingsyrmDesignEditField.Value;
            dataSet = app.dataSet;
            
            dataSet.ThermalLoadKj = eval(value);
            dataSet.AdmiJouleLosses = NaN;
            dataSet.CurrentDensity = NaN;
            
            app.dataSet = dataSet;
            
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CurrentDensityArmsmm2EditField
        function CurrentDensityArmsmm2EditFieldValueChanged(app, event)
            value = app.CurrentDensityArmsmm2EditField.Value;
            dataSet = app.dataSet;
            dataSet.CurrentDensity = eval(value);
            dataSet.ThermalLoadKj = NaN;
            dataSet.AdmiJouleLosses = NaN;
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: PMfillingfactorpuEditField
        function PMfillingfactorpuEditFieldValueChanged(app, event)
            value = app.PMfillingfactorpuEditField.Value;
            value = eval(value);
%             if value>1
%                 value=1;
%             elseif value<0
%                 value=0;
%             end
            app.dataSet.kPM = value;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: SleevematerialDropDown
        function SleevematerialDropDownValueChanged(app, event)
            value = app.SleevematerialDropDown.Value;
            app.dataSet.SleeveMaterial = value;

            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CharcurrentButton
        function CharcurrentButtonValueChanged(app, event)
            value = app.CharcurrentButton.Value;
            if ~strcmp(app.dataSet.FluxBarrierMaterial,'Air')
                app.dataSet.syrmDesignFlag.ichf = value;
            end

            GUI_APP_SetParameters(app);
        end

        % Value changed function: HWCSCcurrentButton
        function HWCSCcurrentButtonValueChanged(app, event)
            value = app.HWCSCcurrentButton.Value;
            if ~strcmp(app.dataSet.FluxBarrierMaterial,'Air')
                app.dataSet.syrmDesignFlag.scf = value;
            end

            GUI_APP_SetParameters(app);
        end

        % Value changed function: DemagratedButton
        function DemagratedButtonValueChanged(app, event)
            value = app.DemagratedButton.Value;
            if ~strcmp(app.dataSet.FluxBarrierMaterial,'Air')
                app.dataSet.syrmDesignFlag.demag0 = value;
            end

            GUI_APP_SetParameters(app);
        end

        % Value changed function: MTPApointButton
        function MTPApointButtonValueChanged(app, event)
            value = app.MTPApointButton.Value;
            app.dataSet.syrmDesignFlag.gf = value;

            GUI_APP_SetParameters(app);
        end

        % Value changed function: DemagHWCButton
        function DemagHWCButtonValueChanged(app, event)
            value = app.DemagHWCButton.Value;
            if ~strcmp(app.dataSet.FluxBarrierMaterial,'Air')
                app.dataSet.syrmDesignFlag.demagHWC = value;
            end

            GUI_APP_SetParameters(app);
        end

        % Cell edit callback: PMNcSegmentsTable
        function PMNcSegmentsTableCellEdit(app, event)
            dataSet = app.dataSet;
            tmp = app.PMNcSegmentsTable.Data;
            
            if (strcmp(dataSet.TypeOfRotor,'Circular')||strcmp(dataSet.TypeOfRotor,'Vtype'))||strcmp(dataSet.TypeOfRotor,'ISeg')
                tmp = ones(2,dataSet.NumOfLayers);
            end
            
            dataSet.PMNc = tmp;
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
        end

        % Value changed function: PMNaSegments
        function PMNaSegmentsValueChanged(app, event)
            value = app.PMNaSegments.Value;
            dataSet = app.dataSet;
            dataSet.PMNa = eval(app.PMNaSegments.Value);

            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Button pushed function: CreateCustomCurrent
        function CreateCustomCurrentButtonPushed(app, event)
            
            app.dataSet = build_customCurrent(app.dataSet);
           
            GUI_APP_SetParameters(app);
        end

        % Value changed function: flag_OptCurrConst
        function flag_OptCurrConstValueChanged(app, event)
            value = app.flag_OptCurrConst.Value;
            switch value
                case 'Constant kj'
                    app.dataSet.flag_OptCurrConst = 0;
                case 'Constant J'
                    app.dataSet.flag_OptCurrConst = 1;
                case 'Constant I'
                    app.dataSet.flag_OptCurrConst = 2;
            end
            GUI_APP_SetParameters(app);
        end

        % Value changed function: Numberof3phasesetsEditField
        function Numberof3phasesetsEditFieldValueChanged(app, event)
            dataSet = app.dataSet;
            n3ph = eval(app.Numberof3phasesetsEditField.Value);
            p = dataSet.NumOfPolePairs;
            n3ph_old = dataSet.Num3PhaseCircuit;

            if(n3ph==0)
                dataSet.Num3PhaseCircuit = NaN;
                num_ph = 5;
            else
                dataSet.Num3PhaseCircuit = n3ph;
                num_ph = 3*n3ph;
            end

            if(isnan(n3ph_old))
                Q_old = dataSet.NumOfSlots*num_ph*2*p;
            else
                Q_old = dataSet.NumOfSlots*6*p*n3ph_old;
            end

            if n3ph>1
                dataSet.PitchShortFac=1;
            end
            dataSet.thetaFBS=0;
            
            if(n3ph == 0)
                dataSet.NumOfSlots = Q_old/(num_ph*2*p);
                Q = round(dataSet.NumOfSlots*2*p*num_ph);
            else
                dataSet.NumOfSlots = Q_old/(3*n3ph*2*p);
                Q = round(dataSet.NumOfSlots*6*p*n3ph);
            end

            dataSet.NumOfStatorSlots = Q;
            Windings = WindingDefinition(dataSet);
            t2 = gcd(Q,2*p);
            Qs = Q/t2;
            dataSet.Qs = Qs;
            dataSet.WinMatr = Windings(:,1:floor(Qs)); % winding matrix, only Qs columns
            
            if(n3ph == 0)
                dataSet.WinFlag=ones(1,num_ph);
                dataSet.Num3PhaseCircuit=NaN;
                dataSet.Active3PhaseSets = NaN;
            else                
                dataSet.WinFlag=ones(1,(n3ph*3));
                dataSet.Num3PhaseCircuit=n3ph;
                dataSet.Active3PhaseSets = ones(1,n3ph);
            end

            app.dataSet=dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: NumberofstatorslotsEditField
        function NumberofstatorslotsEditFieldValueChanged(app, event)
            value = app.NumberofstatorslotsEditField.Value;
            dataSet = app.dataSet;
            Q = round(eval(value));
            dataSet.NumOfStatorSlots = Q;
            p = dataSet.NumOfPolePairs;
            n3ph = dataSet.Num3PhaseCircuit;
            dataSet.NumOfSlots = Q/(3*n3ph*2*p);
            %             yq = dataSet.PitchShortFac*dataSet.NumOfSlots*3*n3ph;
            %             path = pwd;
            %             cd(fullfile (path,'koil'));
            %             system(['koil_syre.exe',' ',num2str(Q),' ',num2str(p),' ',num2str(yq)]);
            %             cd(path);
            %             Windings = MatrixWin();
            Windings = WindingDefinition(dataSet);
            t2 = gcd(Q,2*p);
            Qs = Q/t2;
            dataSet.Qs = Qs;
            dataSet.WinMatr = Windings(:,1:floor(Qs)); % winding matrix, only Qs columns
            
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
            
        end

        % Value changed function: CustomLossMCADCheck
        function CustomLossMCADCheckValueChanged(app, event)
            value = app.CustomLossMCADCheck.Value;
            dataSet = app.dataSet;
            dataSet.CustomLossMCADCheck = value;
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app);
        end

        % Button pushed function: EvalACLossCustomCurrent
        function EvalACLossCustomCurrentButtonPushed(app, event)
      
            skinEffect_evalCustomCurrent(app.dataSet);

        end

        % Button pushed function: toWorkSpaceButton
        function toWorkSpaceButtonPushed(app, event)
            assignin('base','dataSet',app.dataSet);
        end

        % Value changed function: mesh_kpmEdit
        function mesh_kpmEditValueChanged(app, event)
            app.dataSet.mesh_kpm  = eval(app.mesh_kpmEdit.Value);
            GUI_APP_SetParameters(app);
           
        end

        % Value changed function: RotorsleeveinterferencemmEditField
        function RotorsleeveinterferencemmEditFieldValueChanged(app, event)
            value = app.RotorsleeveinterferencemmEditField.Value;
            dataSet = app.dataSet;
            dataSet.SleeveInterference = eval(value);
            app.dataSet = dataSet;
            
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: RotorsleevetemperatureCEditField
        function RotorsleevetemperatureCEditFieldValueChanged(app, event)
            value = app.RotorsleevetemperatureCEditField.Value;
            dataSet = app.dataSet;
            dataSet.SleeveTemperature      = eval(value);
            dataSet.TemperatureRiseAbove20 = dataSet.SleeveTemperature-20;
           
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Button pushed function: SleeveDesignerButton
        function SleeveDesignerButtonPushed(app, event)
            dataSet = app.dataSet;
            [dataSet,flagS] = sleeveDesign(dataSet);
            app.dataSet = dataSet;
            if flagS
                dataSet = DrawAndSaveMachine(dataSet,dataSet.currentfilename,dataSet.currentpathname);
            end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
        end

        % Button pushed function: PlotStatsButton
        function PlotStatsButtonPushed(app, event)
            plotSleeveDesignStats(app.dataSet,app);
        end

        % Button pushed function: JMAGButton
        function JMAGButtonPushed(app, event)
            dataSet = app.dataSet;
            % check if speed is greater than zero
            if dataSet.EvalSpeed==0
                msg = 'Speed MUST be greater than one in JMAG simulations!';
                title = 'WARNING';
                f = warndlg(msg,title,'modal');
                error(msg);
            end
            
            % dataSet.SimulatedCurrent = dataSet.CurrLoPP*dataSet.RatedCurrent;
            
            switch dataSet.EvalType
                case {'singt','singtIron'}
                    eval_operatingPointJMAG(dataSet);
                case {'singm','singmIron'}
                    eval_fluxMapJMAG(dataSet);
                otherwise
                    error('Simulation type not supported in JMAG!!!')
            end
        end

        % Button pushed function: JMAGButton_2
        function JMAGButton_2Pushed(app, event)
            DrawAndSaveMachine_JMAG(app.dataSet);
        end

        % Value changed function: SlotlinerthicknessmmEditField
        function SlotlinerthicknessmmEditFieldValueChanged(app, event)
            value = eval(app.SlotlinerthicknessmmEditField.Value);
            if value<0
                value=0;
            elseif value>1
                value=1;
            end
            app.dataSet.LinerThickness = value;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
        end

        % Value changed function: StructuralPDEDropDown
        function StructuralPDEDropDownValueChanged(app, event)
            value = app.StructuralPDEDropDown.Value;
            app.dataSet.MeshStructuralPDE = value;
            GUI_APP_SetParameters(app);
        end

        % Button pushed function: COMSOLButton_2
        function COMSOLButton_2Pushed(app, event)
            dataSet = app.dataSet;

            switch dataSet.EvalType
                case {'singt','singtIron'}
                    % error('Function under development!')
                    DrawAndSaveMachineCOMSOL(dataSet)
                    eval_operatingPointCOMSOL(dataSet)
                case {'singm','singmIron'}
                    error('Function under development!')
                case 'structural'
                      if strcmp(dataSet.TypeOfRotor,'EESM')
                          eval_vonMisesStress_COMSOL_EESM(dataSet)
                      else
                        % error('Function under development!')
                        eval_vonMisesStress_COMSOL(dataSet)
                      end
                otherwise
                    error('Function not supported!')
            end
        end

        % Button pushed function: COMSOLButton
        function COMSOLButtonPushed(app, event)
            app.dataSet = DrawAndSaveMachineCOMSOL(app.dataSet);
            % error('Function under development!')
        end

        % Value changed function: StackingfactorEditField
        function StackingfactorEditFieldValueChanged(app, event)
            value = app.StackingfactorEditField.Value;
            app.dataSet.LaminationStackingFactor = eval(value);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: FieldcurrentAEditField
        function FieldCurrent(app, event)
            value = app.FieldcurrentAEditField.Value;
            app.dataSet.FieldCurrent = eval(value);
            app.dataSet.FieldCurrLoPP = eval(value)/app.dataSet.RatedFieldCurrent;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: YokewidthmmEditField
        function YokeWidth(app, event)
            value = app.YokewidthmmEditField.Value;
            app.dataSet.YokeWidth = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);            
        end

        % Value changed function: PolebodyheightmmEditField
        function PoleBodyHeight(app, event)
            value = app.PolebodyheightmmEditField.Value;
            app.dataSet.PoleBodyHeight = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);        
        end

        % Value changed function: PoleheadheightmmEditField
        function PoleHeadHeight(app, event)
            value = app.PoleheadheightmmEditField.Value;
            app.dataSet.PoleHeadHeight = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);  
        end

        % Value changed function: PoleAnglepuEditField
        function PoleAnglepu(app, event)
            value = app.PoleAnglepuEditField.Value;
            app.dataSet.ALPHApu = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: PolewidthmmEditField
        function PoleWidth(app, event)
            value = app.PolewidthmmEditField.Value;
            app.dataSet.PoleWidth = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CoilwidthmmEditField
        function CoilWidth(app, event)
            value = app.CoilwidthmmEditField.Value;
            app.dataSet.CoilWidth = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CoilheightmmEditField
        function CoilHeight(app, event)
            value = app.CoilheightmmEditField.Value;
            app.dataSet.CoilHeight = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: PoleHeadFilletmmEditField
        function PoleRotHeadFillet(app, event)
            value = app.PoleHeadFilletmmEditField.Value;
            app.dataSet.PoleRotHeadFillet = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: PoleHeadAngledegEditField
        function PoleRotHeadAngle(app, event)
            value = app.PoleHeadAngledegEditField.Value;
            app.dataSet.PoleRotHeadAngle = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: NumberofturnsperpoleEditField
        function NumberofturnsperpoleEditFieldValueChanged(app, event)
            value = app.NumberofturnsperpoleEditField.Value;
            app.dataSet.FieldTurns = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CoilfillingfactorpuEditField
        function CoilfillingfactorpuEditFieldValueChanged(app, event)
            value = app.CoilfillingfactorpuEditField.Value;
            value = eval(value);
            if value<0.1
                value=0.1;
            elseif value>1
                value=1;
            end
            app.dataSet.RotorConductorFillingFactor = value;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: FieldcurrentdensityArmsmm2EditField
        function FieldcurrentdensityArmsmm2EditFieldValueChanged(app, event)
            value = app.FieldcurrentdensityArmsmm2EditField.Value;
            app.dataSet.RotorCurrentDensity = eval(value);
            app.dataSet.RatedFieldCurrent = NaN;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: RatedfieldcurrentAEditField
        function RatedfieldcurrentAEditFieldValueChanged(app, event)
            value = app.RatedfieldcurrentAEditField.Value;
            app.dataSet.RotorCurrentDensity = NaN;
            app.dataSet.RatedFieldCurrent = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: YokeWidthBou
        function YokeWidthBouValueChanged(app, event)
            value = app.YokeWidthBou.Value;
            app.dataSet.YokeWidthBou = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: YokeWidthBouCheck
        function YokeWidthBouCheckValueChanged(app, event)
            value = app.YokeWidthBouCheck.Value;
            app.dataSet.YokeWidthBouCheck = value;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: PoleBodyHeightBou
        function PoleBodyHeightBouValueChanged(app, event)
            value = app.PoleBodyHeightBou.Value;
            app.dataSet.PoleBodyHeightBou = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: PoleBodyHeightBouCheck
        function PoleBodyHeightBouCheckValueChanged(app, event)
            value = app.PoleBodyHeightBouCheck.Value;
            app.dataSet.PoleBodyHeightBouCheck = value;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app)
        end

        % Value changed function: PoleAnglepuBou
        function PoleAnglepuBouValueChanged(app, event)
            value = app.PoleAnglepuBou.Value;
            app.dataSet.PoleAnglepuBou = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: PoleAnglepuBouCheck
        function PoleAnglepuBouCheckValueChanged(app, event)
            value = app.PoleAnglepuBouCheck.Value;
            app.dataSet.PoleAnglepuBouCheck = value;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: PoleWidthBou
        function PoleWidthBouValueChanged(app, event)
            value = app.PoleWidthBou.Value;
            app.dataSet.PoleWidthBou = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: PoleWidthBouCheck
        function PoleWidthBouCheckValueChanged(app, event)
            value = app.PoleWidthBouCheck.Value;
            app.dataSet.PoleWidthBouCheck = value;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CoilWidthBou
        function CoilWidthBouValueChanged(app, event)
            value = app.CoilWidthBou.Value;
            app.dataSet.CoilWidthBou = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CoilWidthBouCheck
        function CoilWidthBouCheckValueChanged(app, event)
            value = app.CoilWidthBouCheck.Value;
            app.dataSet.CoilWidthBouCheck = value;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CoilHeightBou
        function CoilHeightBouValueChanged(app, event)
            value = app.CoilHeightBou.Value;
            app.dataSet.CoilHeightBou = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: CoilHeightBouCheck
        function CoilHeightBouCheckValueChanged(app, event)
            value = app.CoilHeightBouCheck.Value;
            app.dataSet.CoilHeightBouCheck = value;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: PoleRotHeadFilletBou
        function PoleRotHeadFilletBouValueChanged(app, event)
            value = app.PoleRotHeadFilletBou.Value;
            app.dataSet.PoleRotHeadFilletBou = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: PoleRotHeadFilletBouCheck
        function PoleRotHeadFilletBouCheckValueChanged(app, event)
            value = app.PoleRotHeadFilletBouCheck.Value;
            app.dataSet.PoleRotHeadFilletBouCheck = value;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: PoleRotHeadAngleBou
        function PoleRotHeadAngleBouValueChanged(app, event)
            value = app.PoleRotHeadAngleBou.Value;
            app.dataSet.PoleRotHeadAngleBou = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: PoleRotHeadAngleBouCheck
        function PoleRotHeadAngleBouCheckValueChanged(app, event)
            value = app.PoleRotHeadAngleBouCheck.Value;
            app.dataSet.PoleRotHeadAngleBouCheck = value;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: FieldbystatorcurrentdensityEditField
        function FieldbystatorcurrentdensityEditFieldValueChanged(app, event)
            value = app.FieldbystatorcurrentdensityEditField.Value;
            app.dataSet.FieldStatorCurrentDensityRatio = eval(value);
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end

        % Value changed function: StatorJoulelossWButton
        function StatorJoulelossWButtonValueChanged(app, event)
            value = app.StatorJoulelossWButton.Value;
            app.dataSet.StatorJouleLossOptCheck = value;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: MaxExpPjs
        function MaxExpPjsValueChanged(app, event)
            value = app.MaxExpPjs.Value;
            value = eval(value);
            if value<0
                value = abs(value);
            end
            app.dataSet.MaxExpPjs = value;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: MaxExpPjf
        function MaxExpPjfValueChanged(app, event)
            value = app.MaxExpPjf.Value;
            value = eval(value);
            if value<0
                value = abs(value);
            end
            app.dataSet.MaxExpPjf = value;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: RotorJoulelossWButton
        function RotorJoulelossWButtonValueChanged(app, event)
            value = app.RotorJoulelossWButton.Value;
            app.dataSet.FieldJouleLossOptCheck = value;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: DemagUGOButton
        function DemagUGOButtonValueChanged(app, event)
            value = app.DemagUGOButton.Value;
            if ~strcmp(app.dataSet.FluxBarrierMaterial,'Air')
                app.dataSet.syrmDesignFlag.demagUGO = 0;
            end
            GUI_APP_SetParameters(app);
        end

        % Value changed function: MechstressButton
        function MechstressButtonValueChanged(app, event)
            value = app.MechstressButton.Value;
            app.dataSet.syrmDesignFlag.Mech = 0;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: VonMisesstressMPaButton
        function VonMisesstressMPaButtonValueChanged(app, event)
            value = app.VonMisesstressMPaButton.Value;
            app.dataSet.VonMisesstressOptCheck = value;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: MaxExpMechStress
        function MaxExpMechStressValueChanged(app, event)
            value = app.MaxExpMechStress.Value;
            value = eval(value);
            if value<0
                value = abs(value);
            end
            app.dataSet.MaxExpMechStress = value;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: OffsetcurrentAEditField
        function OffsetcurrentAEditFieldValueChanged(app, event)
            value = app.OffsetcurrentAEditField.Value;
            app.dataSet.SimulatedCurrentOffset = eval(value);
            GUI_APP_SetParameters(app);
        end

        % Button pushed function: ZenodoReadMeButton
        function ZenodoReadMeButtonPushed(app, event)
            ZenodoName = app.ZenodoDropDown.Value;
            ZenodoLibrary_readme(ZenodoName);
        end

        % Button pushed function: ZenodoOpenButton
        function ZenodoOpenButtonPushed(app, event)
            ZenodoName = app.ZenodoDropDown.Value;
            ZenodoLibrary_open(ZenodoName,1);
        end

        % Button pushed function: ZenodoSaveButton
        function ZenodoSaveButtonPushed(app, event)
            ZenodoName = app.ZenodoDropDown.Value;
            ZenodoLibrary_save(ZenodoName);
        end

        % Value changed function: ZenodoDropDown
        function ZenodoDropDownValueChanged(app, event)
            value = app.ZenodoDropDown.Value;
            description = ZenodoLibrary_description(value);
            % link = ZenodoLibrary_open(value,0);
            app.ZenodoTextArea.Value = description;
            % app.ZenodoHyperlink.Text = link;
            % app.ZenodoHyperlink.URL = link;
        end

        % Value changed function: XFEMMcreationButton
        function XFEMMcreationButtonValueChanged(app, event)
            value = app.XFEMMcreationButton.Value;
            app.dataSet.XFEMMcreation = 0;
            if value
                warning('XFEMM model creation still under development!')
            end
            GUI_APP_SetParameters(app);
        end

        % Value changed function: XFEMMsimulationButton
        function XFEMMsimulationButtonValueChanged(app, event)
            value = app.XFEMMsimulationButton.Value;
            app.dataSet.XFEMMsimulation = value;
            GUI_APP_SetParameters(app);
        end

        % Value changed function: FieldcurrentloadpuEditField
        function FieldcurrentloadpuEditFieldValueChanged(app, event)
            dataSet = app.dataSet;
            dataSet.FieldCurrLoPP = eval(app.FieldcurrentloadpuEditField.Value);
            dataSet.FieldCurrent = dataSet.FieldCurrLoPP*dataSet.RatedFieldCurrent;
            
            app.dataSet = dataSet;
            GUI_APP_SetParameters(app)
        end

        % Value changed function: PoleheadshapeDropDown
        function PoleheadshapeDropDownValueChanged(app, event)
            dataSet = app.dataSet;
            tmp = app.PoleheadshapeDropDown.Value;
            if strcmp(tmp,'Arc')
                dataSet.PoleHeadShape = 0;
            elseif strcmp(tmp,'Cos')
                dataSet.PoleHeadShape = 1;
            elseif strcmp(tmp,'3rd harmonic')
                dataSet.PoleHeadShape = 2;
            end
            app.dataSet = dataSet;
            app = GUI_APP_DrawMachine(app);
            GUI_APP_SetParameters(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'GUI_Syre';
            app.AxisGeometry = uiaxes(app.figure1);
            app.AxisLogo = uiaxes(app.figure1);
            app.AxisLogo.XTick = [];
            app.AxisLogo.YTick = [];
            app.SaveMachinePush = uibutton(app.figure1, 'push');
            app.SaveMachinePush.ButtonPushedFcn = createCallbackFcn(app, @SaveMachinePush_Callback, true);
            app.SaveMachinePush.Text = 'Save machine';
            app.text95 = uilabel(app.figure1);
            app.text95.Text = 'ver. 26.1';
            app.ClearCachePush = uibutton(app.figure1, 'push');
            app.ClearCachePush.ButtonPushedFcn = createCallbackFcn(app, @ClearCachePush_Callback, true);
            app.ClearCachePush.Text = 'Clear \tmp';
            app.currentMotFileName = uieditfield(app.figure1, 'text');
            app.currentMotFileName.Value = 'Edit Text';
            app.text120 = uilabel(app.figure1);
            app.text120.Text = 'Current mot file is:';
            app.LoadMachinePush = uibutton(app.figure1, 'push');
            app.LoadMachinePush.ButtonPushedFcn = createCallbackFcn(app, @LoadMachinePushButtonPushed, true);
            app.LoadMachinePush.Text = 'Load Machine';
            app.TabGroup = uitabgroup(app.figure1);
            app.MainDataTab = uitab(app.TabGroup);
            app.MainDataTab.Title = 'Main Data';
            app.GridLayout4 = uigridlayout(app.MainDataTab);
            app.MainMotorParametersPanel = uipanel(app.GridLayout4);
            app.MainMotorParametersPanel.Title = 'Main Motor Parameters';
            app.GridLayout12 = uigridlayout(app.MainMotorParametersPanel);
            app.NumberofpolepairsEditFieldLabel = uilabel(app.GridLayout12);
            app.NumberofpolepairsEditFieldLabel.Text = 'Number of pole pairs';
            app.PolePairsEdit = uieditfield(app.GridLayout12, 'text');
            app.PolePairsEdit.ValueChangedFcn = createCallbackFcn(app, @PolePairsEdit_Callback, true);
            app.NumberofslotspolephaseLabel = uilabel(app.GridLayout12);
            app.NumberofslotspolephaseLabel.Text = 'Number of slots/pole/phase';
            app.NumOfSlotsEdit = uieditfield(app.GridLayout12, 'text');
            app.NumOfSlotsEdit.ValueChangedFcn = createCallbackFcn(app, @NumOfSlotsEdit_Callback, true);
            app.AirgapthicknessmmEditFieldLabel = uilabel(app.GridLayout12);
            app.AirgapthicknessmmEditFieldLabel.Text = 'Airgap thickness [mm]';
            app.GapThiEdit = uieditfield(app.GridLayout12, 'text');
            app.GapThiEdit.ValueChangedFcn = createCallbackFcn(app, @GapThiEdit_Callback, true);
            app.ScaleCheck = uibutton(app.GridLayout12, 'state');
            app.ScaleCheck.ValueChangedFcn = createCallbackFcn(app, @ScaleCheckValueChanged, true);
            app.ScaleCheck.Text = 'Scale';
            app.StatorouterradiusmmEditFieldLabel = uilabel(app.GridLayout12);
            app.StatorouterradiusmmEditFieldLabel.Text = 'Stator outer radius [mm]';
            app.StatorOuterRadEdit = uieditfield(app.GridLayout12, 'text');
            app.StatorOuterRadEdit.ValueChangedFcn = createCallbackFcn(app, @StatorOuterRadEdit_Callback, true);
            app.AirgapradiusmmEditFieldLabel = uilabel(app.GridLayout12);
            app.AirgapradiusmmEditFieldLabel.Text = 'Airgap radius [mm]';
            app.AirGapRadiusEdit = uieditfield(app.GridLayout12, 'text');
            app.AirGapRadiusEdit.ValueChangedFcn = createCallbackFcn(app, @AirGapRadiusEdit_Callback, true);
            app.ShaftradiusmmEditFieldLabel = uilabel(app.GridLayout12);
            app.ShaftradiusmmEditFieldLabel.Text = 'Shaft radius [mm]';
            app.ShaftRadEdit = uieditfield(app.GridLayout12, 'text');
            app.ShaftRadEdit.ValueChangedFcn = createCallbackFcn(app, @ShaftRadEdit_Callback, true);
            app.StacklengthmmEditFieldLabel = uilabel(app.GridLayout12);
            app.StacklengthmmEditFieldLabel.Text = 'Stack length [mm]';
            app.StackLenghtEdit = uieditfield(app.GridLayout12, 'text');
            app.StackLenghtEdit.ValueChangedFcn = createCallbackFcn(app, @StackLenghtEdit_Callback, true);
            app.Numberof3phasesetsEditFieldLabel_2 = uilabel(app.GridLayout12);
            app.Numberof3phasesetsEditFieldLabel_2.Text = 'Number of 3-phase sets';
            app.Numberof3phasesetsEditField = uieditfield(app.GridLayout12, 'text');
            app.Numberof3phasesetsEditField.ValueChangedFcn = createCallbackFcn(app, @Numberof3phasesetsEditFieldValueChanged, true);
            app.NumberofstatorslotsEditFieldLabel = uilabel(app.GridLayout12);
            app.NumberofstatorslotsEditFieldLabel.Text = 'Number of stator slots';
            app.NumberofstatorslotsEditField = uieditfield(app.GridLayout12, 'text');
            app.NumberofstatorslotsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofstatorslotsEditFieldValueChanged, true);
            app.TypeofrotorDropDownLabel = uilabel(app.GridLayout12);
            app.TypeofrotorDropDownLabel.Text = 'Type of rotor';
            app.TypeOfRotorList = uidropdown(app.GridLayout12);
            app.TypeOfRotorList.Items = {'Circular', 'ISeg', 'Seg', 'Fluid', 'SPM', 'SPM-Halbach', 'Vtype', 'Spoke-type', 'IM', 'EESM'};
            app.TypeOfRotorList.ValueChangedFcn = createCallbackFcn(app, @TypeOfRotorList_Callback, true);
            app.TypeOfRotorList.Value = 'Circular';
            app.CustomPanel = uipanel(app.GridLayout4);
            app.CustomPanel.Title = 'Custom Geometry';
            app.GridLayout14 = uigridlayout(app.CustomPanel);
            app.StateLamp = uilamp(app.GridLayout14);
            app.CustomLoad = uibutton(app.GridLayout14, 'push');
            app.CustomLoad.ButtonPushedFcn = createCallbackFcn(app, @CustomLoadButtonPushed, true);
            app.CustomLoad.Text = 'Import from FEMM';
            app.CustomClear = uibutton(app.GridLayout14, 'push');
            app.CustomClear.ButtonPushedFcn = createCallbackFcn(app, @CustomClearButtonPushed, true);
            app.CustomClear.Text = 'Clear';
            app.PreliminaryDesignPanel = uipanel(app.GridLayout4);
            app.PreliminaryDesignPanel.Title = 'Preliminary Design';
            app.GridLayout13 = uigridlayout(app.PreliminaryDesignPanel);
            app.syrmDesignPushButt = uibutton(app.GridLayout13, 'push');
            app.syrmDesignPushButt.ButtonPushedFcn = createCallbackFcn(app, @syrmDesignPushButt_Callback, true);
            app.syrmDesignPushButt.Text = 'Compute (x,b) Design Plane';
            app.FEAfixPushButt = uibutton(app.GridLayout13, 'push');
            app.FEAfixPushButt.ButtonPushedFcn = createCallbackFcn(app, @FEAfixPushButt_Callback, true);
            app.FEAfixPushButt.Text = 'FEAfix';
            app.RangeofxrotorstatorsplitEditFieldLabel = uilabel(app.GridLayout13);
            app.RangeofxrotorstatorsplitEditFieldLabel.Text = 'Range of x (rotor/stator split)';
            app.xRangeEdit = uieditfield(app.GridLayout13, 'text');
            app.xRangeEdit.ValueChangedFcn = createCallbackFcn(app, @xRangeEdit_Callback, true);
            app.RangeofbairgapironsplitEditFieldLabel = uilabel(app.GridLayout13);
            app.RangeofbairgapironsplitEditFieldLabel.Text = 'Range of b (airgap/iron split)';
            app.bRangeEdit = uieditfield(app.GridLayout13, 'text');
            app.bRangeEdit.ValueChangedFcn = createCallbackFcn(app, @bRangeEdit_Callback, true);
            app.IronLoadingTEditFieldLabel = uilabel(app.GridLayout13);
            app.IronLoadingTEditFieldLabel.Text = 'Iron Loading [T]';
            app.BfeEdit = uieditfield(app.GridLayout13, 'text');
            app.BfeEdit.ValueChangedFcn = createCallbackFcn(app, @BfeEdit_Callback, true);
            app.ToothsizefactorpuLabel = uilabel(app.GridLayout13);
            app.ToothsizefactorpuLabel.Text = 'Tooth size factor [p.u.]';
            app.ktEdit = uieditfield(app.GridLayout13, 'text');
            app.ktEdit.ValueChangedFcn = createCallbackFcn(app, @ktEdit_Callback, true);
            app.StatoryokefactorpuEditFieldLabel = uilabel(app.GridLayout13);
            app.StatoryokefactorpuEditFieldLabel.Text = 'Stator yoke factor [p.u.]';
            app.StatoryokefactorpuEditField = uieditfield(app.GridLayout13, 'text');
            app.StatoryokefactorpuEditField.ValueChangedFcn = createCallbackFcn(app, @StatoryokefactorpuEditFieldValueChanged, true);
            app.RotoryokefactorpuEditFieldLabel = uilabel(app.GridLayout13);
            app.RotoryokefactorpuEditFieldLabel.Text = 'Rotor yoke factor [p.u.]';
            app.RotoryokefactorpuEditField = uieditfield(app.GridLayout13, 'text');
            app.RotoryokefactorpuEditField.ValueChangedFcn = createCallbackFcn(app, @RotoryokefactorpuEditFieldValueChanged, true);
            app.ThermalLoadingkjWm2EditFieldLabel = uilabel(app.GridLayout13);
            app.ThermalLoadingkjWm2EditFieldLabel.Text = 'Thermal Loading kj [W/m^2]';
            app.ThermalLoadingsyrmDesignEditField = uieditfield(app.GridLayout13, 'text');
            app.ThermalLoadingsyrmDesignEditField.ValueChangedFcn = createCallbackFcn(app, @ThermalLoadingsyrmDesignEditFieldValueChanged, true);
            app.CurrentDensityArmsmm2EditFieldLabel = uilabel(app.GridLayout13);
            app.CurrentDensityArmsmm2EditFieldLabel.Text = 'Current Density [Arms/mm2]';
            app.CurrentDensityArmsmm2EditField = uieditfield(app.GridLayout13, 'text');
            app.CurrentDensityArmsmm2EditField.ValueChangedFcn = createCallbackFcn(app, @CurrentDensityArmsmm2EditFieldValueChanged, true);
            app.PMfactorpuLabel = uilabel(app.GridLayout13);
            app.PMfactorpuLabel.Text = 'PM factor [p.u.]';
            app.PMfillingfactorpuEditField = uieditfield(app.GridLayout13, 'text');
            app.PMfillingfactorpuEditField.ValueChangedFcn = createCallbackFcn(app, @PMfillingfactorpuEditFieldValueChanged, true);
            app.CharcurrentButton = uibutton(app.GridLayout13, 'state');
            app.CharcurrentButton.ValueChangedFcn = createCallbackFcn(app, @CharcurrentButtonValueChanged, true);
            app.CharcurrentButton.Text = 'Char. current';
            app.HWCSCcurrentButton = uibutton(app.GridLayout13, 'state');
            app.HWCSCcurrentButton.ValueChangedFcn = createCallbackFcn(app, @HWCSCcurrentButtonValueChanged, true);
            app.HWCSCcurrentButton.Text = 'HWC-SC current';
            app.DemagratedButton = uibutton(app.GridLayout13, 'state');
            app.DemagratedButton.ValueChangedFcn = createCallbackFcn(app, @DemagratedButtonValueChanged, true);
            app.DemagratedButton.Text = 'Demag @ rated';
            app.FEAfixcorrectionsandcomputationsLabel = uilabel(app.GridLayout13);
            app.FEAfixcorrectionsandcomputationsLabel.Text = 'FEAfix corrections and computations';
            app.MTPApointButton = uibutton(app.GridLayout13, 'state');
            app.MTPApointButton.ValueChangedFcn = createCallbackFcn(app, @MTPApointButtonValueChanged, true);
            app.MTPApointButton.Text = 'MTPA point';
            app.DemagHWCButton = uibutton(app.GridLayout13, 'state');
            app.DemagHWCButton.ValueChangedFcn = createCallbackFcn(app, @DemagHWCButtonValueChanged, true);
            app.DemagHWCButton.Text = 'Demag @ HWC';
            app.ofFEAfixsimulationsDropDownLabel = uilabel(app.GridLayout13);
            app.ofFEAfixsimulationsDropDownLabel.Text = '# of FEAfix simulations';
            app.FEAfixPopUp = uidropdown(app.GridLayout13);
            app.FEAfixPopUp.Items = {'1', '4', '5', '8', '12', '16', '1000'};
            app.FEAfixPopUp.ValueChangedFcn = createCallbackFcn(app, @FEAfixPopUp_Callback, true);
            app.FEAfixPopUp.Value = '1';
            app.BarrierDesignDropDow = uidropdown(app.GridLayout13);
            app.BarrierDesignDropDow.Items = {'Constant flux barrier thickness', 'Constant flux barrier permeance', 'Lfq minimization'};
            app.BarrierDesignDropDow.ValueChangedFcn = createCallbackFcn(app, @BarrierDesignDropDowValueChanged, true);
            app.BarrierDesignDropDow.Value = 'Constant flux barrier permeance';
            app.CarrierDesignDropDown = uidropdown(app.GridLayout13);
            app.CarrierDesignDropDown.Items = {'No flux carrier design', 'Constant flux carrier thickness', 'Flux carrier thickness proportional to d-flux'};
            app.CarrierDesignDropDown.ValueChangedFcn = createCallbackFcn(app, @CarrierDesignDropDownValueChanged, true);
            app.CarrierDesignDropDown.Value = 'Constant flux carrier thickness';
            app.syrmDesignThermalInputDropDown = uidropdown(app.GridLayout13);
            app.syrmDesignThermalInputDropDown.Items = {'Constant thermal loading kj', 'Constant current density J', 'Constant turns-current NI'};
            app.syrmDesignThermalInputDropDown.ValueChangedFcn = createCallbackFcn(app, @syrmDesignThermalInputDropDownValueChanged, true);
            app.syrmDesignThermalInputDropDown.Value = 'Constant thermal loading kj';
            app.DemagUGOButton = uibutton(app.GridLayout13, 'state');
            app.DemagUGOButton.ValueChangedFcn = createCallbackFcn(app, @DemagUGOButtonValueChanged, true);
            app.DemagUGOButton.Text = 'Demag @ UGO';
            app.MechstressButton = uibutton(app.GridLayout13, 'state');
            app.MechstressButton.ValueChangedFcn = createCallbackFcn(app, @MechstressButtonValueChanged, true);
            app.MechstressButton.Text = 'Mech. stress';
            app.StatorTab_2 = uitab(app.TabGroup);
            app.StatorTab_2.Title = 'Stator';
            app.GridLayout5_3 = uigridlayout(app.StatorTab_2);
            app.StatorParametersPanel_3 = uipanel(app.GridLayout5_3);
            app.StatorParametersPanel_3.Title = 'Stator Parameters';
            app.GridLayout15_4 = uigridlayout(app.StatorParametersPanel_3);
            app.ToothlengthmmEditFieldLabel = uilabel(app.GridLayout15_4);
            app.ToothlengthmmEditFieldLabel.Text = 'Tooth length [mm]';
            app.ToothLengEdit = uieditfield(app.GridLayout15_4, 'text');
            app.ToothLengEdit.ValueChangedFcn = createCallbackFcn(app, @ToothLengEdit_Callback, true);
            app.ToothwidthmmEditFieldLabel = uilabel(app.GridLayout15_4);
            app.ToothwidthmmEditFieldLabel.Text = 'Tooth width [mm]';
            app.ToothWidthEdit = uieditfield(app.GridLayout15_4, 'text');
            app.ToothWidthEdit.ValueChangedFcn = createCallbackFcn(app, @ToothWidthEdit_Callback, true);
            app.SlotwidthmmLabel = uilabel(app.GridLayout15_4);
            app.SlotwidthmmLabel.Text = 'Slot width [mm]';
            app.SlotWidthEdit = uieditfield(app.GridLayout15_4, 'text');
            app.SlotWidthEdit.ValueChangedFcn = createCallbackFcn(app, @SlotWidthEditValueChanged, true);
            app.SlotshapeDropDownLabel = uilabel(app.GridLayout15_4);
            app.SlotshapeDropDownLabel.Text = 'Slot shape';
            app.SlotshapeDropDown = uidropdown(app.GridLayout15_4);
            app.SlotshapeDropDown.Items = {'Trapezoidal', 'Rectangular'};
            app.SlotshapeDropDown.ValueChangedFcn = createCallbackFcn(app, @SlotshapeDropDownValueChanged, true);
            app.SlotshapeDropDown.Value = 'Trapezoidal';
            app.StatorslotopeningpuEditFieldLabel = uilabel(app.GridLayout15_4);
            app.StatorslotopeningpuEditFieldLabel.Text = 'Stator slot opening [p.u.]';
            app.StatorSlotOpeEdit = uieditfield(app.GridLayout15_4, 'text');
            app.StatorSlotOpeEdit.ValueChangedFcn = createCallbackFcn(app, @StatorSlotOpeEdit_Callback, true);
            app.ToothtangdepthmmEditFieldLabel = uilabel(app.GridLayout15_4);
            app.ToothtangdepthmmEditFieldLabel.Text = 'Tooth tang. depth [mm]';
            app.ToothTanDepEdit = uieditfield(app.GridLayout15_4, 'text');
            app.ToothTanDepEdit.ValueChangedFcn = createCallbackFcn(app, @ToothTanDepEdit_Callback, true);
            app.ToothtangangleEditFieldLabel = uilabel(app.GridLayout15_4);
            app.ToothtangangleEditFieldLabel.Text = 'Tooth tang. angle [°]';
            app.ToothTangAngleEdit = uieditfield(app.GridLayout15_4, 'text');
            app.ToothTangAngleEdit.ValueChangedFcn = createCallbackFcn(app, @ToothTangAngleEdit_Callback, true);
            app.FilletatslotbottommmEditFieldLabel = uilabel(app.GridLayout15_4);
            app.FilletatslotbottommmEditFieldLabel.Text = 'Fillet at slot bottom [mm]';
            app.FillCorSlotEdit = uieditfield(app.GridLayout15_4, 'text');
            app.FillCorSlotEdit.ValueChangedFcn = createCallbackFcn(app, @FillCorSlotEdit_Callback, true);
            app.YokelengthmmEditFieldLabel = uilabel(app.GridLayout15_4);
            app.YokelengthmmEditFieldLabel.Text = 'Yoke length [mm]';
            app.YokeLengthEditField = uieditfield(app.GridLayout15_4, 'text');
            app.MainWindingDataPanel_5 = uipanel(app.GridLayout5_3);
            app.MainWindingDataPanel_5.Title = 'Main Winding Data';
            app.GridLayout15_5 = uigridlayout(app.MainWindingDataPanel_5);
            app.SlotfillingfactorEditFieldLabel = uilabel(app.GridLayout15_5);
            app.SlotfillingfactorEditFieldLabel.Text = 'Slot filling factor';
            app.SlotFillFacEdit = uieditfield(app.GridLayout15_5, 'text');
            app.SlotFillFacEdit.ValueChangedFcn = createCallbackFcn(app, @SlotFillFacEdit_Callback, true);
            app.TurnsinseriesperphaseEditFieldLabel = uilabel(app.GridLayout15_5);
            app.TurnsinseriesperphaseEditFieldLabel.Text = 'Turns in series per phase';
            app.TurnsSeriesEdit = uieditfield(app.GridLayout15_5, 'text');
            app.TurnsSeriesEdit.ValueChangedFcn = createCallbackFcn(app, @TurnsSeriesEdit_Callback, true);
            app.PitchshorteningfactorEditFieldLabel = uilabel(app.GridLayout15_5);
            app.PitchshorteningfactorEditFieldLabel.Text = 'Pitch shortening factor';
            app.PitchWindEdit = uieditfield(app.GridLayout15_5, 'text');
            app.PitchWindEdit.ValueChangedFcn = createCallbackFcn(app, @PitchWindEdit_Callback, true);
            app.SlotlinerthicknessmmEditFieldLabel = uilabel(app.GridLayout15_5);
            app.SlotlinerthicknessmmEditFieldLabel.Text = 'Slot liner thickness [mm]';
            app.SlotlinerthicknessmmEditField = uieditfield(app.GridLayout15_5, 'text');
            app.SlotlinerthicknessmmEditField.ValueChangedFcn = createCallbackFcn(app, @SlotlinerthicknessmmEditFieldValueChanged, true);
            app.NumberofsimulatedslotsEditFieldLabel = uilabel(app.GridLayout15_5);
            app.NumberofsimulatedslotsEditFieldLabel.Text = 'Number of simulated slots';
            app.SlotSimulEdit = uieditfield(app.GridLayout15_5, 'text');
            app.SlotSimulEdit.ValueChangedFcn = createCallbackFcn(app, @SlotSimulEdit_Callback, true);
            app.SlotlayerpositionDropDownLabel = uilabel(app.GridLayout15_5);
            app.SlotlayerpositionDropDownLabel.Text = 'Slot layer position';
            app.SlotlayerposDropDown = uidropdown(app.GridLayout15_5);
            app.SlotlayerposDropDown.Items = {'Stacked', 'Side-by-side'};
            app.SlotlayerposDropDown.ValueChangedFcn = createCallbackFcn(app, @SlotlayerposDropDownValueChanged, true);
            app.SlotlayerposDropDown.Value = 'Stacked';
            app.WinTable = uitable(app.GridLayout15_5);
            app.WinTable.ColumnName = {'1'; '2'};
            app.WinTable.RowName = {};
            app.WinTable.ColumnEditable = true;
            app.SaveConfPush = uibutton(app.GridLayout15_5, 'push');
            app.SaveConfPush.ButtonPushedFcn = createCallbackFcn(app, @SaveConfPush_Callback, true);
            app.SaveConfPush.Text = 'Save Configuration';
            app.SetDefaultWinding = uibutton(app.GridLayout15_5, 'push');
            app.SetDefaultWinding.ButtonPushedFcn = createCallbackFcn(app, @SetDefaultWinding_Callback, true);
            app.SetDefaultWinding.Text = 'Default';
            app.SlotModelPanel = uipanel(app.GridLayout5_3);
            app.SlotModelPanel.Title = 'Slot Model';
            app.GridLayout23 = uigridlayout(app.SlotModelPanel);
            app.NumberofconductorsEditFieldLabel = uilabel(app.GridLayout23);
            app.NumberofconductorsEditFieldLabel.Text = 'Number of conductors';
            app.ConductorNumberEdit = uieditfield(app.GridLayout23, 'text');
            app.ConductorNumberEdit.ValueChangedFcn = createCallbackFcn(app, @ConductorNumberEdit_Callback, true);
            app.NumberofparallelsLabel = uilabel(app.GridLayout23);
            app.NumberofparallelsLabel.Text = 'Number of parallels';
            app.ParallelNumberEdit = uieditfield(app.GridLayout23, 'text');
            app.ParallelNumberEdit.ValueChangedFcn = createCallbackFcn(app, @ParallelNumberEditValueChanged, true);
            app.InsulationthicknessmmEditFieldLabel = uilabel(app.GridLayout23);
            app.InsulationthicknessmmEditFieldLabel.Text = 'Insulation thickness [mm]';
            app.ConductorInsulationEdit = uieditfield(app.GridLayout23, 'text');
            app.ConductorInsulationEdit.ValueChangedFcn = createCallbackFcn(app, @ConductorInsulationEdit_Callback, true);
            app.ConductorwidthmmEditFieldLabel = uilabel(app.GridLayout23);
            app.ConductorwidthmmEditFieldLabel.Text = 'Conductor width [mm]';
            app.ConductorWidthEdit = uieditfield(app.GridLayout23, 'text');
            app.ConductorWidthEdit.ValueChangedFcn = createCallbackFcn(app, @ConductorWidthEdit_Callback, true);
            app.ConductorheightmmEditFieldLabel = uilabel(app.GridLayout23);
            app.ConductorheightmmEditFieldLabel.Text = 'Conductor height [mm]';
            app.ConductorHeightEdit = uieditfield(app.GridLayout23, 'text');
            app.ConductorHeightEdit.ValueChangedFcn = createCallbackFcn(app, @ConductorHeightEdit_Callback, true);
            app.ConductorradiusmmEditFieldLabel = uilabel(app.GridLayout23);
            app.ConductorradiusmmEditFieldLabel.Text = 'Conductor radius [mm]';
            app.ConductorRadiusEdit = uieditfield(app.GridLayout23, 'text');
            app.ConductorRadiusEdit.ValueChangedFcn = createCallbackFcn(app, @ConductorRadiusEdit_Callback, true);
            app.ConductorslotgapmmLabel = uilabel(app.GridLayout23);
            app.ConductorslotgapmmLabel.Text = 'Conductor-slot gap [mm]';
            app.ConductorSlotGapEdit = uieditfield(app.GridLayout23, 'text');
            app.ConductorSlotGapEdit.ValueChangedFcn = createCallbackFcn(app, @ConductorSlotGapEditValueChanged, true);
            app.FrequencyvectorHzEditFieldLabel = uilabel(app.GridLayout23);
            app.FrequencyvectorHzEditFieldLabel.Text = 'Frequency vector [Hz]';
            app.SlotModelFrequencyEdit = uieditfield(app.GridLayout23, 'text');
            app.SlotModelFrequencyEdit.ValueChangedFcn = createCallbackFcn(app, @SlotModelFrequencyEdit_Callback, true);
            app.TemperaturevectorCLabel = uilabel(app.GridLayout23);
            app.TemperaturevectorCLabel.Text = 'Temperature vector [°C]';
            app.SlotModelTemperatureEdit = uieditfield(app.GridLayout23, 'text');
            app.SlotModelTemperatureEdit.ValueChangedFcn = createCallbackFcn(app, @SlotModelTemperatureEditValueChanged, true);
            app.DrawSlotModelPush = uibutton(app.GridLayout23, 'push');
            app.DrawSlotModelPush.ButtonPushedFcn = createCallbackFcn(app, @DrawSlotModelPush_Callback, true);
            app.DrawSlotModelPush.Text = 'Draw slot model';
            app.EvalSlotModelPush = uibutton(app.GridLayout23, 'push');
            app.EvalSlotModelPush.ButtonPushedFcn = createCallbackFcn(app, @EvalSlotModelPush_Callback, true);
            app.EvalSlotModelPush.Text = 'Evaluate slot model';
            app.ConductortypeDropDownLabel = uilabel(app.GridLayout23);
            app.ConductortypeDropDownLabel.Text = 'Conductor type';
            app.ConductorTypeEdit = uidropdown(app.GridLayout23);
            app.ConductorTypeEdit.Items = {'Round', 'Square'};
            app.ConductorTypeEdit.ValueChangedFcn = createCallbackFcn(app, @ConductorTypeEdit_Callback, true);
            app.ConductorTypeEdit.Value = 'Round';
            app.RotorTab_2 = uitab(app.TabGroup);
            app.RotorTab_2.Title = 'Rotor';
            app.GridLayout5 = uigridlayout(app.RotorTab_2);
            app.PMSMSyRParametersPanel = uipanel(app.GridLayout5);
            app.PMSMSyRParametersPanel.Title = 'PMSM - SyR Parameters';
            app.GridLayout16 = uigridlayout(app.PMSMSyRParametersPanel);
            app.NumberofrotorbarriersEditFieldLabel = uilabel(app.GridLayout16);
            app.NumberofrotorbarriersEditFieldLabel.Text = 'Number of rotor barriers';
            app.NumberOfLayersEdit = uieditfield(app.GridLayout16, 'text');
            app.NumberOfLayersEdit.ValueChangedFcn = createCallbackFcn(app, @NumberOfLayersEdit_Callback, true);
            app.BarriersanglesalphapuEditFieldLabel = uilabel(app.GridLayout16);
            app.BarriersanglesalphapuEditFieldLabel.Text = 'Barriers angles alpha [p.u.]';
            app.AlphapuEdit = uieditfield(app.GridLayout16, 'text');
            app.AlphapuEdit.ValueChangedFcn = createCallbackFcn(app, @AlphapuEdit_Callback, true);
            app.BarriersanglesalphaEditFieldLabel = uilabel(app.GridLayout16);
            app.BarriersanglesalphaEditFieldLabel.Text = 'Barriers angles alpha [°]';
            app.AlphadegreeEdit = uieditfield(app.GridLayout16, 'text');
            app.AlphadegreeEdit.ValueChangedFcn = createCallbackFcn(app, @AlphadegreeEdit_Callback, true);
            app.BarrierswidthpuLabel = uilabel(app.GridLayout16);
            app.BarrierswidthpuLabel.Text = 'Barriers width [p.u.]';
            app.hcpuEdit = uieditfield(app.GridLayout16, 'text');
            app.hcpuEdit.ValueChangedFcn = createCallbackFcn(app, @hcpuEdit_Callback, true);
            app.BarrierswidthmmLabel = uilabel(app.GridLayout16);
            app.BarrierswidthmmLabel.Text = 'Barriers width [mm]';
            app.hcmmEdit = uieditfield(app.GridLayout16, 'text');
            app.hcmmEdit.ValueChangedFcn = createCallbackFcn(app, @hcmmEdit_Callback, true);
            app.BarriersoffsetpuLabel = uilabel(app.GridLayout16);
            app.BarriersoffsetpuLabel.Text = 'Barriers offset [p.u.]';
            app.DxEdit = uieditfield(app.GridLayout16, 'text');
            app.DxEdit.ValueChangedFcn = createCallbackFcn(app, @DxEdit_Callback, true);
            app.CentralbarriersshrinkpuLabel = uilabel(app.GridLayout16);
            app.CentralbarriersshrinkpuLabel.Text = 'Central barriers shrink [p.u.]';
            app.CentralBarriersShrinkEdit = uieditfield(app.GridLayout16, 'text');
            app.CentralBarriersShrinkEdit.ValueChangedFcn = createCallbackFcn(app, @CentralBarriersShrinkEditValueChanged, true);
            app.InnerbranchradialshiftmmLabel = uilabel(app.GridLayout16);
            app.InnerbranchradialshiftmmLabel.Text = 'Inner branch radial shift [mm]';
            app.RadShiftInnerEdit = uieditfield(app.GridLayout16, 'text');
            app.RadShiftInnerEdit.ValueChangedFcn = createCallbackFcn(app, @RadShiftInnerEditValueChanged, true);
            app.OuterbranchnarrowingfactorpuLabel = uilabel(app.GridLayout16);
            app.OuterbranchnarrowingfactorpuLabel.Text = 'Outer branch narrowing factor [p.u.]';
            app.NarrowFactorEdit = uieditfield(app.GridLayout16, 'text');
            app.NarrowFactorEdit.ValueChangedFcn = createCallbackFcn(app, @NarrowFactorEditValueChanged, true);
            app.PMshapefactorbetapuEditFieldLabel = uilabel(app.GridLayout16);
            app.PMshapefactorbetapuEditFieldLabel.Text = 'PM shape factor beta [p.u.]';
            app.BetaEdit = uieditfield(app.GridLayout16, 'text');
            app.BetaEdit.ValueChangedFcn = createCallbackFcn(app, @BetaEdit_Callback, true);
            app.ThetaFBSmechEditFieldLabel = uilabel(app.GridLayout16);
            app.ThetaFBSmechEditFieldLabel.Text = 'Theta FBS [mech °]';
            app.ThetaFBSEdit = uieditfield(app.GridLayout16, 'text');
            app.ThetaFBSEdit.ValueChangedFcn = createCallbackFcn(app, @ThetaFBSEdit_Callback, true);
            app.EESMRotorParametersPanel = uipanel(app.GridLayout5);
            app.EESMRotorParametersPanel.Title = 'EESM Rotor Parameters';
            app.GridLayout22_2 = uigridlayout(app.EESMRotorParametersPanel);
            app.PolebodyheightmmEditFieldLabel = uilabel(app.GridLayout22_2);
            app.PolebodyheightmmEditFieldLabel.Text = 'Pole body height [mm]';
            app.PolebodyheightmmEditField = uieditfield(app.GridLayout22_2, 'text');
            app.PolebodyheightmmEditField.ValueChangedFcn = createCallbackFcn(app, @PoleBodyHeight, true);
            app.YokewidthmmEditFieldLabel = uilabel(app.GridLayout22_2);
            app.YokewidthmmEditFieldLabel.Text = 'Yoke width [mm]';
            app.YokewidthmmEditField = uieditfield(app.GridLayout22_2, 'text');
            app.YokewidthmmEditField.ValueChangedFcn = createCallbackFcn(app, @YokeWidth, true);
            app.PoleheadheightmmEditFieldLabel = uilabel(app.GridLayout22_2);
            app.PoleheadheightmmEditFieldLabel.Text = 'Pole head height [mm]';
            app.PoleheadheightmmEditField = uieditfield(app.GridLayout22_2, 'text');
            app.PoleheadheightmmEditField.ValueChangedFcn = createCallbackFcn(app, @PoleHeadHeight, true);
            app.PoleAnglepuEditFieldLabel = uilabel(app.GridLayout22_2);
            app.PoleAnglepuEditFieldLabel.Text = 'Pole Angle [p.u.]';
            app.PoleAnglepuEditField = uieditfield(app.GridLayout22_2, 'text');
            app.PoleAnglepuEditField.ValueChangedFcn = createCallbackFcn(app, @PoleAnglepu, true);
            app.PolewidthmmEditFieldLabel = uilabel(app.GridLayout22_2);
            app.PolewidthmmEditFieldLabel.Text = 'Pole width [mm]';
            app.PolewidthmmEditField = uieditfield(app.GridLayout22_2, 'text');
            app.PolewidthmmEditField.ValueChangedFcn = createCallbackFcn(app, @PoleWidth, true);
            app.CoilwidthmmEditFieldLabel = uilabel(app.GridLayout22_2);
            app.CoilwidthmmEditFieldLabel.Text = 'Coil width [mm]';
            app.CoilwidthmmEditField = uieditfield(app.GridLayout22_2, 'text');
            app.CoilwidthmmEditField.ValueChangedFcn = createCallbackFcn(app, @CoilWidth, true);
            app.CoilheightmmEditFieldLabel = uilabel(app.GridLayout22_2);
            app.CoilheightmmEditFieldLabel.Text = 'Coil height [mm]';
            app.CoilheightmmEditField = uieditfield(app.GridLayout22_2, 'text');
            app.CoilheightmmEditField.ValueChangedFcn = createCallbackFcn(app, @CoilHeight, true);
            app.PoleHeadFilletmmEditFieldLabel = uilabel(app.GridLayout22_2);
            app.PoleHeadFilletmmEditFieldLabel.Text = 'Pole Head Fillet [mm]';
            app.PoleHeadFilletmmEditField = uieditfield(app.GridLayout22_2, 'text');
            app.PoleHeadFilletmmEditField.ValueChangedFcn = createCallbackFcn(app, @PoleRotHeadFillet, true);
            app.PoleHeadAngledegEditFieldLabel = uilabel(app.GridLayout22_2);
            app.PoleHeadAngledegEditFieldLabel.Text = 'Pole Head Angle [deg]';
            app.PoleHeadAngledegEditField = uieditfield(app.GridLayout22_2, 'text');
            app.PoleHeadAngledegEditField.ValueChangedFcn = createCallbackFcn(app, @PoleRotHeadAngle, true);
            app.NumberofturnsperpoleEditFieldLabel = uilabel(app.GridLayout22_2);
            app.NumberofturnsperpoleEditFieldLabel.Text = 'Number of turns per pole';
            app.NumberofturnsperpoleEditField = uieditfield(app.GridLayout22_2, 'text');
            app.NumberofturnsperpoleEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofturnsperpoleEditFieldValueChanged, true);
            app.CoilfillingfactorpuEditFieldLabel = uilabel(app.GridLayout22_2);
            app.CoilfillingfactorpuEditFieldLabel.Text = 'Coil filling factor [p.u.]';
            app.CoilfillingfactorpuEditField = uieditfield(app.GridLayout22_2, 'text');
            app.CoilfillingfactorpuEditField.ValueChangedFcn = createCallbackFcn(app, @CoilfillingfactorpuEditFieldValueChanged, true);
            app.PoleheadshapeDropDownLabel = uilabel(app.GridLayout22_2);
            app.PoleheadshapeDropDownLabel.Text = 'Pole head shape';
            app.PoleheadshapeDropDown = uidropdown(app.GridLayout22_2);
            app.PoleheadshapeDropDown.Items = {'Arc', 'Cos', '3rd harmonic'};
            app.PoleheadshapeDropDown.ValueChangedFcn = createCallbackFcn(app, @PoleheadshapeDropDownValueChanged, true);
            app.PoleheadshapeDropDown.Value = 'Cos';
            app.IMRotorParametersPanel = uipanel(app.GridLayout5);
            app.IMRotorParametersPanel.Title = 'IM Rotor Parameters';
            app.GridLayout17 = uigridlayout(app.IMRotorParametersPanel);
            app.NumberofrotorbarsEditFieldLabel = uilabel(app.GridLayout17);
            app.NumberofrotorbarsEditFieldLabel.Text = 'Number of rotor bars';
            app.NumberofrotorbarsEditField = uieditfield(app.GridLayout17, 'text');
            app.NumberofrotorbarsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofrotorbarsEditFieldValueChanged, true);
            app.RotortoothlengthmmEditFieldLabel = uilabel(app.GridLayout17);
            app.RotortoothlengthmmEditFieldLabel.Text = 'Rotor tooth length [mm]';
            app.RotortoothlengthmmEditField = uieditfield(app.GridLayout17, 'text');
            app.RotortoothlengthmmEditField.ValueChangedFcn = createCallbackFcn(app, @RotortoothlengthmmEditFieldValueChanged, true);
            app.RotortoothwidthmmEditFieldLabel = uilabel(app.GridLayout17);
            app.RotortoothwidthmmEditFieldLabel.Text = 'Rotor tooth width [mm]';
            app.RotortoothwidthmmEditField = uieditfield(app.GridLayout17, 'text');
            app.RotortoothwidthmmEditField.ValueChangedFcn = createCallbackFcn(app, @RotortoothwidthmmEditFieldValueChanged, true);
            app.RotorslotopenEditFieldLabel = uilabel(app.GridLayout17);
            app.RotorslotopenEditFieldLabel.Text = 'Rotor slot open';
            app.RotorslotopenEditField = uieditfield(app.GridLayout17, 'text');
            app.RotorslotopenEditField.ValueChangedFcn = createCallbackFcn(app, @RotorslotopenEditFieldValueChanged, true);
            app.RotortoothtangmmEditFieldLabel = uilabel(app.GridLayout17);
            app.RotortoothtangmmEditFieldLabel.Text = 'Rotor tooth tang. [mm]';
            app.RotortoothtangmmEditField = uieditfield(app.GridLayout17, 'text');
            app.RotortoothtangmmEditField.ValueChangedFcn = createCallbackFcn(app, @RotortoothtangmmEditFieldValueChanged, true);
            app.FilletatrotorslottopmmEditFieldLabel = uilabel(app.GridLayout17);
            app.FilletatrotorslottopmmEditFieldLabel.Text = 'Fillet at rotor slot top [mm]';
            app.FilletatrotorslottopmmEditField = uieditfield(app.GridLayout17, 'text');
            app.FilletatrotorslottopmmEditField.ValueChangedFcn = createCallbackFcn(app, @FilletatrotorslottopmmEditFieldValueChanged, true);
            app.FilletatrotorslotbottommmEditFieldLabel = uilabel(app.GridLayout17);
            app.FilletatrotorslotbottommmEditFieldLabel.Text = 'Fillet at rotor slot bottom [mm]';
            app.FilletatrotorslotbottommmEditField = uieditfield(app.GridLayout17, 'text');
            app.FilletatrotorslotbottommmEditField.ValueChangedFcn = createCallbackFcn(app, @FilletatrotorslotbottommmEditFieldValueChanged, true);
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.GridLayout6 = uigridlayout(app.OptionsTab);
            app.ThermalParametersPanel = uipanel(app.GridLayout6);
            app.ThermalParametersPanel.Title = 'Thermal Parameters';
            app.GridLayout18 = uigridlayout(app.ThermalParametersPanel);
            app.ThermalloadingkjWm2Label = uilabel(app.GridLayout18);
            app.ThermalloadingkjWm2Label.Text = 'Thermal loading kj [W/m2]';
            app.ThermalLoadKj = uieditfield(app.GridLayout18, 'text');
            app.ThermalLoadKj.ValueChangedFcn = createCallbackFcn(app, @ThermalLoadKj_Callback, true);
            app.TargetcoppertemperatureCLabel = uilabel(app.GridLayout18);
            app.TargetcoppertemperatureCLabel.Text = 'Target copper temperature [°C]';
            app.CopperTempEdit = uieditfield(app.GridLayout18, 'text');
            app.CopperTempEdit.ValueChangedFcn = createCallbackFcn(app, @CopperTempEdit_Callback, true);
            app.HousingtemperatureCEditFieldLabel = uilabel(app.GridLayout18);
            app.HousingtemperatureCEditFieldLabel.Text = 'Housing temperature [°C]';
            app.HousingTempEdit = uieditfield(app.GridLayout18, 'text');
            app.HousingTempEdit.ValueChangedFcn = createCallbackFcn(app, @HousingTempEdit_Callback, true);
            app.CalculatedratedcurrentALabel = uilabel(app.GridLayout18);
            app.CalculatedratedcurrentALabel.Text = 'Calculated rated current [A]';
            app.CalculatedRatedCurrent = uieditfield(app.GridLayout18, 'text');
            app.AdmittedcopperlossWLabel = uilabel(app.GridLayout18);
            app.AdmittedcopperlossWLabel.Text = 'Admitted copper loss [W]';
            app.JouleLossesEdit = uieditfield(app.GridLayout18, 'text');
            app.JouleLossesEdit.ValueChangedFcn = createCallbackFcn(app, @JouleLossesEditValueChanged, true);
            app.EstimatedcoppertemperatureCLabel = uilabel(app.GridLayout18);
            app.EstimatedcoppertemperatureCLabel.Text = 'Estimated copper temperature [°C]';
            app.EstimatedCoppTemp = uieditfield(app.GridLayout18, 'text');
            app.RMScurrentdensityAmm2Label = uilabel(app.GridLayout18);
            app.RMScurrentdensityAmm2Label.Text = 'Stator current density [Arms/mm2]';
            app.CurrentdensityEdit = uieditfield(app.GridLayout18, 'text');
            app.CurrentdensityEdit.ValueChangedFcn = createCallbackFcn(app, @CurrentdensityEditValueChanged, true);
            app.PhresistancetargettemperatureOhmLabel = uilabel(app.GridLayout18);
            app.PhresistancetargettemperatureOhmLabel.Text = 'Phase resistance @target temp [Ohm]';
            app.Rsedit = uieditfield(app.GridLayout18, 'text');
            app.FieldcurrentdensityArmsmm2EditFieldLabel = uilabel(app.GridLayout18);
            app.FieldcurrentdensityArmsmm2EditFieldLabel.Text = 'Field current density [Arms/mm2]';
            app.FieldcurrentdensityArmsmm2EditField = uieditfield(app.GridLayout18, 'text');
            app.FieldcurrentdensityArmsmm2EditField.ValueChangedFcn = createCallbackFcn(app, @FieldcurrentdensityArmsmm2EditFieldValueChanged, true);
            app.RatedfieldcurrentAEditFieldLabel = uilabel(app.GridLayout18);
            app.RatedfieldcurrentAEditFieldLabel.Text = 'Rated field current [A]';
            app.RatedfieldcurrentAEditField = uieditfield(app.GridLayout18, 'text');
            app.RatedfieldcurrentAEditField.ValueChangedFcn = createCallbackFcn(app, @RatedfieldcurrentAEditFieldValueChanged, true);
            app.FieldresistancetargettempOhmEditFieldLabel = uilabel(app.GridLayout18);
            app.FieldresistancetargettempOhmEditFieldLabel.Text = 'Field resistance @ target temp [Ohm]';
            app.FieldresistancetargettempOhmEditField = uieditfield(app.GridLayout18, 'text');
            app.RibsDesignPanel = uipanel(app.GridLayout6);
            app.RibsDesignPanel.Title = 'Ribs Design';
            app.GridLayout21 = uigridlayout(app.RibsDesignPanel);
            app.TangentialribswidthmmEditFieldLabel = uilabel(app.GridLayout21);
            app.TangentialribswidthmmEditFieldLabel.Text = 'Tangential ribs width [mm]';
            app.TanRibEdit = uieditfield(app.GridLayout21, 'text');
            app.TanRibEdit.ValueChangedFcn = createCallbackFcn(app, @TanRibEdit_Callback, true);
            app.RotorfilletmmEditFieldLabel = uilabel(app.GridLayout21);
            app.RotorfilletmmEditFieldLabel.Text = 'Tangential ribs fillet in [mm]';
            app.RotorFilletTan1EditField = uieditfield(app.GridLayout21, 'text');
            app.RotorFilletTan1EditField.ValueChangedFcn = createCallbackFcn(app, @RotorFilletTan1EditFieldValueChanged, true);
            app.RadialribswidthmmEditFieldLabel = uilabel(app.GridLayout21);
            app.RadialribswidthmmEditFieldLabel.Text = 'Radial ribs width [mm]';
            app.RadRibEdit = uieditfield(app.GridLayout21, 'text');
            app.RadRibEdit.ValueChangedFcn = createCallbackFcn(app, @RadRibEdit_Callback, true);
            app.RotorfilletmmEditFieldLabel_2 = uilabel(app.GridLayout21);
            app.RotorfilletmmEditFieldLabel_2.Text = 'Radial ribs fillet in [mm]';
            app.RotorFilletRadInEditField = uieditfield(app.GridLayout21, 'text');
            app.RotorFilletRadInEditField.ValueChangedFcn = createCallbackFcn(app, @RotorFilletRadInEditFieldValueChanged, true);
            app.RotorfilletmmEditFieldLabel_4 = uilabel(app.GridLayout21);
            app.RotorfilletmmEditFieldLabel_4.Text = 'Radial ribs fillet out [mm]';
            app.RotorFilletRadOutEditField = uieditfield(app.GridLayout21, 'text');
            app.RotorFilletRadOutEditField.ValueChangedFcn = createCallbackFcn(app, @RotorFilletRadOutEditFieldValueChanged, true);
            app.RotorFilletTan2EditField = uieditfield(app.GridLayout21, 'text');
            app.RotorFilletTan2EditField.ValueChangedFcn = createCallbackFcn(app, @RotorFilletTan2EditFieldValueChanged, true);
            app.RotorfilletmmEditFieldLabel_5 = uilabel(app.GridLayout21);
            app.RotorfilletmmEditFieldLabel_5.Text = 'Tangential ribs fillet out [mm]';
            app.RotorfilletmmEditFieldLabel_ = uilabel(app.GridLayout21);
            app.RotorfilletmmEditFieldLabel_.Text = 'Split ribs';
            app.SplitRibsEditField = uieditfield(app.GridLayout21, 'text');
            app.SplitRibsEditField.ValueChangedFcn = createCallbackFcn(app, @SplitRibsEditFieldValueChanged, true);
            app.TangentialribswidthmmEditFieldLabel_2 = uilabel(app.GridLayout21);
            app.TangentialribswidthmmEditFieldLabel_2.Text = 'Radial ribs angle [deg] ';
            app.RadialRibsAngleEditField = uieditfield(app.GridLayout21, 'text');
            app.RadialRibsAngleEditField.ValueChangedFcn = createCallbackFcn(app, @RadialRibsAngleEditFieldValueChanged, true);
            app.TanRibCheck = uibutton(app.GridLayout21, 'state');
            app.TanRibCheck.ValueChangedFcn = createCallbackFcn(app, @TanRibCheckValueChanged, true);
            app.TanRibCheck.Text = 'Manual tan ribs';
            app.RadRibCheck = uibutton(app.GridLayout21, 'state');
            app.RadRibCheck.ValueChangedFcn = createCallbackFcn(app, @RadRibCheck_Callback, true);
            app.RadRibCheck.Text = 'Manual rad ribs';
            app.StructuralParametersPanel = uipanel(app.GridLayout6);
            app.StructuralParametersPanel.Title = 'Structural Parameters';
            app.GridLayout19 = uigridlayout(app.StructuralParametersPanel);
            app.OverspeedrpmLabel = uilabel(app.GridLayout19);
            app.OverspeedrpmLabel.Text = 'Overspeed [rpm]';
            app.OverSpeedEdit = uieditfield(app.GridLayout19, 'text');
            app.OverSpeedEdit.ValueChangedFcn = createCallbackFcn(app, @OverSpeedEdit_Callback, true);
            app.MinimummechtolerancemmLabel = uilabel(app.GridLayout19);
            app.MinimummechtolerancemmLabel.Text = 'Minimum mech. tolerance [mm]';
            app.MecTolerEdit = uieditfield(app.GridLayout19, 'text');
            app.MecTolerEdit.ValueChangedFcn = createCallbackFcn(app, @MecTolerEdit_Callback, true);
            app.RotorsleevethicknessmmEditFieldLabel = uilabel(app.GridLayout19);
            app.RotorsleevethicknessmmEditFieldLabel.Text = 'Rotor sleeve thickness [mm]';
            app.RotorsleevethicknessmmEditField = uieditfield(app.GridLayout19, 'text');
            app.RotorsleevethicknessmmEditField.ValueChangedFcn = createCallbackFcn(app, @RotorsleevethicknessmmEditFieldValueChanged, true);
            app.RotorsleeveinterferencemmEditFieldLabel = uilabel(app.GridLayout19);
            app.RotorsleeveinterferencemmEditFieldLabel.Text = 'Rotor sleeve interference [mm]';
            app.RotorsleeveinterferencemmEditField = uieditfield(app.GridLayout19, 'text');
            app.RotorsleeveinterferencemmEditField.ValueChangedFcn = createCallbackFcn(app, @RotorsleeveinterferencemmEditFieldValueChanged, true);
            app.RotorsleevetemperatureCEditFieldLabel = uilabel(app.GridLayout19);
            app.RotorsleevetemperatureCEditFieldLabel.Text = 'Rotor sleeve temperature [°C]';
            app.RotorsleevetemperatureCEditField = uieditfield(app.GridLayout19, 'text');
            app.RotorsleevetemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @RotorsleevetemperatureCEditFieldValueChanged, true);
            app.SleeveDesignerButton = uibutton(app.GridLayout19, 'push');
            app.SleeveDesignerButton.ButtonPushedFcn = createCallbackFcn(app, @SleeveDesignerButtonPushed, true);
            app.SleeveDesignerButton.Text = 'Sleeve Designer';
            app.PlotStatsButton = uibutton(app.GridLayout19, 'push');
            app.PlotStatsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotStatsButtonPushed, true);
            app.PlotStatsButton.Text = 'Plot Stats';
            app.MaterialsTab = uitab(app.TabGroup);
            app.MaterialsTab.Title = 'Materials';
            app.GridLayout8 = uigridlayout(app.MaterialsTab);
            app.MaterialDataPanel = uipanel(app.GridLayout8);
            app.MaterialDataPanel.Title = 'Material Data';
            app.GridLayout24 = uigridlayout(app.MaterialDataPanel);
            app.StatorcorematerialDropDownLabel = uilabel(app.GridLayout24);
            app.StatorcorematerialDropDownLabel.Text = 'Stator core material';
            app.MassStatorIronEditField = uieditfield(app.GridLayout24, 'text');
            app.kgLabel_2 = uilabel(app.GridLayout24);
            app.kgLabel_2.Text = '[kg]';
            app.StatorslotmaterialDropDownLabel = uilabel(app.GridLayout24);
            app.StatorslotmaterialDropDownLabel.Text = 'Stator slot material';
            app.StatorslotmaterialDropDown = uidropdown(app.GridLayout24);
            app.StatorslotmaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @StatorslotmaterialDropDownValueChanged, true);
            app.MassWindingEditField = uieditfield(app.GridLayout24, 'text');
            app.kgLabel = uilabel(app.GridLayout24);
            app.kgLabel.Text = '[kg]';
            app.RotorcorematerialDropDownLabel = uilabel(app.GridLayout24);
            app.RotorcorematerialDropDownLabel.Text = 'Rotor core material';
            app.RotorcorematerialDropDown = uidropdown(app.GridLayout24);
            app.RotorcorematerialDropDown.ValueChangedFcn = createCallbackFcn(app, @RotorcorematerialDropDownValueChanged, true);
            app.MassRotorIronEditField = uieditfield(app.GridLayout24, 'text');
            app.kgLabel_3 = uilabel(app.GridLayout24);
            app.kgLabel_3.Text = '[kg]';
            app.FluxbarriermaterialDropDownLabel = uilabel(app.GridLayout24);
            app.FluxbarriermaterialDropDownLabel.Text = 'Flux barrier material';
            app.FluxbarriermaterialDropDown = uidropdown(app.GridLayout24);
            app.FluxbarriermaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @FluxbarriermaterialDropDownValueChanged, true);
            app.MassFluxBarrierEditField = uieditfield(app.GridLayout24, 'text');
            app.kgLabel_4 = uilabel(app.GridLayout24);
            app.kgLabel_4.Text = '[kg]';
            app.StatorslotmaterialDropDownLabel_2 = uilabel(app.GridLayout24);
            app.StatorslotmaterialDropDownLabel_2.Text = 'Rotor slot material';
            app.RotorslotmaterialDropDown = uidropdown(app.GridLayout24);
            app.RotorslotmaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @RotorslotmaterialDropDownValueChanged, true);
            app.MassBarEditField = uieditfield(app.GridLayout24, 'text');
            app.kgLabel_7 = uilabel(app.GridLayout24);
            app.kgLabel_7.Text = '[kg]';
            app.ShaftmaterialDropDownLabel = uilabel(app.GridLayout24);
            app.ShaftmaterialDropDownLabel.Text = 'Shaft material';
            app.ShaftmaterialDropDown = uidropdown(app.GridLayout24);
            app.ShaftmaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @ShaftmaterialDropDownValueChanged, true);
            app.SleevematerialDropDownLabel = uilabel(app.GridLayout24);
            app.SleevematerialDropDownLabel.Text = 'Sleeve material';
            app.SleevematerialDropDown = uidropdown(app.GridLayout24);
            app.SleevematerialDropDown.ValueChangedFcn = createCallbackFcn(app, @SleevematerialDropDownValueChanged, true);
            app.RotorInertiaEditField = uieditfield(app.GridLayout24, 'text');
            app.kgm2Label = uilabel(app.GridLayout24);
            app.kgm2Label.Text = '[kg*m^2]';
            app.RotorInertiaLabel = uilabel(app.GridLayout24);
            app.RotorInertiaLabel.Text = 'Rotor Inertia';
            app.TotalmotormassLabel = uilabel(app.GridLayout24);
            app.TotalmotormassLabel.Text = 'Total motor mass';
            app.MassTotalEditField = uieditfield(app.GridLayout24, 'text');
            app.kgLabel_6 = uilabel(app.GridLayout24);
            app.kgLabel_6.Text = '[kg]';
            app.StatorcorematerialDropDown = uidropdown(app.GridLayout24);
            app.StatorcorematerialDropDown.ValueChangedFcn = createCallbackFcn(app, @StatorcorematerialDropDownValueChanged, true);
            app.MaterialLibraryPanel = uipanel(app.GridLayout8);
            app.MaterialLibraryPanel.Title = 'Material Library';
            app.GridLayout25 = uigridlayout(app.MaterialLibraryPanel);
            app.IronLibraryPush = uibutton(app.GridLayout25, 'push');
            app.IronLibraryPush.ButtonPushedFcn = createCallbackFcn(app, @IronLibraryPush_Callback, true);
            app.IronLibraryPush.Text = 'Iron';
            app.ConductorLibraryPush = uibutton(app.GridLayout25, 'push');
            app.ConductorLibraryPush.ButtonPushedFcn = createCallbackFcn(app, @ConductorLibraryPush_Callback, true);
            app.ConductorLibraryPush.Text = 'Conductor';
            app.AddIronPush = uibutton(app.GridLayout25, 'push');
            app.AddIronPush.ButtonPushedFcn = createCallbackFcn(app, @AddIronPush_Callback, true);
            app.AddIronPush.Text = 'Add';
            app.RmvIronPush = uibutton(app.GridLayout25, 'push');
            app.RmvIronPush.ButtonPushedFcn = createCallbackFcn(app, @RmvIronPush_Callback, true);
            app.RmvIronPush.Text = 'Remove';
            app.AddConductorPush = uibutton(app.GridLayout25, 'push');
            app.AddConductorPush.ButtonPushedFcn = createCallbackFcn(app, @AddConductorPush_Callback, true);
            app.AddConductorPush.Text = 'Add';
            app.RmvConductorPush = uibutton(app.GridLayout25, 'push');
            app.RmvConductorPush.ButtonPushedFcn = createCallbackFcn(app, @RmvConductorPush_Callback, true);
            app.RmvConductorPush.Text = 'Remove';
            app.BarrierLibraryPush = uibutton(app.GridLayout25, 'push');
            app.BarrierLibraryPush.ButtonPushedFcn = createCallbackFcn(app, @BarrierLibraryPush_Callback, true);
            app.BarrierLibraryPush.Text = 'Magnet';
            app.AddMagnetPush = uibutton(app.GridLayout25, 'push');
            app.AddMagnetPush.ButtonPushedFcn = createCallbackFcn(app, @AddMagnetPush_Callback, true);
            app.AddMagnetPush.Text = 'Add';
            app.RmvMagnetPush = uibutton(app.GridLayout25, 'push');
            app.RmvMagnetPush.ButtonPushedFcn = createCallbackFcn(app, @RmvMagnetPush_Callback, true);
            app.RmvMagnetPush.Text = 'Remove';
            app.SleeveLibraryPush = uibutton(app.GridLayout25, 'push');
            app.SleeveLibraryPush.ButtonPushedFcn = createCallbackFcn(app, @SleeveLibraryPushButtonPushed, true);
            app.SleeveLibraryPush.Text = 'Sleeve';
            app.AddSleevePush = uibutton(app.GridLayout25, 'push');
            app.AddSleevePush.ButtonPushedFcn = createCallbackFcn(app, @AddSleevePushButtonPushed, true);
            app.AddSleevePush.Text = 'Add';
            app.RmvSleevePush = uibutton(app.GridLayout25, 'push');
            app.RmvSleevePush.ButtonPushedFcn = createCallbackFcn(app, @RmvSleevePushButtonPushed, true);
            app.RmvSleevePush.Text = 'Remove';
            app.ViewPropPush = uibutton(app.GridLayout25, 'push');
            app.ViewPropPush.ButtonPushedFcn = createCallbackFcn(app, @ViewPropPush_Callback, true);
            app.ViewPropPush.Text = 'View material properties';
            app.MagnetPanel = uipanel(app.GridLayout8);
            app.MagnetPanel.Title = 'Permanent Magnet';
            app.GridLayout26 = uigridlayout(app.MagnetPanel);
            app.PMdimTable = uitable(app.GridLayout26);
            app.PMdimTable.ColumnName = {'1'; '2'};
            app.PMdimTable.RowName = {'Central'; 'External'};
            app.PMdimTable.ColumnEditable = [true true true true true];
            app.PMdimTable.CellEditCallback = createCallbackFcn(app, @PMdimTable_CellEditCallback, true);
            app.PMclearanceTable = uitable(app.GridLayout26);
            app.PMclearanceTable.ColumnName = {'1'; '2'};
            app.PMclearanceTable.RowName = {'Central'; 'External'};
            app.PMclearanceTable.ColumnEditable = [true true true true true];
            app.PMclearanceTable.CellEditCallback = createCallbackFcn(app, @PMclearanceTableCellEdit, true);
            app.PMremanenceTEditFieldLabel_3 = uilabel(app.GridLayout26);
            app.PMremanenceTEditFieldLabel_3.Text = 'PM remanence [T]';
            app.BrPMEdit = uieditfield(app.GridLayout26, 'text');
            app.BrPMEdit.ValueChangedFcn = createCallbackFcn(app, @BrPMEdit_Callback, true);
            app.PMtemperatureCEditFieldLabel_3 = uilabel(app.GridLayout26);
            app.PMtemperatureCEditFieldLabel_3.Text = 'PM temperature [°C]';
            app.PMtemperatureEdit = uieditfield(app.GridLayout26, 'text');
            app.PMtemperatureEdit.ValueChangedFcn = createCallbackFcn(app, @PMtemperatureEdit_Callback, true);
            app.CharacteristiccurrentpuLabel = uilabel(app.GridLayout26);
            app.CharacteristiccurrentpuLabel.Text = 'Characteristic I [p.u.]';
            app.CarCurEdit = uieditfield(app.GridLayout26, 'text');
            app.CarCurEdit.ValueChangedFcn = createCallbackFcn(app, @CarCurEdit_Callback, true);
            app.PMDesignPush = uibutton(app.GridLayout26, 'push');
            app.PMDesignPush.ButtonPushedFcn = createCallbackFcn(app, @PMDesignPush_Callback, true);
            app.PMDesignPush.Text = 'PM Design';
            app.PMNcSegmentsTable = uitable(app.GridLayout26);
            app.PMNcSegmentsTable.ColumnName = {'1'; '2'};
            app.PMNcSegmentsTable.RowName = {'Central'; 'External'};
            app.PMNcSegmentsTable.ColumnEditable = [true true true true true];
            app.PMNcSegmentsTable.CellEditCallback = createCallbackFcn(app, @PMNcSegmentsTableCellEdit, true);
            app.PMaxialsegmentsLabel = uilabel(app.GridLayout26);
            app.PMaxialsegmentsLabel.Text = 'PM axial segments';
            app.PMNaSegments = uieditfield(app.GridLayout26, 'text');
            app.PMNaSegments.ValueChangedFcn = createCallbackFcn(app, @PMNaSegmentsValueChanged, true);
            app.LaminationPropertiesPanel = uipanel(app.GridLayout8);
            app.LaminationPropertiesPanel.Title = 'Lamination Properties';
            app.GridLayout41 = uigridlayout(app.LaminationPropertiesPanel);
            app.StackingfactorEditFieldLabel = uilabel(app.GridLayout41);
            app.StackingfactorEditFieldLabel.Text = 'Stacking factor';
            app.StackingfactorEditField = uieditfield(app.GridLayout41, 'text');
            app.StackingfactorEditField.ValueChangedFcn = createCallbackFcn(app, @StackingfactorEditFieldValueChanged, true);
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.GridLayout9 = uigridlayout(app.OptimizationTab);
            app.TabGroup2 = uitabgroup(app.GridLayout9);
            app.EvaluationoptionsTab = uitab(app.TabGroup2);
            app.EvaluationoptionsTab.Title = 'Evaluation options';
            app.GridLayout42 = uigridlayout(app.EvaluationoptionsTab);
            app.OptimizationoptionsPanel = uipanel(app.GridLayout42);
            app.OptimizationoptionsPanel.Title = 'Optimization options';
            app.GridLayout28 = uigridlayout(app.OptimizationoptionsPanel);
            app.CurrentoverloadpuEditFieldLabel_2 = uilabel(app.GridLayout28);
            app.CurrentoverloadpuEditFieldLabel_2.Text = 'Current overload [p.u.]';
            app.CurrentOverLoadEdit = uieditfield(app.GridLayout28, 'text');
            app.CurrentOverLoadEdit.ValueChangedFcn = createCallbackFcn(app, @CurrentOverLoadEdit_Callback, true);
            app.OptimizationtypeDropDown = uidropdown(app.GridLayout28);
            app.OptimizationtypeDropDown.Items = {'MODE Design', 'MODE Refine', 'Surrogate model dataset (LHS)', 'Surrogate model dataset (Sobol)'};
            app.OptimizationtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationtypeDropDownValueChanged, true);
            app.OptimizationtypeDropDown.Value = 'MODE Design';
            app.ofgenerationsEditFieldLabel = uilabel(app.GridLayout28);
            app.ofgenerationsEditFieldLabel.Text = '# of generations';
            app.MaxGenEdit = uieditfield(app.GridLayout28, 'text');
            app.MaxGenEdit.ValueChangedFcn = createCallbackFcn(app, @MaxGenEdit_Callback, true);
            app.PopulationsizeEditFieldLabel = uilabel(app.GridLayout28);
            app.PopulationsizeEditFieldLabel.Text = 'Population size';
            app.XPopEdit = uieditfield(app.GridLayout28, 'text');
            app.XPopEdit.ValueChangedFcn = createCallbackFcn(app, @XPopEdit_Callback, true);
            app.flag_OptCurrConst = uidropdown(app.GridLayout28);
            app.flag_OptCurrConst.Items = {'Constant kj', 'Constant J', 'Constant I'};
            app.flag_OptCurrConst.ValueChangedFcn = createCallbackFcn(app, @flag_OptCurrConstValueChanged, true);
            app.flag_OptCurrConst.Value = 'Constant kj';
            app.StatorcurrentsettingLabel = uilabel(app.GridLayout28);
            app.StatorcurrentsettingLabel.Text = 'Stator current setting';
            app.FieldbystatorcurrentdensityEditFieldLabel = uilabel(app.GridLayout28);
            app.FieldbystatorcurrentdensityEditFieldLabel.Text = 'Field by stator current density';
            app.FieldbystatorcurrentdensityEditField = uieditfield(app.GridLayout28, 'text');
            app.FieldbystatorcurrentdensityEditField.ValueChangedFcn = createCallbackFcn(app, @FieldbystatorcurrentdensityEditFieldValueChanged, true);
            app.OptimizePush = uibutton(app.GridLayout28, 'push');
            app.OptimizePush.ButtonPushedFcn = createCallbackFcn(app, @OptimizePush_Callback, true);
            app.OptimizePush.Text = 'Evaluate';
            app.TimesteppingrawevolutionPanel = uipanel(app.GridLayout42);
            app.TimesteppingrawevolutionPanel.Title = 'Time stepping raw (evolution)';
            app.GridLayout29 = uigridlayout(app.TimesteppingrawevolutionPanel);
            app.RotorangularexcursionEditFieldLabel = uilabel(app.GridLayout29);
            app.RotorangularexcursionEditFieldLabel.Text = 'Rotor angular excursion';
            app.RotorPosiMOEdit = uieditfield(app.GridLayout29, 'text');
            app.RotorPosiMOEdit.ValueChangedFcn = createCallbackFcn(app, @RotorPosiMOEdit_Callback, true);
            app.ofrotorpositionsEditFieldLabel = uilabel(app.GridLayout29);
            app.ofrotorpositionsEditFieldLabel.Text = '# of rotor positions';
            app.SimPosMOEdit = uieditfield(app.GridLayout29, 'text');
            app.SimPosMOEdit.ValueChangedFcn = createCallbackFcn(app, @SimPosMOEdit_Callback, true);
            app.TimesteppingfineParetoreevaluationPanel = uipanel(app.GridLayout42);
            app.TimesteppingfineParetoreevaluationPanel.Title = 'Time stepping fine (Pareto re-evaluation)';
            app.GridLayout30 = uigridlayout(app.TimesteppingfineParetoreevaluationPanel);
            app.RotorangularexcursionEditField_2Label = uilabel(app.GridLayout30);
            app.RotorangularexcursionEditField_2Label.Text = 'Rotor angular excursion';
            app.RotorPosFinerEdit = uieditfield(app.GridLayout30, 'text');
            app.RotorPosFinerEdit.ValueChangedFcn = createCallbackFcn(app, @RotorPosFinerEdit_Callback, true);
            app.ofrotorpositionsEditField_2Label = uilabel(app.GridLayout30);
            app.ofrotorpositionsEditField_2Label.Text = '# of rotor positions';
            app.SimPosFinerEdit = uieditfield(app.GridLayout30, 'text');
            app.SimPosFinerEdit.ValueChangedFcn = createCallbackFcn(app, @SimPosFinerEdit_Callback, true);
            app.ObjectivesandPenalizationLimitsPanel = uipanel(app.GridLayout42);
            app.ObjectivesandPenalizationLimitsPanel.Title = 'Objectives and Penalization Limits';
            app.GridLayout2 = uigridlayout(app.ObjectivesandPenalizationLimitsPanel);
            app.MinExpTorEdit = uieditfield(app.GridLayout2, 'text');
            app.MinExpTorEdit.ValueChangedFcn = createCallbackFcn(app, @MinExpTorEdit_Callback, true);
            app.TorqueOptCheck = uibutton(app.GridLayout2, 'state');
            app.TorqueOptCheck.ValueChangedFcn = createCallbackFcn(app, @TorqueOptCheck_Callback, true);
            app.TorqueOptCheck.Text = 'Torque [Nm]';
            app.TorRipOptCheck = uibutton(app.GridLayout2, 'state');
            app.TorRipOptCheck.ValueChangedFcn = createCallbackFcn(app, @TorRipOptCheck_Callback, true);
            app.TorRipOptCheck.Text = 'Torque ripple (pp) [Nm]';
            app.MaxExpeRippleTorEdit = uieditfield(app.GridLayout2, 'text');
            app.MaxExpeRippleTorEdit.ValueChangedFcn = createCallbackFcn(app, @MaxExpeRippleTorEdit_Callback, true);
            app.MassCuOptCheck = uibutton(app.GridLayout2, 'state');
            app.MassCuOptCheck.ValueChangedFcn = createCallbackFcn(app, @MassCuOptCheck_Callback, true);
            app.MassCuOptCheck.Text = 'Copper mass [kg]';
            app.MassCuEdit = uieditfield(app.GridLayout2, 'text');
            app.MassCuEdit.ValueChangedFcn = createCallbackFcn(app, @MassCuEdit_Callback, true);
            app.MassPMOptCheck = uibutton(app.GridLayout2, 'state');
            app.MassPMOptCheck.ValueChangedFcn = createCallbackFcn(app, @MassPMOptCheck_Callback, true);
            app.MassPMOptCheck.Text = 'PM mass [kg]';
            app.MassPMEdit = uieditfield(app.GridLayout2, 'text');
            app.MassPMEdit.ValueChangedFcn = createCallbackFcn(app, @MassPMEdit_Callback, true);
            app.MinExpPowerFactorEdit = uieditfield(app.GridLayout2, 'text');
            app.MinExpPowerFactorEdit.ValueChangedFcn = createCallbackFcn(app, @MinExpPowerFactorEditValueChanged, true);
            app.PowerFactorOptCheck = uibutton(app.GridLayout2, 'state');
            app.PowerFactorOptCheck.ValueChangedFcn = createCallbackFcn(app, @PowerFactorOptCheckValueChanged, true);
            app.PowerFactorOptCheck.Text = 'Power factor';
            app.MaxExpNoLoadFluxEdit = uieditfield(app.GridLayout2, 'text');
            app.MaxExpNoLoadFluxEdit.ValueChangedFcn = createCallbackFcn(app, @MaxExpNoLoadFluxEditValueChanged, true);
            app.NoLoadFluxOptCheck = uibutton(app.GridLayout2, 'state');
            app.NoLoadFluxOptCheck.ValueChangedFcn = createCallbackFcn(app, @NoLoadFluxOptCheckValueChanged, true);
            app.NoLoadFluxOptCheck.Text = 'No load flux [Vs]';
            app.StatorJoulelossWButton = uibutton(app.GridLayout2, 'state');
            app.StatorJoulelossWButton.ValueChangedFcn = createCallbackFcn(app, @StatorJoulelossWButtonValueChanged, true);
            app.StatorJoulelossWButton.Text = 'Stator Joule loss [W]';
            app.MaxExpPjs = uieditfield(app.GridLayout2, 'text');
            app.MaxExpPjs.ValueChangedFcn = createCallbackFcn(app, @MaxExpPjsValueChanged, true);
            app.MaxExpPjf = uieditfield(app.GridLayout2, 'text');
            app.MaxExpPjf.ValueChangedFcn = createCallbackFcn(app, @MaxExpPjfValueChanged, true);
            app.RotorJoulelossWButton = uibutton(app.GridLayout2, 'state');
            app.RotorJoulelossWButton.ValueChangedFcn = createCallbackFcn(app, @RotorJoulelossWButtonValueChanged, true);
            app.RotorJoulelossWButton.Text = 'Rotor Joule loss [W]';
            app.VonMisesstressMPaButton = uibutton(app.GridLayout2, 'state');
            app.VonMisesstressMPaButton.ValueChangedFcn = createCallbackFcn(app, @VonMisesstressMPaButtonValueChanged, true);
            app.VonMisesstressMPaButton.Text = 'VonMises stress [MPa]';
            app.MaxExpMechStress = uieditfield(app.GridLayout2, 'text');
            app.MaxExpMechStress.ValueChangedFcn = createCallbackFcn(app, @MaxExpMechStressValueChanged, true);
            app.VariablesandBoundariesTab = uitab(app.TabGroup2);
            app.VariablesandBoundariesTab.Title = 'Variables and Boundaries';
            app.GridLayout43 = uigridlayout(app.VariablesandBoundariesTab);
            app.AirgapRadiusBouCheck = uibutton(app.GridLayout43, 'state');
            app.AirgapRadiusBouCheck.ValueChangedFcn = createCallbackFcn(app, @AirgapRadiusBouCheck_Callback, true);
            app.AirgapRadiusBouCheck.Text = 'Airgap radius [p.u.]';
            app.AirgapRadiusBouEdit = uieditfield(app.GridLayout43, 'text');
            app.AirgapRadiusBouEdit.ValueChangedFcn = createCallbackFcn(app, @AirgapRadiusBouEdit_Callback, true);
            app.ToothWidthBouCheck = uibutton(app.GridLayout43, 'state');
            app.ToothWidthBouCheck.ValueChangedFcn = createCallbackFcn(app, @ToothWidthBouCheck_Callback, true);
            app.ToothWidthBouCheck.Text = 'Tooth width [p.u.]';
            app.ToothWidthBouEdit = uieditfield(app.GridLayout43, 'text');
            app.ToothWidthBouEdit.ValueChangedFcn = createCallbackFcn(app, @ToothWidthBouEdit_Callback, true);
            app.ToothLengthBouCheck = uibutton(app.GridLayout43, 'state');
            app.ToothLengthBouCheck.ValueChangedFcn = createCallbackFcn(app, @ToothLengthBouCheck_Callback, true);
            app.ToothLengthBouCheck.Text = 'Tooth lenght [p.u.]';
            app.ToothLenBouEdit = uieditfield(app.GridLayout43, 'text');
            app.ToothLenBouEdit.ValueChangedFcn = createCallbackFcn(app, @ToothLenBouEdit_Callback, true);
            app.StatorSlotOpenBouCheck = uibutton(app.GridLayout43, 'state');
            app.StatorSlotOpenBouCheck.ValueChangedFcn = createCallbackFcn(app, @StatorSlotOpenBouCheck_Callback, true);
            app.StatorSlotOpenBouCheck.Text = 'Stator slot open [p.u.]';
            app.StatorSlotOpenBouEdit = uieditfield(app.GridLayout43, 'text');
            app.StatorSlotOpenBouEdit.ValueChangedFcn = createCallbackFcn(app, @StatorSlotOpenBouEdit_Callback, true);
            app.ToothTangDepthBouCheck = uibutton(app.GridLayout43, 'state');
            app.ToothTangDepthBouCheck.ValueChangedFcn = createCallbackFcn(app, @ToothTangDepthBouCheck_Callback, true);
            app.ToothTangDepthBouCheck.Text = 'Tooth tan. depth [mm]';
            app.ToothTangDepthBouEdit = uieditfield(app.GridLayout43, 'text');
            app.ToothTangDepthBouEdit.ValueChangedFcn = createCallbackFcn(app, @ToothTangDepthBouEdit_Callback, true);
            app.GapBouCheck = uibutton(app.GridLayout43, 'state');
            app.GapBouCheck.ValueChangedFcn = createCallbackFcn(app, @GapBouCheck_Callback, true);
            app.GapBouCheck.Text = 'Airgap thickness [mm]';
            app.GapBouEdit = uieditfield(app.GridLayout43, 'text');
            app.GapBouEdit.ValueChangedFcn = createCallbackFcn(app, @GapBouEdit_Callback, true);
            app.ThetaFBSBouCheck = uibutton(app.GridLayout43, 'state');
            app.ThetaFBSBouCheck.ValueChangedFcn = createCallbackFcn(app, @ThetaFBSBouCheck_Callback, true);
            app.ThetaFBSBouCheck.Text = 'Theta FBS [mech °]';
            app.ThetaFBSBouEdit = uieditfield(app.GridLayout43, 'text');
            app.ThetaFBSBouEdit.ValueChangedFcn = createCallbackFcn(app, @ThetaFBSBouEdit_Callback, true);
            app.GammaBouCheck = uibutton(app.GridLayout43, 'state');
            app.GammaBouCheck.ValueChangedFcn = createCallbackFcn(app, @GammaBouCheck_Callback, true);
            app.GammaBouCheck.Text = 'Gamma [°]';
            app.PhaseAngleCurrBouEdit = uieditfield(app.GridLayout43, 'text');
            app.PhaseAngleCurrBouEdit.ValueChangedFcn = createCallbackFcn(app, @PhaseAngleCurrBouEdit_Callback, true);
            app.Dalpha1BouCheck = uibutton(app.GridLayout43, 'state');
            app.Dalpha1BouCheck.ValueChangedFcn = createCallbackFcn(app, @Dalpha1BouCheck_Callback, true);
            app.Dalpha1BouCheck.Text = '1st barrier pos. [p.u.]';
            app.Alpha1BouEdit = uieditfield(app.GridLayout43, 'text');
            app.Alpha1BouEdit.ValueChangedFcn = createCallbackFcn(app, @Alpha1BouEdit_Callback, true);
            app.DalphaBouCheck = uibutton(app.GridLayout43, 'state');
            app.DalphaBouCheck.ValueChangedFcn = createCallbackFcn(app, @DalphaBouCheck_Callback, true);
            app.DalphaBouCheck.Text = 'Barriers positions [p.u.]';
            app.DeltaAlphaBouEdit = uieditfield(app.GridLayout43, 'text');
            app.DeltaAlphaBouEdit.ValueChangedFcn = createCallbackFcn(app, @DeltaAlphaBouEdit_Callback, true);
            app.hcBouCheck = uibutton(app.GridLayout43, 'state');
            app.hcBouCheck.ValueChangedFcn = createCallbackFcn(app, @hcBouCheck_Callback, true);
            app.hcBouCheck.Text = 'Barrier width [p.u.]';
            app.hcBouEdit = uieditfield(app.GridLayout43, 'text');
            app.hcBouEdit.ValueChangedFcn = createCallbackFcn(app, @hcBouEdit_Callback, true);
            app.DxBouCheck = uibutton(app.GridLayout43, 'state');
            app.DxBouCheck.ValueChangedFcn = createCallbackFcn(app, @DxBouCheck_Callback, true);
            app.DxBouCheck.Text = 'Barrier offset [p.u.]';
            app.DfeBouEdit = uieditfield(app.GridLayout43, 'text');
            app.DfeBouEdit.ValueChangedFcn = createCallbackFcn(app, @DfeBouEdit_Callback, true);
            app.CentralShrinkBouCheck = uibutton(app.GridLayout43, 'state');
            app.CentralShrinkBouCheck.ValueChangedFcn = createCallbackFcn(app, @CentralShrinkBouCheckValueChanged, true);
            app.CentralShrinkBouCheck.Text = 'Barriers shrink [p.u.]';
            app.CentralShrinkBouEdit = uieditfield(app.GridLayout43, 'text');
            app.CentralShrinkBouEdit.ValueChangedFcn = createCallbackFcn(app, @CentralShrinkBouEditValueChanged, true);
            app.RadShiftInnerBouCheck = uibutton(app.GridLayout43, 'state');
            app.RadShiftInnerBouCheck.ValueChangedFcn = createCallbackFcn(app, @RadShiftInnerBouCheckValueChanged, true);
            app.RadShiftInnerBouCheck.Text = 'Barrier shift [p.u.]';
            app.RadShiftInnerBouEdit = uieditfield(app.GridLayout43, 'text');
            app.RadShiftInnerBouEdit.ValueChangedFcn = createCallbackFcn(app, @RadShiftInnerBouEditValueChanged, true);
            app.PMdimBouCheck = uibutton(app.GridLayout43, 'state');
            app.PMdimBouCheck.ValueChangedFcn = createCallbackFcn(app, @PMdimBouCheck_Callback, true);
            app.PMdimBouCheck.Text = 'PM dimension [p.u.]';
            app.PMdimBouEdit = uieditfield(app.GridLayout43, 'text');
            app.PMdimBouEdit.ValueChangedFcn = createCallbackFcn(app, @PMdimBouEdit_Callback, true);
            app.BrBouCheck = uibutton(app.GridLayout43, 'state');
            app.BrBouCheck.ValueChangedFcn = createCallbackFcn(app, @BrBouCheck_Callback, true);
            app.BrBouCheck.Text = 'PM remanence [T]';
            app.BrBouEdit = uieditfield(app.GridLayout43, 'text');
            app.BrBouEdit.ValueChangedFcn = createCallbackFcn(app, @BrBouEdit_Callback, true);
            app.RadRibBouCheck = uibutton(app.GridLayout43, 'state');
            app.RadRibBouCheck.ValueChangedFcn = createCallbackFcn(app, @RadRibBouCheckValueChanged, true);
            app.RadRibBouCheck.Text = 'Radial ribs [p.u.]';
            app.RadRibBouEdit = uieditfield(app.GridLayout43, 'text');
            app.RadRibBouEdit.ValueChangedFcn = createCallbackFcn(app, @RadRibBouEditValueChanged, true);
            app.TanRibBouCheck = uibutton(app.GridLayout43, 'state');
            app.TanRibBouCheck.ValueChangedFcn = createCallbackFcn(app, @TanRibBouCheckValueChanged, true);
            app.TanRibBouCheck.Text = 'Tangential ribs [p.u.]';
            app.TanRibBouEdit = uieditfield(app.GridLayout43, 'text');
            app.TanRibBouEdit.ValueChangedFcn = createCallbackFcn(app, @TanRibBouEditValueChanged, true);
            app.FilletRadribsinBouCheck = uibutton(app.GridLayout43, 'state');
            app.FilletRadribsinBouCheck.ValueChangedFcn = createCallbackFcn(app, @FilletRadribsinBouCheckValueChanged, true);
            app.FilletRadribsinBouCheck.Text = 'Fillet Rad ribs in [mm]';
            app.FilletRadribsinBou = uieditfield(app.GridLayout43, 'text');
            app.FilletRadribsinBou.ValueChangedFcn = createCallbackFcn(app, @FilletRadribsinBouValueChanged, true);
            app.FilletRadribsoutBouCheck = uibutton(app.GridLayout43, 'state');
            app.FilletRadribsoutBouCheck.ValueChangedFcn = createCallbackFcn(app, @FilletRadribsoutBouCheckValueChanged, true);
            app.FilletRadribsoutBouCheck.Text = 'Fillet Rad ribs out [mm]';
            app.FilletRadribsoutBou = uieditfield(app.GridLayout43, 'text');
            app.FilletRadribsoutBou.ValueChangedFcn = createCallbackFcn(app, @FilletRadribsoutBouValueChanged, true);
            app.FilletTanribsinBouCheck = uibutton(app.GridLayout43, 'state');
            app.FilletTanribsinBouCheck.ValueChangedFcn = createCallbackFcn(app, @FilletTanribsinBouCheckValueChanged, true);
            app.FilletTanribsinBouCheck.Text = 'Fillet Tan ribs in [mm]';
            app.FilletTanribsinBou = uieditfield(app.GridLayout43, 'text');
            app.FilletTanribsinBou.ValueChangedFcn = createCallbackFcn(app, @FilletTanribsinBouValueChanged, true);
            app.FilletTanribsoutBouCheck = uibutton(app.GridLayout43, 'state');
            app.FilletTanribsoutBouCheck.ValueChangedFcn = createCallbackFcn(app, @FilletTanribsoutBouCheckValueChanged, true);
            app.FilletTanribsoutBouCheck.Text = 'Fillet Tan ribs out [mm]';
            app.FilletTanribsoutBou = uieditfield(app.GridLayout43, 'text');
            app.FilletTanribsoutBou.ValueChangedFcn = createCallbackFcn(app, @FilletTanribsoutBouValueChanged, true);
            app.BetaPMshapeBouCheck = uibutton(app.GridLayout43, 'state');
            app.BetaPMshapeBouCheck.ValueChangedFcn = createCallbackFcn(app, @BetaPMshapeBouCheck_Callback, true);
            app.BetaPMshapeBouCheck.Text = 'PM shape factor [p.u.]';
            app.BetaPMshapeBouEdit = uieditfield(app.GridLayout43, 'text');
            app.BetaPMshapeBouEdit.ValueChangedFcn = createCallbackFcn(app, @BetaPMshapeBouEdit_Callback, true);
            app.YokeWidthBou = uieditfield(app.GridLayout43, 'text');
            app.YokeWidthBou.ValueChangedFcn = createCallbackFcn(app, @YokeWidthBouValueChanged, true);
            app.YokeWidthBouCheck = uibutton(app.GridLayout43, 'state');
            app.YokeWidthBouCheck.ValueChangedFcn = createCallbackFcn(app, @YokeWidthBouCheckValueChanged, true);
            app.YokeWidthBouCheck.Text = 'Yoke width [mm]';
            app.PoleBodyHeightBou = uieditfield(app.GridLayout43, 'text');
            app.PoleBodyHeightBou.ValueChangedFcn = createCallbackFcn(app, @PoleBodyHeightBouValueChanged, true);
            app.PoleBodyHeightBouCheck = uibutton(app.GridLayout43, 'state');
            app.PoleBodyHeightBouCheck.ValueChangedFcn = createCallbackFcn(app, @PoleBodyHeightBouCheckValueChanged, true);
            app.PoleBodyHeightBouCheck.Text = 'Pole body height [mm]';
            app.PoleAnglepuBou = uieditfield(app.GridLayout43, 'text');
            app.PoleAnglepuBou.ValueChangedFcn = createCallbackFcn(app, @PoleAnglepuBouValueChanged, true);
            app.PoleAnglepuBouCheck = uibutton(app.GridLayout43, 'state');
            app.PoleAnglepuBouCheck.ValueChangedFcn = createCallbackFcn(app, @PoleAnglepuBouCheckValueChanged, true);
            app.PoleAnglepuBouCheck.Text = 'Pole angle [p.u.]';
            app.PoleWidthBou = uieditfield(app.GridLayout43, 'text');
            app.PoleWidthBou.ValueChangedFcn = createCallbackFcn(app, @PoleWidthBouValueChanged, true);
            app.PoleWidthBouCheck = uibutton(app.GridLayout43, 'state');
            app.PoleWidthBouCheck.ValueChangedFcn = createCallbackFcn(app, @PoleWidthBouCheckValueChanged, true);
            app.PoleWidthBouCheck.Text = 'Pole width [mm]';
            app.CoilWidthBou = uieditfield(app.GridLayout43, 'text');
            app.CoilWidthBou.ValueChangedFcn = createCallbackFcn(app, @CoilWidthBouValueChanged, true);
            app.CoilWidthBouCheck = uibutton(app.GridLayout43, 'state');
            app.CoilWidthBouCheck.ValueChangedFcn = createCallbackFcn(app, @CoilWidthBouCheckValueChanged, true);
            app.CoilWidthBouCheck.Text = 'Coil width [mm]';
            app.CoilHeightBou = uieditfield(app.GridLayout43, 'text');
            app.CoilHeightBou.ValueChangedFcn = createCallbackFcn(app, @CoilHeightBouValueChanged, true);
            app.CoilHeightBouCheck = uibutton(app.GridLayout43, 'state');
            app.CoilHeightBouCheck.ValueChangedFcn = createCallbackFcn(app, @CoilHeightBouCheckValueChanged, true);
            app.CoilHeightBouCheck.Text = 'Coil height [mm]';
            app.PoleRotHeadFilletBou = uieditfield(app.GridLayout43, 'text');
            app.PoleRotHeadFilletBou.ValueChangedFcn = createCallbackFcn(app, @PoleRotHeadFilletBouValueChanged, true);
            app.PoleRotHeadFilletBouCheck = uibutton(app.GridLayout43, 'state');
            app.PoleRotHeadFilletBouCheck.ValueChangedFcn = createCallbackFcn(app, @PoleRotHeadFilletBouCheckValueChanged, true);
            app.PoleRotHeadFilletBouCheck.Text = 'Pole head fillet [mm]';
            app.PoleRotHeadAngleBou = uieditfield(app.GridLayout43, 'text');
            app.PoleRotHeadAngleBou.ValueChangedFcn = createCallbackFcn(app, @PoleRotHeadAngleBouValueChanged, true);
            app.PoleRotHeadAngleBouCheck = uibutton(app.GridLayout43, 'state');
            app.PoleRotHeadAngleBouCheck.ValueChangedFcn = createCallbackFcn(app, @PoleRotHeadAngleBouCheckValueChanged, true);
            app.PoleRotHeadAngleBouCheck.Text = 'Pole head angle [°]';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.GridLayout10 = uigridlayout(app.SimulationTab);
            app.CustomCurrentPanel = uipanel(app.GridLayout10);
            app.CustomCurrentPanel.Title = 'Custom Current';
            app.GridLayout32 = uigridlayout(app.CustomCurrentPanel);
            app.CustomCurrentSwitch = uiswitch(app.GridLayout32, 'slider');
            app.CustomCurrentSwitch.ValueChangedFcn = createCallbackFcn(app, @CustomCurrentSwitchValueChanged, true);
            app.LoadCustomCurrent = uibutton(app.GridLayout32, 'push');
            app.LoadCustomCurrent.ButtonPushedFcn = createCallbackFcn(app, @LoadCustomCurrentButtonPushed, true);
            app.LoadCustomCurrent.Text = 'Load current waveform';
            app.text120_2 = uilabel(app.GridLayout32);
            app.text120_2.Text = 'Current waveform data are:';
            app.CustomCurrentFile = uieditfield(app.GridLayout32, 'text');
            app.CustomCurrentFile.ValueChangedFcn = createCallbackFcn(app, @CustomCurrentFileValueChanged, true);
            app.CustomCurrentFile.Value = 'Edit Text';
            app.CreateCustomCurrent = uibutton(app.GridLayout32, 'push');
            app.CreateCustomCurrent.ButtonPushedFcn = createCallbackFcn(app, @CreateCustomCurrentButtonPushed, true);
            app.CreateCustomCurrent.Text = 'Create current waveform';
            app.EvalACLossCustomCurrent = uibutton(app.GridLayout32, 'push');
            app.EvalACLossCustomCurrent.ButtonPushedFcn = createCallbackFcn(app, @EvalACLossCustomCurrentButtonPushed, true);
            app.EvalACLossCustomCurrent.Text = 'Evaluate AC loss';
            app.SimulationSetupPanel = uipanel(app.GridLayout10);
            app.SimulationSetupPanel.Title = 'Simulation Setup';
            app.GridLayout31 = uigridlayout(app.SimulationSetupPanel);
            app.RotorangularexcursioneltEditFieldLabel = uilabel(app.GridLayout31);
            app.RotorangularexcursioneltEditFieldLabel.Text = 'Rotor angular excursion [elt °]';
            app.SpanEltPPEdit = uieditfield(app.GridLayout31, 'text');
            app.SpanEltPPEdit.ValueChangedFcn = createCallbackFcn(app, @SpanEltPPEdit_Callback, true);
            app.NumberofrotorpositionsEditFieldLabel = uilabel(app.GridLayout31);
            app.NumberofrotorpositionsEditFieldLabel.Text = 'Number of rotor positions';
            app.NumOfRotorPosiPPEdit = uieditfield(app.GridLayout31, 'text');
            app.NumOfRotorPosiPPEdit.ValueChangedFcn = createCallbackFcn(app, @NumOfRotorPosiPPEdit_Callback, true);
            app.CurrentphaseangleeltEditFieldLabel = uilabel(app.GridLayout31);
            app.CurrentphaseangleeltEditFieldLabel.Text = 'Current phase angle [elt °]';
            app.GammaPPEdit = uieditfield(app.GridLayout31, 'text');
            app.GammaPPEdit.ValueChangedFcn = createCallbackFcn(app, @GammaPPEdit_Callback, true);
            app.CurrentloadpuEditFieldLabel = uilabel(app.GridLayout31);
            app.CurrentloadpuEditFieldLabel.Text = 'Current load [p.u.]';
            app.CurrLoPPEdit = uieditfield(app.GridLayout31, 'text');
            app.CurrLoPPEdit.ValueChangedFcn = createCallbackFcn(app, @CurrLoPPEdit_Callback, true);
            app.PhasecurrentAEditFieldLabel = uilabel(app.GridLayout31);
            app.PhasecurrentAEditFieldLabel.Text = 'Phase current [A]';
            app.CurrentPP = uieditfield(app.GridLayout31, 'text');
            app.CurrentPP.ValueChangedFcn = createCallbackFcn(app, @CurrentPPValueChanged, true);
            app.PMremanenceTEditFieldLabel_2 = uilabel(app.GridLayout31);
            app.PMremanenceTEditFieldLabel_2.Text = 'PM remanence [T]';
            app.BrPPEdit = uieditfield(app.GridLayout31, 'text');
            app.BrPPEdit.ValueChangedFcn = createCallbackFcn(app, @BrPPEdit_Callback, true);
            app.PMtemperatureCEditFieldLabel_2 = uilabel(app.GridLayout31);
            app.PMtemperatureCEditFieldLabel_2.Text = 'PM temperature [°C]';
            app.TempPPEdit = uieditfield(app.GridLayout31, 'text');
            app.TempPPEdit.ValueChangedFcn = createCallbackFcn(app, @TempPPEdit_Callback, true);
            app.Numberofpointsin0ImaxLabel = uilabel(app.GridLayout31);
            app.Numberofpointsin0ImaxLabel.Text = 'Number of points in [0, Imax]';
            app.NGridPPEdit = uieditfield(app.GridLayout31, 'text');
            app.NGridPPEdit.ValueChangedFcn = createCallbackFcn(app, @NGridPPEdit_Callback, true);
            app.RotorspeedrpmEditFieldLabel = uilabel(app.GridLayout31);
            app.RotorspeedrpmEditFieldLabel.Text = 'Rotor speed [rpm]';
            app.EvaluatedSpeedEdit = uieditfield(app.GridLayout31, 'text');
            app.EvaluatedSpeedEdit.ValueChangedFcn = createCallbackFcn(app, @EvaluatedSpeedEdit_Callback, true);
            app.Active3phasesetsEditFieldLabel = uilabel(app.GridLayout31);
            app.Active3phasesetsEditFieldLabel.Text = 'Active 3-phase sets';
            app.Active3phasesetsEditField = uieditfield(app.GridLayout31, 'text');
            app.Active3phasesetsEditField.ValueChangedFcn = createCallbackFcn(app, @Active3phasesetsEditFieldValueChanged, true);
            app.NumberofmapquadrantsLabel = uilabel(app.GridLayout31);
            app.NumberofmapquadrantsLabel.Text = 'Number of map quadrants';
            app.MapQuadrantsPopUp = uidropdown(app.GridLayout31);
            app.MapQuadrantsPopUp.Items = {'1 quadrant', '2 quadrants', '4 quadrants'};
            app.MapQuadrantsPopUp.ValueChangedFcn = createCallbackFcn(app, @MapQuadrantsPopUpValueChanged, true);
            app.MapQuadrantsPopUp.Value = '1 quadrant';
            app.AxistypeDropDownLabel = uilabel(app.GridLayout31);
            app.AxistypeDropDownLabel.Text = 'Axis type';
            app.AxistypeDropDown = uidropdown(app.GridLayout31);
            app.AxistypeDropDown.Items = {'SR', 'PM'};
            app.AxistypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AxistypeDropDownValueChanged, true);
            app.AxistypeDropDown.Value = 'SR';
            app.EvaluationtypeDropDownLabel = uilabel(app.GridLayout31);
            app.EvaluationtypeDropDownLabel.Text = 'Evaluation type';
            app.EvalTypePopUp = uidropdown(app.GridLayout31);
            app.EvalTypePopUp.Items = {'Single Point', 'Flux Map', 'Characteristic Current', 'Steady-State Short Circuit', 'HWC Short-Circuit Current', 'Demagnetization Curve', 'Demagnetization Curve DQ', 'Demagnetization Analysis', 'Flux Density Analysis', 'Airgap Force', 'Iron Loss - Single Point', 'Iron Loss - Flux Map', 'Structural Analysis'};
            app.EvalTypePopUp.ValueChangedFcn = createCallbackFcn(app, @EvalTypePopUp_Callback, true);
            app.EvalTypePopUp.Value = 'Single Point';
            app.FieldcurrentAEditFieldLabel = uilabel(app.GridLayout31);
            app.FieldcurrentAEditFieldLabel.Text = 'Field current [A]';
            app.FieldcurrentAEditField = uieditfield(app.GridLayout31, 'text');
            app.FieldcurrentAEditField.ValueChangedFcn = createCallbackFcn(app, @FieldCurrent, true);
            app.OffsetcurrentAEditFieldLabel = uilabel(app.GridLayout31);
            app.OffsetcurrentAEditFieldLabel.Text = 'Offset current [A]';
            app.OffsetcurrentAEditField = uieditfield(app.GridLayout31, 'text');
            app.OffsetcurrentAEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetcurrentAEditFieldValueChanged, true);
            app.FieldcurrentloadpuEditFieldLabel = uilabel(app.GridLayout31);
            app.FieldcurrentloadpuEditFieldLabel.Text = 'Field current load [p.u.]';
            app.FieldcurrentloadpuEditField = uieditfield(app.GridLayout31, 'text');
            app.FieldcurrentloadpuEditField.ValueChangedFcn = createCallbackFcn(app, @FieldcurrentloadpuEditFieldValueChanged, true);
            app.StartPProPush = uibutton(app.GridLayout10, 'push');
            app.StartPProPush.ButtonPushedFcn = createCallbackFcn(app, @StartPProPush_Callback, true);
            app.StartPProPush.Text = 'FEMM';
            app.StartPProMagnetPush = uibutton(app.GridLayout10, 'push');
            app.StartPProMagnetPush.ButtonPushedFcn = createCallbackFcn(app, @StartPProMagnetPush_Callback, true);
            app.StartPProMagnetPush.Text = 'MAGNET';
            app.StartPProAnsysPush = uibutton(app.GridLayout10, 'push');
            app.StartPProAnsysPush.ButtonPushedFcn = createCallbackFcn(app, @StartPProAnsysPushButtonPushed, true);
            app.StartPProAnsysPush.Text = 'ANSYS';
            app.MeshControlPanel = uipanel(app.GridLayout10);
            app.MeshControlPanel.Title = 'Mesh Control';
            app.GridLayout20 = uigridlayout(app.MeshControlPanel);
            app.MeshLabel = uilabel(app.GridLayout20);
            app.MeshLabel.Text = 'Mesh size';
            app.MeshEdit = uieditfield(app.GridLayout20, 'text');
            app.MeshEdit.ValueChangedFcn = createCallbackFcn(app, @MeshEdit_Callback, true);
            app.Mesh_MOOALabel = uilabel(app.GridLayout20);
            app.Mesh_MOOALabel.Text = 'Airgap factor';
            app.MeshMOOAEdit = uieditfield(app.GridLayout20, 'text');
            app.MeshMOOAEdit.ValueChangedFcn = createCallbackFcn(app, @MeshMOOAEdit_Callback, true);
            app.Mesh_MOOALabel_2 = uilabel(app.GridLayout20);
            app.Mesh_MOOALabel_2.Text = 'PM factor';
            app.mesh_kpmEdit = uieditfield(app.GridLayout20, 'text');
            app.mesh_kpmEdit.ValueChangedFcn = createCallbackFcn(app, @mesh_kpmEditValueChanged, true);
            app.StructuralPDEDropDownLabel = uilabel(app.GridLayout20);
            app.StructuralPDEDropDownLabel.Text = 'Structural PDE';
            app.StructuralPDEDropDown = uidropdown(app.GridLayout20);
            app.StructuralPDEDropDown.Items = {'FEMM original', 'FEMM fine', 'PDE coarse', 'PDE fine'};
            app.StructuralPDEDropDown.ValueChangedFcn = createCallbackFcn(app, @StructuralPDEDropDownValueChanged, true);
            app.StructuralPDEDropDown.Value = 'PDE fine';
            app.JMAGButton = uibutton(app.GridLayout10, 'push');
            app.JMAGButton.ButtonPushedFcn = createCallbackFcn(app, @JMAGButtonPushed, true);
            app.JMAGButton.Text = 'JMAG';
            app.COMSOLButton_2 = uibutton(app.GridLayout10, 'push');
            app.COMSOLButton_2.ButtonPushedFcn = createCallbackFcn(app, @COMSOLButton_2Pushed, true);
            app.COMSOLButton_2.Text = 'COMSOL';
            app.MotorCADTab = uitab(app.TabGroup);
            app.MotorCADTab.Title = 'Motor-CAD';
            app.GridLayout11 = uigridlayout(app.MotorCADTab);
            app.AxisLogoMCAD = uiaxes(app.GridLayout11);
            app.AxisLogoMCAD.XTick = [];
            app.AxisLogoMCAD.YTick = [];
            app.ThermalLimitsatZeroSpeedPanel = uipanel(app.GridLayout11);
            app.ThermalLimitsatZeroSpeedPanel.Title = 'Thermal Limits at Zero Speed';
            app.GridLayout35 = uigridlayout(app.ThermalLimitsatZeroSpeedPanel);
            app.CopperlimitCLabel = uilabel(app.GridLayout35);
            app.CopperlimitCLabel.Text = 'Copper limit [°C]';
            app.TempCuLimitEdit = uieditfield(app.GridLayout35, 'text');
            app.TempCuLimitEdit.ValueChangedFcn = createCallbackFcn(app, @TempCuLimitEditValueChanged, true);
            app.TransienttimesLabel = uilabel(app.GridLayout35);
            app.TransienttimesLabel.Text = 'Transient time [s]';
            app.TransientTimeEditField = uieditfield(app.GridLayout35, 'text');
            app.TransientTimeEditField.ValueChangedFcn = createCallbackFcn(app, @TransientTimeEditFieldValueChanged, true);
            app.CalculateCont_MCAD = uibutton(app.GridLayout35, 'push');
            app.CalculateCont_MCAD.ButtonPushedFcn = createCallbackFcn(app, @CalculateCont_MCADButtonPushed, true);
            app.CalculateCont_MCAD.Text = 'Calculate Steady';
            app.OutputALabel_2 = uilabel(app.GridLayout35);
            app.OutputALabel_2.Text = 'Output [A]';
            app.SimulatedRatedCurrent = uieditfield(app.GridLayout35, 'text');
            app.CalculateTrans_MCAD = uibutton(app.GridLayout35, 'push');
            app.CalculateTrans_MCAD.ButtonPushedFcn = createCallbackFcn(app, @CalculateTrans_MCADButtonPushed, true);
            app.CalculateTrans_MCAD.Text = 'Calculate Transient';
            app.OutputALabel = uilabel(app.GridLayout35);
            app.OutputALabel.Text = 'Output [A]';
            app.SimulatedTransientCurrent = uieditfield(app.GridLayout35, 'text');
            app.ThermalSimulationPanel = uipanel(app.GridLayout11);
            app.ThermalSimulationPanel.Title = 'Thermal Simulation';
            app.GridLayout33 = uigridlayout(app.ThermalSimulationPanel);
            app.TransientperiodsEditFieldLabel = uilabel(app.GridLayout33);
            app.TransientperiodsEditFieldLabel.Text = 'Transient period [s]';
            app.TransientperiodEditField = uieditfield(app.GridLayout33, 'text');
            app.TransientperiodEditField.ValueChangedFcn = createCallbackFcn(app, @TransientperiodEditFieldValueChanged, true);
            app.NumberofpointsLabel = uilabel(app.GridLayout33);
            app.NumberofpointsLabel.Text = 'Number of points';
            app.TimestepEditField = uieditfield(app.GridLayout33, 'text');
            app.TimestepEditField.ValueChangedFcn = createCallbackFcn(app, @TimestepEditFieldValueChanged, true);
            app.InitialtemperatureCLabel_2 = uilabel(app.GridLayout33);
            app.InitialtemperatureCLabel_2.Text = 'Initial temperature [°C]';
            app.InitialTemperatureEditField = uieditfield(app.GridLayout33, 'text');
            app.InitialTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @InitialTemperatureEditFieldValueChanged2, true);
            app.AmbienttemperatureCLabel = uilabel(app.GridLayout33);
            app.AmbienttemperatureCLabel.Text = 'Ambient temperature [°C]';
            app.AmbientTemperatureEditField = uieditfield(app.GridLayout33, 'text');
            app.AmbientTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @AmbientTemperatureEditFieldValueChanged, true);
            app.RotorspeedrpmEditFieldLabel_2 = uilabel(app.GridLayout33);
            app.RotorspeedrpmEditFieldLabel_2.Text = 'Rotor speed [rpm]';
            app.EvaluatedSpeedMirrorEdit = uieditfield(app.GridLayout33, 'text');
            app.EvaluatedSpeedMirrorEdit.ValueChangedFcn = createCallbackFcn(app, @EvaluatedSpeedMirrorEditValueChanged, true);
            app.ThermalSimulation_MCAD = uibutton(app.GridLayout33, 'push');
            app.ThermalSimulation_MCAD.ButtonPushedFcn = createCallbackFcn(app, @ThermalSimulation_MCADButtonPushed, true);
            app.ThermalSimulation_MCAD.Text = 'Therm Sim';
            app.CustomLossMCADCheck = uicheckbox(app.GridLayout33);
            app.CustomLossMCADCheck.ValueChangedFcn = createCallbackFcn(app, @CustomLossMCADCheckValueChanged, true);
            app.CustomLossMCADCheck.Text = '';
            app.RotorspeedrpmEditFieldLabel_3 = uilabel(app.GridLayout33);
            app.RotorspeedrpmEditFieldLabel_3.Text = 'Manual input of custom loss';
            app.ThermalevaluationtypeDropDownLabel = uilabel(app.GridLayout33);
            app.ThermalevaluationtypeDropDownLabel.Text = 'Thermal evaluation type';
            app.ThermalevaluationtypeDropDown = uidropdown(app.GridLayout33);
            app.ThermalevaluationtypeDropDown.Items = {'Steady State', 'Transient'};
            app.ThermalevaluationtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ThermalevaluationtypeDropDownValueChanged, true);
            app.ThermalevaluationtypeDropDown.Value = 'Steady State';
            app.ElectromagneticModulePanel = uipanel(app.GridLayout11);
            app.ElectromagneticModulePanel.Title = 'Electromagnetic Module';
            app.GridLayout36 = uigridlayout(app.ElectromagneticModulePanel);
            app.ExportFluxMapsPush = uibutton(app.GridLayout36, 'push');
            app.ExportFluxMapsPush.ButtonPushedFcn = createCallbackFcn(app, @ExportFluxMapsPushButtonPushed, true);
            app.ExportFluxMapsPush.Text = 'Export flux maps';
            app.StartMCadSimPush = uibutton(app.GridLayout36, 'push');
            app.StartMCadSimPush.ButtonPushedFcn = createCallbackFcn(app, @StartMCadSimPushButtonPushed, true);
            app.StartMCadSimPush.Text = 'EMag Sim';
            app.BuildThermalModelPanel = uipanel(app.GridLayout11);
            app.BuildThermalModelPanel.Title = 'Build Thermal Model';
            app.GridLayout34 = uigridlayout(app.BuildThermalModelPanel);
            app.InlettemperatureCEditFieldLabel = uilabel(app.GridLayout34);
            app.InlettemperatureCEditFieldLabel.Text = 'Inlet temperature [°C]';
            app.InlettemperatureEditField = uieditfield(app.GridLayout34, 'text');
            app.InlettemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @InlettemperatureEditFieldValueChanged, true);
            app.FluidflowratelminLabel = uilabel(app.GridLayout34);
            app.FluidflowratelminLabel.Text = 'Fluid flow rate [l/min]';
            app.FluidFlowRateEditField = uieditfield(app.GridLayout34, 'text');
            app.FluidFlowRateEditField.ValueChangedFcn = createCallbackFcn(app, @FluidFlowRateEditFieldValueChanged, true);
            app.ExportThermalParameters_MCADPush = uibutton(app.GridLayout34, 'push');
            app.ExportThermalParameters_MCADPush.ButtonPushedFcn = createCallbackFcn(app, @ExportThermalParameters_MCADPushButtonPushed, true);
            app.ExportThermalParameters_MCADPush.Text = 'Build Thermal';
            app.HousingTypeDropDownLabel = uilabel(app.GridLayout34);
            app.HousingTypeDropDownLabel.Text = 'Housing Type';
            app.HousingTypeDropDown = uidropdown(app.GridLayout34);
            app.HousingTypeDropDown.Items = {'Water Jacket (Spiral)', 'Water Jacket (Axial)', 'Axial fins (Servo)', 'None'};
            app.HousingTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @HousingTypeDropDownValueChanged, true);
            app.HousingTypeDropDown.Value = 'Water Jacket (Spiral)';
            app.FluidDropDownLabel = uilabel(app.GridLayout34);
            app.FluidDropDownLabel.Text = 'Fluid ';
            app.FluidDropDown = uidropdown(app.GridLayout34);
            app.FluidDropDown.Items = {'W/G 50/50', 'W/G 60/40', 'Water'};
            app.FluidDropDown.ValueChangedFcn = createCallbackFcn(app, @FluidDropDownValueChanged, true);
            app.FluidDropDown.Value = 'W/G 50/50';
            app.ExportMCadPush = uibutton(app.GridLayout11, 'push');
            app.ExportMCadPush.ButtonPushedFcn = createCallbackFcn(app, @ExportMCadPushButtonPushed, true);
            app.ExportMCadPush.Text = 'Model export';
            app.UtilitiesTab = uitab(app.TabGroup);
            app.UtilitiesTab.Title = 'Utilities';
            app.GridLayout3 = uigridlayout(app.UtilitiesTab);
            app.ExportPanel = uipanel(app.GridLayout3);
            app.ExportPanel.Title = 'Export';
            app.GridLayout37 = uigridlayout(app.ExportPanel);
            app.dxfButton = uibutton(app.GridLayout37, 'push');
            app.dxfButton.ButtonPushedFcn = createCallbackFcn(app, @dxfButtonPushed, true);
            app.dxfButton.Text = 'dxf';
            app.SaveMachineMagnetPush = uibutton(app.GridLayout37, 'push');
            app.SaveMachineMagnetPush.ButtonPushedFcn = createCallbackFcn(app, @SaveMachineMagnetPush_Callback, true);
            app.SaveMachineMagnetPush.Text = 'Simcenter MagNet';
            app.SaveMachineAnsysPush = uibutton(app.GridLayout37, 'push');
            app.SaveMachineAnsysPush.ButtonPushedFcn = createCallbackFcn(app, @SaveMachineAnsysPushButtonPushed, true);
            app.SaveMachineAnsysPush.Text = 'Ansys Maxwell';
            app.AnsysMotorCADButton = uibutton(app.GridLayout37, 'push');
            app.AnsysMotorCADButton.ButtonPushedFcn = createCallbackFcn(app, @AnsysMotorCADButtonPushed, true);
            app.AnsysMotorCADButton.Text = 'Ansys Motor-CAD';
            app.JMAGButton_2 = uibutton(app.GridLayout37, 'push');
            app.JMAGButton_2.ButtonPushedFcn = createCallbackFcn(app, @JMAGButton_2Pushed, true);
            app.JMAGButton_2.Text = 'JMAG';
            app.COMSOLButton = uibutton(app.GridLayout37, 'push');

            app.COMSOLButton.ButtonPushedFcn = createCallbackFcn(app, @COMSOLButtonPushed, true);
            app.COMSOLButton.Text = 'COMSOL';
            app.ParallelcomputingPanel = uipanel(app.GridLayout3);
            app.ParallelcomputingPanel.Title = 'Parallel computing';
            app.GridLayout39 = uigridlayout(app.ParallelcomputingPanel);
            app.CheckButton = uibutton(app.GridLayout39, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.EnableButton = uibutton(app.GridLayout39, 'push');
            app.EnableButton.ButtonPushedFcn = createCallbackFcn(app, @EnableButtonPushed, true);
            app.EnableButton.Text = 'Enable';
            app.DisableButton = uibutton(app.GridLayout39, 'push');
            app.DisableButton.ButtonPushedFcn = createCallbackFcn(app, @DisableButtonPushed, true);
            app.DisableButton.Text = 'Disable';
            app.DocumentationPanel = uipanel(app.GridLayout3);
            app.DocumentationPanel.Title = 'Documentation';
            app.GridLayout40 = uigridlayout(app.DocumentationPanel);
            app.UsermanualButton = uibutton(app.GridLayout40, 'push');
            app.UsermanualButton.ButtonPushedFcn = createCallbackFcn(app, @UsermanualButtonPushed, true);
            app.UsermanualButton.Text = 'User manual';
            app.ReferencesButton = uibutton(app.GridLayout40, 'push');
            app.ReferencesButton.ButtonPushedFcn = createCallbackFcn(app, @ReferencesButtonPushed, true);
            app.ReferencesButton.Text = 'References';
            app.CheckReleaseButton = uibutton(app.GridLayout40, 'push');
            app.CheckReleaseButton.ButtonPushedFcn = createCallbackFcn(app, @CheckReleaseButtonPushed, true);
            app.CheckReleaseButton.Text = 'Check Release';
            app.LaunchMMMButton = uibutton(app.GridLayout3, 'push');
            app.LaunchMMMButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchMMMButtonPushed, true);
            app.LaunchMMMButton.Text = 'Launch MMM';
            app.LaunchxbDesignPlaneExplorerButton = uibutton(app.GridLayout3, 'push');
            app.LaunchxbDesignPlaneExplorerButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchxbDesignPlaneExplorerButtonPushed, true);
            app.LaunchxbDesignPlaneExplorerButton.Text = {'Launch'; '(x,b) Design Plane Explorer'};
            app.toWorkSpaceButton = uibutton(app.GridLayout3, 'push');
            app.toWorkSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @toWorkSpaceButtonPushed, true);
            app.toWorkSpaceButton.Text = 'to WorkSpace';
            app.ZenodoopendatabasePanel = uipanel(app.GridLayout3);
            app.ZenodoopendatabasePanel.Title = 'Zenodo open database';
            app.GridLayout44 = uigridlayout(app.ZenodoopendatabasePanel);
            app.ZenodoDropDown = uidropdown(app.GridLayout44);
            app.ZenodoDropDown.ValueChangedFcn = createCallbackFcn(app, @ZenodoDropDownValueChanged, true);
            app.ZenodoTextArea = uitextarea(app.GridLayout44);
            app.ZenodoReadMeButton = uibutton(app.GridLayout44, 'push');
            app.ZenodoReadMeButton.ButtonPushedFcn = createCallbackFcn(app, @ZenodoReadMeButtonPushed, true);
            app.ZenodoReadMeButton.Text = 'ReadMe';
            app.ZenodoSaveButton = uibutton(app.GridLayout44, 'push');
            app.ZenodoSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ZenodoSaveButtonPushed, true);
            app.ZenodoSaveButton.Text = 'Save';
            app.ZenodoPEICHyperlink = uihyperlink(app.GridLayout44);
            app.ZenodoPEICHyperlink.URL = 'https://zenodo.org/communities/peic-polito/records';
            app.ZenodoPEICHyperlink.Text = 'https://zenodo.org/communities/peic-polito/records';
            app.ZenodoOpenButton = uibutton(app.GridLayout44, 'push');
            app.ZenodoOpenButton.ButtonPushedFcn = createCallbackFcn(app, @ZenodoOpenButtonPushed, true);
            app.ZenodoOpenButton.Text = 'Open';
            app.UpdatedmodelsandmoreonPEICPoliTOcommunityLabel = uilabel(app.GridLayout44);
            app.UpdatedmodelsandmoreonPEICPoliTOcommunityLabel.Text = 'Updated models and more on PEIC@PoliTO community:';
            app.FEMMXFEMMPanel = uipanel(app.GridLayout3);
            app.FEMMXFEMMPanel.Title = 'FEMM / XFEMM';
            app.GridLayout45 = uigridlayout(app.FEMMXFEMMPanel);
            app.XFEMMcreationButton = uibutton(app.GridLayout45, 'state');
            app.XFEMMcreationButton.ValueChangedFcn = createCallbackFcn(app, @XFEMMcreationButtonValueChanged, true);
            app.XFEMMcreationButton.Text = 'FEMM';
            app.ModelcreationLabel = uilabel(app.GridLayout45);
            app.ModelcreationLabel.Text = 'Model creation';
            app.ModelsimulationLabel = uilabel(app.GridLayout45);
            app.ModelsimulationLabel.Text = 'Model simulation';
            app.XFEMMsimulationButton = uibutton(app.GridLayout45, 'state');
            app.XFEMMsimulationButton.ValueChangedFcn = createCallbackFcn(app, @XFEMMsimulationButtonValueChanged, true);
            app.XFEMMsimulationButton.Text = 'FEMM';
            app.CloseallButton = uibutton(app.figure1, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Syre(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_Syre_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end