classdef GUI_Syre_MMM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        toWorkSpaceButton               matlab.ui.control.Button
        CloseallButton                  matlab.ui.control.Button
        NewButton                       matlab.ui.control.Button
        SaveAsPush                      matlab.ui.control.Button
        SavePush                        matlab.ui.control.Button
        LoadPush                        matlab.ui.control.Button
        MotorRatingsPanel               matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        InertiaEditField                matlab.ui.control.EditField
        Inertiakgm2EditFieldLabel       matlab.ui.control.Label
        RotorturnsperpoleEditField      matlab.ui.control.EditField
        RotorturnsperpoleEditFieldLabel  matlab.ui.control.Label
        TurnsinseriesperphaseEditField  matlab.ui.control.EditField
        TurnsinseriesperphaseEditFieldLabel  matlab.ui.control.Label
        MaxrotorcurrentAEditField       matlab.ui.control.EditField
        MaxrotorcurrentAEditFieldLabel  matlab.ui.control.Label
        RotorresistanceOhmEditField     matlab.ui.control.EditField
        RotorresistanceOhmEditFieldLabel  matlab.ui.control.Label
        RotorendwindingmmEditField      matlab.ui.control.EditField
        RotorendwindingmmEditFieldLabel  matlab.ui.control.Label
        RotormaxvoltageVEditField       matlab.ui.control.EditField
        RotormaxvoltageVEditFieldLabel  matlab.ui.control.Label
        RotorwindingtempCEditField      matlab.ui.control.EditField
        RotorwindingtempCEditFieldLabel  matlab.ui.control.Label
        AxistypeDropDown                matlab.ui.control.DropDown
        AxistypeDropDownLabel           matlab.ui.control.Label
        PMtemperatureDropDown           matlab.ui.control.DropDown
        PMtemperatureDropDownLabel      matlab.ui.control.Label
        RatedtorqueEditField            matlab.ui.control.EditField
        RatedtorqueNmEditFieldLabel     matlab.ui.control.Label
        PathnameEditField               matlab.ui.control.EditField
        PathnameEditFieldLabel          matlab.ui.control.Label
        MotornameEditField              matlab.ui.control.EditField
        MotornameEditFieldLabel         matlab.ui.control.Label
        EndwindinglengthEditField       matlab.ui.control.EditField
        StatorendwindingmmLabel         matlab.ui.control.Label
        RatedspeedEditField             matlab.ui.control.EditField
        RatedspeedrpmEditFieldLabel     matlab.ui.control.Label
        RatedpowerEditField             matlab.ui.control.EditField
        RatedpowerWEditFieldLabel       matlab.ui.control.Label
        MaximumspeedEditField           matlab.ui.control.EditField
        MaximumspeedrpmEditFieldLabel   matlab.ui.control.Label
        Numberof3phasesetsEditField     matlab.ui.control.EditField
        Numberof3phasesetsEditFieldLabel  matlab.ui.control.Label
        ActivelengthEditField           matlab.ui.control.EditField
        StacklengthmmLabel              matlab.ui.control.Label
        StatorwindingtempCEditField     matlab.ui.control.EditField
        StatorwindingtempCEditFieldLabel  matlab.ui.control.Label
        PhaseresistanceEditField        matlab.ui.control.EditField
        PhaseresistanceOhmEditFieldLabel  matlab.ui.control.Label
        DClinkvoltageEditField          matlab.ui.control.EditField
        DClinkvoltageVEditFieldLabel    matlab.ui.control.Label
        MaximumcurrentEditField         matlab.ui.control.EditField
        MaxcurrentApkEditFieldLabel     matlab.ui.control.Label
        RatedcurrentEditField           matlab.ui.control.EditField
        RatedcurrentApkEditFieldLabel   matlab.ui.control.Label
        MotortypeEditField              matlab.ui.control.EditField
        MotortypeEditFieldLabel         matlab.ui.control.Label
        SyReMagneticModelManipulationLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        InverseModelPanel               matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        SaveInverseDQTButton            matlab.ui.control.Button
        PlotInverseDQTButton            matlab.ui.control.Button
        EvalInverseDQTButton            matlab.ui.control.Button
        InversedqtFluxMapLabel          matlab.ui.control.Label
        InversedqtMapCheckBox           matlab.ui.control.CheckBox
        SaveInverseDQButton             matlab.ui.control.Button
        PlotInverseDQButton             matlab.ui.control.Button
        EvalInverseDQButton             matlab.ui.control.Button
        InversedqFluxMapLabel           matlab.ui.control.Label
        InversedqCheckBox               matlab.ui.control.CheckBox
        InductanceandAnisotropyMapsPanel  matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        SaveAppInductanceButton         matlab.ui.control.Button
        PlotAppInductanceButton         matlab.ui.control.Button
        EvalAppInductanceButton         matlab.ui.control.Button
        AppInductanceMapLabel           matlab.ui.control.Label
        AppInductanceMapCheckBox        matlab.ui.control.CheckBox
        SaveInductanceButton            matlab.ui.control.Button
        PlotInductanceButton            matlab.ui.control.Button
        EvalInductanceButton            matlab.ui.control.Button
        IncInductanceMapLabel           matlab.ui.control.Label
        InductanceMapsCheckBox          matlab.ui.control.CheckBox
        ControlTrajectoriesPanel        matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        EvaluateMTPAButton              matlab.ui.control.Button
        MethodLabel                     matlab.ui.control.Label
        PrintMTPAButton                 matlab.ui.control.Button
        SaveMTPAButton                  matlab.ui.control.Button
        PlotMTPAButton                  matlab.ui.control.Button
        LoadMTPAButton                  matlab.ui.control.Button
        ControlTrajectoriesLabel        matlab.ui.control.Label
        AOACheckBox                     matlab.ui.control.CheckBox
        MTPAmethodDropDown              matlab.ui.control.DropDown
        ModelsLoadedPanel               matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        SaveDemagnetizationButton       matlab.ui.control.Button
        PlotDemagnetizationButton       matlab.ui.control.Button
        LoadDemagnetizationButton       matlab.ui.control.Button
        DemagnetizationLimitLabel       matlab.ui.control.Label
        DemagnetizationLimitCheckBox    matlab.ui.control.CheckBox
        SaveSkinEffectButton            matlab.ui.control.Button
        PlotSkinEffectButton            matlab.ui.control.Button
        LoadSkinEffectButton            matlab.ui.control.Button
        ACLossModelLabel                matlab.ui.control.Label
        SkinEffectModelCheckBox         matlab.ui.control.CheckBox
        SaveIronLossModelButton         matlab.ui.control.Button
        PlotIronLossModelButton         matlab.ui.control.Button
        LoadIronLossModelButton         matlab.ui.control.Button
        dqIronLossMapLabel              matlab.ui.control.Label
        IronLossModelCheckBox           matlab.ui.control.CheckBox
        SaveDQTmodelButton              matlab.ui.control.Button
        PlotDQTmodelButton              matlab.ui.control.Button
        LoadDQTmodelButton              matlab.ui.control.Button
        dqtFluxMapLabel                 matlab.ui.control.Label
        dqtMapModelCheckBox             matlab.ui.control.CheckBox
        PrintDQmodelButton              matlab.ui.control.Button
        SaveDQmodelButton               matlab.ui.control.Button
        PlotDQmodelButton               matlab.ui.control.Button
        LoadDQmodelButton               matlab.ui.control.Button
        dqFluxMapLabel                  matlab.ui.control.Label
        dqModelCheckBox                 matlab.ui.control.CheckBox
        ScalingSkewingTab               matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        ClearScaleAxisPush              matlab.ui.control.Button
        LoadRefModelPush                matlab.ui.control.Button
        ModelSkewingPanel               matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        SkewingshapeDropDown            matlab.ui.control.DropDown
        SkewModelPush                   matlab.ui.control.Button
        UnskewModelPush                 matlab.ui.control.Button
        dqtMapskewingevaluationEditField  matlab.ui.control.EditField
        dqtMapskewingevaluationEditFieldLabel  matlab.ui.control.Label
        SkewPointsEditField             matlab.ui.control.EditField
        ofpointsalongoneaxisLabel       matlab.ui.control.Label
        SkewSliceEditField              matlab.ui.control.EditField
        NumberofaxialslicesEditFieldLabel_2  matlab.ui.control.Label
        SkewAngleEditField              matlab.ui.control.EditField
        SkewanglemechdegEditFieldLabel_2  matlab.ui.control.Label
        ModelScalingPanel               matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        RotortunsEditField              matlab.ui.control.EditField
        RotortunsperpoleEditFieldLabel  matlab.ui.control.Label
        ScaleMapPush                    matlab.ui.control.Button
        UnscaleModelPush                matlab.ui.control.Button
        ScaleModelPush                  matlab.ui.control.Button
        NewLlqEditField                 matlab.ui.control.EditField
        ExtraqaxisinductanceHLabel      matlab.ui.control.Label
        NewLldEditField                 matlab.ui.control.EditField
        ExtradaxisinductanceHLabel      matlab.ui.control.Label
        NewStatorRadiusEditField        matlab.ui.control.EditField
        StatorradiusmmLabel             matlab.ui.control.Label
        NewLengthEditField              matlab.ui.control.EditField
        ActivelengthmmEditFieldLabel    matlab.ui.control.Label
        NewTurnsEditField               matlab.ui.control.EditField
        TurnsinseriesperphaseEditField_2Label  matlab.ui.control.Label
        NewModelAxis                    matlab.ui.control.UIAxes
        TorqueSpeedTab                  matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        TorqueSpeedPerformanceMapEfficiencyMapPanel  matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        ControlsafeASCDropDown          matlab.ui.control.DropDown
        ControlsafeASCDropDownLabel     matlab.ui.control.Label
        TwControlDropDown               matlab.ui.control.DropDown
        ControlstrategyDropDownLabel    matlab.ui.control.Label
        TwSkinEffectDropDown            matlab.ui.control.DropDown
        SkineffectDropDownLabel         matlab.ui.control.Label
        TwPMlossDropDown                matlab.ui.control.DropDown
        PMlossDropDownLabel             matlab.ui.control.Label
        TwIronLossDropDown              matlab.ui.control.DropDown
        IronlossDropDownLabel           matlab.ui.control.Label
        MaxTwPWMPush                    matlab.ui.control.Button
        TwPMlossFactorEditField         matlab.ui.control.EditField
        PMlossfactorEditFieldLabel      matlab.ui.control.Label
        MaxTwPush                       matlab.ui.control.Button
        TwIronLossFactorEditField       matlab.ui.control.EditField
        IronlossfactorLabel             matlab.ui.control.Label
        TwMechLossEditField             matlab.ui.control.EditField
        MechlosspolyLabel               matlab.ui.control.Label
        TwTemperatureEditField          matlab.ui.control.EditField
        WindingtemperatureCLabel        matlab.ui.control.Label
        TwMinTorqueEditField            matlab.ui.control.EditField
        SpeedlimitsrpmEditFieldLabel_2  matlab.ui.control.Label
        TwNumTorqueEditField            matlab.ui.control.EditField
        TwMaxTorqueEditField            matlab.ui.control.EditField
        TwNumSpeedEditField             matlab.ui.control.EditField
        TwMaxSpeedEditField             matlab.ui.control.EditField
        ofpointsLabel                   matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        TwMinSpeedEditField             matlab.ui.control.EditField
        SpeedlimitsrpmEditFieldLabel    matlab.ui.control.Label
        TwAxis                          matlab.ui.control.UIAxes
        OperatingLimitsPanel            matlab.ui.container.Panel
        GridLayout15                    matlab.ui.container.GridLayout
        OpLimPush                       matlab.ui.control.Button
        OpLimLevelsEditField            matlab.ui.control.EditField
        CurrentlevelsLabel_2            matlab.ui.control.Label
        syreDriveTab                    matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        LinkedeDriveModelPanel          matlab.ui.container.Panel
        GridLayout30                    matlab.ui.container.GridLayout
        SelectButton                    matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        SimulatorEditField              matlab.ui.control.EditField
        EnvironmentEditFieldLabel       matlab.ui.control.Label
        eDrivefileEditField             matlab.ui.control.EditField
        eDrivefileEditFieldLabel        matlab.ui.control.Label
        CreateSimulinkModelButton       matlab.ui.control.Button
        CreatePLECSModelButton          matlab.ui.control.Button
        SensorlesscontrolPanel          matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        PositionerrorestimationDropDown  matlab.ui.control.DropDown
        PositionerrorestDropDownLabel   matlab.ui.control.Label
        DemodulationDropDown            matlab.ui.control.DropDown
        DemodulationDropDownLabel       matlab.ui.control.Label
        InjectedsignalDropDown          matlab.ui.control.DropDown
        InjectedsignalDropDownLabel     matlab.ui.control.Label
        EnableSScontrolLabel            matlab.ui.control.Label
        HighspeedregionLabel            matlab.ui.control.Label
        LowspeedregionHFvoltageinjectionLabel  matlab.ui.control.Label
        SensorlessSwitch                matlab.ui.control.Switch
        ConverterdataPanel              matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        PWMFrequencyEditField           matlab.ui.control.EditField
        PWMfrequencyHzLabel             matlab.ui.control.Label
        DeadtimeEditField               matlab.ui.control.EditField
        DeadtimeusEditFieldLabel        matlab.ui.control.Label
        InternalresistanceEditField     matlab.ui.control.EditField
        InternalresistanceOhmEditFieldLabel  matlab.ui.control.Label
        ONthreasholdEditField           matlab.ui.control.EditField
        ONthreasholdVEditFieldLabel     matlab.ui.control.Label
        ModelSetupPanel                 matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        ACLossModelDropDown             matlab.ui.control.DropDown
        ACLossModelDropDownLabel        matlab.ui.control.Label
        IronLossModelDropDown           matlab.ui.control.DropDown
        IronLossModelDropDownLabel      matlab.ui.control.Label
        ControlstrategyDropDown         matlab.ui.control.DropDown
        ControlstrategyDropDownLabel_2  matlab.ui.control.Label
        ControltypeDropDown             matlab.ui.control.DropDown
        ControltypeDropDownLabel        matlab.ui.control.Label
        FluxmapsmodelDropDown           matlab.ui.control.DropDown
        FluxmapsmodelDropDownLabel      matlab.ui.control.Label
        MotormodelDropDown              matlab.ui.control.DropDown
        MotormodelDropDownLabel         matlab.ui.control.Label
        InvertermodelDropDown           matlab.ui.control.DropDown
        InvertermodelDropDownLabel      matlab.ui.control.Label
        WaveformTab                     matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        LossforShortCircuitevaluationPanel  matlab.ui.container.Panel
        GridLayout27                    matlab.ui.container.GridLayout
        WaveformPMlossfactorEditField   matlab.ui.control.EditField
        WaveformironlossfactorEditField  matlab.ui.control.EditField
        WaveformAClossDropDown          matlab.ui.control.DropDown
        WaveformPMlossDropDown          matlab.ui.control.DropDown
        WaveformironlossDropDown        matlab.ui.control.DropDown
        ACcopperlossLabel               matlab.ui.control.Label
        PMlossLabel                     matlab.ui.control.Label
        IronlossLabel                   matlab.ui.control.Label
        EvaluationsPanel                matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        SinglePointsyreDriveButton      matlab.ui.control.Button
        EvaluateShortCircuitTorqueButton  matlab.ui.control.Button
        PlotTgammaButton                matlab.ui.control.Button
        WaveformShortCircuitButton      matlab.ui.control.Button
        WaveformSingPointButton         matlab.ui.control.Button
        SetupPanel                      matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        WaveformPeriodsEditField        matlab.ui.control.EditField
        ofperiodsLabel                  matlab.ui.control.Label
        WaveformSpeedEditField          matlab.ui.control.EditField
        RotorspeedrpmLabel              matlab.ui.control.Label
        WaveformCurrentEditField        matlab.ui.control.EditField
        PhaseCurrentAEditFieldLabel_2   matlab.ui.control.Label
        WaveformCurrentPUEditField      matlab.ui.control.EditField
        CurrentLoadpuEditFieldLabel_2   matlab.ui.control.Label
        WaveformGammaEditField          matlab.ui.control.EditField
        CurrentphaseangleeltdegEditFieldLabel_2  matlab.ui.control.Label
        ThermalTab                      matlab.ui.container.Tab
        GridLayout25                    matlab.ui.container.GridLayout
        InterpolatedqModelPanel         matlab.ui.container.Panel
        GridLayout26                    matlab.ui.container.GridLayout
        EvalInterpolateFluxMapsTemp     matlab.ui.control.Button
        TargetPMTempEditField           matlab.ui.control.EditField
        CurrentlevelsLabel_3            matlab.ui.control.Label
        SteadyStatePanel                matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        MagnetTempLimitEditField        matlab.ui.control.EditField
        MagnetlimitCLabel               matlab.ui.control.Label
        CopperTempLimitEditField        matlab.ui.control.EditField
        CopperlimitCLabel               matlab.ui.control.Label
        ThTwMinSpeedEditField           matlab.ui.control.EditField
        SpeedlimitsrpmEditFieldLabel_3  matlab.ui.control.Label
        AdjustFluxMapPMtemperatureCheckBox  matlab.ui.control.CheckBox
        ThermalLimitLossMapButton       matlab.ui.control.Button
        ThTwNumSpeedEditField           matlab.ui.control.EditField
        ThTwMaxSpeedEditField           matlab.ui.control.EditField
        ofpointsLabel_2                 matlab.ui.control.Label
        MaxLabel_2                      matlab.ui.control.Label
        MinLabel_2                      matlab.ui.control.Label
        ThermalTwAxis                   matlab.ui.control.UIAxes
        VFMTab                          matlab.ui.container.Tab
        GridLayout28                    matlab.ui.container.GridLayout
        DemagnetizatioRemagnetizationMapsPanel  matlab.ui.container.Panel
        GridLayout29                    matlab.ui.container.GridLayout
        SaveButtonDemagMap              matlab.ui.control.Button
        SaveButtonRemagMap              matlab.ui.control.Button
        PlotButtonDemagMap              matlab.ui.control.Button
        PlotButtonRemagMap              matlab.ui.control.Button
        LoadButtonDemagMap              matlab.ui.control.Button
        LoadButtonRemagMap              matlab.ui.control.Button
        DemagMapLabel                   matlab.ui.control.Label
        RemagMapLabel                   matlab.ui.control.Label
        CheckBoxRemagMap                matlab.ui.control.CheckBox
        CheckBoxDemagMap                matlab.ui.control.CheckBox
        CtrlSaveDeReMagButton           matlab.ui.control.Button
        CtrlPlotDeReMagButton           matlab.ui.control.Button
        CtrlEvalDeReMagButton           matlab.ui.control.Button
        ControlDeRemagLabel             matlab.ui.control.Label
        CtrlDeReMagCheckBox             matlab.ui.control.CheckBox
        AxisLogo                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        motorModel        % Motor model
        motorModelUnScale % Scaled motor model
        motorModelUnSkew  % Skewed motor model
        motorModelRef     % Reference motor model (for scale)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_Syre_MMM_StartUp(app, varargin)
            
            reset(groot); % reset graphics settings to default values
            
            setupPath(0);
            
            % Set figure at the center of the screen
            app.UIFigure.Units = 'pixels';
            screenPos = get(groot,'ScreenSize');
            figPos = app.UIFigure.Position;
            figPos(1:2)=screenPos(3:4)/2-figPos(3:4)/2;
            app.UIFigure.Position = figPos;
            
            % set the first tab
            app.TabGroup.SelectedTab = app.TabGroup.Children(1);
            
            % Axes update
            
            im = imread('syre.png');
            image(app.AxisLogo,im);
            set(app.AxisLogo,'dataAspectRatio',[1 1 1],'Visible','off')
            
            tmp = ver('matlab');
            vMatlab = eval(tmp.Version);
            vMatlabDate = datetime(tmp.Date,'Locale','en_US');
            if vMatlabDate>=datetime('18-Jul-2019','Locale','en_US')
                app.AxisLogo.Interactions = [];
                set(app.AxisLogo.Toolbar,'Visible','off')
                
                %app.NewModelAxis.Interactions = [];
                %set(app.NewModelAxis.Toolbar,'Visible','off')
                
                app.TwAxis.Interactions = [];
                set(app.TwAxis.Toolbar,'Visible','off')
            end
            if vMatlabDate>=datetime('14-May-2021','Locale','en_US')
               app.UIFigure.Icon = 'icon.png';
            end
            
            if length(varargin)==1
                motorModel = varargin{1};
            elseif length(varargin)==2
                motorModel = MMM_load(varargin{1},varargin{2});
            else
                motorModel = MMM_defaultMotorModel();
            end
            motorModel = MMM_back_compatibility(motorModel,0);
            
            if ~strcmp(motorModel.data.motorType,'EE')
                newOptions = {'MTPA', 'Max efficiency'};
            else
                newOptions = {'Min Overall Losses', 'Min Stator Losses', 'Min Rotor Losses', 'Min Overall Joule Losses', 'Min Stator Joule Losses', 'Min Rotor Joule Losses', 'Max Power Factor'};
            end
            newValue = newOptions{1};

            app.TwControlDropDown.Items = newOptions;
            app.TwControlDropDown.Value = newValue;

            app.motorModel = motorModel;
            app.motorModelUnScale = [];
            app.motorModelUnSkew  = [];
            app.motorModelRef     = [];
            MMM_GUI_SetParameters(app)
            
            
        end

        % Button pushed function: LoadPush
        function LoadPushButtonPushed(app, event)
            [filename,pathname] =uigetfile(checkPathSyntax([app.motorModel.data.pathname '*.mat']),'Select a motor file');
            if filename
                motorModel = MMM_load(pathname,filename);
                app.motorModelUnScale = [];
                app.motorModelUnSkew  = [];
                app.motorModelRef     = [];
            else
                motorModel = app.motorModel;
            end
            motorModel = MMM_back_compatibility(motorModel,0);
            
            if filename
                motorModel.data.pathname  = pathname;
                motorModel.data.motorName = filename(1:end-4);
            end


            if ~strcmp(motorModel.data.motorType,'EE')
                newOptions = {'MTPA', 'Max efficiency'};
            else
                newOptions = {'Min Overall Losses', 'Min Stator Losses', 'Min Rotor Losses', 'Min Overall Joule Losses', 'Min Stator Joule Losses', 'Min Rotor Joule Losses', 'Max Power Factor'};
            end
            newValue = newOptions{1};

            app.TwControlDropDown.Items = newOptions;
            app.TwControlDropDown.Value = newValue;
          
            app.motorModel = motorModel;
            MMM_GUI_SetParameters(app);
            
        end

        % Button pushed function: SavePush
        function SavePushButtonPushed(app, event)
            if (isempty(app.motorModelUnScale)&&isempty(app.motorModelUnSkew))
                motorModel = app.motorModel;
                save([app.motorModel.data.pathname app.motorModel.data.motorName '.mat'],'motorModel','-append')
                pathname = app.motorModel.data.pathname;
                resFolder = checkPathSyntax([app.motorModel.data.motorName '_results\MMM results\']);
                if ~exist([pathname resFolder],'dir')
                    mkdir([pathname resFolder]);
                end
%                 tempFolder = [pathname resFolder 'tempModels\'];
%                 if ~exist(tempFolder,'dir')
%                     mkdir(tempFolder);
%                 end
%                 
%                 save([pathname resFolder 'tempModels\motorModel_' int2str(motorModel.data.tempPM) 'deg.mat'],'motorModel');
%                 MMM_changeAxis_allTemperatures(app.motorModel)
            else
                hfig = uifigure;
                uialert(hfig,'There are some critical modifications! Save the model with name.','Scale or skew modifications unsaved','Error')
            end
        end

        % Button pushed function: SaveAsPush
        function SaveAsPushButtonPushed(app, event)
            motorModel = app.motorModel;
            [filename,pathname] = uiputfile([motorModel.data.pathname motorModel.data.motorName '.mat'],'Save Motor Model as');
            if filename
                fileMotOld  = [motorModel.data.motorName '.fem'];
                pathnameOld = motorModel.data.pathname; 
                motorModel.data.pathname  = pathname;
                motorModel.data.motorName = filename(1:end-4);
                save([pathname filename],'motorModel');
                
                if ~isempty(app.motorModelUnScale)
                    kR = app.motorModel.data.R/app.motorModelUnScale.data.R;
                else
                    kR=1;
                end
                
                if isfield(motorModel,'dataSet')
                    if ~isempty(motorModel.dataSet)
                        motorModel.dataSet.currentfilename = filename;
                        motorModel.dataSet.currentpathname = pathname;
                        dataSet = app.motorModel.dataSet;
                        geo     = app.motorModel.geo;
                        per     = app.motorModel.per;
                        mat     = app.motorModel.mat;
                        save([pathname filename],'dataSet','geo','mat','per','-append');
                        copyfile([pathnameOld fileMotOld],[pathname filename(1:end-4) '.fem']);
                        openfemm(0);
                        opendocument([pathname filename(1:end-4) '.fem'])
                        mi_probdef(0,'millimeters','planar',1e-8,geo.l,15);

                        if kR~=1
                            for ii=0:3001
                                mi_selectgroup(ii)
                            end
                            mi_scale2(0,0,kR,4)
                        end

                        mi_saveas([pathname filename(1:end-4) '.fem'])
                        mi_close;
                        closefemm();
                    end
                end
                
                %pathname = app.motorModel.data.pathname;
                resFolder = checkPathSyntax([motorModel.data.motorName '_results\MMM results\']);
                if ~exist([pathname resFolder],'dir')
                    mkdir([pathname resFolder]);
                end
%                 tempFolder = [pathname resFolder 'tempModels\'];
%                 if ~exist(tempFolder,'dir')
%                     mkdir(tempFolder);
%                 end
%                 save([pathname resFolder 'tempModels\motorModel_' int2str(motorModel.data.tempPM) 'deg.mat'],'motorModel');
                
                
                if ~isempty(app.motorModelUnScale)
                    [motorModel] = MMM_scale_allTemperatures(motorModel,app.motorModelUnScale);
                elseif ~isempty(app.motorModelUnSkew)
                    [motorModel] = MMM_skew_allTemperatures(motorModel,app.motorModelUnSkew);
                else
                    %MMM_saveAs_allTemperatures(motorModel.data.pathname, motorModel.data.motorName,pathnameOld, fileMotOld(1:end-4))
                end
                
                save([pathname filename],'motorModel');
                if isfield(motorModel,'dataSet')
                    save([pathname filename],'dataSet','geo','mat','per','-append');
                end
                
                app.motorModel = motorModel;
                app.motorModelUnSkew = [];
                app.motorModelUnScale = [];
                app.motorModelRef = [];
            end
            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function FileCheckPushButtonPushed(app, event)
            [filename,pathname] = uigetfile([app.motorModel.data.pathname app.motorModel.data.motorName '*.mat'],'Select a file to check');
            MMM_checkFile(pathname,filename);
        end

        % Value changed function: dqModelCheckBox
        function dqModelCheckBoxValueChanged(app, event)
            value = app.dqModelCheckBox.Value;
            if ~value
                app.motorModel.FluxMap_dq           = [];
                app.motorModel.IronPMLossMap_dq     = [];
                app.motorModel.FluxMap_dqt          = [];
                app.motorModel.acLossFactor         = [];
                app.motorModel.controlTrajectories  = [];
                app.motorModel.IncInductanceMap_dq  = [];
                app.motorModel.AppInductanceMap_dq  = [];
                app.motorModel.FluxMapInv_dq        = [];
                app.motorModel.FluxMapInv_dqt       = [];
                app.motorModel.DemagnetizationLimit = [];
                
                app.motorModelUnScale = [];
                app.motorModelUnSkew  = [];
            end
            
            MMM_GUI_SetParameters(app)
        end

        % Button pushed function: LoadDQmodelButton
        function LoadDQmodelButtonPushed(app, event)
            FEAfolder = checkPathSyntax([app.motorModel.data.pathname app.motorModel.data.motorName '_results\FEA results\']);
            if ~exist(FEAfolder,'dir')
                FEAfolder = app.motorModel.data.pathname;
            end
            [filename,pathname] = uigetfile([FEAfolder '*.mat'],'Load fdfq_idiq model');
            if filename
                data = load([pathname filename]);
                [fdfq,tempPM] = MMM_load_fdfq(data,app.motorModel.data.p);
                
                if isnan(tempPM)
                    tempPM = app.motorModel.data.tempPM;
                end
                
                % check for dqtMap
                if isfield(data,'dataSet')
                    if data.dataSet.NumOfRotPosPP>=20
                        if exist([pathname 'F_map.mat'],'file')
                            dqtMap = MMM_eval_dqtMap(pathname,'F_map.mat');
                        else
                            dqtMap = [];
                        end
                    else
                        dqtMap = [];
                    end
                else
                    dqtMap = [];
                end
                
                ironLoss = loadIronLossModel([pathname filename]);

                if isfield(data,'dataSet')
                    tmp = MMM_check_loadedAxisType(app.motorModel,data.dataSet,fdfq,dqtMap,ironLoss);
                else
                    disp('Axis check not possible!')
                    tmp.FluxMap_dq       = fdfq;
                    tmp.FluxMap_dqt      = dqtMap;
                    tmp.IronPMLossMap_dq = ironLoss;
                end
                
                if tempPM==app.motorModel.data.tempPM
                    app.motorModel.FluxMap_dq          = tmp.FluxMap_dq;
                    app.motorModel.FluxMap_dqt         = tmp.FluxMap_dqt;
                    app.motorModel.IronPMLossMap_dq    = tmp.IronPMLossMap_dq;
                    app.motorModel.controlTrajectories = [];
                    app.motorModel.IncInductanceMap_dq = [];
                    app.motorModel.FluxMapInv_dq       = [];
                    app.motorModel.FluxMapInv_dqt      = [];
                    app.motorModel.data.tempPM         = tempPM;
                    
                    app.motorModelUnScale = [];
                    app.motorModelUnSkew  = [];
                else
                    error('The selected map is done at a different temperature! Map not loaded');
                end
                
            end
            app.motorModel = MMM_updatePMtemperature(app.motorModel);
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: LoadDQTmodelButton
        function LoadDQTmodelButtonPushed(app, event)
            FEAfolder = checkPathSyntax([app.motorModel.data.pathname app.motorModel.data.motorName '_results\FEA results\']);
            if ~exist(FEAfolder,'dir')
                FEAfolder = app.motorModel.data.pathname;
            end
            [filename,pathname] = uigetfile([FEAfolder '*.mat'],'Load dqtMap model');
            if filename
                data = load([pathname filename]);
                if isfield(data,'dqtMap')
                    app.motorModel.FluxMap_dqt = data.dqtMap;
                elseif isfield(data,'F_map')
                    [dqtMap] = MMM_eval_dqtMap(pathname,filename);
                    app.motorModel.FluxMap_dqt = dqtMap;
                end
                
                if isfield(data,'dataSet')
                    app.motorModel = MMM_check_loadedAxisType(app.motorModel,data.dataSet,[],app.motorModel.FluxMap_dqt,[]);
                else
                    disp('Axis check not possible!')
                end

                app.motorModel.FluxMapInv_dqt = [];
                
                app.motorModelUnScale = [];
                app.motorModelUnSkew  = [];
            end
            app.motorModel = MMM_updatePMtemperature(app.motorModel);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: dqtMapModelCheckBox
        function dqtMapModelCheckBoxValueChanged(app, event)
            value = app.dqtMapModelCheckBox.Value;
            if ~value
                app.motorModel.FluxMap_dqt    = [];
                app.motorModel.FluxMapInv_dqt = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Value changed function: IronLossModelCheckBox
        function IronLossModelCheckBoxValueChanged(app, event)
            value = app.IronLossModelCheckBox.Value;
            if ~value
                app.motorModel.IronPMLossMap_dq = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Value changed function: SkinEffectModelCheckBox
        function SkinEffectModelCheckBoxValueChanged(app, event)
            value = app.SkinEffectModelCheckBox.Value;
            if ~value
                app.motorModel.acLossFactor = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Value changed function: AOACheckBox
        function AOACheckBoxValueChanged(app, event)
            value = app.AOACheckBox.Value;
            if ~value
                app.motorModel.controlTrajectories = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Value changed function: InductanceMapsCheckBox
        function InductanceMapsCheckBoxValueChanged(app, event)
            value = app.InductanceMapsCheckBox.Value;
            if ~value
                app.motorModel.IncInductanceMap_dq = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Value changed function: InversedqCheckBox
        function InversedqCheckBoxValueChanged(app, event)
            value = app.InversedqCheckBox.Value;
            if ~value
                app.motorModel.FluxMapInv_dq = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Value changed function: InversedqtMapCheckBox
        function InversedqtMapCheckBoxValueChanged(app, event)
            value = app.InversedqtMapCheckBox.Value;
            if ~value
                app.motorModel.FluxMapInv_dqt = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Button pushed function: LoadIronLossModelButton
        function LoadIronLossModelButtonPushed(app, event)
            FEAfolder = checkPathSyntax([app.motorModel.data.pathname app.motorModel.data.motorName '_results\FEA results\']);
            if ~exist(FEAfolder,'dir')
                FEAfolder = app.motorModel.data.pathname;
            end
            [filename,pathname] = uigetfile([FEAfolder '*.mat'],'Load iron loss model');
            if filename
                app.motorModel.IronPMLossMap_dq = loadIronLossModel([pathname filename]);
                data = load([pathname filename]);
                if isfield(data,'dataSet')
                    app.motorModel = MMM_check_loadedAxisType(app.motorModel,data.dataSet,[],[],app.motorModel.IronPMLossMap_dq);
                else
                    disp('Axis check not possible!')
                end
            end
            app.motorModel = MMM_updatePMtemperature(app.motorModel);
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: LoadSkinEffectButton
        function LoadSkinEffectButtonPushed(app, event)
            FEAfolder = checkPathSyntax([app.motorModel.data.pathname app.motorModel.data.motorName '_results\FEA results\']);
            if ~exist(FEAfolder,'dir')
                FEAfolder = app.motorModel.data.pathname;
            end
            [filename,pathname] = uigetfile([FEAfolder '\*.mat'],'Load skin effect model');
            if filename
                app.motorModel.acLossFactor = loadSkinEffectModel([pathname filename]);
            end
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: LoadMTPAButton
        function LoadMTPAButtonPushed(app, event)
            [AOA] = MMM_MTPAload(app.motorModel.data.pathname);
            app.motorModel.controlTrajectories = AOA;
            
            MMM_GUI_SetParameters(app)
        end

        % Button pushed function: PlotDQmodelButton
        function PlotDQmodelButtonPushed(app, event)
            MMM_plot_fdfq(app.motorModel);
        end

        % Button pushed function: PlotDQTmodelButton
        function PlotDQTmodelButtonPushed(app, event)
            MMM_plot_dqtMap(app.motorModel);
        end

        % Button pushed function: PlotIronLossModelButton
        function PlotIronLossModelButtonPushed(app, event)
            MMM_plot_ironLoss(app.motorModel);
        end

        % Button pushed function: PlotSkinEffectButton
        function PlotSkinEffectButtonPushed(app, event)
            MMM_plot_skinEffect(app.motorModel);
        end

        % Button pushed function: PlotMTPAButton
        function PlotMTPAButtonPushed(app, event)
            MMM_plot_MTPA(app.motorModel);
        end

        % Button pushed function: EvaluateMTPAButton
        function EvaluateMTPAButtonPushed(app, event)
            method = app.MTPAmethodDropDown.Value;
            [AOA] = MMM_eval_AOA(app.motorModel,method);
            app.motorModel.controlTrajectories = AOA;
            
            if ~(strcmp(app.motorModel.data.motorType,'EE')||strcmp(app.motorModel.data.motorType,'VFM'))
                iamp = abs(app.motorModel.controlTrajectories.MTPA.id + j*app.motorModel.controlTrajectories.MTPA.iq);
                famp = abs(app.motorModel.controlTrajectories.MTPA.fd + j*app.motorModel.controlTrajectories.MTPA.fq);
                id0 = interp1(iamp,app.motorModel.controlTrajectories.MTPA.id,app.motorModel.data.i0);
                iq0 = interp1(iamp,app.motorModel.controlTrajectories.MTPA.iq,app.motorModel.data.i0);
                f0 = interp1(iamp,famp,app.motorModel.data.i0);
                T0 = interp1(iamp,app.motorModel.controlTrajectories.MTPA.T,app.motorModel.data.i0);
            elseif strcmp(app.motorModel.data.motorType,'EE')
                iamp = abs(app.motorModel.controlTrajectories.MTPA.id(end,:) + j*app.motorModel.controlTrajectories.MTPA.iq(end,:));
                famp = abs(app.motorModel.controlTrajectories.MTPA.fd(end,:) + j*app.motorModel.controlTrajectories.MTPA.fq(end,:));
                id0_temp = app.motorModel.controlTrajectories.MTPA.id(end,:);
                iq0_temp = app.motorModel.controlTrajectories.MTPA.iq(end,:);
                id0 = interp1(iamp(~isnan(id0_temp)),id0_temp(~isnan(id0_temp)),app.motorModel.data.i0);
                iq0 = interp1(iamp(~isnan(iq0_temp)),iq0_temp(~isnan(iq0_temp)),app.motorModel.data.i0);
                f0 = interp1(iamp(~isnan(iamp)),famp(~isnan(iamp)),app.motorModel.data.i0);
                T_temp = app.motorModel.controlTrajectories.MTPA.T(end,:);
                T0 = interp1(iamp(~isnan(iamp)),T_temp(~isnan(iamp)),app.motorModel.data.i0);
            end
             if ~(strcmp(app.motorModel.data.motorType,'VFM'))
                app.motorModel.data.n0 = 30/pi/app.motorModel.data.p*(app.motorModel.data.Vdc/sqrt(3)/f0);
                app.motorModel.data.P0 = T0 * pi/30 * app.motorModel.data.n0;
                app.motorModel.data.T0 = T0;
                
                app.motorModel.WaveformSetup.CurrAngle = 180/pi * angle(id0 + j*iq0);
             end 
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: SaveDQmodelButton
        function SaveDQmodelButtonPushed(app, event)
            MMM_save_fdfq(app.motorModel)
        end

        % Button pushed function: SaveDQTmodelButton
        function SaveDQTmodelButtonPushed(app, event)
            MMM_save_dqtMap(app.motorModel)
        end

        % Button pushed function: SaveIronLossModelButton
        function SaveIronLossModelButtonPushed(app, event)
            MMM_save_ironLoss(app.motorModel);
        end

        % Button pushed function: SaveSkinEffectButton
        function SaveSkinEffectButtonPushed(app, event)
            MMM_save_skinEffect(app.motorModel);
        end

        % Button pushed function: SaveMTPAButton
        function SaveMTPAButtonPushed(app, event)
            MMM_save_AOA(app.motorModel);
        end

        % Button pushed function: PrintDQmodelButton
        function PrintDQmodelButtonPushed(app, event)
            MMM_print_fdfq(app.motorModel);
        end

        % Button pushed function: PrintMTPAButton
        function PrintMTPAButtonPushed(app, event)
            MMM_print_AOA(app.motorModel);
        end

        % Value changed function: MotornameEditField
        function MotornameEditFieldValueChanged(app, event)
            value = app.MotornameEditField.Value;
            app.motorModel.data.motorName = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: Numberof3phasesetsEditField
        function Numberof3phasesetsEditFieldValueChanged(app, event)
            value = app.Numberof3phasesetsEditField.Value;
            app.motorModel.data.n3phase = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: MotortypeEditField
        function MotortypeEditFieldValueChanged(app, event)
            value = app.MotortypeEditField.Value;
            app.motorModel.data.motorType = value;

            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function AxistypeEditFieldValueChanged(app, event)
            value = app.AxistypeEditField.Value;
            app.motorModel.data.axisType = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: RatedpowerEditField
        function RatedpowerEditFieldValueChanged(app, event)
            value = app.RatedpowerEditField.Value;
            app.motorModel.data.P0 = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: RatedcurrentEditField
        function RatedcurrentEditFieldValueChanged(app, event)
            value = app.RatedcurrentEditField.Value;
            app.motorModel.data.i0 = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: MaximumcurrentEditField
        function MaximumcurrentEditFieldValueChanged(app, event)
            value = app.MaximumcurrentEditField.Value;
            value = eval(value);
            if ~isempty(app.motorModel.FluxMap_dq)
                value = min([value max(abs(app.motorModel.FluxMap_dq.Id),[],'all') max(abs(app.motorModel.FluxMap_dq.Iq),[],'all')]);
            end
            app.motorModel.data.Imax = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: DClinkvoltageEditField
        function DClinkvoltageEditFieldValueChanged(app, event)
            value = app.DClinkvoltageEditField.Value;
            app.motorModel.data.Vdc = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: RatedspeedEditField
        function RatedspeedEditFieldValueChanged(app, event)
            value = app.RatedspeedEditField.Value;
            app.motorModel.data.n0 = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: MaximumspeedEditField
        function MaximumspeedEditFieldValueChanged(app, event)
            value = app.MaximumspeedEditField.Value;
            app.motorModel.data.nmax = eval(value);
            app.motorModel.TnSetup.nmax = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: PhaseresistanceEditField
        function PhaseresistanceEditFieldValueChanged(app, event)
            value = app.PhaseresistanceEditField.Value;
            app.motorModel.data.Rs = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: StatorwindingtempCEditField
        function StatorwindingtempCEditFieldValueChanged(app, event)
            value = app.StatorwindingtempCEditField.Value;
            tempNew = eval(value);
            %app.motorModel.data.Rs = app.motorModel.data.Rs*(235+tempNew)/(235+app.motorModel.data.tempCu); % adapt the phase resistance to the new temperature
            app.motorModel.data.Rs = calcRsTempFreq(app.motorModel.data.Rs,app.motorModel.data.tempCu,app.motorModel.mat.SlotCond.alpha,app.motorModel.data.l,app.motorModel.data.lend,[],'0',tempNew,0);
            app.motorModel.data.tempCu = tempNew;         
            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function PMtemperatureEditFieldValueChanged(app, event)
            value = app.PMtemperatureEditField.Value;
            app.motorModel.data.tempPM = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: ActivelengthEditField
        function ActivelengthEditFieldValueChanged(app, event)
            value = app.ActivelengthEditField.Value;
            app.motorModel.data.l = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TurnsinseriesperphaseEditField
        function TurnsinseriesperphaseEditFieldValueChanged(app, event)
            value = app.TurnsinseriesperphaseEditField.Value;
            app.motorModel.data.Ns = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: EvalInverseDQButton
        function EvalInverseDQButtonPushed(app, event)
            [idiq] = MMM_eval_inverseModel_dq(app.motorModel);
            app.motorModel.FluxMapInv_dq = idiq;
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: EvalInverseDQTButton
        function EvalInverseDQTButtonPushed(app, event)
            [dqtMapF] = MMM_eval_inverse_dqtMap(app.motorModel);
            app.motorModel.FluxMapInv_dqt = dqtMapF;
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: SaveInverseDQButton
        function SaveInverseDQButtonPushed(app, event)
            MMM_save_idiq(app.motorModel);
        end

        % Button pushed function: SaveInverseDQTButton
        function SaveInverseDQTButtonPushed(app, event)
            MMM_save_dqtMapF(app.motorModel);
        end

        % Button pushed function: PlotInverseDQButton
        function PlotInverseDQButtonPushed(app, event)
            MMM_plot_idiq(app.motorModel);
        end

        % Button pushed function: PlotInverseDQTButton
        function PlotInverseDQTButtonPushed(app, event)
            MMM_plot_dqtMapF(app.motorModel);
        end

        % Callback function
        function NumCurrLevelTgammaEditFieldValueChanged(app, event)
            value = app.NumCurrLevelTgammaEditField.Value;
            app.motorModel.data.nCurr = eval(value);
            MMM_GUI_SetParameters(app)
        end

        % Button pushed function: PlotTgammaButton
        function PlotTgammaButtonPushed(app, event)
            MMM_plot_VSgamma(app.motorModel);
        end

        % Callback function
        function DQTmodelCheckBoxValueChanged(app, event)
            value = app.DQTmodelCheckBox.Value;
            if ~value
                app.motorModel.FluxMap_dqt    = [];
                app.motorModel.FluxMapInv_dqt = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Callback function
        function DQTplotPushButtonPushed(app, event)
            MMM_plot_dqtMap(app.motorModel);
        end

        % Callback function
        function DQTsavePushButtonPushed(app, event)
            MMM_save_dqtMap(app.motorModel);
        end

        % Callback function
        function DQTevalPushButtonPushed(app, event)
            [dqtMap] = MMM_eval_dqtMap(app.motorModel.data.pathname);
            app.motorModel.FluxMap_dqt = dqtMap;
            
            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function DQTgammaEditFieldValueChanged(app, event)
            
            value = app.WaveformGammaEditField.Value;
            app.motorModel.dqtElab.CurrAngle = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function DQTcurrentPUEditFieldValueChanged(app, event)
            value = app.WaveformCurrentPUEditField.Value;
            app.motorModel.dqtElab.CurrLoad = eval(value);
            app.motorModel.dqtElab.CurrAmpl = eval(value)*app.motorModel.data.i0;
            
            iamp = abs(app.motorModel.controlTrajectories.MTPA.id + j*app.motorModel.controlTrajectories.MTPA.iq);
            idref = interp1(iamp,app.motorModel.controlTrajectories.MTPA.id,eval(value)*app.motorModel.data.i0);
            iqref = interp1(iamp,app.motorModel.controlTrajectories.MTPA.iq,eval(value)*app.motorModel.data.i0);
            
            app.motorModel.dqtElab.CurrAngle = 180/pi * angle(idref + j*iqref);
            
            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function DQTharmEditFieldValueChanged(app, event)
            value = app.DQTharmEditField.Value;
            app.motorModel.dqtElab.harmonic = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function DQTsingtButtonPushed(app, event)
            MMM_eval_OperatingPoint_dqtMap(app.motorModel);
        end

        % Callback function
        function DQTharmButtonPushed(app, event)
            MMM_plot_FFTdqtMap(app.motorModel);
        end

        % Value changed function: NewTurnsEditField
        function NewTurnsEditFieldValueChanged(app, event)
            value = app.NewTurnsEditField.Value;
            if isempty(app.motorModelUnScale)
                app.motorModelUnScale = app.motorModel;
            end
            app.motorModel.tmpScale.Ns = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: NewLengthEditField
        function NewLengthEditFieldValueChanged(app, event)
            value = app.NewLengthEditField.Value;
            if isempty(app.motorModelUnScale)
                app.motorModelUnScale = app.motorModel;
            end
            app.motorModel.tmpScale.l = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: NewLldEditField
        function NewLldEditFieldValueChanged(app, event)
            value = app.NewLldEditField.Value;
            if isempty(app.motorModelUnScale)
                app.motorModelUnScale = app.motorModel;
            end
            app.motorModel.tmpScale.Lld = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: NewLlqEditField
        function NewLlqEditFieldValueChanged(app, event)
            value = app.NewLlqEditField.Value;
            if isempty(app.motorModelUnScale)
                app.motorModelUnScale = app.motorModel;
            end
            app.motorModel.tmpScale.Llq = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: UnscaleModelPush
        function UnscaleModelPushButtonPushed(app, event)
            app.motorModel = app.motorModelUnScale;
            app.motorModelUnScale = [];
            
            cla(app.NewModelAxis);
            
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: ScaleModelPush
        function ScaleModelPushButtonPushed(app, event)
            app.motorModel = MMM_scale(app.motorModelUnScale,app.motorModel.tmpScale);
            
            cla(app.NewModelAxis);
            MMM_plot_scale(app.NewModelAxis,app.motorModelUnScale,'-b','Original');
            MMM_plot_scale(app.NewModelAxis,app.motorModel,'--r','Scaled');
            if ~isempty(app.motorModelRef)
                MMM_plot_scale(app.NewModelAxis,app.motorModelRef,':k','Reference');
            end
        end

        % Button pushed function: ClearScaleAxisPush
        function ClearScaleAxisPushButtonPushed(app, event)
            cla(app.NewModelAxis);
            app.motorModelRef = [];
        end

        % Button pushed function: UnskewModelPush
        function UnskewModelPushButtonPushed(app, event)
            app.motorModel = app.motorModelUnSkew;
            app.motorModelUnSkew = [];
            
            cla(app.NewModelAxis);
            
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: SkewAngleEditField
        function SkewAngleEditFieldValueChanged(app, event)
            value = app.SkewAngleEditField.Value;
            if isempty(app.motorModelUnSkew)
                app.motorModelUnSkew = app.motorModel;
            end
            app.motorModel.tmpSkew.thSkw = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: SkewSliceEditField
        function SkewSliceEditFieldValueChanged(app, event)
            value = app.SkewSliceEditField.Value;
            if isempty(app.motorModelUnSkew)
                app.motorModelUnSkew = app.motorModel;
            end
            app.motorModel.tmpSkew.nSlice = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: SkewPointsEditField
        function SkewPointsEditFieldValueChanged(app, event)
            value = app.SkewPointsEditField.Value;
            if isempty(app.motorModelUnSkew)
                app.motorModelUnSkew = app.motorModel;
            end
            app.motorModel.tmpSkew.nPoints = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: SkewModelPush
        function SkewModelPushButtonPushed(app, event)
            app.motorModel = MMM_skew(app.motorModelUnSkew,app.motorModel.tmpSkew,app.dqtMapskewingevaluationEditField);
            
            cla(app.NewModelAxis);
            MMM_plot_scale(app.NewModelAxis,app.motorModelUnSkew,'-b','Straight');
            MMM_plot_scale(app.NewModelAxis,app.motorModel,'--r','Skewed');
            if ~isempty(app.motorModelRef)
                MMM_plot_scale(app.NewModelAxis,app.motorModelRef,':k','Reference');
            end
        end

        % Button pushed function: LoadRefModelPush
        function LoadRefModelPushButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.mat');
            if filename
                data = load([pathname filename]);
                FluxMap_dq = MMM_load_fdfq(data,0);
                app.motorModelRef.FluxMap_dq       = FluxMap_dq;
                app.motorModelRef.FluxMap_dqt      = [];
                app.motorModelRef.IronPMLossMap_dq = [];
            else
                app.motorModelRef = app.motorModel;
            end
            MMM_GUI_SetParameters(app);
            MMM_plot_scale(app.NewModelAxis,app.motorModelRef,':k','Refefence');
        end

        % Callback function
        function ClearRefModelPushButtonPushed(app, event)
            app.motorModelRef = [];
            cla(app.NewModelAxis);
        end

        % Value changed function: OpLimLevelsEditField
        function OpLimLevelsEditFieldValueChanged(app, event)
            value = app.OpLimLevelsEditField.Value;
            app.motorModel.data.nCurr = eval(value);
            MMM_GUI_SetParameters(app)
        end

        % Button pushed function: OpLimPush
        function OpLimPushButtonPushed(app, event)
            MMM_eval_OpLim(app.motorModel);
        end

        % Callback function
        function RatingsEvalPushButtonPushed(app, event)
            app.motorModel = MMM_eval_Ratings(app.motorModel);
            
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwMinSpeedEditField
        function TwMinSpeedEditFieldValueChanged(app, event)
            value = app.TwMinSpeedEditField.Value;
            app.motorModel.TnSetup.nmin = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwMaxSpeedEditField
        function TwMaxSpeedEditFieldValueChanged(app, event)
            value = app.TwMaxSpeedEditField.Value;
            app.motorModel.TnSetup.nmax = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwNumSpeedEditField
        function TwNumSpeedEditFieldValueChanged(app, event)
            value = app.TwNumSpeedEditField.Value;
            app.motorModel.TnSetup.nstep = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwMinTorqueEditField
        function TwMinTorqueEditFieldValueChanged(app, event)
            value = app.TwMinTorqueEditField.Value;
            app.motorModel.TnSetup.Tmin = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwMaxTorqueEditField
        function TwMaxTorqueEditFieldValueChanged(app, event)
            value = app.TwMaxTorqueEditField.Value;
            app.motorModel.TnSetup.Tmax = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwNumTorqueEditField
        function TwNumTorqueEditFieldValueChanged(app, event)
            value = app.TwNumTorqueEditField.Value;
            app.motorModel.TnSetup.Tstep = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwTemperatureEditField
        function TwTemperatureEditFieldValueChanged(app, event)
            value = app.TwTemperatureEditField.Value;
            app.motorModel.TnSetup.temperature = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwMechLossEditField
        function TwMechLossEditFieldValueChanged(app, event)
            value = app.TwMechLossEditField.Value;
            app.motorModel.TnSetup.MechLoss = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwIronLossDropDown
        function TwIronLossDropDownValueChanged(app, event)
            value = app.TwIronLossDropDown.Value;
            if isempty(app.motorModel.IronPMLossMap_dq)
                value = 'No';
            end
            app.motorModel.TnSetup.IronLossFlag = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwIronLossFactorEditField
        function TwIronLossFactorEditFieldValueChanged(app, event)
            value = app.TwIronLossFactorEditField.Value;
            app.motorModel.TnSetup.IronLossFactor = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwSkinEffectDropDown
        function TwSkinEffectDropDownValueChanged(app, event)
            value = app.TwSkinEffectDropDown.Value;
            if isempty(app.motorModel.acLossFactor)
                value = 'No';
            end
            app.motorModel.TnSetup.SkinEffectFlag = value;
            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function TwSkinEffectMethodDropDownValueChanged(app, event)
            value = app.TwSkinEffectMethodDropDown.Value;
            app.motorModel.TnSetup.SkinEffectMethod = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwControlDropDown
        function TwControlDropDownValueChanged(app, event)
            value = app.TwControlDropDown.Value;
            app.motorModel.TnSetup.Control = value;
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: MaxTwPush
        function MaxTwPushButtonPushed(app, event)
            MMM_MaxTw(app.motorModel,app.TwAxis);
            cla(app.TwAxis);
        end

        % Button pushed function: EvalInductanceButton
        function EvalInductanceButtonPushed(app, event)
            app.motorModel.IncInductanceMap_dq = MMM_eval_inductanceMap(app.motorModel);
            
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: PlotInductanceButton
        function PlotInductanceButtonPushed(app, event)
            MMM_plot_InductanceMap(app.motorModel);
        end

        % Button pushed function: SaveInductanceButton
        function SaveInductanceButtonPushed(app, event)
            MMM_save_inductanceMap(app.motorModel);
        end

        % Value changed function: TwPMlossDropDown
        function TwPMlossDropDownValueChanged(app, event)
            value = app.TwPMlossDropDown.Value;
            app.motorModel.TnSetup.PMLossFlag = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TwPMlossFactorEditField
        function TwPMlossFactorEditFieldValueChanged(app, event)
            value = app.TwPMlossFactorEditField.Value;
            app.motorModel.TnSetup.PMLossFactor = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function RUNSimulinkModelButtonPushed(app, event)
            close_system('Syr_ctrl_SFun',0)
            motorModel = MMM_CtrlSIM(app.motorModel);
            MMM_GUI_SetParameters(app);          
            MMM_SIMplot(motorModel);
        end

        % Value changed function: InertiaEditField
        function InertiaEditFieldValueChanged(app, event)
            value = app.InertiaEditField.Value;
            app.motorModel.data.J = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: ControltypeDropDown
        function ControltypeDropDownValueChanged(app, event)
            value = app.ControltypeDropDown.Value;
            app.motorModel.SyreDrive.modelSetup.Ctrl_type = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: FluxmapsmodelDropDown
        function FluxmapsmodelDropDownValueChanged(app, event)
            value = app.FluxmapsmodelDropDown.Value;
            app.motorModel.SyreDrive.modelSetup.FMapsModel = value;
            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function ONthreasholdEditFieldValueChanged(app, event)
            value = app.ONthreasholdEditField.Value;
            app.motorModel.SyreDrive.Converter.V0 = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: InternalresistanceEditField
        function InternalresistanceEditFieldValueChanged(app, event)
            value = app.InternalresistanceEditField.Value;
            app.motorModel.SyreDrive.Converter.Rd = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: DeadtimeEditField
        function DeadtimeEditFieldValueChanged(app, event)
            value = app.DeadtimeEditField.Value;
            app.motorModel.SyreDrive.Converter.dT = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: ONthreasholdEditField
        function ONthreasholdEditFieldValueChanged2(app, event)
            value = app.ONthreasholdEditField.Value;
            app.motorModel.SyreDrive.Converter.V0 = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: EvaluateShortCircuitTorqueButton
        function EvaluateShortCircuitTorqueButtonPushed(app, event)
            MMM_eval_shortCircuit_steadyState(app.motorModel);
        end

        % Value changed function: PMtemperatureDropDown
        function PMtemperatureDropDownValueChanged(app, event)
            value = app.PMtemperatureDropDown.Value;
            
            [app.motorModel] = MMM_changePMtemperature(app.motorModel,value);
            MMM_GUI_SetParameters(app);
            
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            app.motorModel        = [];
            app.motorModelRef     = [];
            app.motorModelUnScale = [];
            app.motorModelUnSkew  = [];
            
            [motorModel] = MMM_newModel();
            motorModel = MMM_back_compatibility(motorModel,0);
            
            save([motorModel.data.pathname motorModel.data.motorName '.mat'],'motorModel');
            
            app.motorModel = motorModel;
            
            cla(app.NewModelAxis);
            cla(app.TwAxis);
            
            MMM_GUI_SetParameters(app);
            
        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            close all
            clc
            cla(app.NewModelAxis);
            cla(app.TwAxis);
        end

        % Value changed function: SensorlessSwitch
        function SensorlessSwitchValueChanged(app, event)
            value = app.SensorlessSwitch.Value;
            app.motorModel.SyreDrive.SS_on = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: DemodulationDropDown
        function DemodulationDropDownValueChanged(app, event)
            value = app.DemodulationDropDown.Value;
            app.motorModel.SyreDrive.SS_settings.dem = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: InjectedsignalDropDown
        function InjectedsignalDropDownValueChanged(app, event)
            value = app.InjectedsignalDropDown.Value;
            app.motorModel.SyreDrive.SS_settings.inj_waveform = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: PositionerrorestimationDropDown
        function PositionerrorestimationDropDownValueChanged(app, event)
            value = app.PositionerrorestimationDropDown.Value;
            app.motorModel.SyreDrive.SS_settings.HS_ctrl = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: WaveformGammaEditField
        function WaveformGammaEditFieldValueChanged(app, event)
            value = app.WaveformGammaEditField.Value;
            app.motorModel.WaveformSetup.CurrAngle = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: WaveformCurrentPUEditField
        function WaveformCurrentPUEditFieldValueChanged(app, event)
            value = app.WaveformCurrentPUEditField.Value;
            app.motorModel.WaveformSetup.CurrLoad = eval(value);
            app.motorModel.WaveformSetup.CurrAmpl = eval(value)*app.motorModel.data.i0;
            if ~isempty(app.motorModel.controlTrajectories)
                app.motorModel.WaveformSetup.CurrAngle = interp1(...
                    abs(app.motorModel.controlTrajectories.MTPA.id+j*app.motorModel.controlTrajectories.MTPA.iq),...
                    angle(app.motorModel.controlTrajectories.MTPA.id+j*app.motorModel.controlTrajectories.MTPA.iq)*180/pi,...
                    app.motorModel.WaveformSetup.CurrAmpl);
            end
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: WaveformSpeedEditField
        function WaveformSpeedEditFieldValueChanged(app, event)
            value = app.WaveformSpeedEditField.Value;
            app.motorModel.WaveformSetup.EvalSpeed = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: WaveformPeriodsEditField
        function WaveformPeriodsEditFieldValueChanged(app, event)
            value = app.WaveformPeriodsEditField.Value;
            value = round(eval(value));
            app.motorModel.WaveformSetup.nCycle = value;
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: WaveformSingPointButton
        function WaveformSingPointButtonPushed(app, event)
            MMM_eval_OperatingPoint_dqtMap(app.motorModel);
        end

        % Button pushed function: WaveformShortCircuitButton
        function WaveformShortCircuitButtonPushed(app, event)
            if ((length(app.motorModel.WaveformSetup.CurrLoad)>1)||(length(app.motorModel.WaveformSetup.CurrAmpl)>1)||(length(app.motorModel.WaveformSetup.CurrAngle)>1))
                app.motorModel.WaveformSetup.CurrLoad  = app.motorModel.WaveformSetup.CurrLoad(1);
                app.motorModel.WaveformSetup.CurrAmpl  = app.motorModel.WaveformSetup.CurrAmpl(1);
                app.motorModel.WaveformSetup.CurrAngle = app.motorModel.WaveformSetup.CurrAngle(1);
                MMM_GUI_SetParameters(app);
            end
            MMM_eval_shortCircuit_transient(app.motorModel);
            
        end

        % Value changed function: AxistypeDropDown
        function AxistypeDropDownValueChanged(app, event)
            value = app.AxistypeDropDown.Value;
            app.motorModel = MMM_changeAxis(app.motorModel,value);
            MMM_GUI_SetParameters(app);
            
        end

        % Value changed function: NewStatorRadiusEditField
        function NewStatorRadiusEditFieldValueChanged(app, event)
            value = app.NewStatorRadiusEditField.Value;
            if isempty(app.motorModelUnScale)
                app.motorModelUnScale = app.motorModel;
            end
            app.motorModel.tmpScale.R = eval(value);
            
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: CopperTempLimitEditField
        function CopperTempLimitEditFieldValueChanged(app, event)
            value = app.CopperTempLimitEditField.Value;
            app.motorModel.Thermal.TempCuLimit = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: MagnetTempLimitEditField
        function MagnetTempLimitEditFieldValueChanged(app, event)
            value = app.MagnetTempLimitEditField.Value;
            app.motorModel.Thermal.TempPmLimit = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: ThTwMinSpeedEditField
        function ThTwMinSpeedEditFieldValueChanged(app, event)
            value = app.ThTwMinSpeedEditField.Value;
            app.motorModel.Thermal.nmin = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: ThTwMaxSpeedEditField
        function ThTwMaxSpeedEditFieldValueChanged(app, event)
            value = app.ThTwMaxSpeedEditField.Value;
            app.motorModel.Thermal.nmax = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: ThTwNumSpeedEditField
        function ThTwNumSpeedEditFieldValueChanged(app, event)
            value = app.ThTwNumSpeedEditField.Value;
            app.motorModel.Thermal.NumSpeed = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function ThermalLimitFluxMapButtonPushed(app, event)
            MMM_Eval_ThermalLimit_FluxMap(app.motorModel,app.ThermalTwAxis)
            cla(app.ThermalTwAxis);
        end

        % Button pushed function: ThermalLimitLossMapButton
        function ThermalLimitLossMapButtonPushed(app, event)
            MMM_eval_thermalLimit_LossMap(app.motorModel,app.ThermalTwAxis);
            cla(app.ThermalTwAxis);
        end

        % Value changed function: EndwindinglengthEditField
        function EndwindinglengthEditFieldValueChanged(app, event)
            value = app.EndwindinglengthEditField.Value;

            app.motorModel.data.lend = eval(value);

            app.motorModel.data.Rs = app.motorModel.data.Rs*(eval(value)+app.motorModel.data.l)/(app.motorModel.dataSet.EndWindingsLength+app.motorModel.data.l);
            app.motorModel.dataSet.EndWindingsLength = eval(value);

            MMM_GUI_SetParameters(app);
        end

        % Value changed function: AdjustFluxMapPMtemperatureCheckBox
        function AdjustFluxMapPMtemperatureCheckBoxValueChanged(app, event)
            value = app.AdjustFluxMapPMtemperatureCheckBox.Value;
            app.motorModel.Thermal.interpTempPM = value;
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: EvalInterpolateFluxMapsTemp
        function EvalInterpolateFluxMapsTempPushed(app, event)
            app.motorModel = MMM_eval_InterpFluxMapTemp(app.motorModel);

            MMM_GUI_SetParameters(app);
        end

        % Value changed function: TargetPMTempEditField
        function TargetPMTempEditFieldValueChanged(app, event)
            value = app.TargetPMTempEditField.Value;
            app.motorModel.data.targetPMtemp = eval(value);

            MMM_GUI_SetParameters(app);
        end

        % Value changed function: InvertermodelDropDown
        function InvertermodelDropDownValueChanged(app, event)
            value = app.InvertermodelDropDown.Value;
            app.motorModel.SyreDrive.modelSetup.InverterModel = value;
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: ScaleMapPush
        function ScaleMapPushButtonPushed(app, event)
            MMM_eval_mapScale(app.motorModel);
        end

        % Value changed function: RatedtorqueEditField
        function RatedtorqueEditFieldValueChanged(app, event)
            value = app.RatedtorqueEditField.Value;
            app.motorModel.data.T0 = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: CreatePLECSModelButton
        function CreatePLECSModelButtonPushed(app, event)
            app.motorModel = MMM_createPLECSmodel(app.motorModel);
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: CreateSimulinkModelButton
        function CreateSimulinkModelButtonPushed(app, event)
            app.motorModel = MMM_createSimulinkModel(app.motorModel);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: IronLossModelDropDown
        function IronLossModelDropDownValueChanged(app, event)
            value = app.IronLossModelDropDown.Value;
            if isempty(app.motorModel.IronPMLossMap_dq)
                value = 'No';
            end
            app.motorModel.SyreDrive.modelSetup.IronLoss = value;
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: EvalAppInductanceButton
        function EvalAppInductanceButtonPushed(app, event)
            app.motorModel.AppInductanceMap_dq = MMM_eval_appInductanceMap(app.motorModel);
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: PlotAppInductanceButton
        function PlotAppInductanceButtonPushed(app, event)
            MMM_plot_AppInductanceMap(app.motorModel);
        end

        % Button pushed function: SaveAppInductanceButton
        function SaveAppInductanceButtonPushed(app, event)
            MMM_save_appInductanceMap(app.motorModel);
        end

        % Button pushed function: toWorkSpaceButton
        function toWorkSpaceButtonPushed(app, event)
            assignin('base','motorModel',app.motorModel);
        end

        % Value changed function: PWMFrequencyEditField
        function PWMFrequencyEditFieldValueChanged(app, event)
            value = app.PWMFrequencyEditField.Value;
            app.motorModel.SyreDrive.Converter.fPWM = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: MaxTwPWMPush
        function MaxTwPWMPushButtonPushed(app, event)
            MMM_MaxTwPWM(app.motorModel,app.TwAxis);
            cla(app.TwAxis);
        end

        % Button pushed function: LoadDemagnetizationButton
        function LoadDemagnetizationButtonPushed(app, event)
            FEAfolder = checkPathSyntax([app.motorModel.data.pathname app.motorModel.data.motorName '_results\FEA results\']);
            if ~exist(FEAfolder,'dir')
                FEAfolder = app.motorModel.data.pathname;
            end
            [filename,pathname] = uigetfile([FEAfolder '*.mat'],'Load fdfq_idiq model');
            if filename
                app.motorModel.DemagnetizationLimit = MMM_load_demag([pathname filename]);
            end
            
            MMM_GUI_SetParameters(app);
            
        end

        % Button pushed function: PlotDemagnetizationButton
        function PlotDemagnetizationButtonPushed(app, event)
            MMM_plot_demagLimit(app.motorModel);
        end

        % Value changed function: ControlstrategyDropDown
        function ControlstrategyDropDownValueChanged(app, event)
            value = app.ControlstrategyDropDown.Value;
            app.motorModel.SyreDrive.modelSetup.Ctrl_strategy = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: ControlsafeASCDropDown
        function ControlsafeASCDropDownValueChanged(app, event)
            value = app.ControlsafeASCDropDown.Value;
            if isempty(app.motorModel.DemagnetizationLimit)
                value = 'No';
            end
            app.motorModel.TnSetup.ASCsafeFlag = value;
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: SaveDemagnetizationButton
        function SaveDemagnetizationButtonPushed(app, event)
            MMM_save_demagLimit(app.motorModel)
        end

        % Value changed function: AppInductanceMapCheckBox
        function AppInductanceMapCheckBoxValueChanged(app, event)
            value = app.AppInductanceMapCheckBox.Value;
            if ~value
                app.motorModel.AppInductanceMap_dq = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Value changed function: MotormodelDropDown
        function MotormodelDropDownValueChanged(app, event)
            value = app.MotormodelDropDown.Value;
            app.motorModel.SyreDrive.modelSetup.motorModelType = value;
            switch value
                case 'Controlled Current Generators (CCG)'

                case 'Voltage-Behing-Reactance (VBR)'
                    error('Work in progress...')
                case 'SimScape FEM-based PMSM'
                    app.motorModel.SyreDrive.modelSetup.FMapsModel = 'dqt Model';
                    app.motorModel.SyreDrive.modelSetup.WindingLossAC = 'No';
            end
            MMM_GUI_SetParameters(app)
        end

        % Value changed function: ACLossModelDropDown
        function ACLossModelDropDownValueChanged(app, event)
            value = app.ACLossModelDropDown.Value;
            if isempty(app.motorModel.acLossFactor)
                value = 'No';
            end
            if strcmp(app.motorModel.SyreDrive.modelSetup.motorModelType,'SimScape FEM-based PMSM')
                value = 'No';
            end
            app.motorModel.SyreDrive.modelSetup.WindingLossAC = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: WaveformironlossDropDown
        function WaveformironlossDropDownValueChanged(app, event)
            value = app.WaveformironlossDropDown.Value;
            if isempty(app.motorModel.IronPMLossMap_dq)
                value = 'No';
            end
            app.motorModel.WaveformSetup.IronLossFlag = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: WaveformPMlossDropDown
        function WaveformPMlossDropDownValueChanged(app, event)
            value = app.WaveformPMlossDropDown.Value;
            if isempty(app.motorModel.IronPMLossMap_dq)
                value = 'No';
            end
            app.motorModel.WaveformSetup.PMLossFlag = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: WaveformAClossDropDown
        function WaveformAClossDropDownValueChanged(app, event)
            value = app.WaveformAClossDropDown.Value;
            if isempty(app.motorModel.acLossFactor)
                value = 'No';
            end
            app.motorModel.WaveformSetup.ACLossFlag = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: WaveformironlossfactorEditField
        function WaveformironlossfactorEditFieldValueChanged(app, event)
            value = app.WaveformironlossfactorEditField.Value;
            value = eval(value);
            if value<0
                value = 1;
            end
            app.motorModel.WaveformSetup.IronLossFactor = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: WaveformPMlossfactorEditField
        function WaveformPMlossfactorEditFieldValueChanged(app, event)
            value = app.WaveformPMlossfactorEditField.Value;
            value = eval(value);
            if value<0
                value = 1;
            end
            app.motorModel.WaveformSetup.PMLossFactor = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: CtrlDeReMagCheckBox
        function CtrlDeReMagCheckBoxValueChanged(app, event)
            value = app.CtrlDeReMagCheckBox.Value;
            if ~value
                app.motorModel.VFMdata.Control = [];
            end
            MMM_GUI_SetParameters(app)
        end

        % Button pushed function: CtrlEvalDeReMagButton
        function CtrlEvalDeReMagButtonPushed(app, event)
            app.motorModel.VFMdata.Control = MMM_eval_DeReMagMap(app.motorModel);
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: CtrlPlotDeReMagButton
        function CtrlPlotDeReMagButtonPushed(app, event)
            MMM_plot_DeReMagMaps(app.motorModel);
        end

        % Button pushed function: CtrlSaveDeReMagButton
        function CtrlSaveDeReMagButtonPushed(app, event)
            MMM_save_DeReMagMaps(app.motorModel);
        end

        % Button pushed function: PlotButtonRemagMap
        function PlotButtonRemagMapPushed(app, event)
            MMM_plot_RemagMap(app.motorModel)
        end

        % Value changed function: CheckBoxDemagMap
        function CheckBoxDemagMapValueChanged(app, event)
            value = app.CheckBoxDemagMap.Value;
            if ~value
                app.motorModel.VFMdata.data.Demag = [];
            end

            MMM_GUI_SetParameters(app)
        end

        % Button pushed function: LoadButtonRemagMap
        function LoadButtonRemagMapPushed(app, event)
            error('Works in progress')
            % FEAfolder = checkPathSyntax([app.motorModel.data.pathname app.motorModel.data.motorName '_results\FEA results\']);
            % if ~exist(FEAfolder,'dir')
            %     FEAfolder = app.motorModel.data.pathname;
            % end
            % [filename,pathname] = uigetfile([FEAfolder '*.mat'],'Load RemagMap model');
            % if filename
            %     app.motorModel.VFM.data.Remag = MMM_load_RemagMap([pathname filename]);
            % end
            % 
            % MMM_GUI_SetParameters(app);
            
        end

        % Button pushed function: LoadButtonDemagMap
        function LoadButtonDemagMapPushed(app, event)
            error('Works in progress')
            % FEAfolder = checkPathSyntax([app.motorModel.data.pathname app.motorModel.data.motorName '_results\FEA results\']);
            % if ~exist(FEAfolder,'dir')
            %     FEAfolder = app.motorModel.data.pathname;
            % end
            % [filename,pathname] = uigetfile([FEAfolder '*.mat'],'Load DemagMap model');
            % if filename
            %     app.motorModel.VFM.data.Demag = MMM_load_DemagMap([pathname filename]);
            % end
            % 
            % MMM_GUI_SetParameters(app);
            
        end

        % Button pushed function: PlotButtonDemagMap
        function PlotButtonDemagMapPushed(app, event)
            MMM_plot_DemagMap(app.motorModel)
        end

        % Value changed function: CheckBoxRemagMap
        function CheckBoxRemagMapValueChanged(app, event)
            value = app.CheckBoxRemagMap.Value;
            if ~value
                app.motorModel.VFMdata.data.Remag = [];
            end

            MMM_GUI_SetParameters(app);
        end

        % Value changed function: MaxrotorcurrentAEditField
        function MaxrotorcurrentAEditFieldValueChanged(app, event)
            value = app.MaxrotorcurrentAEditField.Value;
            app.motorModel.data.if0 = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: RotormaxvoltageVEditField
        function RotormaxvoltageVEditFieldValueChanged(app, event)
            value = app.RotormaxvoltageVEditField.Value;
            app.motorModel.data.Vr = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Callback function
        function RotorturnsperpoleTextAreaValueChanged(app, event)
            
        end

        % Value changed function: RotorturnsperpoleEditField
        function RotorturnsperpoleEditFieldValueChanged(app, event)
            value = app.RotorturnsperpoleEditField.Value;
            app.motorModel.data.Nr = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: RotorendwindingmmEditField
        function RotorendwindingmmEditFieldValueChanged(app, event)
            value = app.RotorendwindingmmEditField.Value;
            app.motorModel.data.lendf = eval(value);
            app.motorModel.data.Rf = app.motorModel.data.Rf*(eval(value)+app.motorModel.data.l)/(app.motorModel.dataSet.RotorEndWindingsLength+app.motorModel.data.l);
            app.motorModel.dataSet.RotorEndWindingsLength = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: RotorwindingtempCEditField
        function RotorwindingtempCEditFieldValueChanged(app, event)
            value = app.RotorwindingtempCEditField.Value;
            tempNew = eval(value);
            tmpRf = calcRsTempFreq(app.motorModel.data.Rf,app.motorModel.data.tempCoRo,app.motorModel.mat.BarCond.alpha,app.motorModel.data.l,app.motorModel.data.lendf,[],'0',tempNew,0);
            app.motorModel.data.Rf = round(tmpRf,3);
            app.motorModel.data.tempCoRo = tempNew;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: RotorresistanceOhmEditField
        function RotorresistanceOhmEditFieldValueChanged(app, event)
            value = app.RotorresistanceOhmEditField.Value;
            app.motorModel.data.Rf = eval(value);
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: SinglePointsyreDriveButton
        function SinglePointsyreDriveButtonPushed(app, event)
            MMM_eval_OperatingPoint_syreDrive(app.motorModel);
        end

        % Value changed function: SkewingshapeDropDown
        function SkewingshapeDropDownValueChanged(app, event)
            value = app.SkewingshapeDropDown.Value;
            app.motorModel.tmpSkew.shape = value;
            MMM_GUI_SetParameters(app);
        end

        % Value changed function: RotortunsEditField
        function RotortunsEditFieldValueChanged(app, event)
            value = app.RotortunsEditField.Value;
            if isempty(app.motorModelUnScale)
                app.motorModelUnScale = app.motorModel;
            end

            app.motorModel.tmpScale.Nr = eval(value);

            MMM_GUI_SetParameters(app)
        end

        % Value changed function: WaveformCurrentEditField
        function WaveformCurrentEditFieldValueChanged(app, event)
            value = app.WaveformCurrentEditField.Value;
            app.motorModel.WaveformSetup.CurrAmpl = eval(value);
            app.motorModel.WaveformSetup.CurrLoad = eval(value)/app.motorModel.data.i0;
            if ~isempty(app.motorModel.controlTrajectories)
                app.motorModel.WaveformSetup.CurrAngle = interp1(...
                    abs(app.motorModel.controlTrajectories.MTPA.id+j*app.motorModel.controlTrajectories.MTPA.iq),...
                    angle(app.motorModel.controlTrajectories.MTPA.id+j*app.motorModel.controlTrajectories.MTPA.iq)*180/pi,...
                    app.motorModel.WaveformSetup.CurrAmpl);
            end
            
            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.motorModel.SyreDrive.SIM_file  = [];
            app.motorModel.SyreDrive.Simulator = [];

            MMM_GUI_SetParameters(app);
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            [filename,pathname] = uigetfile([cd],'Select syreDrive model to link');
            SIM_file = checkPathSyntax([pathname '\' filename]);
            if strcmp(motorModel.SyreDrive.SIM_file(end-2:end),'slx')
                Simulator = 'Simulink';
            elseif strcmp(motorModel.SyreDrive.SIM_file(end-4:end),'plecs')
                Simulator = 'PLECS';
            else
                Simulator = [];
            end
            if ~isempty(Simulator)
                app.motorModel.SyreDrive.SIM_file = SIM_file;
                app.motorModel.SyreDrive.Simulator = Simulator;
            end

            MMM_GUI_SetParameters(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'GUI_Syre_MMM';
            app.AxisLogo = uiaxes(app.UIFigure);
            app.AxisLogo.XTick = [];
            app.AxisLogo.XTickLabelRotationMode = 'manual';
            app.AxisLogo.YTick = [];
            app.AxisLogo.YTickLabelRotationMode = 'manual';
            app.AxisLogo.ZTickLabelRotationMode = 'manual';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.GridLayout4 = uigridlayout(app.MainTab);
            app.ModelsLoadedPanel = uipanel(app.GridLayout4);
            app.ModelsLoadedPanel.Title = 'Models Loaded';
            app.GridLayout5 = uigridlayout(app.ModelsLoadedPanel);
            app.dqModelCheckBox = uicheckbox(app.GridLayout5);
            app.dqModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @dqModelCheckBoxValueChanged, true);
            app.dqModelCheckBox.Text = '';
            app.dqFluxMapLabel = uilabel(app.GridLayout5);
            app.dqFluxMapLabel.Text = 'dq Flux Map';
            app.LoadDQmodelButton = uibutton(app.GridLayout5, 'push');
            app.LoadDQmodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDQmodelButtonPushed, true);
            app.LoadDQmodelButton.Text = 'Load';
            app.PlotDQmodelButton = uibutton(app.GridLayout5, 'push');
            app.PlotDQmodelButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDQmodelButtonPushed, true);
            app.PlotDQmodelButton.Text = 'Plot';
            app.SaveDQmodelButton = uibutton(app.GridLayout5, 'push');
            app.SaveDQmodelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDQmodelButtonPushed, true);
            app.SaveDQmodelButton.Text = 'Save';
            app.PrintDQmodelButton = uibutton(app.GridLayout5, 'push');
            app.PrintDQmodelButton.ButtonPushedFcn = createCallbackFcn(app, @PrintDQmodelButtonPushed, true);
            app.PrintDQmodelButton.Text = 'Print';
            app.dqtMapModelCheckBox = uicheckbox(app.GridLayout5);
            app.dqtMapModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @dqtMapModelCheckBoxValueChanged, true);
            app.dqtMapModelCheckBox.Text = '';
            app.dqtFluxMapLabel = uilabel(app.GridLayout5);
            app.dqtFluxMapLabel.Text = 'dqt Flux Map';
            app.LoadDQTmodelButton = uibutton(app.GridLayout5, 'push');
            app.LoadDQTmodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDQTmodelButtonPushed, true);
            app.LoadDQTmodelButton.Text = 'Load';
            app.PlotDQTmodelButton = uibutton(app.GridLayout5, 'push');
            app.PlotDQTmodelButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDQTmodelButtonPushed, true);
            app.PlotDQTmodelButton.Text = 'Plot';
            app.SaveDQTmodelButton = uibutton(app.GridLayout5, 'push');
            app.SaveDQTmodelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDQTmodelButtonPushed, true);
            app.SaveDQTmodelButton.Text = 'Save';
            app.IronLossModelCheckBox = uicheckbox(app.GridLayout5);
            app.IronLossModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @IronLossModelCheckBoxValueChanged, true);
            app.IronLossModelCheckBox.Text = '';
            app.dqIronLossMapLabel = uilabel(app.GridLayout5);
            app.dqIronLossMapLabel.Text = 'dq Iron Loss Map';
            app.LoadIronLossModelButton = uibutton(app.GridLayout5, 'push');
            app.LoadIronLossModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIronLossModelButtonPushed, true);
            app.LoadIronLossModelButton.Text = 'Load';
            app.PlotIronLossModelButton = uibutton(app.GridLayout5, 'push');
            app.PlotIronLossModelButton.ButtonPushedFcn = createCallbackFcn(app, @PlotIronLossModelButtonPushed, true);
            app.PlotIronLossModelButton.Text = 'Plot';
            app.SaveIronLossModelButton = uibutton(app.GridLayout5, 'push');
            app.SaveIronLossModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveIronLossModelButtonPushed, true);
            app.SaveIronLossModelButton.Text = 'Save';
            app.SkinEffectModelCheckBox = uicheckbox(app.GridLayout5);
            app.SkinEffectModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @SkinEffectModelCheckBoxValueChanged, true);
            app.SkinEffectModelCheckBox.Text = '';
            app.ACLossModelLabel = uilabel(app.GridLayout5);
            app.ACLossModelLabel.Text = 'AC Loss Model';
            app.LoadSkinEffectButton = uibutton(app.GridLayout5, 'push');
            app.LoadSkinEffectButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSkinEffectButtonPushed, true);
            app.LoadSkinEffectButton.Text = 'Load';
            app.PlotSkinEffectButton = uibutton(app.GridLayout5, 'push');
            app.PlotSkinEffectButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSkinEffectButtonPushed, true);
            app.PlotSkinEffectButton.Text = 'Plot';
            app.SaveSkinEffectButton = uibutton(app.GridLayout5, 'push');
            app.SaveSkinEffectButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSkinEffectButtonPushed, true);
            app.SaveSkinEffectButton.Text = 'Save';
            app.DemagnetizationLimitCheckBox = uicheckbox(app.GridLayout5);
            app.DemagnetizationLimitCheckBox.Text = '';
            app.DemagnetizationLimitLabel = uilabel(app.GridLayout5);
            app.DemagnetizationLimitLabel.Text = 'Demagnetization Limit';
            app.LoadDemagnetizationButton = uibutton(app.GridLayout5, 'push');
            app.LoadDemagnetizationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDemagnetizationButtonPushed, true);
            app.LoadDemagnetizationButton.Text = 'Load';
            app.PlotDemagnetizationButton = uibutton(app.GridLayout5, 'push');
            app.PlotDemagnetizationButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDemagnetizationButtonPushed, true);
            app.PlotDemagnetizationButton.Text = 'Plot';
            app.SaveDemagnetizationButton = uibutton(app.GridLayout5, 'push');
            app.SaveDemagnetizationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDemagnetizationButtonPushed, true);
            app.SaveDemagnetizationButton.Text = 'Save';
            app.ControlTrajectoriesPanel = uipanel(app.GridLayout4);
            app.ControlTrajectoriesPanel.Title = 'Control Trajectories';
            app.GridLayout6 = uigridlayout(app.ControlTrajectoriesPanel);
            app.MTPAmethodDropDown = uidropdown(app.GridLayout6);
            app.MTPAmethodDropDown.Items = {'LUT', 'Fit'};
            app.MTPAmethodDropDown.Value = 'LUT';
            app.AOACheckBox = uicheckbox(app.GridLayout6);
            app.AOACheckBox.ValueChangedFcn = createCallbackFcn(app, @AOACheckBoxValueChanged, true);
            app.AOACheckBox.Text = '';
            app.ControlTrajectoriesLabel = uilabel(app.GridLayout6);
            app.ControlTrajectoriesLabel.Text = 'Control Trajectories';
            app.LoadMTPAButton = uibutton(app.GridLayout6, 'push');
            app.LoadMTPAButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMTPAButtonPushed, true);
            app.LoadMTPAButton.Text = 'Load';
            app.PlotMTPAButton = uibutton(app.GridLayout6, 'push');
            app.PlotMTPAButton.ButtonPushedFcn = createCallbackFcn(app, @PlotMTPAButtonPushed, true);
            app.PlotMTPAButton.Text = 'Plot';
            app.SaveMTPAButton = uibutton(app.GridLayout6, 'push');
            app.SaveMTPAButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMTPAButtonPushed, true);
            app.SaveMTPAButton.Text = 'Save';
            app.PrintMTPAButton = uibutton(app.GridLayout6, 'push');
            app.PrintMTPAButton.ButtonPushedFcn = createCallbackFcn(app, @PrintMTPAButtonPushed, true);
            app.PrintMTPAButton.Text = 'Print';
            app.MethodLabel = uilabel(app.GridLayout6);
            app.MethodLabel.Text = 'Method:';
            app.EvaluateMTPAButton = uibutton(app.GridLayout6, 'push');
            app.EvaluateMTPAButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateMTPAButtonPushed, true);
            app.EvaluateMTPAButton.Text = 'Evaluate';
            app.InductanceandAnisotropyMapsPanel = uipanel(app.GridLayout4);
            app.InductanceandAnisotropyMapsPanel.Title = 'Inductance and Anisotropy Maps';
            app.GridLayout7 = uigridlayout(app.InductanceandAnisotropyMapsPanel);
            app.InductanceMapsCheckBox = uicheckbox(app.GridLayout7);
            app.InductanceMapsCheckBox.ValueChangedFcn = createCallbackFcn(app, @InductanceMapsCheckBoxValueChanged, true);
            app.InductanceMapsCheckBox.Text = '';
            app.IncInductanceMapLabel = uilabel(app.GridLayout7);
            app.IncInductanceMapLabel.Text = 'Inc. Inductance Map';
            app.EvalInductanceButton = uibutton(app.GridLayout7, 'push');
            app.EvalInductanceButton.ButtonPushedFcn = createCallbackFcn(app, @EvalInductanceButtonPushed, true);
            app.EvalInductanceButton.Text = 'Eval';
            app.PlotInductanceButton = uibutton(app.GridLayout7, 'push');
            app.PlotInductanceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInductanceButtonPushed, true);
            app.PlotInductanceButton.Text = 'Plot';
            app.SaveInductanceButton = uibutton(app.GridLayout7, 'push');
            app.SaveInductanceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInductanceButtonPushed, true);
            app.SaveInductanceButton.Text = 'Save';
            app.AppInductanceMapCheckBox = uicheckbox(app.GridLayout7);
            app.AppInductanceMapCheckBox.ValueChangedFcn = createCallbackFcn(app, @AppInductanceMapCheckBoxValueChanged, true);
            app.AppInductanceMapCheckBox.Text = '';
            app.AppInductanceMapLabel = uilabel(app.GridLayout7);
            app.AppInductanceMapLabel.Text = 'App. Inductance Map';
            app.EvalAppInductanceButton = uibutton(app.GridLayout7, 'push');
            app.EvalAppInductanceButton.ButtonPushedFcn = createCallbackFcn(app, @EvalAppInductanceButtonPushed, true);
            app.EvalAppInductanceButton.Text = 'Eval';
            app.PlotAppInductanceButton = uibutton(app.GridLayout7, 'push');
            app.PlotAppInductanceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAppInductanceButtonPushed, true);
            app.PlotAppInductanceButton.Text = 'Plot';
            app.SaveAppInductanceButton = uibutton(app.GridLayout7, 'push');
            app.SaveAppInductanceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAppInductanceButtonPushed, true);
            app.SaveAppInductanceButton.Text = 'Save';
            app.InverseModelPanel = uipanel(app.GridLayout4);
            app.InverseModelPanel.Title = 'Inverse Model';
            app.GridLayout10 = uigridlayout(app.InverseModelPanel);
            app.InversedqCheckBox = uicheckbox(app.GridLayout10);
            app.InversedqCheckBox.ValueChangedFcn = createCallbackFcn(app, @InversedqCheckBoxValueChanged, true);
            app.InversedqCheckBox.Text = '';
            app.InversedqFluxMapLabel = uilabel(app.GridLayout10);
            app.InversedqFluxMapLabel.Text = 'Inverse dq Flux Map';
            app.EvalInverseDQButton = uibutton(app.GridLayout10, 'push');
            app.EvalInverseDQButton.ButtonPushedFcn = createCallbackFcn(app, @EvalInverseDQButtonPushed, true);
            app.EvalInverseDQButton.Text = 'Eval';
            app.PlotInverseDQButton = uibutton(app.GridLayout10, 'push');
            app.PlotInverseDQButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInverseDQButtonPushed, true);
            app.PlotInverseDQButton.Text = 'Plot';
            app.SaveInverseDQButton = uibutton(app.GridLayout10, 'push');
            app.SaveInverseDQButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInverseDQButtonPushed, true);
            app.SaveInverseDQButton.Text = 'Save';
            app.InversedqtMapCheckBox = uicheckbox(app.GridLayout10);
            app.InversedqtMapCheckBox.ValueChangedFcn = createCallbackFcn(app, @InversedqtMapCheckBoxValueChanged, true);
            app.InversedqtMapCheckBox.Text = '';
            app.InversedqtFluxMapLabel = uilabel(app.GridLayout10);
            app.InversedqtFluxMapLabel.Text = 'Inverse dqt Flux Map';
            app.EvalInverseDQTButton = uibutton(app.GridLayout10, 'push');
            app.EvalInverseDQTButton.ButtonPushedFcn = createCallbackFcn(app, @EvalInverseDQTButtonPushed, true);
            app.EvalInverseDQTButton.Text = 'Eval';
            app.PlotInverseDQTButton = uibutton(app.GridLayout10, 'push');
            app.PlotInverseDQTButton.ButtonPushedFcn = createCallbackFcn(app, @PlotInverseDQTButtonPushed, true);
            app.PlotInverseDQTButton.Text = 'Plot';
            app.SaveInverseDQTButton = uibutton(app.GridLayout10, 'push');
            app.SaveInverseDQTButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInverseDQTButtonPushed, true);
            app.SaveInverseDQTButton.Text = 'Save';
            app.ScalingSkewingTab = uitab(app.TabGroup);
            app.ScalingSkewingTab.Title = 'Scaling & Skewing';
            app.GridLayout11 = uigridlayout(app.ScalingSkewingTab);
            app.NewModelAxis = uiaxes(app.GridLayout11);
            title(app.NewModelAxis, 'Magnetic Model Comparison')
            xlabel(app.NewModelAxis, 'i_{dq} [A]')
            ylabel(app.NewModelAxis, '\lambda_{dq} [Vs]')
            app.NewModelAxis.XTickLabelRotationMode = 'manual';
            app.NewModelAxis.YTickLabelRotationMode = 'manual';
            app.NewModelAxis.ZTickLabelRotationMode = 'manual';
            app.ModelScalingPanel = uipanel(app.GridLayout11);
            app.ModelScalingPanel.Title = 'Model Scaling';
            app.GridLayout12 = uigridlayout(app.ModelScalingPanel);
            app.TurnsinseriesperphaseEditField_2Label = uilabel(app.GridLayout12);
            app.TurnsinseriesperphaseEditField_2Label.Text = 'Turns in series per phase';
            app.NewTurnsEditField = uieditfield(app.GridLayout12, 'text');
            app.NewTurnsEditField.ValueChangedFcn = createCallbackFcn(app, @NewTurnsEditFieldValueChanged, true);
            app.ActivelengthmmEditFieldLabel = uilabel(app.GridLayout12);
            app.ActivelengthmmEditFieldLabel.Text = 'Active length [mm]';
            app.NewLengthEditField = uieditfield(app.GridLayout12, 'text');
            app.NewLengthEditField.ValueChangedFcn = createCallbackFcn(app, @NewLengthEditFieldValueChanged, true);
            app.StatorradiusmmLabel = uilabel(app.GridLayout12);
            app.StatorradiusmmLabel.Text = 'Stator radius [mm]';
            app.NewStatorRadiusEditField = uieditfield(app.GridLayout12, 'text');
            app.NewStatorRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @NewStatorRadiusEditFieldValueChanged, true);
            app.ExtradaxisinductanceHLabel = uilabel(app.GridLayout12);
            app.ExtradaxisinductanceHLabel.Text = 'Extra d-axis inductance [H]';
            app.NewLldEditField = uieditfield(app.GridLayout12, 'text');
            app.NewLldEditField.ValueChangedFcn = createCallbackFcn(app, @NewLldEditFieldValueChanged, true);
            app.ExtraqaxisinductanceHLabel = uilabel(app.GridLayout12);
            app.ExtraqaxisinductanceHLabel.Text = 'Extra q-axis inductance [H]';
            app.NewLlqEditField = uieditfield(app.GridLayout12, 'text');
            app.NewLlqEditField.ValueChangedFcn = createCallbackFcn(app, @NewLlqEditFieldValueChanged, true);
            app.ScaleModelPush = uibutton(app.GridLayout12, 'push');
            app.ScaleModelPush.ButtonPushedFcn = createCallbackFcn(app, @ScaleModelPushButtonPushed, true);
            app.ScaleModelPush.Text = 'Scale Model';
            app.UnscaleModelPush = uibutton(app.GridLayout12, 'push');
            app.UnscaleModelPush.ButtonPushedFcn = createCallbackFcn(app, @UnscaleModelPushButtonPushed, true);
            app.UnscaleModelPush.Text = 'Cancel';
            app.ScaleMapPush = uibutton(app.GridLayout12, 'push');
            app.ScaleMapPush.ButtonPushedFcn = createCallbackFcn(app, @ScaleMapPushButtonPushed, true);
            app.ScaleMapPush.Text = 'Evaluate (L,Ns) Plane';
            app.RotortunsperpoleEditFieldLabel = uilabel(app.GridLayout12);
            app.RotortunsperpoleEditFieldLabel.Text = 'Rotor tuns per pole';
            app.RotortunsEditField = uieditfield(app.GridLayout12, 'text');
            app.RotortunsEditField.ValueChangedFcn = createCallbackFcn(app, @RotortunsEditFieldValueChanged, true);
            app.ModelSkewingPanel = uipanel(app.GridLayout11);
            app.ModelSkewingPanel.Title = 'Model Skewing';
            app.GridLayout13 = uigridlayout(app.ModelSkewingPanel);
            app.SkewanglemechdegEditFieldLabel_2 = uilabel(app.GridLayout13);
            app.SkewanglemechdegEditFieldLabel_2.Text = 'Skew angle [mech deg]';
            app.SkewAngleEditField = uieditfield(app.GridLayout13, 'text');
            app.SkewAngleEditField.ValueChangedFcn = createCallbackFcn(app, @SkewAngleEditFieldValueChanged, true);
            app.NumberofaxialslicesEditFieldLabel_2 = uilabel(app.GridLayout13);
            app.NumberofaxialslicesEditFieldLabel_2.Text = 'Number of axial slices';
            app.SkewSliceEditField = uieditfield(app.GridLayout13, 'text');
            app.SkewSliceEditField.ValueChangedFcn = createCallbackFcn(app, @SkewSliceEditFieldValueChanged, true);
            app.ofpointsalongoneaxisLabel = uilabel(app.GridLayout13);
            app.ofpointsalongoneaxisLabel.Text = '# of points along one axis';
            app.SkewPointsEditField = uieditfield(app.GridLayout13, 'text');
            app.SkewPointsEditField.ValueChangedFcn = createCallbackFcn(app, @SkewPointsEditFieldValueChanged, true);
            app.dqtMapskewingevaluationEditFieldLabel = uilabel(app.GridLayout13);
            app.dqtMapskewingevaluationEditFieldLabel.Text = 'dqtMap skewing evaluation';
            app.dqtMapskewingevaluationEditField = uieditfield(app.GridLayout13, 'text');
            app.UnskewModelPush = uibutton(app.GridLayout13, 'push');
            app.UnskewModelPush.ButtonPushedFcn = createCallbackFcn(app, @UnskewModelPushButtonPushed, true);
            app.UnskewModelPush.Text = 'Cancel';
            app.SkewModelPush = uibutton(app.GridLayout13, 'push');
            app.SkewModelPush.ButtonPushedFcn = createCallbackFcn(app, @SkewModelPushButtonPushed, true);
            app.SkewModelPush.Text = 'Skew Model';
            app.SkewingshapeDropDown = uidropdown(app.GridLayout13);
            app.SkewingshapeDropDown.Items = {'Linear skewing', 'Herringbone (V-shape) skewing'};
            app.SkewingshapeDropDown.ValueChangedFcn = createCallbackFcn(app, @SkewingshapeDropDownValueChanged, true);
            app.SkewingshapeDropDown.Value = 'Linear skewing';
            app.LoadRefModelPush = uibutton(app.GridLayout11, 'push');
            app.LoadRefModelPush.ButtonPushedFcn = createCallbackFcn(app, @LoadRefModelPushButtonPushed, true);
            app.LoadRefModelPush.Text = 'Load Reference Model';
            app.ClearScaleAxisPush = uibutton(app.GridLayout11, 'push');
            app.ClearScaleAxisPush.ButtonPushedFcn = createCallbackFcn(app, @ClearScaleAxisPushButtonPushed, true);
            app.ClearScaleAxisPush.Text = 'Clear Axis';
            app.TorqueSpeedTab = uitab(app.TabGroup);
            app.TorqueSpeedTab.Title = 'Torque-Speed';
            app.GridLayout14 = uigridlayout(app.TorqueSpeedTab);
            app.OperatingLimitsPanel = uipanel(app.GridLayout14);
            app.OperatingLimitsPanel.Title = 'Operating Limits';
            app.GridLayout15 = uigridlayout(app.OperatingLimitsPanel);
            app.CurrentlevelsLabel_2 = uilabel(app.GridLayout15);
            app.CurrentlevelsLabel_2.Text = 'Current levels';
            app.OpLimLevelsEditField = uieditfield(app.GridLayout15, 'text');
            app.OpLimLevelsEditField.ValueChangedFcn = createCallbackFcn(app, @OpLimLevelsEditFieldValueChanged, true);
            app.OpLimPush = uibutton(app.GridLayout15, 'push');
            app.OpLimPush.ButtonPushedFcn = createCallbackFcn(app, @OpLimPushButtonPushed, true);
            app.OpLimPush.Text = 'Evaluate';
            app.TorqueSpeedPerformanceMapEfficiencyMapPanel = uipanel(app.GridLayout14);
            app.TorqueSpeedPerformanceMapEfficiencyMapPanel.Title = 'Torque - Speed Performance Map & Efficiency Map';
            app.GridLayout2 = uigridlayout(app.TorqueSpeedPerformanceMapEfficiencyMapPanel);
            app.TwAxis = uiaxes(app.GridLayout2);
            title(app.TwAxis, 'T-n feasible points')
            xlabel(app.TwAxis, 'n [rpm]')
            ylabel(app.TwAxis, 'T [Nm]')
            app.TwAxis.XTickLabelRotationMode = 'manual';
            app.TwAxis.YTickLabelRotationMode = 'manual';
            app.TwAxis.ZTickLabelRotationMode = 'manual';
            app.SpeedlimitsrpmEditFieldLabel = uilabel(app.GridLayout2);
            app.SpeedlimitsrpmEditFieldLabel.Text = 'Speed limits [rpm]';
            app.TwMinSpeedEditField = uieditfield(app.GridLayout2, 'text');
            app.TwMinSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @TwMinSpeedEditFieldValueChanged, true);
            app.MinLabel = uilabel(app.GridLayout2);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.GridLayout2);
            app.MaxLabel.Text = 'Max';
            app.ofpointsLabel = uilabel(app.GridLayout2);
            app.ofpointsLabel.Text = '# of points';
            app.TwMaxSpeedEditField = uieditfield(app.GridLayout2, 'text');
            app.TwMaxSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @TwMaxSpeedEditFieldValueChanged, true);
            app.TwNumSpeedEditField = uieditfield(app.GridLayout2, 'text');
            app.TwNumSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @TwNumSpeedEditFieldValueChanged, true);
            app.TwMaxTorqueEditField = uieditfield(app.GridLayout2, 'text');
            app.TwMaxTorqueEditField.ValueChangedFcn = createCallbackFcn(app, @TwMaxTorqueEditFieldValueChanged, true);
            app.TwNumTorqueEditField = uieditfield(app.GridLayout2, 'text');
            app.TwNumTorqueEditField.ValueChangedFcn = createCallbackFcn(app, @TwNumTorqueEditFieldValueChanged, true);
            app.SpeedlimitsrpmEditFieldLabel_2 = uilabel(app.GridLayout2);
            app.SpeedlimitsrpmEditFieldLabel_2.Text = 'Torque limits [Nm]';
            app.TwMinTorqueEditField = uieditfield(app.GridLayout2, 'text');
            app.TwMinTorqueEditField.ValueChangedFcn = createCallbackFcn(app, @TwMinTorqueEditFieldValueChanged, true);
            app.WindingtemperatureCLabel = uilabel(app.GridLayout2);
            app.WindingtemperatureCLabel.Text = 'Winding temperature [°C]';
            app.TwTemperatureEditField = uieditfield(app.GridLayout2, 'text');
            app.TwTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @TwTemperatureEditFieldValueChanged, true);
            app.MechlosspolyLabel = uilabel(app.GridLayout2);
            app.MechlosspolyLabel.Text = 'Mech. loss poly';
            app.TwMechLossEditField = uieditfield(app.GridLayout2, 'text');
            app.TwMechLossEditField.ValueChangedFcn = createCallbackFcn(app, @TwMechLossEditFieldValueChanged, true);
            app.IronlossfactorLabel = uilabel(app.GridLayout2);
            app.IronlossfactorLabel.Text = 'Iron loss factor';
            app.TwIronLossFactorEditField = uieditfield(app.GridLayout2, 'text');
            app.TwIronLossFactorEditField.ValueChangedFcn = createCallbackFcn(app, @TwIronLossFactorEditFieldValueChanged, true);
            app.MaxTwPush = uibutton(app.GridLayout2, 'push');
            app.MaxTwPush.ButtonPushedFcn = createCallbackFcn(app, @MaxTwPushButtonPushed, true);
            app.MaxTwPush.Text = 'Evaluate - SIN';
            app.PMlossfactorEditFieldLabel = uilabel(app.GridLayout2);
            app.PMlossfactorEditFieldLabel.Text = 'PM loss factor';
            app.TwPMlossFactorEditField = uieditfield(app.GridLayout2, 'text');
            app.TwPMlossFactorEditField.ValueChangedFcn = createCallbackFcn(app, @TwPMlossFactorEditFieldValueChanged, true);
            app.MaxTwPWMPush = uibutton(app.GridLayout2, 'push');
            app.MaxTwPWMPush.ButtonPushedFcn = createCallbackFcn(app, @MaxTwPWMPushButtonPushed, true);
            app.MaxTwPWMPush.Text = 'Evaluate - PWM';
            app.IronlossDropDownLabel = uilabel(app.GridLayout2);
            app.IronlossDropDownLabel.Text = 'Iron loss';
            app.TwIronLossDropDown = uidropdown(app.GridLayout2);
            app.TwIronLossDropDown.Items = {'Yes', 'No'};
            app.TwIronLossDropDown.ValueChangedFcn = createCallbackFcn(app, @TwIronLossDropDownValueChanged, true);
            app.TwIronLossDropDown.Value = 'Yes';
            app.PMlossDropDownLabel = uilabel(app.GridLayout2);
            app.PMlossDropDownLabel.Text = 'PM loss';
            app.TwPMlossDropDown = uidropdown(app.GridLayout2);
            app.TwPMlossDropDown.Items = {'Yes', 'No'};
            app.TwPMlossDropDown.ValueChangedFcn = createCallbackFcn(app, @TwPMlossDropDownValueChanged, true);
            app.TwPMlossDropDown.Value = 'Yes';
            app.SkineffectDropDownLabel = uilabel(app.GridLayout2);
            app.SkineffectDropDownLabel.Text = 'Skin effect';
            app.TwSkinEffectDropDown = uidropdown(app.GridLayout2);
            app.TwSkinEffectDropDown.Items = {'Yes', 'No'};
            app.TwSkinEffectDropDown.ValueChangedFcn = createCallbackFcn(app, @TwSkinEffectDropDownValueChanged, true);
            app.TwSkinEffectDropDown.Value = 'Yes';
            app.ControlstrategyDropDownLabel = uilabel(app.GridLayout2);
            app.ControlstrategyDropDownLabel.Text = 'Control strategy';
            app.TwControlDropDown = uidropdown(app.GridLayout2);
            app.TwControlDropDown.Items = {'Max efficiency', 'MTPA'};
            app.TwControlDropDown.ValueChangedFcn = createCallbackFcn(app, @TwControlDropDownValueChanged, true);
            app.TwControlDropDown.Value = 'MTPA';
            app.ControlsafeASCDropDownLabel = uilabel(app.GridLayout2);
            app.ControlsafeASCDropDownLabel.Text = 'Control safe ASC';
            app.ControlsafeASCDropDown = uidropdown(app.GridLayout2);
            app.ControlsafeASCDropDown.Items = {'Yes', 'No'};
            app.ControlsafeASCDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlsafeASCDropDownValueChanged, true);
            app.ControlsafeASCDropDown.Value = 'No';
            app.syreDriveTab = uitab(app.TabGroup);
            app.syreDriveTab.Title = 'syreDrive';
            app.GridLayout16 = uigridlayout(app.syreDriveTab);
            app.ModelSetupPanel = uipanel(app.GridLayout16);
            app.ModelSetupPanel.Title = 'Model Setup';
            app.GridLayout17 = uigridlayout(app.ModelSetupPanel);
            app.InvertermodelDropDownLabel = uilabel(app.GridLayout17);
            app.InvertermodelDropDownLabel.Text = 'Inverter model';
            app.InvertermodelDropDown = uidropdown(app.GridLayout17);
            app.InvertermodelDropDown.Items = {'Average', 'PWM'};
            app.InvertermodelDropDown.ValueChangedFcn = createCallbackFcn(app, @InvertermodelDropDownValueChanged, true);
            app.InvertermodelDropDown.Value = 'Average';
            app.MotormodelDropDownLabel = uilabel(app.GridLayout17);
            app.MotormodelDropDownLabel.Text = 'Motor model';
            app.MotormodelDropDown = uidropdown(app.GridLayout17);
            app.MotormodelDropDown.Items = {'Controlled Current Generators (CCG)', 'Voltage-Behind-Reactance (VBR)', 'SimScape FEM-based PMSM', 'Non-Circuital Model (FM)'};
            app.MotormodelDropDown.ValueChangedFcn = createCallbackFcn(app, @MotormodelDropDownValueChanged, true);
            app.MotormodelDropDown.Value = 'Controlled Current Generators (CCG)';
            app.FluxmapsmodelDropDownLabel = uilabel(app.GridLayout17);
            app.FluxmapsmodelDropDownLabel.Text = 'Flux maps model';
            app.FluxmapsmodelDropDown = uidropdown(app.GridLayout17);
            app.FluxmapsmodelDropDown.Items = {'dq Model', 'dqt Model'};
            app.FluxmapsmodelDropDown.ValueChangedFcn = createCallbackFcn(app, @FluxmapsmodelDropDownValueChanged, true);
            app.FluxmapsmodelDropDown.Value = 'dq Model';
            app.ControltypeDropDownLabel = uilabel(app.GridLayout17);
            app.ControltypeDropDownLabel.Text = 'Control type';
            app.ControltypeDropDown = uidropdown(app.GridLayout17);
            app.ControltypeDropDown.Items = {'Current control', 'Torque control', 'Speed control'};
            app.ControltypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ControltypeDropDownValueChanged, true);
            app.ControltypeDropDown.Value = 'Current control';
            app.ControlstrategyDropDownLabel_2 = uilabel(app.GridLayout17);
            app.ControlstrategyDropDownLabel_2.Text = 'Control strategy';
            app.ControlstrategyDropDown = uidropdown(app.GridLayout17);
            app.ControlstrategyDropDown.Items = {'FOC', 'DFVC'};
            app.ControlstrategyDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlstrategyDropDownValueChanged, true);
            app.ControlstrategyDropDown.Value = 'FOC';
            app.IronLossModelDropDownLabel = uilabel(app.GridLayout17);
            app.IronLossModelDropDownLabel.Text = 'Iron Loss Model';
            app.IronLossModelDropDown = uidropdown(app.GridLayout17);
            app.IronLossModelDropDown.Items = {'Yes', 'No'};
            app.IronLossModelDropDown.ValueChangedFcn = createCallbackFcn(app, @IronLossModelDropDownValueChanged, true);
            app.IronLossModelDropDown.Value = 'Yes';
            app.ACLossModelDropDownLabel = uilabel(app.GridLayout17);
            app.ACLossModelDropDownLabel.Text = 'AC Loss Model';
            app.ACLossModelDropDown = uidropdown(app.GridLayout17);
            app.ACLossModelDropDown.Items = {'Yes', 'No'};
            app.ACLossModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ACLossModelDropDownValueChanged, true);
            app.ACLossModelDropDown.Value = 'Yes';
            app.ConverterdataPanel = uipanel(app.GridLayout16);
            app.ConverterdataPanel.Title = 'Converter data';
            app.GridLayout18 = uigridlayout(app.ConverterdataPanel);
            app.ONthreasholdVEditFieldLabel = uilabel(app.GridLayout18);
            app.ONthreasholdVEditFieldLabel.Text = 'ON threashold [V]';
            app.ONthreasholdEditField = uieditfield(app.GridLayout18, 'text');
            app.ONthreasholdEditField.ValueChangedFcn = createCallbackFcn(app, @ONthreasholdEditFieldValueChanged2, true);
            app.InternalresistanceOhmEditFieldLabel = uilabel(app.GridLayout18);
            app.InternalresistanceOhmEditFieldLabel.Text = 'Internal resistance [Ohm]';
            app.InternalresistanceEditField = uieditfield(app.GridLayout18, 'text');
            app.InternalresistanceEditField.ValueChangedFcn = createCallbackFcn(app, @InternalresistanceEditFieldValueChanged, true);
            app.DeadtimeusEditFieldLabel = uilabel(app.GridLayout18);
            app.DeadtimeusEditFieldLabel.Text = 'Dead time [us]';
            app.DeadtimeEditField = uieditfield(app.GridLayout18, 'text');
            app.DeadtimeEditField.ValueChangedFcn = createCallbackFcn(app, @DeadtimeEditFieldValueChanged, true);
            app.PWMfrequencyHzLabel = uilabel(app.GridLayout18);
            app.PWMfrequencyHzLabel.Text = 'PWM frequency [Hz]';
            app.PWMFrequencyEditField = uieditfield(app.GridLayout18, 'text');
            app.PWMFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @PWMFrequencyEditFieldValueChanged, true);
            app.SensorlesscontrolPanel = uipanel(app.GridLayout16);
            app.SensorlesscontrolPanel.Title = 'Sensorless control';
            app.GridLayout19 = uigridlayout(app.SensorlesscontrolPanel);
            app.SensorlessSwitch = uiswitch(app.GridLayout19, 'slider');
            app.SensorlessSwitch.ValueChangedFcn = createCallbackFcn(app, @SensorlessSwitchValueChanged, true);
            app.LowspeedregionHFvoltageinjectionLabel = uilabel(app.GridLayout19);
            app.LowspeedregionHFvoltageinjectionLabel.Text = 'Low speed region (HF voltage injection)';
            app.HighspeedregionLabel = uilabel(app.GridLayout19);
            app.HighspeedregionLabel.Text = 'High speed region';
            app.EnableSScontrolLabel = uilabel(app.GridLayout19);
            app.EnableSScontrolLabel.Text = 'Enable SS control:';
            app.InjectedsignalDropDownLabel = uilabel(app.GridLayout19);
            app.InjectedsignalDropDownLabel.Text = 'Injected signal';
            app.InjectedsignalDropDown = uidropdown(app.GridLayout19);
            app.InjectedsignalDropDown.Items = {'Sinusoidal', 'Squarewave'};
            app.InjectedsignalDropDown.ValueChangedFcn = createCallbackFcn(app, @InjectedsignalDropDownValueChanged, true);
            app.InjectedsignalDropDown.Value = 'Sinusoidal';
            app.DemodulationDropDownLabel = uilabel(app.GridLayout19);
            app.DemodulationDropDownLabel.Text = 'Demodulation';
            app.DemodulationDropDown = uidropdown(app.GridLayout19);
            app.DemodulationDropDown.Items = {'Current', 'Flux'};
            app.DemodulationDropDown.ValueChangedFcn = createCallbackFcn(app, @DemodulationDropDownValueChanged, true);
            app.DemodulationDropDown.Value = 'Current';
            app.PositionerrorestDropDownLabel = uilabel(app.GridLayout19);
            app.PositionerrorestDropDownLabel.Text = 'Position error est.';
            app.PositionerrorestimationDropDown = uidropdown(app.GridLayout19);
            app.PositionerrorestimationDropDown.Items = {'Active Flux', 'APP'};
            app.PositionerrorestimationDropDown.ValueChangedFcn = createCallbackFcn(app, @PositionerrorestimationDropDownValueChanged, true);
            app.PositionerrorestimationDropDown.Value = 'Active Flux';
            app.CreatePLECSModelButton = uibutton(app.GridLayout16, 'push');
            app.CreatePLECSModelButton.ButtonPushedFcn = createCallbackFcn(app, @CreatePLECSModelButtonPushed, true);
            app.CreatePLECSModelButton.Text = 'Create PLECS Model';
            app.CreateSimulinkModelButton = uibutton(app.GridLayout16, 'push');
            app.CreateSimulinkModelButton.ButtonPushedFcn = createCallbackFcn(app, @CreateSimulinkModelButtonPushed, true);
            app.CreateSimulinkModelButton.Text = 'Create Simulink Model';
            app.LinkedeDriveModelPanel = uipanel(app.GridLayout16);
            app.LinkedeDriveModelPanel.Title = 'Linked eDrive Model';
            app.GridLayout30 = uigridlayout(app.LinkedeDriveModelPanel);
            app.eDrivefileEditFieldLabel = uilabel(app.GridLayout30);
            app.eDrivefileEditFieldLabel.Text = 'eDrive file';
            app.eDrivefileEditField = uieditfield(app.GridLayout30, 'text');
            app.EnvironmentEditFieldLabel = uilabel(app.GridLayout30);
            app.EnvironmentEditFieldLabel.Text = 'Simulator';
            app.SimulatorEditField = uieditfield(app.GridLayout30, 'text');
            app.ClearButton = uibutton(app.GridLayout30, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SelectButton = uibutton(app.GridLayout30, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.WaveformTab = uitab(app.TabGroup);
            app.WaveformTab.Title = 'Waveform';
            app.GridLayout22 = uigridlayout(app.WaveformTab);
            app.SetupPanel = uipanel(app.GridLayout22);
            app.SetupPanel.Title = 'Setup';
            app.GridLayout23 = uigridlayout(app.SetupPanel);
            app.CurrentphaseangleeltdegEditFieldLabel_2 = uilabel(app.GridLayout23);
            app.CurrentphaseangleeltdegEditFieldLabel_2.Text = 'Current phase angle [elt deg]';
            app.WaveformGammaEditField = uieditfield(app.GridLayout23, 'text');
            app.WaveformGammaEditField.ValueChangedFcn = createCallbackFcn(app, @WaveformGammaEditFieldValueChanged, true);
            app.CurrentLoadpuEditFieldLabel_2 = uilabel(app.GridLayout23);
            app.CurrentLoadpuEditFieldLabel_2.Text = 'Current Load [p.u.]';
            app.WaveformCurrentPUEditField = uieditfield(app.GridLayout23, 'text');
            app.WaveformCurrentPUEditField.ValueChangedFcn = createCallbackFcn(app, @WaveformCurrentPUEditFieldValueChanged, true);
            app.PhaseCurrentAEditFieldLabel_2 = uilabel(app.GridLayout23);
            app.PhaseCurrentAEditFieldLabel_2.Text = 'Phase Current [A]';
            app.WaveformCurrentEditField = uieditfield(app.GridLayout23, 'text');
            app.WaveformCurrentEditField.ValueChangedFcn = createCallbackFcn(app, @WaveformCurrentEditFieldValueChanged, true);
            app.RotorspeedrpmLabel = uilabel(app.GridLayout23);
            app.RotorspeedrpmLabel.Text = 'Rotor speed [rpm]';
            app.WaveformSpeedEditField = uieditfield(app.GridLayout23, 'text');
            app.WaveformSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @WaveformSpeedEditFieldValueChanged, true);
            app.ofperiodsLabel = uilabel(app.GridLayout23);
            app.ofperiodsLabel.Text = '# of periods';
            app.WaveformPeriodsEditField = uieditfield(app.GridLayout23, 'text');
            app.WaveformPeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @WaveformPeriodsEditFieldValueChanged, true);
            app.EvaluationsPanel = uipanel(app.GridLayout22);
            app.EvaluationsPanel.Title = 'Evaluations';
            app.GridLayout24 = uigridlayout(app.EvaluationsPanel);
            app.WaveformSingPointButton = uibutton(app.GridLayout24, 'push');
            app.WaveformSingPointButton.ButtonPushedFcn = createCallbackFcn(app, @WaveformSingPointButtonPushed, true);
            app.WaveformSingPointButton.Text = 'Single Point (dqtMap)';
            app.WaveformShortCircuitButton = uibutton(app.GridLayout24, 'push');
            app.WaveformShortCircuitButton.ButtonPushedFcn = createCallbackFcn(app, @WaveformShortCircuitButtonPushed, true);
            app.WaveformShortCircuitButton.Text = 'Transient Short-Circuit';
            app.PlotTgammaButton = uibutton(app.GridLayout24, 'push');
            app.PlotTgammaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTgammaButtonPushed, true);
            app.PlotTgammaButton.Text = 'Constant Current Curves';
            app.EvaluateShortCircuitTorqueButton = uibutton(app.GridLayout24, 'push');
            app.EvaluateShortCircuitTorqueButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateShortCircuitTorqueButtonPushed, true);
            app.EvaluateShortCircuitTorqueButton.Text = 'Steady-State Short-Circuit';
            app.SinglePointsyreDriveButton = uibutton(app.GridLayout24, 'push');
            app.SinglePointsyreDriveButton.ButtonPushedFcn = createCallbackFcn(app, @SinglePointsyreDriveButtonPushed, true);
            app.SinglePointsyreDriveButton.Text = 'Single Point (syreDrive)';
            app.LossforShortCircuitevaluationPanel = uipanel(app.GridLayout22);
            app.LossforShortCircuitevaluationPanel.Title = 'Loss for Short-Circuit evaluation';
            app.GridLayout27 = uigridlayout(app.LossforShortCircuitevaluationPanel);
            app.IronlossLabel = uilabel(app.GridLayout27);
            app.IronlossLabel.Text = 'Iron loss';
            app.PMlossLabel = uilabel(app.GridLayout27);
            app.PMlossLabel.Text = 'PM loss';
            app.ACcopperlossLabel = uilabel(app.GridLayout27);
            app.ACcopperlossLabel.Text = 'AC copper loss';
            app.WaveformironlossDropDown = uidropdown(app.GridLayout27);
            app.WaveformironlossDropDown.Items = {'Yes', 'No'};
            app.WaveformironlossDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveformironlossDropDownValueChanged, true);
            app.WaveformironlossDropDown.Value = 'Yes';
            app.WaveformPMlossDropDown = uidropdown(app.GridLayout27);
            app.WaveformPMlossDropDown.Items = {'Yes', 'No'};
            app.WaveformPMlossDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveformPMlossDropDownValueChanged, true);
            app.WaveformPMlossDropDown.Value = 'Yes';
            app.WaveformAClossDropDown = uidropdown(app.GridLayout27);
            app.WaveformAClossDropDown.Items = {'Yes', 'No'};
            app.WaveformAClossDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveformAClossDropDownValueChanged, true);
            app.WaveformAClossDropDown.Value = 'Yes';
            app.WaveformironlossfactorEditField = uieditfield(app.GridLayout27, 'text');
            app.WaveformironlossfactorEditField.ValueChangedFcn = createCallbackFcn(app, @WaveformironlossfactorEditFieldValueChanged, true);
            app.WaveformPMlossfactorEditField = uieditfield(app.GridLayout27, 'text');
            app.WaveformPMlossfactorEditField.ValueChangedFcn = createCallbackFcn(app, @WaveformPMlossfactorEditFieldValueChanged, true);
            app.ThermalTab = uitab(app.TabGroup);
            app.ThermalTab.Title = 'Thermal';
            app.GridLayout25 = uigridlayout(app.ThermalTab);
            app.SteadyStatePanel = uipanel(app.GridLayout25);
            app.SteadyStatePanel.Title = 'Steady State';
            app.GridLayout3 = uigridlayout(app.SteadyStatePanel);
            app.ThermalTwAxis = uiaxes(app.GridLayout3);
            title(app.ThermalTwAxis, 'Continuous T-n ')
            xlabel(app.ThermalTwAxis, 'n [rpm]')
            ylabel(app.ThermalTwAxis, 'T [Nm]')
            app.MinLabel_2 = uilabel(app.GridLayout3);
            app.MinLabel_2.Text = 'Min';
            app.MaxLabel_2 = uilabel(app.GridLayout3);
            app.MaxLabel_2.Text = 'Max';
            app.ofpointsLabel_2 = uilabel(app.GridLayout3);
            app.ofpointsLabel_2.Text = '# of points';
            app.ThTwMaxSpeedEditField = uieditfield(app.GridLayout3, 'text');
            app.ThTwMaxSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @ThTwMaxSpeedEditFieldValueChanged, true);
            app.ThTwNumSpeedEditField = uieditfield(app.GridLayout3, 'text');
            app.ThTwNumSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @ThTwNumSpeedEditFieldValueChanged, true);
            app.ThermalLimitLossMapButton = uibutton(app.GridLayout3, 'push');
            app.ThermalLimitLossMapButton.ButtonPushedFcn = createCallbackFcn(app, @ThermalLimitLossMapButtonPushed, true);
            app.ThermalLimitLossMapButton.Text = 'Evalute';
            app.AdjustFluxMapPMtemperatureCheckBox = uicheckbox(app.GridLayout3);
            app.AdjustFluxMapPMtemperatureCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdjustFluxMapPMtemperatureCheckBoxValueChanged, true);
            app.AdjustFluxMapPMtemperatureCheckBox.Text = 'Adjust Flux Map PM temperature';
            app.SpeedlimitsrpmEditFieldLabel_3 = uilabel(app.GridLayout3);
            app.SpeedlimitsrpmEditFieldLabel_3.Text = 'Speed limits [rpm]';
            app.ThTwMinSpeedEditField = uieditfield(app.GridLayout3, 'text');
            app.ThTwMinSpeedEditField.ValueChangedFcn = createCallbackFcn(app, @ThTwMinSpeedEditFieldValueChanged, true);
            app.CopperlimitCLabel = uilabel(app.GridLayout3);
            app.CopperlimitCLabel.Text = 'Copper limit [°C]';
            app.CopperTempLimitEditField = uieditfield(app.GridLayout3, 'text');
            app.CopperTempLimitEditField.ValueChangedFcn = createCallbackFcn(app, @CopperTempLimitEditFieldValueChanged, true);
            app.MagnetlimitCLabel = uilabel(app.GridLayout3);
            app.MagnetlimitCLabel.Text = 'PM limit [°C]';
            app.MagnetTempLimitEditField = uieditfield(app.GridLayout3, 'text');
            app.MagnetTempLimitEditField.ValueChangedFcn = createCallbackFcn(app, @MagnetTempLimitEditFieldValueChanged, true);
            app.InterpolatedqModelPanel = uipanel(app.GridLayout25);
            app.InterpolatedqModelPanel.Title = 'Interpolate dq Model';
            app.GridLayout26 = uigridlayout(app.InterpolatedqModelPanel);
            app.CurrentlevelsLabel_3 = uilabel(app.GridLayout26);
            app.CurrentlevelsLabel_3.Text = 'PM temp [°C] ';
            app.TargetPMTempEditField = uieditfield(app.GridLayout26, 'text');
            app.TargetPMTempEditField.ValueChangedFcn = createCallbackFcn(app, @TargetPMTempEditFieldValueChanged, true);
            app.EvalInterpolateFluxMapsTemp = uibutton(app.GridLayout26, 'push');
            app.EvalInterpolateFluxMapsTemp.ButtonPushedFcn = createCallbackFcn(app, @EvalInterpolateFluxMapsTempPushed, true);
            app.EvalInterpolateFluxMapsTemp.Text = 'Eval';
            app.VFMTab = uitab(app.TabGroup);
            app.VFMTab.Title = 'VFM';
            app.GridLayout28 = uigridlayout(app.VFMTab);
            app.DemagnetizatioRemagnetizationMapsPanel = uipanel(app.GridLayout28);
            app.DemagnetizatioRemagnetizationMapsPanel.Title = 'Demagnetizatio Remagnetization Maps';
            app.GridLayout29 = uigridlayout(app.DemagnetizatioRemagnetizationMapsPanel);
            app.CtrlDeReMagCheckBox = uicheckbox(app.GridLayout29);
            app.CtrlDeReMagCheckBox.ValueChangedFcn = createCallbackFcn(app, @CtrlDeReMagCheckBoxValueChanged, true);
            app.CtrlDeReMagCheckBox.Text = '';
            app.ControlDeRemagLabel = uilabel(app.GridLayout29);
            app.ControlDeRemagLabel.Text = 'Control DeRemag';
            app.CtrlEvalDeReMagButton = uibutton(app.GridLayout29, 'push');
            app.CtrlEvalDeReMagButton.ButtonPushedFcn = createCallbackFcn(app, @CtrlEvalDeReMagButtonPushed, true);
            app.CtrlEvalDeReMagButton.Text = 'Eval';
            app.CtrlPlotDeReMagButton = uibutton(app.GridLayout29, 'push');
            app.CtrlPlotDeReMagButton.ButtonPushedFcn = createCallbackFcn(app, @CtrlPlotDeReMagButtonPushed, true);
            app.CtrlPlotDeReMagButton.Text = 'Plot';
            app.CtrlSaveDeReMagButton = uibutton(app.GridLayout29, 'push');
            app.CtrlSaveDeReMagButton.ButtonPushedFcn = createCallbackFcn(app, @CtrlSaveDeReMagButtonPushed, true);
            app.CtrlSaveDeReMagButton.Text = 'Save';
            app.CheckBoxDemagMap = uicheckbox(app.GridLayout29);
            app.CheckBoxDemagMap.ValueChangedFcn = createCallbackFcn(app, @CheckBoxDemagMapValueChanged, true);
            app.CheckBoxDemagMap.Text = '';
            app.CheckBoxRemagMap = uicheckbox(app.GridLayout29);
            app.CheckBoxRemagMap.ValueChangedFcn = createCallbackFcn(app, @CheckBoxRemagMapValueChanged, true);
            app.CheckBoxRemagMap.Text = '';
            app.RemagMapLabel = uilabel(app.GridLayout29);
            app.RemagMapLabel.Text = 'Remag Map';
            app.DemagMapLabel = uilabel(app.GridLayout29);
            app.DemagMapLabel.Text = 'Demag Map';
            app.LoadButtonRemagMap = uibutton(app.GridLayout29, 'push');
            app.LoadButtonRemagMap.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonRemagMapPushed, true);
            app.LoadButtonRemagMap.Text = 'Load';
            app.LoadButtonDemagMap = uibutton(app.GridLayout29, 'push');
            app.LoadButtonDemagMap.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonDemagMapPushed, true);
            app.LoadButtonDemagMap.Text = 'Load';
            app.PlotButtonRemagMap = uibutton(app.GridLayout29, 'push');
            app.PlotButtonRemagMap.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonRemagMapPushed, true);
            app.PlotButtonRemagMap.Text = 'Plot';
            app.PlotButtonDemagMap = uibutton(app.GridLayout29, 'push');
            app.PlotButtonDemagMap.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonDemagMapPushed, true);
            app.PlotButtonDemagMap.Text = 'Plot';
            app.SaveButtonRemagMap = uibutton(app.GridLayout29, 'push');
            app.SaveButtonRemagMap.Text = 'Save';
            app.SaveButtonDemagMap = uibutton(app.GridLayout29, 'push');
            app.SaveButtonDemagMap.Text = 'Save';
            app.SyReMagneticModelManipulationLabel = uilabel(app.UIFigure);
            app.SyReMagneticModelManipulationLabel.Text = {'SyR-e'; 'Magnetic Model Manipulation'};
            app.MotorRatingsPanel = uipanel(app.UIFigure);
            app.MotorRatingsPanel.Title = 'Motor Ratings';
            app.GridLayout = uigridlayout(app.MotorRatingsPanel);
            app.MotortypeEditFieldLabel = uilabel(app.GridLayout);
            app.MotortypeEditFieldLabel.Text = 'Motor type';
            app.MotortypeEditField = uieditfield(app.GridLayout, 'text');
            app.MotortypeEditField.ValueChangedFcn = createCallbackFcn(app, @MotortypeEditFieldValueChanged, true);
            app.RatedcurrentApkEditFieldLabel = uilabel(app.GridLayout);
            app.RatedcurrentApkEditFieldLabel.Text = 'Rated current [Apk]';
            app.RatedcurrentEditField = uieditfield(app.GridLayout, 'text');
            app.RatedcurrentEditField.ValueChangedFcn = createCallbackFcn(app, @RatedcurrentEditFieldValueChanged, true);
            app.MaxcurrentApkEditFieldLabel = uilabel(app.GridLayout);
            app.MaxcurrentApkEditFieldLabel.Text = 'Max current [Apk]';
            app.MaximumcurrentEditField = uieditfield(app.GridLayout, 'text');
            app.MaximumcurrentEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumcurrentEditFieldValueChanged, true);
            app.DClinkvoltageVEditFieldLabel = uilabel(app.GridLayout);
            app.DClinkvoltageVEditFieldLabel.Text = 'DC link voltage [V]';
            app.DClinkvoltageEditField = uieditfield(app.GridLayout, 'text');
            app.DClinkvoltageEditField.ValueChangedFcn = createCallbackFcn(app, @DClinkvoltageEditFieldValueChanged, true);
            app.PhaseresistanceOhmEditFieldLabel = uilabel(app.GridLayout);
            app.PhaseresistanceOhmEditFieldLabel.Text = 'Phase resistance [Ohm]';
            app.PhaseresistanceEditField = uieditfield(app.GridLayout, 'text');
            app.PhaseresistanceEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseresistanceEditFieldValueChanged, true);
            app.StatorwindingtempCEditFieldLabel = uilabel(app.GridLayout);
            app.StatorwindingtempCEditFieldLabel.Text = 'Stator winding temp [°C]';
            app.StatorwindingtempCEditField = uieditfield(app.GridLayout, 'text');
            app.StatorwindingtempCEditField.ValueChangedFcn = createCallbackFcn(app, @StatorwindingtempCEditFieldValueChanged, true);
            app.StacklengthmmLabel = uilabel(app.GridLayout);
            app.StacklengthmmLabel.Text = 'Stack length [mm]';
            app.ActivelengthEditField = uieditfield(app.GridLayout, 'text');
            app.ActivelengthEditField.ValueChangedFcn = createCallbackFcn(app, @ActivelengthEditFieldValueChanged, true);
            app.Numberof3phasesetsEditFieldLabel = uilabel(app.GridLayout);
            app.Numberof3phasesetsEditFieldLabel.Text = 'Number of 3phase sets';
            app.Numberof3phasesetsEditField = uieditfield(app.GridLayout, 'text');
            app.Numberof3phasesetsEditField.ValueChangedFcn = createCallbackFcn(app, @Numberof3phasesetsEditFieldValueChanged, true);
            app.MaximumspeedrpmEditFieldLabel = uilabel(app.GridLayout);
            app.MaximumspeedrpmEditFieldLabel.Text = 'Maximum speed [rpm]';
            app.MaximumspeedEditField = uieditfield(app.GridLayout, 'text');
            app.MaximumspeedEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumspeedEditFieldValueChanged, true);
            app.RatedpowerWEditFieldLabel = uilabel(app.GridLayout);
            app.RatedpowerWEditFieldLabel.Text = 'Rated power [W]';
            app.RatedpowerEditField = uieditfield(app.GridLayout, 'text');
            app.RatedpowerEditField.ValueChangedFcn = createCallbackFcn(app, @RatedpowerEditFieldValueChanged, true);
            app.RatedspeedrpmEditFieldLabel = uilabel(app.GridLayout);
            app.RatedspeedrpmEditFieldLabel.Text = 'Rated speed [rpm]';
            app.RatedspeedEditField = uieditfield(app.GridLayout, 'text');
            app.RatedspeedEditField.ValueChangedFcn = createCallbackFcn(app, @RatedspeedEditFieldValueChanged, true);
            app.StatorendwindingmmLabel = uilabel(app.GridLayout);
            app.StatorendwindingmmLabel.Text = 'Stator end winding [mm]';
            app.EndwindinglengthEditField = uieditfield(app.GridLayout, 'text');
            app.EndwindinglengthEditField.ValueChangedFcn = createCallbackFcn(app, @EndwindinglengthEditFieldValueChanged, true);
            app.MotornameEditFieldLabel = uilabel(app.GridLayout);
            app.MotornameEditFieldLabel.Text = 'Motor name';
            app.MotornameEditField = uieditfield(app.GridLayout, 'text');
            app.MotornameEditField.ValueChangedFcn = createCallbackFcn(app, @MotornameEditFieldValueChanged, true);
            app.PathnameEditFieldLabel = uilabel(app.GridLayout);
            app.PathnameEditFieldLabel.Text = 'Pathname';
            app.PathnameEditField = uieditfield(app.GridLayout, 'text');
            app.RatedtorqueNmEditFieldLabel = uilabel(app.GridLayout);
            app.RatedtorqueNmEditFieldLabel.Text = 'Rated torque [Nm]';
            app.RatedtorqueEditField = uieditfield(app.GridLayout, 'text');
            app.RatedtorqueEditField.ValueChangedFcn = createCallbackFcn(app, @RatedtorqueEditFieldValueChanged, true);
            app.PMtemperatureDropDownLabel = uilabel(app.GridLayout);
            app.PMtemperatureDropDownLabel.Text = 'PM temperature';
            app.PMtemperatureDropDown = uidropdown(app.GridLayout);
            app.PMtemperatureDropDown.Items = {'Add'};
            app.PMtemperatureDropDown.ValueChangedFcn = createCallbackFcn(app, @PMtemperatureDropDownValueChanged, true);
            app.PMtemperatureDropDown.Value = 'Add';
            app.AxistypeDropDownLabel = uilabel(app.GridLayout);
            app.AxistypeDropDownLabel.Text = 'Axis type';
            app.AxistypeDropDown = uidropdown(app.GridLayout);
            app.AxistypeDropDown.Items = {'SR', 'PM'};
            app.AxistypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AxistypeDropDownValueChanged, true);
            app.AxistypeDropDown.Value = 'SR';
            app.RotorwindingtempCEditFieldLabel = uilabel(app.GridLayout);
            app.RotorwindingtempCEditFieldLabel.Text = 'Rotor winding temp [°C]';
            app.RotorwindingtempCEditField = uieditfield(app.GridLayout, 'text');
            app.RotorwindingtempCEditField.ValueChangedFcn = createCallbackFcn(app, @RotorwindingtempCEditFieldValueChanged, true);
            app.RotormaxvoltageVEditFieldLabel = uilabel(app.GridLayout);
            app.RotormaxvoltageVEditFieldLabel.Text = 'Rotor max voltage [V]';
            app.RotormaxvoltageVEditField = uieditfield(app.GridLayout, 'text');
            app.RotormaxvoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @RotormaxvoltageVEditFieldValueChanged, true);
            app.RotorendwindingmmEditFieldLabel = uilabel(app.GridLayout);
            app.RotorendwindingmmEditFieldLabel.Text = 'Rotor end winding [mm]';
            app.RotorendwindingmmEditField = uieditfield(app.GridLayout, 'text');
            app.RotorendwindingmmEditField.ValueChangedFcn = createCallbackFcn(app, @RotorendwindingmmEditFieldValueChanged, true);
            app.RotorresistanceOhmEditFieldLabel = uilabel(app.GridLayout);
            app.RotorresistanceOhmEditFieldLabel.Text = 'Rotor resistance [Ohm]';
            app.RotorresistanceOhmEditField = uieditfield(app.GridLayout, 'text');
            app.RotorresistanceOhmEditField.ValueChangedFcn = createCallbackFcn(app, @RotorresistanceOhmEditFieldValueChanged, true);
            app.MaxrotorcurrentAEditFieldLabel = uilabel(app.GridLayout);
            app.MaxrotorcurrentAEditFieldLabel.Text = 'Max rotor current [A]';
            app.MaxrotorcurrentAEditField = uieditfield(app.GridLayout, 'text');
            app.MaxrotorcurrentAEditField.ValueChangedFcn = createCallbackFcn(app, @MaxrotorcurrentAEditFieldValueChanged, true);
            app.TurnsinseriesperphaseEditFieldLabel = uilabel(app.GridLayout);
            app.TurnsinseriesperphaseEditFieldLabel.Text = 'Turns in series per phase';
            app.TurnsinseriesperphaseEditField = uieditfield(app.GridLayout, 'text');
            app.TurnsinseriesperphaseEditField.ValueChangedFcn = createCallbackFcn(app, @TurnsinseriesperphaseEditFieldValueChanged, true);
            app.RotorturnsperpoleEditFieldLabel = uilabel(app.GridLayout);
            app.RotorturnsperpoleEditFieldLabel.Text = 'Rotor turns per pole';
            app.RotorturnsperpoleEditField = uieditfield(app.GridLayout, 'text');
            app.RotorturnsperpoleEditField.ValueChangedFcn = createCallbackFcn(app, @RotorturnsperpoleEditFieldValueChanged, true);
            app.Inertiakgm2EditFieldLabel = uilabel(app.GridLayout);
            app.Inertiakgm2EditFieldLabel.Text = 'Inertia [kg m^2]';
            app.InertiaEditField = uieditfield(app.GridLayout, 'text');
            app.InertiaEditField.ValueChangedFcn = createCallbackFcn(app, @InertiaEditFieldValueChanged, true);
            app.LoadPush = uibutton(app.UIFigure, 'push');
            app.LoadPush.ButtonPushedFcn = createCallbackFcn(app, @LoadPushButtonPushed, true);
            app.LoadPush.Tooltip = {''};
            app.LoadPush.Text = 'Load';
            app.SavePush = uibutton(app.UIFigure, 'push');
            app.SavePush.ButtonPushedFcn = createCallbackFcn(app, @SavePushButtonPushed, true);
            app.SavePush.Text = 'Save';
            app.SaveAsPush = uibutton(app.UIFigure, 'push');
            app.SaveAsPush.ButtonPushedFcn = createCallbackFcn(app, @SaveAsPushButtonPushed, true);
            app.SaveAsPush.Text = 'Save As';
            app.NewButton = uibutton(app.UIFigure, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.CloseallButton = uibutton(app.UIFigure, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
            app.toWorkSpaceButton = uibutton(app.UIFigure, 'push');
            app.toWorkSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @toWorkSpaceButtonPushed, true);
            app.toWorkSpaceButton.Text = 'to WorkSpace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Syre_MMM(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_Syre_MMM_StartUp(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end