classdef optimizationResultsExplorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        optimizationResultsExplorerUIFigure  matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        DrawmotorButton                matlab.ui.control.Button
        CorrelationplotButton          matlab.ui.control.Button
        RemoveunfeasibleButton         matlab.ui.control.StateButton
        DatanotloadedLabel             matlab.ui.control.Label
        ObjectivesListBox              matlab.ui.control.ListBox
        ObjectivesListBoxLabel         matlab.ui.control.Label
        caxisDropDown                  matlab.ui.control.DropDown
        caxisDropDownLabel             matlab.ui.control.Label
        zaxisDropDown                  matlab.ui.control.DropDown
        zaxisDropDownLabel             matlab.ui.control.Label
        yaxisDropDown                  matlab.ui.control.DropDown
        yaxisDropDownLabel             matlab.ui.control.Label
        xaxisDropDown                  matlab.ui.control.DropDown
        xaxisDropDownLabel             matlab.ui.control.Label
        CloseallButton                 matlab.ui.control.Button
        toWorkSpaceButton              matlab.ui.control.Button
        Plot3DButton                   matlab.ui.control.Button
        Plot2DButton                   matlab.ui.control.Button
        LoadOptimizationResultsButton  matlab.ui.control.Button
        VariablesListBoxLabel          matlab.ui.control.Label
        VariablesListBox               matlab.ui.control.ListBox
        UIAxes                         matlab.ui.control.UIAxes
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
        SyReLabel                      matlab.ui.control.Label
        xbDesignPlaneExplorerLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        optRes % optimization results
        OREsetup % setup options
    end
    
    properties (Access = private)
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            % Set figure at the center of the screen
            app.optimizationResultsExplorerUIFigure.Units = 'pixels';
            screenPos = get(groot,'ScreenSize');
            figPos = app.optimizationResultsExplorerUIFigure.Position;
            figPos(1:2)=screenPos(3:4)/2-figPos(3:4)/2;
            app.optimizationResultsExplorerUIFigure.Position = figPos;
             
            % mfilesPath = fileparts(which('ORE_SetParameters.m'));
            % addpath(mfilesPath);

            if length(varargin)==2
                [app.optRes] = ORE_loadData(varargin{1},varargin{2});
            else
                app.optRes = [];
            end

            app.OREsetup.xaxis = 'none';
            app.OREsetup.yaxis = 'none';
            app.OREsetup.zaxis = 'none';
            app.OREsetup.caxis = 'none';
            app.OREsetup.removeUnfeasible = 0;

            ORE_SetParameters(app);


        end

        % Button pushed function: CloseallButton
        function CloseallButtonPushed(app, event)
            close all
            % cla(app.UIAxes)
            app.OREsetup.xaxis = 'none';
            app.OREsetup.yaxis = 'none';
            app.OREsetup.zaxis = 'none';
            app.OREsetup.caxis = 'none';
            app.OREsetup.removeUnfeasible = 0;

            ORE_SetParameters(app);
        end

        % Button pushed function: LoadOptimizationResultsButton
        function LoadOptimizationResultsButtonPushed(app, event)
            app.optRes = ORE_loadData();
            
            app.OREsetup.xaxis = 'none';
            app.OREsetup.yaxis = 'none';
            app.OREsetup.zaxis = 'none';
            app.OREsetup.caxis = 'none';
            app.OREsetup.removeUnfeasible = 0;

            ORE_SetParameters(app);

        end

        % Value changed function: RemoveunfeasibleButton
        function RemoveunfeasibleButtonValueChanged(app, event)
            value = app.RemoveunfeasibleButton.Value;
            app.OREsetup.removeUnfeasible = value;

            ORE_SetParameters(app);
        end

        % Value changed function: xaxisDropDown
        function xaxisDropDownValueChanged(app, event)
            value = app.xaxisDropDown.Value;
            app.OREsetup.xaxis = value;

            ORE_SetParameters(app);
        end

        % Value changed function: yaxisDropDown
        function yaxisDropDownValueChanged(app, event)
            value = app.yaxisDropDown.Value;
            app.OREsetup.yaxis = value;

            ORE_SetParameters(app);
        end

        % Value changed function: zaxisDropDown
        function zaxisDropDownValueChanged(app, event)
            value = app.zaxisDropDown.Value;
            app.OREsetup.zaxis = value;

            ORE_SetParameters(app);
        end

        % Value changed function: caxisDropDown
        function caxisDropDownValueChanged(app, event)
            value = app.caxisDropDown.Value;
            app.OREsetup.caxis = value;

            ORE_SetParameters(app);
        end

        % Button pushed function: Plot2DButton
        function Plot2DButtonPushed(app, event)
            ORE_plot(app.optRes,app.OREsetup,'2D')
        end

        % Button pushed function: Plot3DButton
        function Plot3DButtonPushed(app, event)
            ORE_plot(app.optRes,app.OREsetup,'3D')
        end

        % Button pushed function: CorrelationplotButton
        function CorrelationplotButtonPushed(app, event)
            ORE_plotCorrelation(app.optRes)
        end

        % Button pushed function: toWorkSpaceButton
        function toWorkSpaceButtonPushed(app, event)
            tmp = ORE_removeUnfeasible(app.optRes,app.OREsetup.removeUnfeasible);
            assignin('base','optRes',tmp)
        end

        % Button pushed function: DrawmotorButton
        function DrawmotorButtonPushed(app, event)
            ORE_drawMotor(app.optRes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.optimizationResultsExplorerUIFigure = uifigure('Visible', 'off');
            colormap(app.optimizationResultsExplorerUIFigure, 'parula');
            app.optimizationResultsExplorerUIFigure.Name = 'SyR-e (x,b) Design Plane Explorer';
            app.optimizationResultsExplorerUIFigure.Icon = 'icon.png';
            app.xbDesignPlaneExplorerLabel = uilabel(app.optimizationResultsExplorerUIFigure);
            app.xbDesignPlaneExplorerLabel.Text = 'Optimization Results Explorer';
            app.SyReLabel = uilabel(app.optimizationResultsExplorerUIFigure);
            app.SyReLabel.Text = 'SyR-e';
            app.Image = uiimage(app.optimizationResultsExplorerUIFigure);
            app.Image.ImageSource = 'syreHeader.png';
            app.Image2 = uiimage(app.optimizationResultsExplorerUIFigure);
            app.Image2.ImageSource = 'POLI2021-PEIC_colori.png';
            app.Panel = uipanel(app.optimizationResultsExplorerUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.UIAxes = uiaxes(app.GridLayout);
            colormap(app.UIAxes, 'turbo')
            app.VariablesListBox = uilistbox(app.GridLayout);
            app.VariablesListBox.Items = {};
            app.VariablesListBox.Multiselect = 'on';
            app.VariablesListBox.Value = {};
            app.VariablesListBoxLabel = uilabel(app.GridLayout);
            app.VariablesListBoxLabel.Text = 'Variables';
            app.LoadOptimizationResultsButton = uibutton(app.GridLayout, 'push');
            app.LoadOptimizationResultsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOptimizationResultsButtonPushed, true);
            app.LoadOptimizationResultsButton.Text = {'Load'; 'Optimization Results'};
            app.Plot2DButton = uibutton(app.GridLayout, 'push');
            app.Plot2DButton.ButtonPushedFcn = createCallbackFcn(app, @Plot2DButtonPushed, true);
            app.Plot2DButton.Text = 'Plot 2D';
            app.Plot3DButton = uibutton(app.GridLayout, 'push');
            app.Plot3DButton.ButtonPushedFcn = createCallbackFcn(app, @Plot3DButtonPushed, true);
            app.Plot3DButton.Text = 'Plot 3D';
            app.toWorkSpaceButton = uibutton(app.GridLayout, 'push');
            app.toWorkSpaceButton.ButtonPushedFcn = createCallbackFcn(app, @toWorkSpaceButtonPushed, true);
            app.toWorkSpaceButton.Text = 'to WorkSpace';
            app.CloseallButton = uibutton(app.GridLayout, 'push');
            app.CloseallButton.ButtonPushedFcn = createCallbackFcn(app, @CloseallButtonPushed, true);
            app.CloseallButton.Text = 'Close all';
            app.xaxisDropDownLabel = uilabel(app.GridLayout);
            app.xaxisDropDownLabel.Text = 'x axis';
            app.xaxisDropDown = uidropdown(app.GridLayout);
            app.xaxisDropDown.Items = {'none'};
            app.xaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @xaxisDropDownValueChanged, true);
            app.xaxisDropDown.Value = 'none';
            app.yaxisDropDownLabel = uilabel(app.GridLayout);
            app.yaxisDropDownLabel.Text = 'y axis';
            app.yaxisDropDown = uidropdown(app.GridLayout);
            app.yaxisDropDown.Items = {'none'};
            app.yaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @yaxisDropDownValueChanged, true);
            app.yaxisDropDown.Value = 'none';
            app.zaxisDropDownLabel = uilabel(app.GridLayout);
            app.zaxisDropDownLabel.Text = 'z axis';
            app.zaxisDropDown = uidropdown(app.GridLayout);
            app.zaxisDropDown.Items = {'none'};
            app.zaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @zaxisDropDownValueChanged, true);
            app.zaxisDropDown.Value = 'none';
            app.caxisDropDownLabel = uilabel(app.GridLayout);
            app.caxisDropDownLabel.Text = 'c axis';
            app.caxisDropDown = uidropdown(app.GridLayout);
            app.caxisDropDown.Items = {'none'};
            app.caxisDropDown.ValueChangedFcn = createCallbackFcn(app, @caxisDropDownValueChanged, true);
            app.caxisDropDown.Value = 'none';
            app.ObjectivesListBoxLabel = uilabel(app.GridLayout);
            app.ObjectivesListBoxLabel.Text = 'Objectives';
            app.ObjectivesListBox = uilistbox(app.GridLayout);
            app.ObjectivesListBox.Items = {};
            app.ObjectivesListBox.Multiselect = 'on';
            app.ObjectivesListBox.Value = {};
            app.DatanotloadedLabel = uilabel(app.GridLayout);
            app.DatanotloadedLabel.Text = 'Data not loaded';
            app.RemoveunfeasibleButton = uibutton(app.GridLayout, 'state');
            app.RemoveunfeasibleButton.ValueChangedFcn = createCallbackFcn(app, @RemoveunfeasibleButtonValueChanged, true);
            app.RemoveunfeasibleButton.Text = 'Remove unfeasible';
            app.CorrelationplotButton = uibutton(app.GridLayout, 'push');
            app.CorrelationplotButton.ButtonPushedFcn = createCallbackFcn(app, @CorrelationplotButtonPushed, true);
            app.CorrelationplotButton.Text = 'Correlation plot';
            app.DrawmotorButton = uibutton(app.GridLayout, 'push');
            app.DrawmotorButton.ButtonPushedFcn = createCallbackFcn(app, @DrawmotorButtonPushed, true);
            app.DrawmotorButton.Text = 'Draw motor';
            % Show the figure after all components are created
            app.optimizationResultsExplorerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optimizationResultsExplorer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.optimizationResultsExplorerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.optimizationResultsExplorerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.optimizationResultsExplorerUIFigure)
        end
    end
end